classdef DistortionCleaner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RawData_Axes                    matlab.ui.control.UIAxes
        CorrectedData_Axes              matlab.ui.control.UIAxes
        AnchorPointPairsfromGUIPanel    matlab.ui.container.Panel
        BoxsizepixelSpinnerLabel        matlab.ui.control.Label
        Boxsizepixel_Spinner            matlab.ui.control.Spinner
        FixedPointIntervalumLabel       matlab.ui.control.Label
        FixedpointIntervalum_EditField  matlab.ui.control.NumericEditField
        EquallydigitalIntervalpixelEditFieldLabel  matlab.ui.control.Label
        EquallydigitalIntervalpixel_EditField  matlab.ui.control.NumericEditField
        GeneralParametersUpdate_Button  matlab.ui.control.Button
        Gridnumber_xSpinnerLabel        matlab.ui.control.Label
        Gridnumber_x_Spinner            matlab.ui.control.Spinner
        Gridnumber_ySpinnerLabel        matlab.ui.control.Label
        Gridnumber_y_Spinner            matlab.ui.control.Spinner
        StartpointxpixelSpinnerLabel    matlab.ui.control.Label
        Startpointxpixel_x_Spinner      matlab.ui.control.Spinner
        StartpointypixelSpinnerLabel    matlab.ui.control.Label
        Startpointypixel_y_Spinner      matlab.ui.control.Spinner
        RawDataCorrection_Button        matlab.ui.control.Button
        ImageDataInputOutputPanel       matlab.ui.container.Panel
        SelectFile_RawData              matlab.ui.control.Button
        RawDateFolderLabel              matlab.ui.control.Label
        Filepath_RawDatefolder          matlab.ui.control.EditField
        SaveTiffheaderSIButton          matlab.ui.control.Button
        FOVParametersPanel              matlab.ui.container.Panel
        FOV_xumLabel                    matlab.ui.control.Label
        FOVX_EditField                  matlab.ui.control.NumericEditField
        FOV_yumLabel                    matlab.ui.control.Label
        FOVY_EditField                  matlab.ui.control.NumericEditField
        Pixelsize_xumpixLabel           matlab.ui.control.Label
        PixSizeX_EditField              matlab.ui.control.NumericEditField
        Pixelsize_yumpixLabel_2         matlab.ui.control.Label
        PixSizeY_EditField              matlab.ui.control.NumericEditField
        ResultsLoad_Button              matlab.ui.control.Button
        FilePathNameLabel_3             matlab.ui.control.Label
        Filepath_FOVParametersInput     matlab.ui.control.EditField
        SelectFile_FOVParametersInput   matlab.ui.control.Button
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        AnchorPointPairsfromFilePanel   matlab.ui.container.Panel
        FilePathNameLabel_2             matlab.ui.control.Label
        Filepath_RawAnchorpointpairs    matlab.ui.control.EditField
        SelectFile_AnchorPointPairs     matlab.ui.control.Button
        InloadFile_AnchorPointPairs     matlab.ui.control.Button
        GridDateinputPanel              matlab.ui.container.Panel
        FilePathNameLabel               matlab.ui.control.Label
        Filepath_RawGridFile            matlab.ui.control.EditField
        SelectFile_RawGrid              matlab.ui.control.Button
        InloadFile_RawGrid              matlab.ui.control.Button
        ResultsSavePanel                matlab.ui.container.Panel
        ResultsSave_Button_2            matlab.ui.control.Button
        FileNameLabel_2                 matlab.ui.control.Label
        Filepath_CorrectedGridFile      matlab.ui.control.EditField
        FileNameLabel                   matlab.ui.control.Label
        Filepath_CorrectedAnthorPointPairs  matlab.ui.control.EditField
        CopyFile_AnchorPointPairs       matlab.ui.control.Button
        PairingResultSave_Button        matlab.ui.control.Button
        SelectFile_NewAnchorPointPairs  matlab.ui.control.Button
        CopyFile_CorrectedGridFile      matlab.ui.control.Button
        SelectFile_CorrectedGridFile    matlab.ui.control.Button
        ResultsSave_Button              matlab.ui.control.Button
        FileNameLabel_3                 matlab.ui.control.Label
        Filepath_FOVParametersSave      matlab.ui.control.EditField
        CopyFile_FOVParametersSave      matlab.ui.control.Button
        SelectFile_FOVParametersSave    matlab.ui.control.Button
        CopyFile_NewTransformMatrix     matlab.ui.control.Button
        SelectFile_NewTransformMatrix   matlab.ui.control.Button
        FileNameLabel_4                 matlab.ui.control.Label
        Filepath_NewTransformMatrix     matlab.ui.control.EditField
        ResultsSave_Button_3            matlab.ui.control.Button
        RawDataCorrection_Button_2      matlab.ui.control.Button
        TimestampEditFieldLabel         matlab.ui.control.Label
        TimestampEditField              matlab.ui.control.EditField
        PairingResultAccept_Button      matlab.ui.control.Button
        TotalfilesEditFieldLabel        matlab.ui.control.Label
        TotalfilesEditField             matlab.ui.control.NumericEditField
        LeftfilesEditFieldLabel         matlab.ui.control.Label
        LeftfilesEditField              matlab.ui.control.NumericEditField
        ProgressLabel                   matlab.ui.control.Label
        ProgressGauge                   matlab.ui.control.LinearGauge
        TransformMatrixinputPanel       matlab.ui.container.Panel
        FilePathNameLabel_4             matlab.ui.control.Label
        Filepath_TranformMatrix         matlab.ui.control.EditField
        SelectFile_TranformMatrix       matlab.ui.control.Button
        InloadFile_TranformMatrix       matlab.ui.control.Button
        TransformMatrixMonitorTextAreaLabel  matlab.ui.control.Label
        TransformMatrixMonitorTextArea  matlab.ui.control.TextArea
        InputMatrixEditFieldLabel       matlab.ui.control.Label
        InputMatrixEditField            matlab.ui.control.NumericEditField
        PositionDataCorrection_Button   matlab.ui.control.Button
        PositionDataInputOutputPanel    matlab.ui.container.Panel
        SelectFile_PositionData         matlab.ui.control.Button
        RawDateFolderLabel_2            matlab.ui.control.Label
        Filepath_PositionDatefolder     matlab.ui.control.EditField
    end


    properties (Access = public)
        h1 % Description
        h2 % Description
        Num_pair % Descriptio
        L_box % Description
        Frame_size_x % Description
        Frame_size_y % Description
        Grid_data_raw % Description
        Grid_data_corrected % Description
        fixed_point % Description
        moved_point % Description
        RawGrid_Path % Description
        RawGrid_File % Description
        Time_now % Description        
        FOVParameter % Description
        RawImage_File % Description
        RawImage_Path % Description
        tform % Description
        tform_inverse % Description
        MatrixInput=0; % Description
        PositionImage_File % Description
        PositionImage_Path % Description
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFile_AnchorPointPairs
        function SelectFile_AnchorPointPairsButtonPushed(app, event)

            [AnchorPointPairs_File,AnchorPointPairs_Path] = uigetfile('*.csv'); 
            app.Filepath_RawAnchorpointpairs.Value=[AnchorPointPairs_Path,AnchorPointPairs_File];
            app.TextArea.Value=['User is going to select Raw Anchor Point Pairs from ' app.Filepath_RawAnchorpointpairs.Value];

            
%             fixed_point=zeros(Num_pair,2);
%             moved_point=zeros(Num_pair,2);
%             for i=1:1:Num_pair                                              % transfer the current rectangles position to the anthor point pairs
%                     Recent_point_distortion=getPosition(h1(i));
%                     Recent_point_correction=getPosition(h2(i));
%                     moved_point(i,:)=Recent_point_distortion(1:2);
%                     fixed_point(i,:)=Recent_point_correction(1:2);
%             end
%           
%             end
        end

        % Callback function: Filepath_RawAnchorpointpairs, 
        % Filepath_RawAnchorpointpairs
        function Filepath_RawAnchorpointpairsValueChanged(app, event)
        app.TextArea.Value=['The Raw Anchor Point Pairs file input path was changed to ' app.Filepath_RawAnchorpointpairs.Value];
        end

        % Button pushed function: SelectFile_RawGrid
        function SelectFile_RawGridButtonPushed(app, event)
            [app.RawGrid_File,app.RawGrid_Path] = uigetfile('*.tif');         
            app.Filepath_RawGridFile.Value=[app.RawGrid_Path,app.RawGrid_File];
            
            app.TextArea.Value=['User is going to select Raw Grids Image from ' app.Filepath_RawGridFile.Value];
            
        end

        % Value changed function: Filepath_RawGridFile
        function Filepath_RawGridFileValueChanged(app, event)
%             global Filepath_RawGrid;
%             Filepath_RawGrid = app.Filepath_RawGridFile.Value;
            app.TextArea.Value=['The Raw Rad Grids Image file input path was changed to ' app.Filepath_RawGridFile.Value];
        end

        % Button pushed function: SelectFile_RawData
        function SelectFile_RawDataButtonPushed(app, event)

            [app.RawImage_File,app.RawImage_Path] = uigetfile('*.tif'); 
            app.Filepath_RawDatefolder.Value=app.RawImage_Path;
             app.TextArea.Value=['User has selected the Raw Date from ' app.Filepath_RawDatefolder.Value];
            
            
        end

        % Value changed function: Filepath_RawDatefolder
        function Filepath_RawDatefolderValueChanged(app, event)
%             global RawImage_File;
%             global Filepath_RawImage;
%             Filepath_RawImage =[app.Filepath_RawDatefolder.Value,RawImage_File];
%             app.Filepath_SaveAnchorPointPairs.Value=RawImage_File;
              app.TextArea.Value=['The Raw Date file input path was changed to ' app.Filepath_RawDatefolder.Value];
            
        end

        % Callback function
        function Filepath_SaveAnchorPointPairsValueChanged(app, event)
%                 global Filepath_SaveRawAnchorpointpairs;
%                 global Filepath_RawAnchorpointpairsFile;
%                 Filepath_SaveRawAnchorpointpairs=app.Filepath_SaveAnchorPointPairs.Value;
%                 app.Filepath_SaveCorrectedGridFile.Value=Filepath_RawAnchorpointpairsFile;
            app.TextArea.Value=['The New Anchor Point Pairs save path was changed to ' app.Filepath_SaveAnchorPointPairs.Value];
        end

        % Callback function
        function Filepath_SaveCorrectedGridFileValueChanged(app, event)
%                 value = app.Filepath_SaveCorrectedGridFile.Value; 
                app.TextArea.Value=['The Corrected Grid Image save path was changed to ' app.Filepath_SaveCorrectedGridFile.Value];
        end

        % Button pushed function: InloadFile_AnchorPointPairs
        function InloadFile_AnchorPointPairsButtonPushed(app, event)


            Point_list=csvread(app.Filepath_RawAnchorpointpairs.Value);
            
            app.TextArea.Value=['The Anchor Point Pairs was loaded from ' app.Filepath_RawAnchorpointpairs.Value];

            [app.Num_pair,~]=size(Point_list); 

            app.fixed_point=Point_list(:,1:1:2);
            app.moved_point=Point_list(:,3:1:4);

            app.L_box=app.Boxsizepixel_Spinner.Value;


            if exist('hh1')
                delete(hh1);
            else
            end
            
            if exist('hh2')
                delete(hh2);
            else
            end
            
            delete(app.h1);
            delete(app.h2);
            
            for i=1:1:app.Num_pair
                figure (1);
                subplot(1,2,1);
                hh1(i)=imrect(gca,[app.moved_point(i,1),app.moved_point(i,2),app.L_box,app.L_box]);
                subplot(1,2,2);
                hh2(i)=imrect(gca,[app.fixed_point(i,1),app.fixed_point(i,2),app.L_box,app.L_box]);
            end
            app.h1=hh1;
            app.h2=hh2;
        end

        % Button pushed function: InloadFile_RawGrid
        function InloadFile_RawGridButtonPushed(app, event)
            FilePath_grid_file=app.Filepath_RawGridFile.Value;
            app.Filepath_CorrectedGridFile.Value=app.Filepath_RawGridFile.Value;
            app.Grid_data_raw=imread(FilePath_grid_file); 
            
            app.TextArea.Value=['The Raw Grids Image was loaded from ' FilePath_grid_file];
            
            [app.Frame_size_x,app.Frame_size_y]=size(app.Grid_data_raw);


            figure(1);
            subplot(1,2,1);      
            imshow(app.Grid_data_raw,[min(min(app.Grid_data_raw)) max(max(app.Grid_data_raw))]);
            title('Raw Grids');  
            subplot(1,2,2);
            imshow(app.Grid_data_raw,[min(min(app.Grid_data_raw)) max(max(app.Grid_data_raw))]);
            title('Corrected Grids'); 
        end

        % Button pushed function: GeneralParametersUpdate_Button
        function GeneralParametersUpdate_ButtonPushed(app, event)
        
     
        X_grid_num=app.Gridnumber_x_Spinner.Value;
        Y_grid_num=app.Gridnumber_y_Spinner.Value;    
        Start_X=app.Startpointxpixel_x_Spinner.Value;  % usually the first anthor point pairs are in the same position in both image (raw and corrected grids images), so if the anthor points are generated by GUI, you need to generate the first anthor point in an intersection of the grids.
        Start_Y=app.Startpointypixel_y_Spinner.Value;
        Interval=app.EquallydigitalIntervalpixel_EditField.Value; % pixel, change this parameter to adjust the digital distance (pixel distance) of adjacent anthor points according to the parameter "Fixedpoint_Interval" and the grid size;
        app.L_box=app.Boxsizepixel_Spinner.Value;
        
        app.Num_pair=X_grid_num*Y_grid_num;
        
        app.TextArea.Value='The Anchor Point Pairs was generated/upgrated!' ;


        if exist('hh1')
            delete(hh1);
        else
        end
        
        if exist('hh2')
            delete(hh2);
        else
        end
        
        delete(app.h1);
        delete(app.h2);
        
        figure (1);
        subplot(1,2,1);
            for i=1:1:X_grid_num
                for j=1:1:Y_grid_num
                    hh1((i-1)*Y_grid_num+j)=imrect(gca,[Start_X+(i-1)*Interval,Start_Y+(j-1)*Interval,app.L_box,app.L_box]); % draw the rectangel box in raw grid image
                end
            end   
            
        figure (1);
        subplot(1,2,2);
            for i=1:1:X_grid_num
                for j=1:1:Y_grid_num
                    hh2((i-1)*Y_grid_num+j)=imrect(gca,[Start_X+(i-1)*Interval,Start_Y+(j-1)*Interval,app.L_box,app.L_box]);% draw the rectangles box in corrected grid image
                end
            end  
            
            app.h1=hh1;
            app.h2=hh2;
     
        end

        % Button pushed function: PairingResultAccept_Button
        function PairingResultAccept_ButtonPushed(app, event)
            
            if app.InputMatrixEditField.Value==0


                    app.fixed_point=zeros(app.Num_pair,2);
                    app.moved_point=zeros(app.Num_pair,2);
                        
                    for i=1:1:app.Num_pair                                              % transfer the current rectangles position to the anthor point pairs
                        Recent_point_distortion=getPosition(app.h1(i));
                        Recent_point_correction=getPosition(app.h2(i));
                        app.moved_point(i,:)=Recent_point_distortion(1:2);
                        app.fixed_point(i,:)=Recent_point_correction(1:2);
                    end
                
                    app.TransformMatrixMonitorTextArea.Value='The Matric is generated!' ;


                    % do correction porcess of the grid image according to the currecent
                    % anthor point pairs and update the processing result in Corrected
                    % Grids image.
                    
                    app.tform = fitgeotrans(app.moved_point,app.fixed_point,'pwl'); % generate the affine transformation mask with anthor points   
                    app.tform_inverse=fitgeotrans(app.fixed_point,app.moved_point,'pwl');
            else
                    
                    app.TransformMatrixMonitorTextArea.Value='Directly input the Matric' ;
                 
            end


                    app.Grid_data_corrected=app.Grid_data_raw;  
                    
                    tform_hh=app.tform(1);
                    
                    app.Grid_data_corrected(:,:) = imwarp(app.Grid_data_raw(:,:),tform_hh,'OutputView',imref2d(size(app.Grid_data_raw(:,:))));    
                    
                    app.TextArea.Value='The pairing result was accepted and the Grid Correction is done!' ;
    
                    
                    figure (1);
                    subplot(1,2,2);
                    imshow(app.Grid_data_corrected,[min(min(app.Grid_data_raw)) max(max(app.Grid_data_raw))]);
                    title('Corrected Grids'); 
              
                     if app.InputMatrixEditField.Value==0     
                            
                            figure (1);
                            subplot(1,2,2);    
                                for i=1:1:app.Num_pair
                                    app.h2(i)=imrect(gca,[app.fixed_point(i,1),app.fixed_point(i,2),app.L_box,app.L_box]);
                                end
                     else
                     end
                     imshow(app.Grid_data_raw,[min(min(app.Grid_data_raw)) max(max(app.Grid_data_raw))],'parent', app.RawData_Axes);   
                     imshow(app.Grid_data_corrected,[min(min(app.Grid_data_raw)) max(max(app.Grid_data_raw))],'parent', app.CorrectedData_Axes);    
                        
                    if app.InputMatrixEditField.Value==0   
                        
                    % calculate the pixel size (um/pixel) and FOV (um)
                    app.PixSizeX_EditField.Value=app.FixedpointIntervalum_EditField.Value/(app.fixed_point(app.Gridnumber_y_Spinner.Value+1,1)-app.fixed_point(1,1));
                    app.PixSizeY_EditField.Value=app.FixedpointIntervalum_EditField.Value/(app.fixed_point(2,2)-app.fixed_point(1,2));
                    app.FOVX_EditField.Value=app.Frame_size_x*app.PixSizeX_EditField.Value;
                    app.FOVY_EditField.Value=app.Frame_size_y*app.PixSizeY_EditField.Value;
                
                    app.TextArea.Value='The FOV Parameters were calculated using the Corrected Grid Image and upgrated!' ;
                    
                    else
                    end
                    
        end

        % Button pushed function: PairingResultSave_Button
        function PairingResultSave_ButtonPushed(app, event)
            
                    NewAnchorPointPairs_File=app.Filepath_CorrectedAnthorPointPairs.Value;
                    if isempty(app.Time_now)
                        app.Time_now=datestr(now,'YYYY-mm-dd-HH-MM-SS');
                        app.TimestampEditField.Value=app.Time_now;
                    else
                    end
                    
                    NewAnchorPointPairs_File=[NewAnchorPointPairs_File '_' app.Time_now '.csv'];
                    PointPair=[app.fixed_point app.moved_point];
                    csvwrite(NewAnchorPointPairs_File,PointPair);
                    
                    app.TextArea.Value=['The new Paired Anchor Points were saved to ' NewAnchorPointPairs_File];
                    
        end

        % Button pushed function: ResultsSave_Button_2
        function ResultsSave_Button_2Pushed(app, event)
        
            NewName_Correctedgird=app.Filepath_CorrectedGridFile.Value;
            if isempty(app.Time_now)
                app.Time_now=datestr(now,'YYYY-mm-dd-HH-MM-SS');
                app.TimestampEditField.Value=app.Time_now;
            else
            end
            NewName_Correctedgird=[NewName_Correctedgird '_corrected_' app.Time_now '.tif'];
            Grid_data_corrected_16bit=uint16(app.Grid_data_corrected(:,:));
            imwrite(Grid_data_corrected_16bit,NewName_Correctedgird,'WriteMode','overwrite');
            t=Tiff(NewName_Correctedgird,'r+');
            setTag(t,'XResolution',1*10000/app.PixSizeX_EditField.Value);
            setTag(t,'YResolution',1*10000/app.PixSizeY_EditField.Value);
            setTag(t,'ResolutionUnit',Tiff.ResolutionUnit.Centimeter);
            close(t);


            app.TextArea.Value=['The Corrected Grid Data was saved to ' NewName_Correctedgird];
        end

        % Button pushed function: RawDataCorrection_Button_2
        function RawDataCorrection_Button_2Pushed(app, event)
            app.Time_now=datestr(now,'YYYY-mm-dd-HH-MM-SS');
            app.TimestampEditField.Value=app.Time_now;
            app.TextArea.Value='Timestamp was updated!' ;
           
        end

        % Callback function
        function SelectFile_CorrectedGridButtonPushed(app, event)
             [NewAnchorPointPairs_File,NewAnchorPointPairs_Path] = uigetfile('*.tif'); 
             app.TextArea.Value=['User is going to select the save the Corrected Grids Image to ' NewAnchorPointPairs_Path NewAnchorPointPairs_File];
        end

        % Button pushed function: CopyFile_AnchorPointPairs
        function CopyFile_AnchorPointPairsButtonPushed(app, event)
        app.Filepath_CorrectedAnthorPointPairs.Value=app.Filepath_RawAnchorpointpairs.Value;
        app.Filepath_CorrectedAnthorPointPairs.Value(end-3:end)=[];
        app.TextArea.Value=['User copied the save path of the new Anchor Point Pair from input as ' app.Filepath_CorrectedAnthorPointPairs.Value];
        
        
        end

        % Button pushed function: SelectFile_NewAnchorPointPairs
        function SelectFile_NewAnchorPointPairsButtonPushed(app, event)
            [NewAnchorPointPairs_File,NewAnchorPointPairs_Path] = uigetfile('*.csv'); 
            app.Filepath_CorrectedAnthorPointPairs.Value=[NewAnchorPointPairs_Path,NewAnchorPointPairs_File];
            app.Filepath_CorrectedAnthorPointPairs.Value(end-3:end)=[];
            app.TextArea.Value=['User is going to save the new Anchor Point Pair to ' app.Filepath_CorrectedAnthorPointPairs.Value];
        end

        % Button pushed function: CopyFile_CorrectedGridFile
        function CopyFile_CorrectedGridFileButtonPushed(app, event)
            app.Filepath_CorrectedGridFile.Value=app.Filepath_RawGridFile.Value;
            app.Filepath_CorrectedGridFile.Value(end-3:end)=[];
            app.TextArea.Value=['User copied the save path of the Corrected Grids Image from input as ' app.Filepath_CorrectedGridFile.Value];
        end

        % Button pushed function: SelectFile_CorrectedGridFile
        function SelectFile_CorrectedGridFileButtonPushed(app, event)
            [CorrectedGrid_File,CorrectedGrid_Path] = uigetfile('*.tif'); 
            app.Filepath_CorrectedGridFile.Value=[CorrectedGrid_Path,CorrectedGrid_File];
            app.Filepath_CorrectedGridFile.Value(end-3:end)=[];
            app.TextArea.Value=['User is going to save the Corrected Grids to ' app.Filepath_CorrectedGridFile.Value];
        end

        % Button pushed function: SelectFile_FOVParametersInput
        function SelectFile_FOVParametersInputButtonPushed(app, event)
            [FOVParameterInput_File,FOVParameterInput_Path] = uigetfile('*.csv'); 
            app.Filepath_FOVParametersInput.Value=[FOVParameterInput_Path,FOVParameterInput_File];
            app.TextArea.Value=['User is going to select the FOV Parameters from ' app.Filepath_FOVParametersInput.Value];
        end

        % Button pushed function: ResultsLoad_Button
        function ResultsLoad_ButtonPushed(app, event)
            app.FOVParameter=csvread(app.Filepath_FOVParametersInput.Value);
            app.FOVX_EditField.Value=app.FOVParameter(1);
            app.FOVY_EditField.Value=app.FOVParameter(2);
            app.PixSizeX_EditField.Value=app.FOVParameter(3);
            app.PixSizeY_EditField.Value=app.FOVParameter(4);
            app.TextArea.Value=['The FOV Parameters was loaded from ' app.Filepath_FOVParametersInput.Value];
            
        end

        % Button pushed function: CopyFile_FOVParametersSave
        function CopyFile_FOVParametersSaveButtonPushed(app, event)
        app.Filepath_FOVParametersSave.Value=app.Filepath_FOVParametersInput.Value;
        app.Filepath_FOVParametersSave.Value(end-3:end)=[];
        app.TextArea.Value=['User copied the save path of the FOV Parameters from input as ' app.Filepath_FOVParametersSave.Value];
        
        end

        % Button pushed function: SelectFile_FOVParametersSave
        function SelectFile_FOVParametersSaveButtonPushed(app, event)
            [NewFOVParameters_File,NewFOVParameter_Path] = uigetfile('*.csv'); 
            app.Filepath_FOVParametersSave.Value=[NewFOVParameter_Path,NewFOVParameters_File];
            app.Filepath_FOVParametersSave.Value(end-3:end)=[];
            app.TextArea.Value=['User is going to save the FOV Parameters to ' app.Filepath_FOVParametersSave.Value];
        end

        % Button pushed function: ResultsSave_Button
        function ResultsSave_ButtonPushed(app, event)
                    NewFOVParameters_File=app.Filepath_FOVParametersSave.Value;
                    
                    if isempty(app.Time_now)
                        app.Time_now=datestr(now,'YYYY-mm-dd-HH-MM-SS');
                        app.TimestampEditField.Value=app.Time_now;
                    else
                    end
                    
                    NewFOVParameters_File=[NewFOVParameters_File '_' app.Time_now '.csv'];
                    NewFOVParameters=[app.FOVX_EditField.Value;app.FOVY_EditField.Value;app.PixSizeX_EditField.Value;app.PixSizeY_EditField.Value];
                    
                    csvwrite(NewFOVParameters_File,NewFOVParameters);
                    
                    app.TextArea.Value=['The FOV Parameters were saved to ' NewFOVParameters_File];
                    
                    
        end

        % Button pushed function: RawDataCorrection_Button
        function RawDataCorrection_ButtonPushed(app, event)
                            % input raw data
                
                Filelist_raw=dir([app.RawImage_Path '*.tif']);
                Image_num=length(Filelist_raw);
                app.TotalfilesEditField.Value=Image_num;
                
                
                %generate the new folder for the correction results
                if isempty(app.Time_now)
                        app.Time_now=datestr(now,'YYYY-mm-dd-HH-MM-SS');
                        app.TimestampEditField.Value=app.Time_now;
                else
              
                end
                
                Result_folder_raw=[app.RawImage_Path '\Distortion Corrected Image_' app.Time_now]; % save the corrected images;
                mkdir(Result_folder_raw);% save the corrected images;
                
                
                app.TextArea.Value=['A new result folder was generated: ' Result_folder_raw];
                pause(1);
                
                app.TextArea.Value='Raw Data Correction start!: ';
                
                pause(1)
                
                          if app.InputMatrixEditField.Value==0
                          app.tform = fitgeotrans(app.moved_point,app.fixed_point,'pwl'); %% generate the affine transformation mask
                          app.TransformMatrixMonitorTextArea.Value='The Matric is generated!' ;
                          
                          else
                          app.TransformMatrixMonitorTextArea.Value='Directly input the Matric' ;
                          end
                
                
                 for i=1:1:Image_num
                         app.LeftfilesEditField.Value=Image_num-i;
                         app.ProgressGauge.Value=100*i/Image_num;
                         FileName_raw=Filelist_raw(i).name;
                         Image_File_raw=[app.RawImage_Path FileName_raw];
                         
                         info = imfinfo(Image_File_raw);
                         Z_size = numel(info);
                         X_size=info.Width;
                         Y_size=info.Height;
                         ImageStack=zeros(X_size,Y_size,Z_size);
                         
                         for k = 1:Z_size
                                ImageStack(:,:,k) = imread(Image_File_raw, k);
                         end
    
                         app.TextArea.Value=['Correcting image ',num2str(i),'...'];
                         
                         ImageStack_corrected=ImageStack;


                          for j=1:1:Z_size
                          ImageStack_corrected(:,:,j) = imwarp(ImageStack(:,:,j),app.tform,'OutputView',imref2d(size(ImageStack(:,:,j))));
                          ImageStack_corrected_16bit=uint16(ImageStack_corrected(:,:,j));
                          imwrite(ImageStack_corrected_16bit,[Result_folder_raw,'\',FileName_raw,'_wrappiing corrected.tif'],'WriteMode','append');
                          t2=Tiff([Result_folder_raw,'\',FileName_raw,'_wrappiing corrected.tif'],'r+');
                            setTag(t2,'XResolution',1*10000/app.PixSizeX_EditField.Value);
                            setTag(t2,'YResolution',1*10000/app.PixSizeY_EditField.Value);
                            setTag(t2,'ResolutionUnit',Tiff.ResolutionUnit.Centimeter);
                            close(t2);


                          app.TextArea.Value=['porcessing image stack ' num2str(Image_num) 'plane ',num2str(j)];
                          
                          end

                          app.TextArea.Value=['image stack ' num2str(i) ',done '];

                     imshow(mean(ImageStack,3),[min(min(min(ImageStack))) max(max(max(ImageStack)))],'parent', app.RawData_Axes);
 
                     imshow(mean(ImageStack_corrected,3),[min(min(min(ImageStack))) max(max(max(ImageStack)))],'parent', app.CorrectedData_Axes);

                     drawnow;
                     

                %    pause(0.25);    
                 end

                 app.TextArea.Value=['All fisished!'];


        end

        % Value changed function: FixedpointIntervalum_EditField
        function FixedpointIntervalum_EditFieldValueChanged(app, event)
            app.TextArea.Value=['The Fixed Point Interval was changed to ' num2str(app.FixedpointIntervalum_EditField.Value) ' um'];
            
        end

        % Value changed function: 
        % EquallydigitalIntervalpixel_EditField
        function EquallydigitalIntervalpixel_EditFieldValueChanged(app, event)
            
            app.TextArea.Value=['The Equally Digital Interval was changed to ' num2str(app.EquallydigitalIntervalpixel_EditField.Value) ' pixel'];
        end

        % Value changed function: Boxsizepixel_Spinner
        function Boxsizepixel_SpinnerValueChanged(app, event)
            
            app.TextArea.Value=['The Box Size was changed to ' num2str(app.Boxsizepixel_Spinner.Value) ' pixel'];
        end

        % Value changed function: Gridnumber_x_Spinner
        function Gridnumber_x_SpinnerValueChanged(app, event)
            
            app.TextArea.Value=['The Grid Number x was changed to ' num2str(app.Gridnumber_x_Spinner.Value)];
        end

        % Value changed function: Gridnumber_y_Spinner
        function Gridnumber_y_SpinnerValueChanged(app, event)
            
            app.TextArea.Value=['The Grid Number y was changed to ' num2str(app.Gridnumber_y_Spinner.Value)];
        end

        % Value changed function: Startpointxpixel_x_Spinner
        function Startpointxpixel_x_SpinnerValueChanged(app, event)
            
             app.TextArea.Value=['The Start Point x was changed to ' num2str(app.Startpointxpixel_x_Spinner.Value)];
        end

        % Value changed function: Startpointypixel_y_Spinner
        function Startpointypixel_y_SpinnerValueChanged(app, event)
            
            app.TextArea.Value=['The Start Point y was changed to ' num2str(app.Startpointxpixel_y_Spinner.Value)];
        end

        % Value changed function: Filepath_FOVParametersInput
        function Filepath_FOVParametersInputValueChanged(app, event)
            
            app.TextArea.Value=['The FOV Parameters input path was changed to ' app.Filepath_FOVParametersInput.Value];
        end

        % Value changed function: FOVX_EditField
        function FOVX_EditFieldValueChanged(app, event)
            
            app.TextArea.Value=['The FOV x was changed to ' num2str(app.FOVX_EditField.Value) ' um'];
        end

        % Value changed function: FOVY_EditField
        function FOVY_EditFieldValueChanged(app, event)
            
            app.TextArea.Value=['The FOV y was changed to ' num2str(app.FOVY_EditField.Value) ' um'];
        end

        % Value changed function: PixSizeX_EditField
        function PixSizeX_EditFieldValueChanged(app, event)
            
            app.TextArea.Value=['The Pixel Size of x was changed to ' num2str(app.PixSizeX_EditField.Value) ' um/pixel'];
        end

        % Value changed function: PixSizeY_EditField
        function PixSizeY_EditFieldValueChanged(app, event)
           
             app.TextArea.Value=['The Pixel Size of y was changed to ' num2str(app.PixSizeY_EditField.Value) ' um/pixel'];
        end

        % Value changed function: Filepath_CorrectedGridFile
        function Filepath_CorrectedGridFileValueChanged(app, event)
            
            app.TextArea.Value=['The Corrected Grids Image save path was changed to ' app.Filepath_CorrectedGridFile.Value];
        end

        % Value changed function: Filepath_CorrectedAnthorPointPairs
        function Filepath_CorrectedAnthorPointPairsValueChanged(app, event)
            
            app.TextArea.Value=['The New Anthor Point Pairs save path was changed to ' app.Filepath_CorrectedAnthorPointPairs.Value];
        end

        % Value changed function: Filepath_FOVParametersSave
        function Filepath_FOVParametersSaveValueChanged(app, event)
            
            app.TextArea.Value=['The New FOV Parameters save path was changed to ' app.Filepath_FOVParametersSave.Value];
        end

        % Button pushed function: SelectFile_TranformMatrix
        function SelectFile_TranformMatrixButtonPushed(app, event)
            
            [TransformMatrix_File,TransformMatrix_Path] = uigetfile('*.mat'); 
            app.Filepath_TranformMatrix.Value=[TransformMatrix_Path,TransformMatrix_File];
            app.TextArea.Value=['User is going to select Tranform Matrix from ' app.Filepath_TranformMatrix.Value];

        end

        % Value changed function: Filepath_TranformMatrix
        function Filepath_TranformMatrixValueChanged(app, event)
            app.TextArea.Value=['The Transform Matrix input path was changed to ' app.Filepath_TranformMatrix.Value];
        end

        % Button pushed function: InloadFile_TranformMatrix
        function InloadFile_TranformMatrixButtonPushed(app, event)
            
            app.InputMatrixEditField.Value=1;
            
            
            Matrix=load(app.Filepath_TranformMatrix.Value);
            
            app.tform=getfield(Matrix,'TransformMatrix');
            app.tform_inverse=getfield(Matrix,'TransformMatrix_inverse') % Description
            app.TextArea.Value=['The TransformMatrix was loaded from ' app.Filepath_TranformMatrix.Value];

%             [app.Num_pair,~]=size(Point_list); 
% 
%             app.fixed_point=Point_list(:,1:1:2);
%             app.moved_point=Point_list(:,3:1:4);
% 
%             app.L_box=app.Boxsizepixel_Spinner.Value;
        end

        % Button pushed function: CopyFile_NewTransformMatrix
        function CopyFile_NewTransformMatrixButtonPushed(app, event)
            app.Filepath_NewTransformMatrix.Value=app.Filepath_TranformMatrix.Value;
            app.Filepath_NewTransformMatrix.Value(end-3:end)=[];
            app.TextArea.Value=['User copied the save path of the New Tranform Matrix from input as ' app.Filepath_TranformMatrix.Value];
        end

        % Button pushed function: SelectFile_NewTransformMatrix
        function SelectFile_NewTransformMatrixButtonPushed(app, event)
            [NewTransformMatrix_File,NewTransformMatrix_Path] = uigetfile('*.mat'); 
            app.Filepath_NewTransformMatrix.Value=[NewTransformMatrix_Path,NewTransformMatrix_File];
            app.Filepath_NewTransformMatrix.Value(end-3:end)=[];
            app.TextArea.Value=['User is going to save the New Transform Matrix to ' app.Filepath_NewTransformMatrix.Value];
        end

        % Button pushed function: ResultsSave_Button_3
        function ResultsSave_Button_3Pushed(app, event)
                    
            NewName_NewTranformMatrix=app.Filepath_NewTransformMatrix.Value;
            
            if isempty(app.Time_now)
                app.Time_now=datestr(now,'YYYY-mm-dd-HH-MM-SS');
                app.TimestampEditField.Value=app.Time_now;
            else
            end
            
            NewName_NewTranformMatrix=[NewName_NewTranformMatrix,'_', app.Time_now,'.mat'];
            
            TransformMatrix=app.tform;
            TransformMatrix_inverse=app.tform_inverse;
            save(NewName_NewTranformMatrix,'TransformMatrix');
            save(NewName_NewTranformMatrix,'TransformMatrix_inverse','-append');
            
        end

        % Value changed function: Filepath_PositionDatefolder
        function Filepath_PositionDatefolderValueChanged(app, event)
                app.TextArea.Value=['The Position file input path was changed to ' app.Filepath_PositionDatefolder.Value];
        end

        % Button pushed function: SelectFile_PositionData
        function SelectFile_PositionDataButtonPushed(app, event)
            [app.PositionImage_File,app.PositionImage_Path] = uigetfile('*.csv'); 
            app.Filepath_PositionDatefolder.Value=app.PositionImage_Path;
            app.TextArea.Value=['User has selected the Position Date from ' app.Filepath_PositionDatefolder.Value];
            
        end

        % Button pushed function: PositionDataCorrection_Button
        function PositionDataCorrection_ButtonPushed(app, event)
                
                Filelist_position=dir([app.PositionImage_Path '*.csv']);
                file_num=length(Filelist_position);
                app.TotalfilesEditField.Value=file_num;
                
                
                %generate the new folder for the correction results
                if isempty(app.Time_now)
                        app.Time_now=datestr(now,'YYYY-mm-dd-HH-MM-SS');
                        app.TimestampEditField.Value=app.Time_now;
                else
              
                end
                
                Result_folder_raw=[app.PositionImage_Path '\Distortion Corrected position_' app.Time_now]; % save the corrected images;
                mkdir(Result_folder_raw);% save the corrected images;
                
                
                app.TextArea.Value=['A new result folder was generated: ' Result_folder_raw];
                pause(1);
                
                app.TextArea.Value='Position Data Correction start!: ';
                
                pause(1)
                
                          if app.InputMatrixEditField.Value==0
                          app.tform = fitgeotrans(app.moved_point,app.fixed_point,'pwl'); %% generate the affine transformation mask
                          app.TransformMatrixMonitorTextArea.Value='The Matric is generated!' ;
                          
                          else
                          app.TransformMatrixMonitorTextArea.Value='Directly input the Matric' ;
                          end
                
                
                 for i=1:1:file_num
                         app.LeftfilesEditField.Value=file_num-i;
                         app.ProgressGauge.Value=100*i/file_num;
                         FileName_position=Filelist_position(i).name;
                         Position_File_raw=[app.PositionImage_Path FileName_position];
                         
                         Fixed_point_userinput=csvread(Position_File_raw);
                         Moved_point_useroutput=zeros(size(Fixed_point_userinput));
                         
                         app.TextArea.Value=['Correcting position file ',num2str(i),'...'];
                         
                         Moved_point_useroutput = transformPointsInverse(app.tform_inverse,Fixed_point_userinput );
                         
                            Position_File_raw(end-3:end)=[];
                            
                            csvwrite([Result_folder_raw,'\',FileName_position,'_wrappiing corrected position.csv'],Moved_point_useroutput);


                          app.TextArea.Value=['File ' num2str(i) ',done '];

                %    pause(0.25);    
                 end

                 app.TextArea.Value=['All fisished!'];
        end

        % Button pushed function: SaveTiffheaderSIButton
        function SaveTiffheaderSIButtonPushed(app, event)
       
            FileList_raw=dir([app.RawImage_Path '*.tif']);
            
            Image_num=length(FileList_raw);
            
            app.TotalfilesEditField.Value=Image_num;
            
            app.TextArea.Value=['Start to extract tiff meta data'];
            pause(2);
            
            for i=1:1:Image_num
                app.LeftfilesEditField.Value=Image_num-i;
                app.ProgressGauge.Value=100*i/Image_num;
                FileName_raw=FileList_raw(i).name;
                Image_File_raw=[app.RawImage_Path FileName_raw];
                app.TextArea.Value=['processing ' Image_File_raw];
                pause(1);
                [header,~,imInfo]=scanimage.util.opentif(Image_File_raw);
                app.TextArea.Value=['Meta data for tiff ' num2str(i) ' is loaded'];
                pause(1);
                save([Image_File_raw(1:end-4),'_tifHeader.mat'],'header');
                app.TextArea.Value=[Image_File_raw(1:end-4),'_tifHeader.mat is saved.'];
                pause(1);
                save([Image_File_raw(1:end-4),'_imgInfo.mat'],'imInfo');
                app.TextArea.Value=[Image_File_raw(1:end-4),'_imInfo.mat is saved.'];
            end
            
            app.TextArea.Value=['All finished!'];
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RawData_Axes = uiaxes(app.UIFigure);
            title(app.RawData_Axes, 'Raw data ')
            xlabel(app.RawData_Axes, 'X')
            ylabel(app.RawData_Axes, 'Y')
            app.CorrectedData_Axes = uiaxes(app.UIFigure);
            title(app.CorrectedData_Axes, 'Corrected data')
            xlabel(app.CorrectedData_Axes, 'X')
            ylabel(app.CorrectedData_Axes, 'Y')
            app.AnchorPointPairsfromGUIPanel = uipanel(app.UIFigure);
            app.AnchorPointPairsfromGUIPanel.Title = 'Anchor Point Pairs from GUI';
            app.BoxsizepixelSpinnerLabel = uilabel(app.AnchorPointPairsfromGUIPanel);
            app.BoxsizepixelSpinnerLabel.Text = 'Box size (pixel)';
            app.Boxsizepixel_Spinner = uispinner(app.AnchorPointPairsfromGUIPanel);
            app.Boxsizepixel_Spinner.ValueChangedFcn = createCallbackFcn(app, @Boxsizepixel_SpinnerValueChanged, true);
            app.Boxsizepixel_Spinner.Value = 10;
            app.FixedPointIntervalumLabel = uilabel(app.AnchorPointPairsfromGUIPanel);
            app.FixedPointIntervalumLabel.Text = 'Fixed Point Interval (um)';
            app.FixedpointIntervalum_EditField = uieditfield(app.AnchorPointPairsfromGUIPanel, 'numeric');
            app.FixedpointIntervalum_EditField.ValueChangedFcn = createCallbackFcn(app, @FixedpointIntervalum_EditFieldValueChanged, true);
            app.FixedpointIntervalum_EditField.Value = 100;
            app.EquallydigitalIntervalpixelEditFieldLabel = uilabel(app.AnchorPointPairsfromGUIPanel);
            app.EquallydigitalIntervalpixelEditFieldLabel.Text = 'Equally digital Interval (pixel)';
            app.EquallydigitalIntervalpixel_EditField = uieditfield(app.AnchorPointPairsfromGUIPanel, 'numeric');
            app.EquallydigitalIntervalpixel_EditField.ValueChangedFcn = createCallbackFcn(app, @EquallydigitalIntervalpixel_EditFieldValueChanged, true);
            app.EquallydigitalIntervalpixel_EditField.Value = 110;
            app.GeneralParametersUpdate_Button = uibutton(app.AnchorPointPairsfromGUIPanel, 'push');
            app.GeneralParametersUpdate_Button.ButtonPushedFcn = createCallbackFcn(app, @GeneralParametersUpdate_ButtonPushed, true);
            app.GeneralParametersUpdate_Button.Text = 'Generate/Update';
            app.Gridnumber_xSpinnerLabel = uilabel(app.AnchorPointPairsfromGUIPanel);
            app.Gridnumber_xSpinnerLabel.Text = 'Grid number_x';
            app.Gridnumber_x_Spinner = uispinner(app.AnchorPointPairsfromGUIPanel);
            app.Gridnumber_x_Spinner.ValueChangedFcn = createCallbackFcn(app, @Gridnumber_x_SpinnerValueChanged, true);
            app.Gridnumber_x_Spinner.Value = 4;
            app.Gridnumber_ySpinnerLabel = uilabel(app.AnchorPointPairsfromGUIPanel);
            app.Gridnumber_ySpinnerLabel.Text = 'Grid number_y';
            app.Gridnumber_y_Spinner = uispinner(app.AnchorPointPairsfromGUIPanel);
            app.Gridnumber_y_Spinner.ValueChangedFcn = createCallbackFcn(app, @Gridnumber_y_SpinnerValueChanged, true);
            app.Gridnumber_y_Spinner.Value = 5;
            app.StartpointxpixelSpinnerLabel = uilabel(app.AnchorPointPairsfromGUIPanel);
            app.StartpointxpixelSpinnerLabel.Text = 'Start point x (pixel)';
            app.Startpointxpixel_x_Spinner = uispinner(app.AnchorPointPairsfromGUIPanel);
            app.Startpointxpixel_x_Spinner.ValueChangedFcn = createCallbackFcn(app, @Startpointxpixel_x_SpinnerValueChanged, true);
            app.Startpointxpixel_x_Spinner.Value = 55;
            app.StartpointypixelSpinnerLabel = uilabel(app.AnchorPointPairsfromGUIPanel);
            app.StartpointypixelSpinnerLabel.Text = 'Start point y (pixel)';
            app.Startpointypixel_y_Spinner = uispinner(app.AnchorPointPairsfromGUIPanel);
            app.Startpointypixel_y_Spinner.ValueChangedFcn = createCallbackFcn(app, @Startpointypixel_y_SpinnerValueChanged, true);
            app.Startpointypixel_y_Spinner.Value = 25;
            app.RawDataCorrection_Button = uibutton(app.UIFigure, 'push');
            app.RawDataCorrection_Button.ButtonPushedFcn = createCallbackFcn(app, @RawDataCorrection_ButtonPushed, true);
            app.RawDataCorrection_Button.Text = 'Raw Data Correction!';
            app.ImageDataInputOutputPanel = uipanel(app.UIFigure);
            app.ImageDataInputOutputPanel.Title = 'Image Data Input/Output';
            app.SelectFile_RawData = uibutton(app.ImageDataInputOutputPanel, 'push');
            app.SelectFile_RawData.ButtonPushedFcn = createCallbackFcn(app, @SelectFile_RawDataButtonPushed, true);
            app.SelectFile_RawData.Text = 'select';
            app.RawDateFolderLabel = uilabel(app.ImageDataInputOutputPanel);
            app.RawDateFolderLabel.Text = 'Raw Date Folder';
            app.Filepath_RawDatefolder = uieditfield(app.ImageDataInputOutputPanel, 'text');
            app.Filepath_RawDatefolder.ValueChangedFcn = createCallbackFcn(app, @Filepath_RawDatefolderValueChanged, true);
            app.Filepath_RawDatefolder.Value = 'C:\Users\weijianz\OneDrive - NTNU\Moser lab\software\Mini2P_processing and analysis\FOV and resolution measurement\MINI2P_FOV and Resolution\Test data';
            app.SaveTiffheaderSIButton = uibutton(app.ImageDataInputOutputPanel, 'push');
            app.SaveTiffheaderSIButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTiffheaderSIButtonPushed, true);
            app.SaveTiffheaderSIButton.Text = 'Save Tiffheader (SI)';
            app.FOVParametersPanel = uipanel(app.UIFigure);
            app.FOVParametersPanel.Title = 'FOV Parameters';
            app.FOV_xumLabel = uilabel(app.FOVParametersPanel);
            app.FOV_xumLabel.Text = 'FOV_x (um)';
            app.FOVX_EditField = uieditfield(app.FOVParametersPanel, 'numeric');
            app.FOVX_EditField.ValueChangedFcn = createCallbackFcn(app, @FOVX_EditFieldValueChanged, true);
            app.FOVX_EditField.Value = 420;
            app.FOV_yumLabel = uilabel(app.FOVParametersPanel);
            app.FOV_yumLabel.Text = 'FOV_y (um) ';
            app.FOVY_EditField = uieditfield(app.FOVParametersPanel, 'numeric');
            app.FOVY_EditField.ValueChangedFcn = createCallbackFcn(app, @FOVY_EditFieldValueChanged, true);
            app.FOVY_EditField.Value = 420;
            app.Pixelsize_xumpixLabel = uilabel(app.FOVParametersPanel);
            app.Pixelsize_xumpixLabel.Text = 'Pixel size_x (um/pix)';
            app.PixSizeX_EditField = uieditfield(app.FOVParametersPanel, 'numeric');
            app.PixSizeX_EditField.ValueChangedFcn = createCallbackFcn(app, @PixSizeX_EditFieldValueChanged, true);
            app.PixSizeX_EditField.Value = 0.6;
            app.Pixelsize_yumpixLabel_2 = uilabel(app.FOVParametersPanel);
            app.Pixelsize_yumpixLabel_2.Text = 'Pixel size_y (um/pix)';
            app.PixSizeY_EditField = uieditfield(app.FOVParametersPanel, 'numeric');
            app.PixSizeY_EditField.ValueChangedFcn = createCallbackFcn(app, @PixSizeY_EditFieldValueChanged, true);
            app.PixSizeY_EditField.Value = 0.6;
            app.ResultsLoad_Button = uibutton(app.FOVParametersPanel, 'push');
            app.ResultsLoad_Button.ButtonPushedFcn = createCallbackFcn(app, @ResultsLoad_ButtonPushed, true);
            app.ResultsLoad_Button.Text = 'Load';
            app.FilePathNameLabel_3 = uilabel(app.FOVParametersPanel);
            app.FilePathNameLabel_3.Text = 'File Path/Name';
            app.Filepath_FOVParametersInput = uieditfield(app.FOVParametersPanel, 'text');
            app.Filepath_FOVParametersInput.ValueChangedFcn = createCallbackFcn(app, @Filepath_FOVParametersInputValueChanged, true);
            app.Filepath_FOVParametersInput.Value = '\\home.ansatt.ntnu.no\weijianz\Desktop\MINI2P_V3A_001_Calibration\MINI2P_V3C_001_FOVParameter';
            app.SelectFile_FOVParametersInput = uibutton(app.FOVParametersPanel, 'push');
            app.SelectFile_FOVParametersInput.ButtonPushedFcn = createCallbackFcn(app, @SelectFile_FOVParametersInputButtonPushed, true);
            app.SelectFile_FOVParametersInput.Text = 'Select';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.AnchorPointPairsfromFilePanel = uipanel(app.UIFigure);
            app.AnchorPointPairsfromFilePanel.Title = 'Anchor Point Pairs from File';
            app.FilePathNameLabel_2 = uilabel(app.AnchorPointPairsfromFilePanel);
            app.FilePathNameLabel_2.Text = 'File Path/Name';
            app.Filepath_RawAnchorpointpairs = uieditfield(app.AnchorPointPairsfromFilePanel, 'text');
            app.Filepath_RawAnchorpointpairs.ValueChangedFcn = createCallbackFcn(app, @Filepath_RawAnchorpointpairsValueChanged, true);
            app.Filepath_RawAnchorpointpairs.ValueChangingFcn = createCallbackFcn(app, @Filepath_RawAnchorpointpairsValueChanged, true);
            app.SelectFile_AnchorPointPairs = uibutton(app.AnchorPointPairsfromFilePanel, 'push');
            app.SelectFile_AnchorPointPairs.ButtonPushedFcn = createCallbackFcn(app, @SelectFile_AnchorPointPairsButtonPushed, true);
            app.SelectFile_AnchorPointPairs.Text = 'Select';
            app.InloadFile_AnchorPointPairs = uibutton(app.AnchorPointPairsfromFilePanel, 'push');
            app.InloadFile_AnchorPointPairs.ButtonPushedFcn = createCallbackFcn(app, @InloadFile_AnchorPointPairsButtonPushed, true);
            app.InloadFile_AnchorPointPairs.Text = 'Load';
            app.GridDateinputPanel = uipanel(app.UIFigure);
            app.GridDateinputPanel.Title = 'Grid Date input';
            app.FilePathNameLabel = uilabel(app.GridDateinputPanel);
            app.FilePathNameLabel.Text = 'File Path/Name';
            app.Filepath_RawGridFile = uieditfield(app.GridDateinputPanel, 'text');
            app.Filepath_RawGridFile.ValueChangedFcn = createCallbackFcn(app, @Filepath_RawGridFileValueChanged, true);
            app.Filepath_RawGridFile.Value = 'C:\Users\weijianz\OneDrive - NTNU\Moser lab\software\Mini2P_processing and analysis\FOV and resolution measurement\MINI2P_FOV and Resolution\Test data\MINI2P_V3C_001_50umGrids.tif';
            app.SelectFile_RawGrid = uibutton(app.GridDateinputPanel, 'push');
            app.SelectFile_RawGrid.ButtonPushedFcn = createCallbackFcn(app, @SelectFile_RawGridButtonPushed, true);
            app.SelectFile_RawGrid.Text = 'Select';
            app.InloadFile_RawGrid = uibutton(app.GridDateinputPanel, 'push');
            app.InloadFile_RawGrid.ButtonPushedFcn = createCallbackFcn(app, @InloadFile_RawGridButtonPushed, true);
            app.InloadFile_RawGrid.Text = 'Load';
            app.ResultsSavePanel = uipanel(app.UIFigure);
            app.ResultsSavePanel.Title = 'Results Save';
            app.ResultsSave_Button_2 = uibutton(app.ResultsSavePanel, 'push');
            app.ResultsSave_Button_2.ButtonPushedFcn = createCallbackFcn(app, @ResultsSave_Button_2Pushed, true);
            app.ResultsSave_Button_2.Text = 'Save Corrected Grids Image';
            app.FileNameLabel_2 = uilabel(app.ResultsSavePanel);
            app.FileNameLabel_2.Text = 'File Name';
            app.Filepath_CorrectedGridFile = uieditfield(app.ResultsSavePanel, 'text');
            app.Filepath_CorrectedGridFile.ValueChangedFcn = createCallbackFcn(app, @Filepath_CorrectedGridFileValueChanged, true);
            app.FileNameLabel = uilabel(app.ResultsSavePanel);
            app.FileNameLabel.Text = 'File Name';
            app.Filepath_CorrectedAnthorPointPairs = uieditfield(app.ResultsSavePanel, 'text');
            app.Filepath_CorrectedAnthorPointPairs.ValueChangedFcn = createCallbackFcn(app, @Filepath_CorrectedAnthorPointPairsValueChanged, true);
            app.CopyFile_AnchorPointPairs = uibutton(app.ResultsSavePanel, 'push');
            app.CopyFile_AnchorPointPairs.ButtonPushedFcn = createCallbackFcn(app, @CopyFile_AnchorPointPairsButtonPushed, true);
            app.CopyFile_AnchorPointPairs.Text = 'Copy from Input';
            app.PairingResultSave_Button = uibutton(app.ResultsSavePanel, 'push');
            app.PairingResultSave_Button.ButtonPushedFcn = createCallbackFcn(app, @PairingResultSave_ButtonPushed, true);
            app.PairingResultSave_Button.Text = 'Save Point Pairs';
            app.SelectFile_NewAnchorPointPairs = uibutton(app.ResultsSavePanel, 'push');
            app.SelectFile_NewAnchorPointPairs.ButtonPushedFcn = createCallbackFcn(app, @SelectFile_NewAnchorPointPairsButtonPushed, true);
            app.SelectFile_NewAnchorPointPairs.Text = 'Select from file';
            app.CopyFile_CorrectedGridFile = uibutton(app.ResultsSavePanel, 'push');
            app.CopyFile_CorrectedGridFile.ButtonPushedFcn = createCallbackFcn(app, @CopyFile_CorrectedGridFileButtonPushed, true);
            app.CopyFile_CorrectedGridFile.Text = 'Copy from Input';
            app.SelectFile_CorrectedGridFile = uibutton(app.ResultsSavePanel, 'push');
            app.SelectFile_CorrectedGridFile.ButtonPushedFcn = createCallbackFcn(app, @SelectFile_CorrectedGridFileButtonPushed, true);
            app.SelectFile_CorrectedGridFile.Text = 'Select from file';
            app.ResultsSave_Button = uibutton(app.ResultsSavePanel, 'push');
            app.ResultsSave_Button.ButtonPushedFcn = createCallbackFcn(app, @ResultsSave_ButtonPushed, true);
            app.ResultsSave_Button.Text = 'Save FOV Reports';
            app.FileNameLabel_3 = uilabel(app.ResultsSavePanel);
            app.FileNameLabel_3.Text = 'File Name';
            app.Filepath_FOVParametersSave = uieditfield(app.ResultsSavePanel, 'text');
            app.Filepath_FOVParametersSave.ValueChangedFcn = createCallbackFcn(app, @Filepath_FOVParametersSaveValueChanged, true);
            app.CopyFile_FOVParametersSave = uibutton(app.ResultsSavePanel, 'push');
            app.CopyFile_FOVParametersSave.ButtonPushedFcn = createCallbackFcn(app, @CopyFile_FOVParametersSaveButtonPushed, true);
            app.CopyFile_FOVParametersSave.Text = 'Copy from Input';
            app.SelectFile_FOVParametersSave = uibutton(app.ResultsSavePanel, 'push');
            app.SelectFile_FOVParametersSave.ButtonPushedFcn = createCallbackFcn(app, @SelectFile_FOVParametersSaveButtonPushed, true);
            app.SelectFile_FOVParametersSave.Text = 'Select from file';
            app.CopyFile_NewTransformMatrix = uibutton(app.ResultsSavePanel, 'push');
            app.CopyFile_NewTransformMatrix.ButtonPushedFcn = createCallbackFcn(app, @CopyFile_NewTransformMatrixButtonPushed, true);
            app.CopyFile_NewTransformMatrix.Text = 'Copy from Input';
            app.SelectFile_NewTransformMatrix = uibutton(app.ResultsSavePanel, 'push');
            app.SelectFile_NewTransformMatrix.ButtonPushedFcn = createCallbackFcn(app, @SelectFile_NewTransformMatrixButtonPushed, true);
            app.SelectFile_NewTransformMatrix.Text = 'Select from file';
            app.FileNameLabel_4 = uilabel(app.ResultsSavePanel);
            app.FileNameLabel_4.Text = 'File Name';
            app.Filepath_NewTransformMatrix = uieditfield(app.ResultsSavePanel, 'text');
            app.ResultsSave_Button_3 = uibutton(app.ResultsSavePanel, 'push');
            app.ResultsSave_Button_3.ButtonPushedFcn = createCallbackFcn(app, @ResultsSave_Button_3Pushed, true);
            app.ResultsSave_Button_3.Text = 'Save Transform Matrix';
            app.RawDataCorrection_Button_2 = uibutton(app.UIFigure, 'push');
            app.RawDataCorrection_Button_2.ButtonPushedFcn = createCallbackFcn(app, @RawDataCorrection_Button_2Pushed, true);
            app.RawDataCorrection_Button_2.Text = 'Get New Timestamp';
            app.TimestampEditFieldLabel = uilabel(app.UIFigure);
            app.TimestampEditFieldLabel.Text = 'Timestamp';
            app.TimestampEditField = uieditfield(app.UIFigure, 'text');
            app.TimestampEditField.Value = '2019-06-13-10-07-32';
            app.PairingResultAccept_Button = uibutton(app.UIFigure, 'push');
            app.PairingResultAccept_Button.ButtonPushedFcn = createCallbackFcn(app, @PairingResultAccept_ButtonPushed, true);
            app.PairingResultAccept_Button.Text = 'Try Grid Correction!';
            app.TotalfilesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalfilesEditFieldLabel.Text = 'Total files';
            app.TotalfilesEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftfilesEditFieldLabel = uilabel(app.UIFigure);
            app.LeftfilesEditFieldLabel.Text = 'Left files';
            app.LeftfilesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProgressLabel = uilabel(app.UIFigure);
            app.ProgressLabel.Text = 'Progress (%)';
            app.ProgressGauge = uigauge(app.UIFigure, 'linear');
            app.ProgressGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TransformMatrixinputPanel = uipanel(app.UIFigure);
            app.TransformMatrixinputPanel.Title = 'Transform Matrix input';
            app.FilePathNameLabel_4 = uilabel(app.TransformMatrixinputPanel);
            app.FilePathNameLabel_4.Text = 'File Path/Name';
            app.Filepath_TranformMatrix = uieditfield(app.TransformMatrixinputPanel, 'text');
            app.Filepath_TranformMatrix.ValueChangedFcn = createCallbackFcn(app, @Filepath_TranformMatrixValueChanged, true);
            app.SelectFile_TranformMatrix = uibutton(app.TransformMatrixinputPanel, 'push');
            app.SelectFile_TranformMatrix.ButtonPushedFcn = createCallbackFcn(app, @SelectFile_TranformMatrixButtonPushed, true);
            app.SelectFile_TranformMatrix.Text = 'Select';
            app.InloadFile_TranformMatrix = uibutton(app.TransformMatrixinputPanel, 'push');
            app.InloadFile_TranformMatrix.ButtonPushedFcn = createCallbackFcn(app, @InloadFile_TranformMatrixButtonPushed, true);
            app.InloadFile_TranformMatrix.Text = 'Load';
            app.TransformMatrixMonitorTextAreaLabel = uilabel(app.UIFigure);
            app.TransformMatrixMonitorTextAreaLabel.Text = 'Transform Matrix Monitor';
            app.TransformMatrixMonitorTextArea = uitextarea(app.UIFigure);
            app.InputMatrixEditFieldLabel = uilabel(app.UIFigure);
            app.InputMatrixEditFieldLabel.Text = 'Input Matrix?';
            app.InputMatrixEditField = uieditfield(app.UIFigure, 'numeric');
            app.PositionDataCorrection_Button = uibutton(app.UIFigure, 'push');
            app.PositionDataCorrection_Button.ButtonPushedFcn = createCallbackFcn(app, @PositionDataCorrection_ButtonPushed, true);
            app.PositionDataCorrection_Button.Text = 'Position Data Correction!';
            app.PositionDataInputOutputPanel = uipanel(app.UIFigure);
            app.PositionDataInputOutputPanel.Title = 'Position Data Input/Output';
            app.SelectFile_PositionData = uibutton(app.PositionDataInputOutputPanel, 'push');
            app.SelectFile_PositionData.ButtonPushedFcn = createCallbackFcn(app, @SelectFile_PositionDataButtonPushed, true);
            app.SelectFile_PositionData.Text = 'select';
            app.RawDateFolderLabel_2 = uilabel(app.PositionDataInputOutputPanel);
            app.RawDateFolderLabel_2.Text = 'Raw Date Folder';
            app.Filepath_PositionDatefolder = uieditfield(app.PositionDataInputOutputPanel, 'text');
            app.Filepath_PositionDatefolder.ValueChangedFcn = createCallbackFcn(app, @Filepath_PositionDatefolderValueChanged, true);
            app.Filepath_PositionDatefolder.Value = 'C:\Users\weijianz\OneDrive - NTNU\Moser lab\software\Mini2P_processing and analysis\FOV and resolution measurement\MINI2P_FOV and Resolution\Raw image\';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DistortionCleaner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end