classdef NATEXplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DatainputPanel                 matlab.ui.container.Panel
        Savetooptionfile               matlab.ui.control.Button
        AnalysistypeDropDownLabel      matlab.ui.control.Label
        Analysistype                   matlab.ui.control.DropDown
        Selectoptionfile               matlab.ui.control.Button
        SelectDatafolderandDLCoutput   matlab.ui.control.Button
        OptionsforneuronalactivityanalysisPanel  matlab.ui.container.Panel
        MovingwindowsEditFieldLabel    matlab.ui.control.Label
        Movingwindow                   matlab.ui.control.NumericEditField
        TransientlastingsLabel         matlab.ui.control.Label
        Transientlasting               matlab.ui.control.NumericEditField
        LowpassfilterHzEditFieldLabel  matlab.ui.control.Label
        Lowpassfilter                  matlab.ui.control.NumericEditField
        NeuropilcorrectionEditFieldLabel  matlab.ui.control.Label
        Neuropilcorrection             matlab.ui.control.NumericEditField
        CelldistancethresholdpixelEditFieldLabel  matlab.ui.control.Label
        Celldistancethreshold          matlab.ui.control.NumericEditField
        CellcrosscorrelationthresholdEditFieldLabel  matlab.ui.control.Label
        Cellcrosscorrelationthreshold  matlab.ui.control.NumericEditField
        CelloverlapratiothresholdEditFieldLabel  matlab.ui.control.Label
        Celloverlapratiothreshold      matlab.ui.control.NumericEditField
        TransientextendsLabel          matlab.ui.control.Label
        Transientextend                matlab.ui.control.NumericEditField
        SaveSIInformation              matlab.ui.control.CheckBox
        OptionsfortrackinganalysisPanel  matlab.ui.container.Panel
        ResolutioncmpixelEditFieldLabel  matlab.ui.control.Label
        TrackingResolution             matlab.ui.control.NumericEditField
        CenterxpixelEditFieldLabel     matlab.ui.control.Label
        Centerx                        matlab.ui.control.NumericEditField
        CenterypixelEditFieldLabel     matlab.ui.control.Label
        Centery                        matlab.ui.control.NumericEditField
        Sessioncombined                matlab.ui.control.CheckBox
        TrackingframerateHzEditFieldLabel  matlab.ui.control.Label
        Trackingframerate              matlab.ui.control.NumericEditField
        TrackingsychedwithMINI2P       matlab.ui.control.CheckBox
        TabHead                        matlab.ui.container.TabGroup
        M1Tab                          matlab.ui.container.Tab
        Image2                         matlab.ui.control.Image
        HeadPositionLabel_2            matlab.ui.control.Label
        HeadDirectionLabel_2           matlab.ui.control.Label
        LeftpointLabel                 matlab.ui.control.Label
        RightpointLabel                matlab.ui.control.Label
        CSVcolumnLabel_2               matlab.ui.control.Label
        XLabel_3                       matlab.ui.control.Label
        Right_Head_X                   matlab.ui.control.NumericEditField
        YLabel_3                       matlab.ui.control.Label
        Right_Head_Y                   matlab.ui.control.NumericEditField
        CSVcolumnLabel                 matlab.ui.control.Label
        XLabel_4                       matlab.ui.control.Label
        Left_Head_X                    matlab.ui.control.NumericEditField
        YLabel_4                       matlab.ui.control.Label
        Left_Head_Y                    matlab.ui.control.NumericEditField
        M2Tab                          matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        HeadPositionLabel              matlab.ui.control.Label
        HeadDirectionLabel             matlab.ui.control.Label
        AnteriorpointLabel             matlab.ui.control.Label
        PosteriorpointLabel            matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        Anterior_Head_X                matlab.ui.control.NumericEditField
        YLabel                         matlab.ui.control.Label
        Anterior_Head_Y                matlab.ui.control.NumericEditField
        XLabel_2                       matlab.ui.control.Label
        Posterior_Head_X               matlab.ui.control.NumericEditField
        YLabel_2                       matlab.ui.control.Label
        Posterior_Head_Y               matlab.ui.control.NumericEditField
        CSVcolumnLabel_3               matlab.ui.control.Label
        CSVcolumnLabel_4               matlab.ui.control.Label
        M3Tab                          matlab.ui.container.Tab
        HeadPositionLabel_3            matlab.ui.control.Label
        HeadDirectionNALabel           matlab.ui.control.Label
        CSVcolumnLabel_9               matlab.ui.control.Label
        XLabel_9                       matlab.ui.control.Label
        Center_Head_X                  matlab.ui.control.NumericEditField
        YLabel_9                       matlab.ui.control.Label
        Center_Head_Y                  matlab.ui.control.NumericEditField
        Image4                         matlab.ui.control.Image
        TrackBody                      matlab.ui.control.CheckBox
        TabBody                        matlab.ui.container.TabGroup
        M1Tab_2                        matlab.ui.container.Tab
        Image3_3                       matlab.ui.control.Image
        BodyPositionLabel_2            matlab.ui.control.Label
        BodyDirectionLabel_2           matlab.ui.control.Label
        AnteriorpointLabel_3           matlab.ui.control.Label
        PosteriorpointLabel_3          matlab.ui.control.Label
        CSVcolumnLabel_7               matlab.ui.control.Label
        CSVcolumnLabel_8               matlab.ui.control.Label
        XLabel_7                       matlab.ui.control.Label
        Anterior_Body_X                matlab.ui.control.NumericEditField
        YLabel_7                       matlab.ui.control.Label
        Anterior_Body_Y                matlab.ui.control.NumericEditField
        XLabel_8                       matlab.ui.control.Label
        Posterior_Body_X               matlab.ui.control.NumericEditField
        YLabel_8                       matlab.ui.control.Label
        Posterior_Body_Y               matlab.ui.control.NumericEditField
        M2Tab_2                        matlab.ui.container.Tab
        BodyPositionLabel_3            matlab.ui.control.Label
        BodyDirectionNALabel           matlab.ui.control.Label
        CSVcolumnLabel_10              matlab.ui.control.Label
        Center_Body_X                  matlab.ui.control.NumericEditField
        Center_Body_Y                  matlab.ui.control.NumericEditField
        Image5                         matlab.ui.control.Image
        SpeedsmoothsLabel              matlab.ui.control.Label
        SpeedSmooth                    matlab.ui.control.NumericEditField
        HeadtrackingfassionLabel       matlab.ui.control.Label
        BodytrackingfassionLabel       matlab.ui.control.Label
        NeuronalactivityanalysisresultpreviewPanel  matlab.ui.container.Panel
        ColorbarAxes                   matlab.ui.control.UIAxes
        ShowactivitytracesButton       matlab.ui.control.Button
        WindowsEditFieldLabel          matlab.ui.control.Label
        Window                         matlab.ui.control.NumericEditField
        StartsEditFieldLabel           matlab.ui.control.Label
        Starttime                      matlab.ui.control.NumericEditField
        OffsetdFFEditFieldLabel        matlab.ui.control.Label
        Offset                         matlab.ui.control.NumericEditField
        TotalCellsEditFieldLabel       matlab.ui.control.Label
        TotalCells                     matlab.ui.control.NumericEditField
        PlanesEditFieldLabel           matlab.ui.control.Label
        Planes                         matlab.ui.control.NumericEditField
        ChannelsEditFieldLabel         matlab.ui.control.Label
        Channels                       matlab.ui.control.NumericEditField
        RepeatedcellsEditFieldLabel    matlab.ui.control.Label
        Repeatedcells                  matlab.ui.control.NumericEditField
        XpixelEditFieldLabel           matlab.ui.control.Label
        Xpixel                         matlab.ui.control.NumericEditField
        YpixelEditFieldLabel           matlab.ui.control.Label
        Ypixel                         matlab.ui.control.NumericEditField
        TotalvolumesLabel              matlab.ui.control.Label
        TotalVolumes                   matlab.ui.control.NumericEditField
        TotalTimesEditFieldLabel       matlab.ui.control.Label
        TotalTimes                     matlab.ui.control.NumericEditField
        FramerateEditFieldLabel        matlab.ui.control.Label
        Framerate                      matlab.ui.control.NumericEditField
        VolumerateLabel                matlab.ui.control.Label
        Volumerate                     matlab.ui.control.NumericEditField
        ShowSNRdistributionButton      matlab.ui.control.Button
        ShoweventcountdistributionButton  matlab.ui.control.Button
        SmoothforshowEditFieldLabel    matlab.ui.control.Label
        Smoothforshow                  matlab.ui.control.NumericEditField
        ShowprojectedimageButton       matlab.ui.control.Button
        ChannelDropDownLabel           matlab.ui.control.Label
        ChannelDropDown                matlab.ui.control.DropDown
        PlaneDropDownLabel             matlab.ui.control.Label
        PlaneDropDown                  matlab.ui.control.DropDown
        MaxLabel                       matlab.ui.control.Label
        Max                            matlab.ui.control.NumericEditField
        RotationdegreeEditFieldLabel   matlab.ui.control.Label
        Rotationdegree                 matlab.ui.control.NumericEditField
        Fliphorizontally               matlab.ui.control.CheckBox
        TypeDropDownLabel              matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        ShowallcellmapButton           matlab.ui.control.Button
        CellmapcolorcodeDropDownLabel  matlab.ui.control.Label
        CellmapcolorcodeDropDown       matlab.ui.control.DropDown
        CellmapcolormapDropDownLabel   matlab.ui.control.Label
        CellmapcolormapDropDown        matlab.ui.control.DropDown
        Imageoverlaid                  matlab.ui.control.CheckBox
        Range_minEditFieldLabel        matlab.ui.control.Label
        Range_min                      matlab.ui.control.NumericEditField
        Range_maxEditField_2Label      matlab.ui.control.Label
        Range_max                      matlab.ui.control.NumericEditField
        ShowcellID                     matlab.ui.control.CheckBox
        ShowselectcellmapButton        matlab.ui.control.Button
        TracetypeDropDownLabel         matlab.ui.control.Label
        TracetypeDropDown              matlab.ui.control.DropDown
        Showrepeatcell                 matlab.ui.control.CheckBox
        ListBox                        matlab.ui.control.ListBox
        TrackinganalysisresultpreviewPanel  matlab.ui.container.Panel
        TotaldistancecmEditFieldLabel  matlab.ui.control.Label
        Totaldistance                  matlab.ui.control.NumericEditField
        MeanspeedcmsEditFieldLabel     matlab.ui.control.Label
        Meanspeed                      matlab.ui.control.NumericEditField
        thspeedcmsLabel                matlab.ui.control.Label
        Highspeed                      matlab.ui.control.NumericEditField
        TotalsessionsEditFieldLabel    matlab.ui.control.Label
        Totalsessions                  matlab.ui.control.NumericEditField
        ShowsessionDropDownLabel       matlab.ui.control.Label
        ShowsessionDropDown            matlab.ui.control.DropDown
        TrajectoryplottypeLabel        matlab.ui.control.Label
        TrajectoryplottypeDropDown     matlab.ui.control.DropDown
        ShowtrajectoryButton           matlab.ui.control.Button
        Colorcode_mincmsLabel          matlab.ui.control.Label
        ColorRange_min                 matlab.ui.control.NumericEditField
        Colormap_maxcmsLabel           matlab.ui.control.Label
        ColorRange_max                 matlab.ui.control.NumericEditField
        TotalframesEditFieldLabel      matlab.ui.control.Label
        TotalframesTracking            matlab.ui.control.NumericEditField
        TotalTimesEditField_2Label     matlab.ui.control.Label
        TotalTimesTracking             matlab.ui.control.NumericEditField
        StartTimesLabel                matlab.ui.control.Label
        StartTimeTracking              matlab.ui.control.NumericEditField
        EndTImesLabel                  matlab.ui.control.Label
        EndTimeTracking                matlab.ui.control.NumericEditField
        BackgroundDropDownLabel        matlab.ui.control.Label
        BackgroundDropDown             matlab.ui.control.DropDown
        ShowdirectionplotButton        matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        NATgenerationPanel             matlab.ui.container.Panel
        Suite2PfilesLabel              matlab.ui.control.Label
        Suite2PfilesListBox            matlab.ui.control.ListBox
        DLCfilesListBoxLabel           matlab.ui.control.Label
        DLCfilesListBox                matlab.ui.control.ListBox
        GenerateNATButton              matlab.ui.control.Button
        AlignwithEditFieldLabel        matlab.ui.control.Label
        Suite2PAlignedFile             matlab.ui.control.NumericEditField
        AlignwithEditField_2Label      matlab.ui.control.Label
        DLCAlignedFile                 matlab.ui.control.NumericEditField
        Suite2pselectedListBoxLabel    matlab.ui.control.Label
        Suite2pselectedListBox         matlab.ui.control.ListBox
        DLCselectedListBoxLabel        matlab.ui.control.Label
        DLCselectedListBox             matlab.ui.control.ListBox
        HeadpositioncorrectioncmLabel  matlab.ui.control.Label
        Headpositioncorrection         matlab.ui.control.NumericEditField
        NATresultpreviewPanel          matlab.ui.container.Panel
        ShowresultsButton              matlab.ui.control.Button
        SaveCheckBox                   matlab.ui.control.CheckBox
        SignaltypeDropDownLabel        matlab.ui.control.Label
        SignaltypeDropDown             matlab.ui.control.DropDown
        TabGroup                       matlab.ui.container.TabGroup
        RangeTab                       matlab.ui.container.Tab
        CellstocheckEditFieldLabel     matlab.ui.control.Label
        Cellstocheck                   matlab.ui.control.NumericEditField
        StartcellEditFieldLabel        matlab.ui.control.Label
        Startcell                      matlab.ui.control.NumericEditField
        CellselectionLabel             matlab.ui.control.Label
        ArbitraryTab                   matlab.ui.container.Tab
        CellIDLabel                    matlab.ui.control.Label
        CellID                         matlab.ui.control.EditField
        CellselectionLabel_2           matlab.ui.control.Label
        TrackingandcombinedplotsoptionsPanel  matlab.ui.container.Panel
        AnglesmoothingdegreeLabel      matlab.ui.control.Label
        Directionsmoothing             matlab.ui.control.NumericEditField
        AnglebindegreeLabel            matlab.ui.control.Label
        Directionbinsize               matlab.ui.control.NumericEditField
        SpatialbincmEditFieldLabel     matlab.ui.control.Label
        Spatialbin                     matlab.ui.control.NumericEditField
        SpatialsmoothingcmEditFieldLabel  matlab.ui.control.Label
        Spatialsmoothing               matlab.ui.control.NumericEditField
        SpeedthresholdmincmsEditFieldLabel  matlab.ui.control.Label
        Speedthresholdmin              matlab.ui.control.NumericEditField
        SpeedthresholdmaxcmsEditFieldLabel  matlab.ui.control.Label
        Speedthresholdmax              matlab.ui.control.NumericEditField
        PlotrangemincmLabel            matlab.ui.control.Label
        PlotRange_min                  matlab.ui.control.NumericEditField
        PlotrangemaxcmLabel            matlab.ui.control.Label
        PlotRange_max                  matlab.ui.control.NumericEditField
        MintimesEditFieldLabel         matlab.ui.control.Label
        Mintime                        matlab.ui.control.NumericEditField
        TrackingandtuningcolormapDropDownLabel  matlab.ui.control.Label
        TrackingandtuningcolormapDropDown  matlab.ui.control.DropDown
        ShowpartDropDownLabel          matlab.ui.control.Label
        ShowpartDropDown               matlab.ui.control.DropDown
        ControlPanel                   matlab.ui.container.Panel
        RunanalysisButton              matlab.ui.control.Button
        AbortanalysisButton            matlab.ui.control.Button
        CloseallfiguresButton          matlab.ui.control.Button
        CleanupcommendtextButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        File_folder_raw;
        DLC_files;
        
        
        options=struct;
        
        
        SelectCell;
        
        Suite2PFolder
        NeuronInformation=struct;
        NeuronActiveMatrix=struct;
        TrackingResultMatrix=struct;
        ExperimentInformation=struct;
        NAT;
        
        
        Abort_flag=false;


        FigTracking;
        FigDirection;
        TrackPlotHandle;
        TrackColorbarHandle;
        
        
        bodyparts_raw={}; % Description
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDatafolderandDLCoutput
        function SelectDatafolderandDLCoutputPushed(app, event)
            
            app.File_folder_raw=uigetdir; % munually select the raw file folder;
            app.ListBox.Items={};
            app.ListBox.Items(length(app.ListBox.Items)+1)={'Data folder was founded'};
            app.ListBox.Items(length(app.ListBox.Items)+1)={app.File_folder_raw};
            scroll(app.ListBox, 'bottom')
            cd (app.File_folder_raw); % the working folder will be changed to the data folder
            if strcmp(app.Analysistype.Value,'Complete pipline') || strcmp(app.Analysistype.Value,'Only Tracking')|| strcmp(app.Analysistype.Value,'MINI2P+Tracking')
                app.DLC_files=uigetfile('*.csv','Select DLC outputs for tracking analysis','MultiSelect','on');
                app.ListBox.Items(length(app.ListBox.Items)+1)={'DLC outputs selected:'};
                if iscell(app.DLC_files)
                    for i=1:1:length(app.DLC_files)
                        app.ListBox.Items(length(app.ListBox.Items)+1)={app.DLC_files{1,i}};
                    end
                else
                    app.ListBox.Items(length(app.ListBox.Items)+1)={app.DLC_files};
                end
                
            elseif strcmp(app.Analysistype.Value,'Load in processed data')
                if isfile('NeuronActiveMatrix.mat')
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'NeuronActiveMatrix.mat was found'};
                    Load_tem=load('NeuronActiveMatrix.mat');
                    app.NeuronActiveMatrix=Load_tem.NeuronActiveMatrix;
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'NeuronActiveMatrix.mat was loaded'};
                else
                    
                end
                if isfile('NeuronInformation.mat')
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'NeuronInformation.mat was found'};
                    Load_tem=load('NeuronInformation.mat');
                    app.NeuronInformation=Load_tem.NeuronInformation;
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'NeuronInformation.mat was loaded'};
                    
                    % load in neuronal activity analysis options used in
                    % processed data
                    app.Movingwindow.Value=app.NeuronInformation.Movingwindow;
                    app.Transientlasting.Value=app.NeuronInformation.Transientlasting;
                    app.Lowpassfilter.Value=app.NeuronInformation.Lowpassfilter;
                    app.Neuropilcorrection.Value=app.NeuronInformation.Neuropilcorrection;
                    app.Celldistancethreshold.Value=app.NeuronInformation.Celldistancethreshold;
                    app.Cellcrosscorrelationthreshold.Value=app.NeuronInformation.Cellcrosscorrelationthreshold;
                    app.Celloverlapratiothreshold.Value=app.NeuronInformation.Celloverlapratiothreshold;
                    app.Transientextend.Value=app.NeuronInformation.Transientextend;
                    app.SaveSIInformation.Value=app.NeuronInformation.SaveSIInformation;
                    
                    % load in neuronal activity analysis results used in
                    % processed data
                    app.TotalCells.Value=app.NeuronInformation.TotalCell;
                    app.Repeatedcells.Value=length(app.NeuronInformation.RepeatCell);
                    app.Xpixel.Value=app.NeuronInformation.Framesize(1);
                    app.Ypixel.Value=app.NeuronInformation.Framesize(2);
                    app.Channels.Value=double(app.NeuronInformation.ImagingChannel);
                    app.ChannelDropDown.Items={};
                    for i=1:1:app.Channels.Value
                        app.ChannelDropDown.Items(length(app.ChannelDropDown.Items)+1)={num2str(i)};
                        drawnow;
                    end
                    app.Planes.Value=double(app.NeuronInformation.ImagingPlane);
                    app.PlaneDropDown.Items={};
                    for i=1:1:app.Planes.Value
                        app.PlaneDropDown.Items(length(app.PlaneDropDown.Items)+1)={num2str(i)};
                        drawnow;
                    end
                    app.TotalVolumes.Value=app.NeuronInformation.FrameClockNumber;
                    app.Volumerate.Value=app.NeuronInformation.volumerate;
                    app.Framerate.Value=app.NeuronInformation.volumerate.*double(app.NeuronInformation.ImagingPlane);
                    
                    app.TotalTimes.Value=app.NeuronInformation.FrameClockNumber./(app.NeuronInformation.volumerate);
                    app.Suite2PfilesListBox.Items={};
                    for i=1:1:length(app.NeuronInformation.Options.frames_per_file)
                        app.Suite2PfilesListBox.Items(length(app.Suite2PfilesListBox.Items)+1)={[num2str(i),': ',num2str(app.NeuronInformation.Options.frames_per_file(i))]};
                    end
                    app.Suite2PfilesListBox.Value=app.Suite2PfilesListBox.Items(app.Suite2PAlignedFile.Value);
                    drawnow;
                else
                    app.TotalCells.Value=0;
                    app.Repeatedcells.Value=0;
                    app.Xpixel.Value=0;
                    app.Ypixel.Value=0;
                    app.Channels.Value=0;
                    app.ChannelDropDown.Items={};
                    app.Planes.Value=0;
                    app.PlaneDropDown.Items={};
                    app.TotalVolumes.Value=0;
                    app.Volumerate.Value=0;
                    app.Framerate.Value=0;
                    app.TotalTimes.Value=0;
                    app.Suite2PfilesListBox.Items={};
                    drawnow;
                end
                
                if isfile('TrackingResultMatrix.mat')
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'TrackingResultMatrix.mat was found'};
                    Load_tem=load('TrackingResultMatrix.mat');
                    app.TrackingResultMatrix=Load_tem.TrackingResultMatrix;
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'TrackingResultMatrix.mat was loaded'};
                    if isfield(app.TrackingResultMatrix, 'DLC_files')
                        app.DLC_files=app.TrackingResultMatrix.DLC_files;
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'DLC file address was found in TrackingResultMatrix.mat.'};
                    else
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'Can not find DLC file address in TrackingResultMatrix.mat!!'};
                        app.DLC_files=[];
                    end
                    % load in tracking analysis options used in processed data
                    app.TrackingResolution.Value=app.TrackingResultMatrix.TrackingResolution;
                    
                    if app.TrackingResultMatrix.HeadTrackingfasion==1
                        app.TabHead.SelectedTab=app.M1Tab;
                        app.Left_Head_X.Value=app.TrackingResultMatrix.Left_Head_X;
                        app.Left_Head_Y.Value=app.TrackingResultMatrix.Left_Head_Y;
                        app.Right_Head_X.Value=app.TrackingResultMatrix.Right_Head_X;
                        app.Right_Head_Y.Value=app.TrackingResultMatrix.Right_Head_Y;
                        
                    elseif app.TrackingResultMatrix.HeadTrackingfasion==2
                        app.TabHead.SelectedTab=app.M2Tab;
                        app.Anterior_Head_X.Value=app.TrackingResultMatrix.Anterior_Head_X;
                        app.Anterior_Head_Y.Value=app.TrackingResultMatrix.Anterior_Head_Y;
                        app.Posterior_Head_X.Value=app.TrackingResultMatrix.Posterior_Head_X;
                        app.Posterior_Head_Y.Value=app.TrackingResultMatrix.Posterior_Head_Y;
                        
                    elseif app.TrackingResultMatrix.HeadTrackingfasion==3
                        app.TabHead.SelectedTab=app.M3Tab;
                        app.Center_Head_X.Value=app.TrackingResultMatrix.Center_Head_X;
                        app.Center_Head_Y.Value=app.TrackingResultMatrix.Center_Head_Y;
                    else
                    end
                    
                    if app.TrackingResultMatrix.BodyTrackingfasion==1
                        app.TabBody.SelectedTab=app.M1Tab_2;
                        app.Anterior_Body_X.Value=app.TrackingResultMatrix.Anterior_Body_X;
                        app.Anterior_Body_Y.Value=app.TrackingResultMatrix.Anterior_Body_Y;
                        app.Posterior_Body_X.Value=app.TrackingResultMatrix.Posterior_Body_X;
                        app.Posterior_Body_Y.Value=app.TrackingResultMatrix.Posterior_Body_Y;

                        
                    elseif app.TrackingResultMatrix.BodyTrackingfasion==2
                        app.TabBody.SelectedTab=app.M2Tab_2;
                        app.Center_Body_X.Value=app.TrackingResultMatrix.Center_Body_X;
                        app.Center_Body_X.Value=app.TrackingResultMatrix.Center_Body_X;
                    else
                    end                    
                    
                    app.TrackBody.Value=app.TrackingResultMatrix.TrackBody;
                    if app.TrackBody.Value==0                       
                        app.Anterior_Body_X.Enable=0;
                        app.Anterior_Body_Y.Enable=0;
                        app.Posterior_Body_X.Enable=0;
                        app.Posterior_Body_Y.Enable=0;
                        app.Center_Body_X.Enable=0;
                        app.Center_Body_Y.Enable=0;
                    else
                        app.Anterior_Body_X.Enable=1;
                        app.Anterior_Body_Y.Enable=1;
                        app.Posterior_Body_X.Enable=1;
                        app.Posterior_Body_Y.Enable=1;
                        app.Center_Body_X.Enable=1;
                        app.Center_Body_Y.Enable=1;
                    end
                 

                    app.Centerx.Value=app.TrackingResultMatrix.BoxCenter(1);
                    app.Centery.Value=app.TrackingResultMatrix.BoxCenter(2);
                    app.Trackingframerate.Value=app.TrackingResultMatrix.Trackingframerate;
                    app.TrackingsychedwithMINI2P.Value=app.TrackingResultMatrix.SychtoMINI2P;
                    app.Sessioncombined.Value=app.TrackingResultMatrix.Sessioncombined;
                    if app.TrackingsychedwithMINI2P.Value==1
                        app.Trackingframerate.Editable=0;
                        
                    else
                        app.Trackingframerate.Editable=1;
                    end
                    
                    % load in tracking analysis result used in processed data
                    
                    
                    app.Totalsessions.Value=app.TrackingResultMatrix.Session;
                    
                   app.ShowsessionDropDown.Items={};
                    app.DLCfilesListBox.Items={};
                    for i=1:1:app.TrackingResultMatrix.Session
                        app.DLCfilesListBox.Items(length(app.DLCfilesListBox.Items)+1)={[num2str(i),': ',num2str(app.TrackingResultMatrix.FrameineachSession(i))]};
                        app.ShowsessionDropDown.Items(length(app.ShowsessionDropDown.Items)+1)={num2str(i)};
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                    end
                    app.DLCfilesListBox.Value=app.DLCfilesListBox.Items(app.DLCAlignedFile.Value);                    
                    
                    
                    app.TotalframesTracking.Value=length(app.TrackingResultMatrix.PositionDirectionExtract{1,str2double(app.ShowsessionDropDown.Value)}.headspeed);
                    drawnow;
                    app.TotalTimesTracking.Value=length(app.TrackingResultMatrix.PositionDirectionExtract{1,str2double(app.ShowsessionDropDown.Value)}.headspeed)./app.TrackingResultMatrix.Trackingframerate;
                    drawnow;
                    app.StartTimeTracking.Value=0;
                    app.EndTimeTracking.Value=app.TotalTimesTracking.Value;
                    if strcmp(app.ShowpartDropDown.Value,'Head')
                        app.Totaldistance.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.HeadTotalDistance;
                        app.Meanspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.HeadMeanSpeed;
                        app.Highspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.Head90thSpeed;
                    else
                        app.Totaldistance.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.BodyTotalDistance;
                        app.Meanspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.BodyMeanSpeed;
                        app.Highspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.Body90thSpeed;
                    end
 
                else
                    app.Totalsessions.Value=0;
                    app.TotalframesTracking.Value=0;
                    app.TotalTimesTracking.Value=0;
                    app.StartTimeTracking.Value=0;
                    app.EndTimeTracking.Value=0;
                    app.Totaldistance.Value=0;
                    app.Meanspeed.Value=0;
                    app.Highspeed.Value=0;
                    app.ShowsessionDropDown.Items={};
                    app.DLCfilesListBox.Items={};
                end
                        
                
                if isfile('NAT.mat')
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'NAT.mat was found'};
                    Load_tem=load('NAT.mat');
                    app.NAT=Load_tem.NAT;
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'NAT.mat was loaded'};
                else
                end
                
                
                if isfile('ExperimentInformation.mat')
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'ExperimentInformation.mat was found'};
                    Load_tem=load('ExperimentInformation.mat');
                    app.ExperimentInformation=Load_tem.ExperimentInformation;
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'ExperimentInformation.mat was loaded'};
                    
                    % load in NAT options used in processed data
                    app.Headpositioncorrection.Value=app.ExperimentInformation.Headpositioncorrection;
                    app.Suite2PAlignedFile.Value=app.ExperimentInformation.Suite2PAlignedFile;
                    app.DLCAlignedFile.Value=app.ExperimentInformation.DLCAlignedFile;
                    app.Suite2PfilesListBox.Value=app.Suite2PfilesListBox.Items(app.Suite2PAlignedFile.Value);
                    app.DLCfilesListBox.Value=app.DLCfilesListBox.Items(app.DLCAlignedFile.Value);
                    app.Suite2pselectedListBox.Items={};
                    app.DLCselectedListBox.Items={};
                    for i=1:1:app.ExperimentInformation.Suite2P_filetaken
                        app.Suite2pselectedListBox.Items(length(app.Suite2pselectedListBox.Items)+1)=app.Suite2PfilesListBox.Items(app.Suite2PAlignedFile.Value+i-1);
                    end
                    app.Sessioncombined.Value=app.ExperimentInformation.Sessioncombined;
                    if app.Sessioncombined.Value==0
                        for i=1:1:app.ExperimentInformation.DLC_filetaken
                            app.DLCselectedListBox.Items(length(app.DLCselectedListBox.Items)+1)=app.DLCfilesListBox.Items(app.DLCAlignedFile.Value+i-1);
                        end
                    else
                        app.DLCselectedListBox.Items(length(app.DLCselectedListBox.Items)+1)=app.DLCfilesListBox.Items(1);
                    end
                else
                end
                
            end
        end

        % Button pushed function: Selectoptionfile
        function SelectoptionfilePushed(app, event)
            [app.option_file,app.option_path] = uigetfile('*.mat','Please select the option file');
            app.options=imread([app.option_path,'\',app.option_file]);
        end

        % Button pushed function: CleanupcommendtextButton
        function CleanupcommendtextButtonPushed(app, event)
            app.ListBox.Items={};
        end

        % Callback function
        function TrackingfasionValueChanged(app, event)
            Model= app.Trackingfasion.Value;
            if Model=='DLC1'
                app.ListBox.Items(length(app.ListBox.Items)+1)={'check tracking parts: nose, mouse, lefthand, righthand, leftleg, rightleg, tailbase, bodycenter.'};
                app.bodyparts_raw={'nose', 'mouse', 'lefthand', 'righthand', 'leftleg', 'rightleg', 'tailbase', 'bodycenter'};
            elseif Model=='DLC2'
                app.ListBox.Items(length(app.ListBox.Items)+1)={'check tracking parts: miniscope, leftear, rightear, bodycenter, tailbase.'};
                app.bodyparts_raw={'miniscope', 'leftear', 'rightear', 'bodycenter', 'tailbase'};
            elseif Model=='DLC3'
                app.ListBox.Items(length(app.ListBox.Items)+1)={'check tracking parts: nose, leftear, rightear, bodycenter, tailbase.'};
                app.bodyparts_raw={'nose', 'leftear', 'rightear', 'bodycenter', 'tailbase'};
            else
            end
            scroll(app.ListBox, 'bottom') ;
        end

        % Button pushed function: Savetooptionfile
        function SavetooptionfilePushed(app, event)
            
        end

        % Button pushed function: AbortanalysisButton
        function AbortanalysisButtonPushed(app, event)
            app.Abort_flag=true;
            app.ListBox.Items(length(app.ListBox.Items)+1)={'Used decided to abort the analysis!'};
            drawnow;
        end

        % Button pushed function: RunanalysisButton
        function RunanalysisButtonPushed(app, event)
            % clear up all analysis results
            close all;
            clc;
            app.Abort_flag=false;
            app.ListBox.Items(length(app.ListBox.Items)+1)={'Fellowing options are used:'};
            app.options.Movingwindow=app.Movingwindow.Value;
            app.options.Transientlasting=app.Transientlasting.Value;
            app.options.Lowpassfilter=app.Lowpassfilter.Value;
            app.options.Neuropilcorrection=app.Neuropilcorrection.Value;
            app.options.Celldistancethreshold=app.Celldistancethreshold.Value;
            app.options.Cellcrosscorrelationthreshold=app.Cellcrosscorrelationthreshold.Value;
            app.options.Celloverlapratiothreshold=app.Celloverlapratiothreshold.Value;
            app.options.Transientextend=app.Transientextend.Value;
            app.options.SaveSIInformation=app.SaveSIInformation.Value;
            app.options.TrackingResolution=app.TrackingResolution.Value;                       
            app.options.HeadTrackingfasion=app.TabHead.SelectedTab.Title;
            app.options.BodyTrackingfasion=app.TabHead.SelectedTab.Title; 
            app.options.TrackBody=app.TrackBody.Value;                                    
            app.options.Centerx=app.Centerx.Value;
            app.options.Centery=app.Centery.Value;
            app.options.Sessioncombined=app.Sessioncombined.Value;
            app.options.TrackingsychedwithMINI2P=app.TrackingsychedwithMINI2P.Value;
            app.options.Headpositioncorrection=app.Headpositioncorrection.Value;
            app.options.DLCAlignedFile=app.DLCAlignedFile.Value;
            app.options.Suite2PAlignedFile=app.Suite2PAlignedFile.Value;            
            % display the current options
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Moving window(s): ',num2str(app.options.Movingwindow)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['transient lasting(s): ',num2str(app.options.Transientlasting)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Lowpass filter(Hz): ',num2str(app.options.Lowpassfilter)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Neuropil correction: ',num2str(app.options.Neuropilcorrection)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['transient extend(s): ',num2str(app.options.Transientextend)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Cell distance(pix): ',num2str(app.options.Celldistancethreshold)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Cell crosscorrelation: ',num2str(app.options.Cellcrosscorrelationthreshold)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Cell overlap: ',num2str(app.options.Celloverlapratiothreshold)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Save SI information: ',num2str(app.options.SaveSIInformation)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Session Combine: ',num2str(app.options.Sessioncombined)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Tracking resolution (mm/pix): ',num2str(app.options.TrackingResolution)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Body tracking?: ',num2str(app.TrackBody.Value)]};           
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Boxcenter x(pix): ',num2str(app.options.Centerx)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Boxcenter y(pix): ',num2str(app.options.Centery)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Head position correction: ',num2str(app.options.Headpositioncorrection)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Suite2P align with file: ',num2str(app.options.Suite2PAlignedFile)]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['DLC align with file: ',num2str(app.options.DLCAlignedFile)]};
            scroll(app.ListBox, 'bottom');
            drawnow;
            
            % currently NATEX support thre DLC models as bellow.
            
            if strcmp(app.options.HeadTrackingfasion,'M1')
                
                app.options.Left_Head_X=app.Left_Head_X.Value;
                app.options.Left_Head_Y=app.Left_Head_Y.Value;
                app.options.Right_Head_X=app.Right_Head_X.Value;
                app.options.Right_Head_Y=app.Right_Head_Y.Value;                
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking model 1 is selected'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking position: lest point, right point;'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Left point csv column: ',num2str(app.Left_Head_X.Value),', ',num2str(app.Left_Head_Y.Value)]};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Right point csv column: ',num2str(app.Right_Head_X.Value),', ',num2str(app.Right_Head_Y.Value)]};                
            elseif strcmp(app.options.HeadTrackingfasion,'M2')                
                app.options.Anterior_Head_X=app.Anterior_Head_X.Value;
                app.options.Anterior_Head_Y=app.Anterior_Head_Y.Value;
                app.options.Posterior_Head_X=app.Posterior_Head_X.Value;
                app.options.Posterior_Head_Y=app.Posterior_Head_Y.Value;                                  
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking model  2 is selected'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking position: anterior point, posterior point;'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Anterior point csv column: ',num2str(app.Anterior_Head_X.Value),', ',num2str(app.Anterior_Head_Y.Value)]};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Posterior point csv column: ',num2str(app.Posterior_Head_X.Value),', ',num2str(app.Posterior_Head_Y.Value)]};                
            elseif strcmp(app.options.HeadTrackingfasion,'M3')
                
                app.options.Center_Head_X=app.Center_Head_X.Value;
                app.options.Center_Head_Y=app.Center_Head_Y.Value;

                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking model 3 is selected'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Tracking head direction is not possible!'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking position: center point'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Center point csv column: ',num2str(app.Center_Head_X.Value),', ',num2str(app.Center_Head_Y.Value)]};
            else
            end
            scroll(app.ListBox, 'bottom')
            drawnow            


            app.ListBox.Items(length(app.ListBox.Items)+1)={['Analysis trpe: ',app.Analysistype.Value]};
            if strcmp(app.Analysistype.Value,'Complete pipline') || strcmp(app.Analysistype.Value,'Only MINI2P')|| strcmp(app.Analysistype.Value,'MINI2P+Tracking')
                % clear up the neuronal activity analysis results
                app.TotalCells.Value=0;
                app.Repeatedcells.Value=0;
                app.Xpixel.Value=0;
                app.Ypixel.Value=0;
                app.Channels.Value=0;
                app.ChannelDropDown.Items={};
                app.Planes.Value=0;
                app.PlaneDropDown.Items={};
                app.TotalVolumes.Value=0;
                app.Volumerate.Value=0;
                app.Framerate.Value=0;
                app.TotalTimes.Value=0;
                app.Suite2PfilesListBox.Items={};
                drawnow;
            else
            end
            
            if strcmp(app.Analysistype.Value,'Complete pipline') || strcmp(app.Analysistype.Value,'Only Tracking')|| strcmp(app.Analysistype.Value,'MINI2P+Tracking')
                
                if isempty(app.DLC_files)
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Forgot to select DLC files? Analysis is aborted!'};
                scroll(app.ListBox, 'bottom')
                drawnow;
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Please select DLC files by pressing "Select Data folder andDLC output“ buttom :)'};
                scroll(app.ListBox, 'bottom')
                drawnow; 
                return
                else
                end
                
                
                % clear up the tracking anaylsis results
                app.Totalsessions.Value=0;
                app.TotalframesTracking.Value=0;
                app.TotalTimesTracking.Value=0;
                app.StartTimeTracking.Value=0;
                app.EndTimeTracking.Value=0;
                app.Totaldistance.Value=0;
                app.Meanspeed.Value=0;
                app.Highspeed.Value=0;
                app.ShowsessionDropDown.Items={};
                app.DLCfilesListBox.Items={};
            else
            end
            app.Suite2pselectedListBox.Items={};
            app.DLCselectedListBox.Items={};
            
            if strcmp(app.Analysistype.Value,'Complete pipline') || strcmp(app.Analysistype.Value,'Only MINI2P')|| strcmp(app.Analysistype.Value,'MINI2P+Tracking')
                
                
                %%%%%%%%%%%%load in suite2P output %%%%%%%%%%%%
                
                % if Multiplane imaging was applied, the combined folder should be
                % generated. Then only the data in combined folder will be analyzed.
                % Otherwise, only analyze the plane0 data
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Now start to analyze neuronal activity data....'};
                scroll(app.ListBox, 'bottom') ;
                drawnow;
                if exist([app.File_folder_raw,'\suite2p\combined'],'dir')==7
                    app.Suite2PFolder=[app.File_folder_raw,'\suite2p\combined\'];
                    app.ListBox.Items(length(app.ListBox.Items)+1)={['Suite2P folder: ',app.Suite2PFolder]};
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Ha, this is multiplane imaging.'};
                    scroll(app.ListBox, 'bottom') ;
                    drawnow;
                    [app.NeuronInformation,app.NeuronActiveMatrix]=preprocessing.GenerateNeuronInformation([app.File_folder_raw,'\suite2p\combined\Fall.mat']);
                    scroll(app.ListBox, 'bottom') ;
                else
                    app.ListBox.Items(length(app.ListBox.Items)+1)={['Suite2P folder: ',app.Suite2PFolder]};
                    app.Suite2PFolder=[app.File_folder_raw,'\suite2p\plane0\'];
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Oh, this is single plane imaging.'};
                    scroll(app.ListBox, 'bottom') ;
                    drawnow;
                    [app.NeuronInformation,app.NeuronActiveMatrix]=preprocessing.GenerateNeuronInformation([app.File_folder_raw,'\suite2p\plane0\Fall.mat']);
                    scroll(app.ListBox, 'bottom') ;
                end
                app.Suite2PfilesListBox.Items={};
                for i=1:1:length(app.NeuronInformation.Options.frames_per_file)
                    app.Suite2PfilesListBox.Items(length(app.Suite2PfilesListBox.Items)+1)={[num2str(i),': ',num2str(app.NeuronInformation.Options.frames_per_file(i))]};
                end
                
                
                app.Suite2PfilesListBox.Value=app.Suite2PfilesListBox.Items(app.options.Suite2PAlignedFile);
                app.NeuronInformation.Framesize(1)=size(app.NeuronInformation.Options.refImg,1);
                app.NeuronInformation.Framesize(2)=size(app.NeuronInformation.Options.refImg,2);
                app.Xpixel.Value=app.NeuronInformation.Framesize(1);
                app.Ypixel.Value=app.NeuronInformation.Framesize(2);
                app.Channels.Value=double(app.NeuronInformation.ImagingChannel);
                
                app.ChannelDropDown.Items={};
                for i=1:1:app.Channels.Value
                    app.ChannelDropDown.Items(length(app.ChannelDropDown.Items)+1)={num2str(i)};
                    drawnow;
                end
                
                app.Planes.Value=double(app.NeuronInformation.ImagingPlane);
                app.PlaneDropDown.Items={};
                for i=1:1:app.Planes.Value
                    app.PlaneDropDown.Items(length(app.PlaneDropDown.Items)+1)={num2str(i)};
                    drawnow;
                end
                
                app.TotalVolumes.Value=app.NeuronInformation.FrameClockNumber;
                app.Framerate.Value=app.NeuronInformation.volumerate.*double(app.NeuronInformation.ImagingPlane);
                if app.options.TrackingsychedwithMINI2P==1
                    app.Trackingframerate.BackgroundColor=[.9 .9 .9];
                    app.Trackingframerate.Value=app.Framerate.Value;
                    app.options.Trackingframerate=app.Trackingframerate.Value;
                else
                    app.Trackingframerate.BackgroundColor=[1 1 1];
                    app.options.Trackingframerate=app.Trackingframerate.Value;
                end
                app.Volumerate.Value=app.NeuronInformation.volumerate;
                
                
                app.TotalTimes.Value=app.NeuronInformation.FrameClockNumber./app.NeuronInformation.volumerate;
                drawnow;
                
                app.NeuronInformation.File_folder_Suite2P=app.Suite2PFolder;
                app.NeuronInformation.File_folder_root=app.File_folder_raw;
                
                
                %%%%% save the SI information to seperate files %%%%
                %header has the detail imaging information including the hardware setups and recoding conditions
                %Aout{i} is the raw 16bit data, organized by the size M x N x C x F x S x V , where C spans the channel indices, F spans the frame indicies, S spans the slice indices, and V the volume indices.
                %imgInfor includes the general imaging infortmation
                % search for all .tif files in this folder, only necessasy for SI information saving. No need for suite2p or DLC output analysis
                
                app.NeuronInformation.RawImage={}; %the content for the raw imaging data
                app.NeuronInformation.header={};
                app.NeuronInformation.imgInfo={};
                app.NeuronInformation.SaveSIInformation=app.options.SaveSIInformation;
                if app.options.SaveSIInformation==1
                    app.NeuronInformation.TIF_Files=dir([app.NeuronInformation.File_folder_root,'\*.tif']);
                    [TIF_num,~]=size(app.NeuronInformation.TIF_Files);
                    if TIF_num~=0
                        app.ListBox.Items(length(app.ListBox.Items)+1)={[num2str(TIF_num),' Imaging tif files were found']};
                        drawnow;
                        for i=1:1:TIF_num
                            app.ListBox.Items(length(app.ListBox.Items)+1)={app.NeuronInformation.TIF_Files(i).name};
                            drawnow;
                        end
                    else
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'0 imaging tif files were not found'};
                    end
                    
                    drawnow;
                    
                    for WhichTiff=1:1:size(app.NeuronInformation.TIF_Files,1)
                        app.ListBox.Items(length(app.ListBox.Items)+1)={['Now reading SI information from tiff file ' num2str(WhichTiff)  '.....']};
                        drawnow;
                        [header,~, imgInfo] = scanimage.util.opentif([app.NeuronInformation.TIF_Files(WhichTiff).folder,'\',app.NeuronInformation.TIF_Files(WhichTiff).name]);
                        app.NeuronInformation.header{WhichTiff,1}=header;
                        app.NeuronInformation.imgInfo{WhichTiff,1}=imgInfo;
                        filename1=imgInfo.filename;
                        app.ListBox.Items(length(app.ListBox.Items)+1)={['SI information for: "' filename1  '" is loaded']};
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                        save([filename1(1:end-4),'_tifHeader.mat'],'header');% save the SI information, only necessasy for SI information saving. No need for DJ analysis
                        app.ListBox.Items(length(app.ListBox.Items)+1)={[filename1(1:end-4),'_tifHeader.mat',' is saved']};
                        save([filename1(1:end-4),'_imgInfo.mat'],'imgInfo');% save the imaging information,only necessasy for SI information saving. No need for DJ analysis
                        app.ListBox.Items(length(app.ListBox.Items)+1)={[filename1(1:end-4),'_imgInfo.mat',' is saved']};
                        scroll(app.ListBox, 'bottom') ;
                        if app.Abort_flag==true
                            app.ListBox.Items(length(app.ListBox.Items)+1)={'Analysis is aborted!'};
                            scroll(app.ListBox, 'bottom')
                            drawnow;
                            
                            break
                        else
                        end
                    end
                else
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'SI information is not saved!'};
                    scroll(app.ListBox, 'bottom') ;
                end
                
                if app.Abort_flag==true
                    app.Abort_flag=false;
                    return
                else
                end
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['X size(pix): ',num2str(app.NeuronInformation.Framesize(1))]};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Y size(pix): ',num2str(app.NeuronInformation.Framesize(2))]};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Z size(plane): ',num2str(app.NeuronInformation.ImagingPlane)]};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Channel: ',num2str(app.NeuronInformation.ImagingChannel)]};


                %%%%%%%%%%% pre-processing the calcium data %%%%%%%%%%
                
                app.NeuronInformation.TotalCell=length(find(app.NeuronInformation.CellPossibility(:,1)==1));
                app.NeuronInformation.IsCell=find(app.NeuronInformation.CellPossibility(:,1)==1); %  tick out the cells idenfied as "Not-cell" in Suite2p
                app.NeuronInformation.ROIStat_Iscell=app.NeuronInformation.ROIStat(1,app.NeuronInformation.IsCell);
                app.NeuronInformation.ROIStat_Iscell=app.NeuronInformation.ROIStat(1,app.NeuronInformation.IsCell);
                app.NeuronActiveMatrix.EventTrain_IsCell=zeros(size(app.NeuronActiveMatrix.EventTrain,1),app.NeuronInformation.TotalCell+2);
                app.NeuronActiveMatrix.EventTrain_IsCell(:,1:2)=app.NeuronActiveMatrix.EventTrain(:,1:2);
                app.NeuronActiveMatrix.EventTrain_IsCell(:,3:app.NeuronInformation.TotalCell+2)=app.NeuronActiveMatrix.EventTrain(:,app.NeuronInformation.IsCell+2);
                app.NeuronActiveMatrix.F_raw_IsCell=zeros(size(app.NeuronActiveMatrix.F_raw,1),app.NeuronInformation.TotalCell+2);
                app.NeuronActiveMatrix.F_raw_IsCell(:,1:2)=app.NeuronActiveMatrix.F_raw(:,1:2);
                app.NeuronActiveMatrix.F_raw_IsCell(:,3:app.NeuronInformation.TotalCell+2)=app.NeuronActiveMatrix.F_raw(:,app.NeuronInformation.IsCell+2);
                app.NeuronActiveMatrix.F_neuropil_IsCell=zeros(size(app.NeuronActiveMatrix.F_neuropil,1),app.NeuronInformation.TotalCell+2);
                app.NeuronActiveMatrix.F_neuropil_IsCell(:,1:2)=app.NeuronActiveMatrix.F_neuropil(:,1:2);
                app.NeuronActiveMatrix.F_neuropil_IsCell(:,3:app.NeuronInformation.TotalCell+2)=app.NeuronActiveMatrix.F_neuropil(:,app.NeuronInformation.IsCell+2);
                
                % calculate the raw detaF/F, threadholded deconvolved event
                app.NeuronInformation.Lowpassfilter=app.options.Lowpassfilter; %Hz, smooth the noisy calcium signal
                app.NeuronInformation.Movingwindow=app.options.Movingwindow; %second, for computing the local fluorescence baseline
                app.NeuronInformation.Transientlasting=app.options.Transientlasting; %second
                app.NeuronInformation.Transientextend=app.options.Transientextend;
                app.NeuronInformation.Neuropilcorrection=app.options.Neuropilcorrection;
                app.NeuronActiveMatrix.detaF=app.NeuronActiveMatrix.F_raw_IsCell;
                app.NeuronActiveMatrix.detaF_F=app.NeuronActiveMatrix.F_raw_IsCell;
                app.NeuronActiveMatrix.detaF_F_filtered=app.NeuronActiveMatrix.F_raw_IsCell;
                app.NeuronActiveMatrix.F_zero=app.NeuronActiveMatrix.F_raw_IsCell;
                app.NeuronActiveMatrix.Significanttransient=app.NeuronActiveMatrix.F_raw_IsCell;
                app.NeuronActiveMatrix.detaF(:,3:end)=app.NeuronActiveMatrix.F_raw_IsCell(:,3:end)-app.NeuronInformation.Neuropilcorrection*app.NeuronActiveMatrix.F_neuropil_IsCell(:,3:end); % neuropil correction
                
                %calculate the focal plane information for each cell
                app.NeuronInformation.CellinPlane=zeros(app.NeuronInformation.TotalCell,1);
                app.NeuronInformation.FocalPlane=zeros(app.NeuronInformation.ImagingPlane,1);
                if ~isempty(app.NeuronInformation.header) % get the focal plane from the SI meta data
                    if app.NeuronInformation.ImagingPlane>1
                        app.NeuronInformation.FocalPlane=app.NeuronInformation.header{1,1}.SI.hStackManager.zsRelative;
                    else
                        app.NeuronInformation.FocalPlane=0; % right now just manually set the focal plane if only one plane was recorded. Should be able to read this directly from SI metadata;
                    end
                else    % set the focal plane manually
                    app.NeuronInformation.FocalPlane=[];
                end
                
                %Calcium trace pre-processing
                for i=3:1:size(app.NeuronActiveMatrix.detaF_F,2)
                    [app.NeuronActiveMatrix.detaF_F_filtered(:,i), app.NeuronActiveMatrix.detaF_F(:,i), app.NeuronActiveMatrix.F_zero(:,i), app.NeuronActiveMatrix.Significanttransient(:,i)]=preprocessing.DetaF_T_baselinecorrect(app.NeuronActiveMatrix.detaF(:,i),app.NeuronInformation.Lowpassfilter,app.NeuronInformation.Movingwindow,app.NeuronInformation.Transientlasting,app.NeuronInformation.volumerate,app.NeuronInformation.Transientextend);
                    app.ListBox.Items(length(app.ListBox.Items)+1)={['F-zero, dF/F, and significant transient calculation for neuron: ', num2str(i)]};
                    scroll(app.ListBox, 'bottom') ;
                    drawnow;
                    if app.Abort_flag==true
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'Analysis is aborted!'};
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                        break
                    else
                    end
                end
                if app.Abort_flag==true
                    app.Abort_flag=false;
                    return
                else
                end
                app.NeuronActiveMatrix.detaF_F_filtered=app.NeuronActiveMatrix.detaF_F;
                
                for i=3:1:app.NeuronInformation.TotalCell+2
                    app.NeuronActiveMatrix.detaF_F_filtered(:,i)=app.NeuronActiveMatrix.detaF_F(:,i).*app.NeuronActiveMatrix.Significanttransient(:,i); % filtered calcium trace by picking up the significnat transient.
                end
                % deconvolved calcium event filteting and scaling to df/f
                app.NeuronActiveMatrix.FilteredEvent(:,1:2)=app.NeuronActiveMatrix.detaF_F(:,1:2);
                
                for i=3:1:app.NeuronInformation.TotalCell+2
                    Event_baseline=app.NeuronActiveMatrix.Significanttransient(:,i)==0;
                    Event_uncertainty=mean(app.NeuronActiveMatrix.EventTrain_IsCell(Event_baseline,i));
                    app.NeuronActiveMatrix.FilteredEvent(:,i)=(app.NeuronActiveMatrix.EventTrain_IsCell(:,i)-Event_uncertainty).*app.NeuronActiveMatrix.Significanttransient(:,i); % only keep the deconvoled spikes within the significant transient period.
                    for j=1:1:size(app.NeuronActiveMatrix.FilteredEvent(:,i),1)
                        if app.NeuronActiveMatrix.FilteredEvent(j,i)<=0
                            app.NeuronActiveMatrix.FilteredEvent(j,i)=0;
                        else
                        end
                    end
                    % remove outlier
                    Outliers=app.NeuronActiveMatrix.FilteredEvent(:,i)>2*prctile(app.NeuronActiveMatrix.FilteredEvent(:,i),99.9);
                    app.NeuronActiveMatrix.FilteredEvent(Outliers,i)=prctile(app.NeuronActiveMatrix.FilteredEvent(:,i),99.95);
                    dF_F_MAX=max(app.NeuronActiveMatrix.detaF_F_filtered(:,i));
                    Event_MAX=max(app.NeuronActiveMatrix.FilteredEvent(:,i));
                    app.NeuronActiveMatrix.FilteredEvent(:,i)=app.NeuronActiveMatrix.FilteredEvent(:,i).*dF_F_MAX./Event_MAX;  % normalized the Event to df/f, important step!!!!!
                end
                
                % clearup bad frames (No signal)
                app.NeuronInformation.BadCell=[];
                %
                k=1;
                for i=1:1:app.NeuronInformation.TotalCell
                    if sum(app.NeuronActiveMatrix.F_raw_IsCell(:,i+2))==0
                        app.NeuronInformation.BadCell(k)=i;
                        k=k+1;
                    else
                    end
                end
                
                % Calculate the SNR and event count of each cell
                % the SNR is defined as the average of peak amptitutes (the first peak after each deconvoled Event) devides the average STD in non-transient period of the calcium trace;
                app.NeuronInformation.SNR=zeros(app.NeuronInformation.TotalCell,1);
                app.NeuronInformation.EventCount_raw=zeros(app.NeuronInformation.TotalCell,1);
                for i=1:1:app.NeuronInformation.TotalCell
                    app.NeuronInformation.SNR(i)=preprocessing.CalculateSNR(app.NeuronActiveMatrix.detaF_F(:,i+2),find(app.NeuronActiveMatrix.Significanttransient(:,i+2)==1),find(app.NeuronActiveMatrix.Significanttransient(:,i+2)==0));
                    app.NeuronInformation.EventCount_raw(i,1)=length(find(app.NeuronActiveMatrix.FilteredEvent(:,i+2)>0));
                    %         NeuronInformation.SNR(i)=preprocessing.CalculateSNR(NeuronActiveMatrix.detaF_F(:,i+2),find(NeuronActiveMatrix.Significanttransient(:,i+2)==1),find(NeuronActiveMatrix.Significanttransient(:,i+2)==0));
                    app.ListBox.Items(length(app.ListBox.Items)+1)={['Neuron ',num2str(i),' SNR: ',num2str(app.NeuronInformation.SNR(i)),' Event count:',num2str(app.NeuronInformation.EventCount_raw(i,1))]};
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                    pause(0.05);
                    if app.Abort_flag==true
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'Analysis is aborted!'};
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                        break
                    else
                    end
                end
                if app.Abort_flag==true
                    app.Abort_flag=false;
                    return
                else
                end
                
                
                %%%%%% claster the same cell in different planes %%%%%%
                
                % the method of deciding the same cell in different plane is：
                % Step1：calculat the cross-correlation of each cell pairs in two adjecent
                % plane；
                % Step2：calculate the center distance of each cell pairs in two adjecent
                % plane；
                % Step3：calculate the overlap ratio of the cell footprints
                % Step4: set the threadholds of cross-correlation, center distance and overlapping;
                % Step5: link the threadholded cell pairs to the same cell family. The
                % famility tree can cross multiplanes;
                % Step6: keep only the brightest cells in a family;
                
                for i=1:1:app.NeuronInformation.TotalCell
                    app.NeuronInformation.ROIStat_Iscell{1,i}.med(1)=mod(app.NeuronInformation.ROIStat_Iscell{1,i}.med(1),app.NeuronInformation.Framesize(1)); % recover the real cell position from the combined (tailored) data (data in combined folder)
                    app.NeuronInformation.ROIStat_Iscell{1,i}.med(2)=mod(app.NeuronInformation.ROIStat_Iscell{1,i}.med(2),app.NeuronInformation.Framesize(2)); % recover the real cell position from the combined (tailored) data (data in combined folder)
                end
                if app.NeuronInformation.ImagingPlane>1
                    for i=1:1:app.NeuronInformation.TotalCell
                        app.NeuronInformation.CellinPlane(i,1)=app.NeuronInformation.ROIStat_Iscell{i}.iplane;
                    end
                    app.NeuronInformation.CellinEachPlane=zeros(app.NeuronInformation.ImagingPlane,1);
                    for i=1:1:app.NeuronInformation.ImagingPlane
                        app.NeuronInformation.CellinEachPlane(i)=size(find(app.NeuronInformation.CellinPlane(:,1)==(i-1)),1);
                    end
                else
                    for i=1:1:app.NeuronInformation.TotalROINumber
                        app.NeuronInformation.CellinPlane(i,1)=0;
                    end
                    app.NeuronInformation.CellinEachPlane=zeros(app.NeuronInformation.ImagingPlane,1);
                    for i=1:1:app.NeuronInformation.ImagingPlane
                        app.NeuronInformation.CellinEachPlane(i)=size(find(app.NeuronInformation.CellinPlane(:,1)==(i-1)),1);
                    end
                end
                
                app.NeuronInformation.CellCrosscorrelation_betweenPlane=cell(1,app.NeuronInformation.ImagingPlane-1);
                app.NeuronInformation.CellCenter_betweenPlane=cell(1,app.NeuronInformation.ImagingPlane-1);
                app.NeuronInformation.CellOverlap_betweenPlane=cell(1,app.NeuronInformation.ImagingPlane-1);
                app.NeuronInformation.StartCell_eachPlane=preprocessing.CalculateStartFrame(app.NeuronInformation.CellinEachPlane', 1);
                if app.NeuronInformation.ImagingPlane>1
                    for i=1:1:app.NeuronInformation.ImagingPlane-1
                        app.NeuronInformation.CellCrosscorrelation_betweenPlane{1,i}=zeros(app.NeuronInformation.CellinEachPlane(i),app.NeuronInformation.CellinEachPlane(i+1));
                        app.NeuronInformation.CellCenter_betweenPlane{1,i}=zeros(app.NeuronInformation.CellinEachPlane(i),app.NeuronInformation.CellinEachPlane(i+1));
                        for j=1:1:app.NeuronInformation.CellinEachPlane(i)
                            for k=1:1:app.NeuronInformation.CellinEachPlane(i+1)
                                r=corr(smoothdata(app.NeuronActiveMatrix.F_raw_IsCell(:,app.NeuronInformation.StartCell_eachPlane(i)+j+1),'gaussian',1),smoothdata(app.NeuronActiveMatrix.F_raw_IsCell(:,app.NeuronInformation.StartCell_eachPlane(i+1)+k+1),'gaussian',1));
                                app.NeuronInformation.CellCrosscorrelation_betweenPlane{1,i}(j,k)=r;
                                app.NeuronInformation.CellCenter_betweenPlane{1,i}(j,k)=sqrt(sum((app.NeuronInformation.ROIStat_Iscell{1,app.NeuronInformation.StartCell_eachPlane(i)+j-1}.med-app.NeuronInformation.ROIStat_Iscell{1,app.NeuronInformation.StartCell_eachPlane(i+1)+k-1}.med).^2));
                                if ~isempty(app.NeuronInformation.ROIStat_Iscell{1,app.NeuronInformation.StartCell_eachPlane(i)+j-1}.ypix)&& ~isempty(app.NeuronInformation.ROIStat_Iscell{1,app.NeuronInformation.StartCell_eachPlane(i+1)+k-1}.ypix)
                                    OverLay=preprocessing.overlap([app.NeuronInformation.ROIStat_Iscell{1,app.NeuronInformation.StartCell_eachPlane(i)+j-1}.ypix;app.NeuronInformation.ROIStat_Iscell{1,app.NeuronInformation.StartCell_eachPlane(i)+j-1}.xpix],[app.NeuronInformation.ROIStat_Iscell{1,app.NeuronInformation.StartCell_eachPlane(i+1)+k-1}.ypix;app.NeuronInformation.ROIStat_Iscell{1,app.NeuronInformation.StartCell_eachPlane(i+1)+k-1}.xpix],app.NeuronInformation.Framesize);
                                else
                                    OverLay(1:2)=0;
                                end
                                app.NeuronInformation.CellOverlap_betweenPlane{1,i}(j,k)=max(OverLay(1:2));
                            end
                            app.ListBox.Items(length(app.ListBox.Items)+1)={['Search for child cell for cell number ',num2str(j),' in plane ',num2str(i)]};
                            scroll(app.ListBox, 'bottom')
                            drawnow;
                            if app.Abort_flag==true
                                app.ListBox.Items(length(app.ListBox.Items)+1)={'Analysis is aborted!'};
                                scroll(app.ListBox, 'bottom')
                                drawnow;
                                drawnow
                                break
                            else
                            end
                        end
                        if app.Abort_flag==true
                            app.Abort_flag=false;
                            return
                        else
                        end
                    end
                else
                end
                app.NeuronInformation.Celldistancethreshold=app.options.Celldistancethreshold;
                app.NeuronInformation.Cellcrosscorrelationthreshold=app.options.Cellcrosscorrelationthreshold;
                app.NeuronInformation.Celloverlapratiothreshold=app.options.Celloverlapratiothreshold;
                
                SameCellCandidate=cell(1,app.NeuronInformation.ImagingPlane-1);
                
                for i=1:1:app.NeuronInformation.ImagingPlane-1
                    [x,y]=find((app.NeuronInformation.CellCenter_betweenPlane{1,i}(:,:)<app.NeuronInformation.Celldistancethreshold).*(app.NeuronInformation.CellCrosscorrelation_betweenPlane{1,i}(:,:)>app.NeuronInformation.Cellcrosscorrelationthreshold).*(app.NeuronInformation.CellOverlap_betweenPlane{1,i}(:,:)>app.NeuronInformation.Celloverlapratiothreshold));
                    app.NeuronInformation.SameCellCandidate{1,i}=[x y];
                end
                
                app.NeuronInformation.Samecelltracking=zeros(1,app.NeuronInformation.TotalCell);
                %
                for i=1:1:app.NeuronInformation.ImagingPlane-1
                    for j=1:1:size(app.NeuronInformation.SameCellCandidate{1,i},1)
                        app.NeuronInformation.Samecelltracking(app.NeuronInformation.StartCell_eachPlane(i)+app.NeuronInformation.SameCellCandidate{1,i}(j,1)-1)=app.NeuronInformation.StartCell_eachPlane(i+1)+app.NeuronInformation.SameCellCandidate{1,i}(j,2)-1;
                    end
                end
                
                app.NeuronInformation.SamecellFamily={};
                family_number=1;
                Samecelltracking_temp=app.NeuronInformation.Samecelltracking;
                % familytree=find(Samecelltracking_temp~=0);
                % SamecellFamily_tem=zeros(2,size(familytree,2));
                % link cells to the same famaly tree in more than 2 planes
                for i=1:1:size(Samecelltracking_temp,2)
                    if Samecelltracking_temp(i)~=0
                        Mum=i;
                        child=Samecelltracking_temp(Mum);
                        family=[Mum,child];
                        while Samecelltracking_temp(child)~=0
                            family=[family,Samecelltracking_temp(child)];
                            child=Samecelltracking_temp(child);
                        end
                        app.NeuronInformation.SamecellFamily{1,family_number}=family;
                        family_number=family_number+1;
                        Samecelltracking_temp(family)=0;
                    else
                    end
                end
                %
                app.NeuronInformation.SamecellFamily_rawID=cell(size(app.NeuronInformation.SamecellFamily));
                app.NeuronInformation.SamecellFamily_Brightest=zeros(size(app.NeuronInformation.SamecellFamily));
                app.NeuronInformation.RepeatCell=[];
                for i=1:1:size(app.NeuronInformation.SamecellFamily,2)
                    for j=1:1:size(app.NeuronInformation.SamecellFamily{1,i},2)
                        app.NeuronInformation.SamecellFamily_rawID{1,i}(j)=app.NeuronInformation.IsCell(app.NeuronInformation.SamecellFamily{1,i}(j));
                        BrightnessinFamily(j)=mean(app.NeuronActiveMatrix.detaF_F(find(app.NeuronActiveMatrix.Significanttransient(:,app.NeuronInformation.SamecellFamily{1,i}(j)+2)>0),app.NeuronInformation.SamecellFamily{1,i}(j)+2));
                    end
                    [~,BrightestID]=max(BrightnessinFamily);
                    app.NeuronInformation.SamecellFamily_Brightest(i)=app.NeuronInformation.SamecellFamily{1,i}(BrightestID);
                    app.NeuronInformation.RepeatCell=[app.NeuronInformation.RepeatCell,setdiff(app.NeuronInformation.SamecellFamily{1,i},app.NeuronInformation.SamecellFamily{1,i}(BrightestID))];
                end
                app.NeuronInformation.RepeatCell=unique(app.NeuronInformation.RepeatCell);
                
                app.TotalCells.Value=app.NeuronInformation.TotalCell;
                app.Repeatedcells.Value=length(app.NeuronInformation.RepeatCell);
                drawnow;
                
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Saving NueronInformation and NeuronAcitivity files.....'};
                NeuronActiveMatrix=app.NeuronActiveMatrix;
                NeuronInformation=app.NeuronInformation;
                save ([app.NeuronInformation.File_folder_root,'\NeuronActiveMatrix.mat'],'NeuronActiveMatrix');
                save ([app.NeuronInformation.File_folder_root,'\NeuronInformation.mat'],'NeuronInformation');
                clear NeuronActiveMatrix;
                clear NeuronInformation;
                app.ListBox.Items(length(app.ListBox.Items)+1)={'NueronInformation and NeuronAcitivity files are  saved.'};
                scroll(app.ListBox, 'bottom')
            else
            end
            
            %%%%%%%%%%%%%%%%%%%% process the tarcking data %%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if strcmp(app.Analysistype.Value,'Complete pipline') || strcmp(app.Analysistype.Value,'Only Tracking')|| strcmp(app.Analysistype.Value,'MINI2P+Tracking')
  
                app.TrackingResultMatrix.DLC_files=app.DLC_files;
                % set the tracking parameters from the raw tracking video, and load in the tracking output
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Now start to anaylze tracking data....'};
                scroll(app.ListBox, 'bottom')
                drawnow;
                if iscell(app.DLC_files)
                    app.TrackingResultMatrix.SessionRAW=size(app.DLC_files,2);
                else
                    app.TrackingResultMatrix.SessionRAW=1;
                end
                
                app.TrackingResultMatrix.File_folder_raw=app.File_folder_raw;
                app.ListBox.Items(length(app.ListBox.Items)+1)={[num2str(app.TrackingResultMatrix.SessionRAW) ' DLC files to analyze.']};
                scroll(app.ListBox, 'bottom');
                drawnow;


                %%%%%% load in the DLC output %%%%%%s
                app.TrackingResultMatrix.DLCRaw=cell(1,app.TrackingResultMatrix.SessionRAW);
                app.TrackingResultMatrix.FrameineachSessionRAW=[];
                for i=1:1:app.TrackingResultMatrix.SessionRAW
                    if iscell(app.DLC_files)
                        app.TrackingResultMatrix.DLCfilename{1,i}=app.DLC_files{1,i};
                        app.ListBox.Items(length(app.ListBox.Items)+1)={['Loading DLC output: ', app.DLC_files{1,i}]};
                    else
                        app.TrackingResultMatrix.DLCfilename{1,i}=app.DLC_files;
                        app.ListBox.Items(length(app.ListBox.Items)+1)={['Loading DLC output: ', app.DLC_files]};
                    end
                    scroll(app.ListBox, 'bottom');
                    drawnow;
                    app.TrackingResultMatrix.DLCRaw{1,i}=csvread(app.TrackingResultMatrix.DLCfilename{1,i},3,0);
                    app.TrackingResultMatrix.FrameineachSessionRAW(i)=size(app.TrackingResultMatrix.DLCRaw{1,i},1);
                    app.ListBox.Items(length(app.ListBox.Items)+1)={[num2str(app.TrackingResultMatrix.FrameineachSessionRAW(i)),' are loaded']};
                    scroll(app.ListBox, 'bottom');
                    drawnow;
                end
                
                if app.options.Sessioncombined==1
                    app.TrackingResultMatrix.Sessioncombined=1;
                    app.TrackingResultMatrix.Session=1;% combined all session
                    app.ShowsessionDropDown.Items={'1'};
                    app.TrackingResultMatrix.FrameineachSession=sum(app.TrackingResultMatrix.FrameineachSessionRAW);
                    app.Totalsessions.Value=1;
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'All sessions are combined to one session for analysis!'};
                    app.ListBox.Items(length(app.ListBox.Items)+1)={['Total frames: ',num2str(app.TrackingResultMatrix.FrameineachSession)]};
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                else
                    app.TrackingResultMatrix.Sessioncombined=0;
                    app.TrackingResultMatrix.Session=app.TrackingResultMatrix.SessionRAW;
                    app.TrackingResultMatrix.FrameineachSession=app.TrackingResultMatrix.FrameineachSessionRAW;
                    app.Totalsessions.Value=app.TrackingResultMatrix.Session;
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Each session are analyzing seperately!'};
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Frames in each session: '};
                    scroll(app.ListBox, 'bottom')
                    app.ShowsessionDropDown.Items={};
                    for i=1:1:app.TrackingResultMatrix.Session
                        app.ListBox.Items(length(app.ListBox.Items)+1)={num2str(app.TrackingResultMatrix.FrameineachSession(i))};
                        app.ShowsessionDropDown.Items(length(app.ShowsessionDropDown.Items)+1)={num2str(i)};
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                    end
                end
                app.DLCfilesListBox.Items={};
                for i=1:1:app.TrackingResultMatrix.Session
                    app.DLCfilesListBox.Items(length(app.DLCfilesListBox.Items)+1)={[num2str(i),': ',num2str(app.TrackingResultMatrix.FrameineachSession(i))]};
                    scroll(app.ListBox, 'bottom');
                    drawnow;
                end
                app.DLCfilesListBox.Value=app.DLCfilesListBox.Items(app.DLCAlignedFile.Value);
                
                DLCCombined=[];
                app.TrackingResultMatrix.DLC={};
                if app.TrackingResultMatrix.Sessioncombined==1
                    for i=1:1:app.TrackingResultMatrix.SessionRAW
                        DLCCombined=[DLCCombined;app.TrackingResultMatrix.DLCRaw{1,i}];
                    end
                    app.TrackingResultMatrix.DLC{1,1}=DLCCombined;
                else
                    app.TrackingResultMatrix.DLC=app.TrackingResultMatrix.DLCRaw;
                end
                
                %%%%%% clear up the raw data by appling a filter for highly cenfitatial rate %%%%%%
                app.TrackingResultMatrix.DLCFiltered=cell(1,app.TrackingResultMatrix.Session);
                app.TrackingResultMatrix.DLCFiltered_percent=cell(1,app.TrackingResultMatrix.Session);
                for i=1:1:app.TrackingResultMatrix.Session
                    [ DLCFiltered_tem,app.TrackingResultMatrix.DLCFiltered_percent{1,i}]=preprocessing.adp_filt(app.TrackingResultMatrix.DLC{1,i});
                    % recover the size of DLC files
                    app.TrackingResultMatrix.DLCFiltered{1,i}=app.TrackingResultMatrix.DLC{1,i};
                    app.TrackingResultMatrix.DLCFiltered{1,i}(:,2:3:end)=DLCFiltered_tem(:,1:2:end);
                    app.TrackingResultMatrix.DLCFiltered{1,i}(:,3:3:end)=DLCFiltered_tem(:,2:2:end);
                end
                %
                %%%%%% set the tracking method according to the use selection %%%%%%
 
                app.TrackingResultMatrix.TrackBody=app.TrackBody.Value;
                
                
                if strcmp(app.TabHead.SelectedTab.Title,'M1')
                    app.TrackingResultMatrix.HeadTrackingfasion=1;
                    app.TrackingResultMatrix.Left_Head_X=app.Left_Head_X.Value;
                    app.TrackingResultMatrix.Left_Head_Y=app.Left_Head_Y.Value;
                    app.TrackingResultMatrix.Right_Head_X=app.Right_Head_X.Value;
                    app.TrackingResultMatrix.Right_Head_Y=app.Right_Head_Y.Value;
                elseif strcmp(app.TabHead.SelectedTab.Title,'M2')    
                    app.TrackingResultMatrix.HeadTrackingfasion=2;
                    app.TrackingResultMatrix.Anterior_Head_X=app.Anterior_Head_X.Value;
                    app.TrackingResultMatrix.Anterior_Head_Y=app.Anterior_Head_Y.Value;
                    app.TrackingResultMatrix.Posterior_Head_X=app.Posterior_Head_X.Value;
                    app.TrackingResultMatrix.Posterior_Head_Y=app.Posterior_Head_Y.Value; 
                elseif strcmp(app.TabHead.SelectedTab.Title,'M3')
                    app.TrackingResultMatrix.HeadTrackingfasion=3;
                    app.TrackingResultMatrix.Center_Head_X=app.Center_Head_X.Value;
                    app.TrackingResultMatrix.Center_Head_Y=app.Center_Head_Y.Value;
                else
                end
                   
                if strcmp(app.TabBody.SelectedTab.Title,'M1')
                    app.TrackingResultMatrix.BodyTrackingfasion=1;
                    app.TrackingResultMatrix.Anterior_Body_X=app.Anterior_Body_X.Value;
                    app.TrackingResultMatrix.Anterior_Body_Y=app.Anterior_Body_Y.Value;
                    app.TrackingResultMatrix.Posterior_Body_X=app.Posterior_Body_X.Value;
                    app.TrackingResultMatrix.Posterior_Body_Y=app.Posterior_Body_Y.Value; 
                elseif strcmp(app.TabBody.SelectedTab.Title,'M2')    
                    app.TrackingResultMatrix.BodyTrackingfasion=2;
                    app.TrackingResultMatrix.Center_Body_X=app.Center_Body_X.Value;
                    app.TrackingResultMatrix.Center_Body_Y=app.Center_Body_Y.Value;
                else
                end                   


                app.TrackingResultMatrix.SychtoMINI2P=app.options.TrackingsychedwithMINI2P;
                
                if app.TrackingResultMatrix.SychtoMINI2P==0 || strcmp(app.Analysistype.Value,'Only Tracking')
                    app.Trackingframerate.Editable=1;
                else
                    app.Trackingframerate.Editable=0;
                end
                    app.options.Trackingframerate=app.Trackingframerate.Value;
                    app.TrackingResultMatrix.Trackingframerate=app.options.Trackingframerate;

                
                app.TrackingResultMatrix.TrackingResolution=app.options.TrackingResolution; % cm/pixel
                app.TrackingResultMatrix.BoxCenter=[app.options.Centerx,app.options.Centery]; % middle of the tracking position, pixel
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Center of the tracking:' };
                app.ListBox.Items(length(app.ListBox.Items)+1)={['x: ',num2str(app.TrackingResultMatrix.BoxCenter(1)),' y: ',num2str(app.TrackingResultMatrix.BoxCenter(2))] } ;
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.TrackingResultMatrix.BodypartRaw={};
                app.TrackingResultMatrix.BodypartRaw=app.bodyparts_raw;
                app.TrackingResultMatrix.TimeStamp=cell(1,app.TrackingResultMatrix.Session);
                app.TrackingResultMatrix.DLCfilename=cell(1,app.TrackingResultMatrix.Session);
                
                
                for i=1:1:app.TrackingResultMatrix.Session
                    app.TrackingResultMatrix.TimeStamp{1,i}=double((1:1:app.TrackingResultMatrix.FrameineachSession(i))-1)./app.TrackingResultMatrix.Trackingframerate;
                    app.TrackingResultMatrix.TimeStamp{1,i}=app.TrackingResultMatrix.TimeStamp{1,i}';
                end
                
                
                for i=1:1:app.TrackingResultMatrix.Session
                    TrackingdataRaw_tem=app.TrackingResultMatrix.DLCFiltered{1,i};
                    TrackingdataRaw_tem(:,2:3:end)=(TrackingdataRaw_tem(:,2:3:end)-app.TrackingResultMatrix.BoxCenter(1))*app.TrackingResultMatrix.TrackingResolution; %change unit from pixel to cm, and center the box
                    TrackingdataRaw_tem(:,3:3:end)=-1*(TrackingdataRaw_tem(:,3:3:end)-app.TrackingResultMatrix.BoxCenter(2))*app.TrackingResultMatrix.TrackingResolution; %change unit from pixel to cm, and center the box
                    if app.TrackingResultMatrix.HeadTrackingfasion==1
                        app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadLeftposition=TrackingdataRaw_tem(:,[app.Left_Head_X.Value:app.Left_Head_Y.Value]);
                        app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadRightposition=TrackingdataRaw_tem(:,[app.Right_Head_X.Value:app.Right_Head_Y.Value]);
                    elseif app.TrackingResultMatrix.HeadTrackingfasion==2
                        app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadAnteriorposition=TrackingdataRaw_tem(:,[app.Anterior_Head_X.Value:app.Anterior_Head_Y.Value]);
                        app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadPosteriorposition=TrackingdataRaw_tem(:,[app.Posterior_Head_X.Value:app.Posterior_Head_Y.Value]);
                    elseif app.TrackingResultMatrix.HeadTrackingfasion==3
                        app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadCenterposition=TrackingdataRaw_tem(:,[app.Center_Head_X.Value:app.Center_Head_Y.Value]);
                    else
                    end
                    if app.TrackBody.Value==1
                        if app.TrackingResultMatrix.BodyTrackingfasion==1
                            app.TrackingResultMatrix.TrackingdataRaw{1,i}.BodyAnteriorposition=TrackingdataRaw_tem(:,[app.Anterior_Body_X.Value:app.Anterior_Body_Y.Value]);
                            app.TrackingResultMatrix.TrackingdataRaw{1,i}.BodyPosteriorposition=TrackingdataRaw_tem(:,[app.Posterior_Body_X.Value:app.Posterior_Body_Y.Value]);
                        elseif app.TrackingResultMatrix.BodyTrackingfasion==2
                            app.TrackingResultMatrix.TrackingdataRaw{1,i}.BodyCenterposition=TrackingdataRaw_tem(:,[app.Center_Body_X.Value:app.Center_Body_Y.Value]);
                        else
                        end
                    else
                    end
                end
                

                 %%%%%% get the head and body position and direction %%%%%%
                app.TrackingResultMatrix.PositionDirectionExtract=cell(1,app.TrackingResultMatrix.Session);
                app.TrackingResultMatrix.Speedsmooth=app.SpeedSmooth.Value;
                for i=1:1:app.TrackingResultMatrix.Session
                    app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headposition=zeros(app.TrackingResultMatrix.FrameineachSession(i),2);
                    app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headdirection=zeros(app.TrackingResultMatrix.FrameineachSession(i),1);
                    app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyposition=zeros(app.TrackingResultMatrix.FrameineachSession(i),2);
                    app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodydirection=zeros(app.TrackingResultMatrix.FrameineachSession(i),1);
                    app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headbodyangle=zeros(app.TrackingResultMatrix.FrameineachSession(i),1);
                    app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed=zeros(app.TrackingResultMatrix.FrameineachSession(i),1);
                    app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed=zeros(app.TrackingResultMatrix.FrameineachSession(i),1);
                end
                %
                
                if app.TrackingResultMatrix.HeadTrackingfasion==1
                    % calculate the headposition
                    % the center of leftear and rightear,centered in box middle
                    for i=1:1:app.TrackingResultMatrix.Session
                        app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headposition=(app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadLeftposition(:,1:2)+app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadRightposition(:,1:2))/2;
                    end
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Head position was calculated!' };
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                    % calculate the headdirection
                    % the perpendicular line to the line from leftear to right( mouse to nose), east 0,north 90,
                    for i=1:1:app.TrackingResultMatrix.Session
                        for j=1:1:app.TrackingResultMatrix.FrameineachSession(i)
                            app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headdirection(j)=mod(SpatialTuning_BNT.DirectionCalulate(app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadLeftposition(j,1),app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadLeftposition(j,2),app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadRightposition(j,1),app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadRightposition(j,2))+90,360);
                        end
                    end
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Head direction was calculated!' };
                    scroll(app.ListBox, 'bottom')
                    drawnow;
 
                elseif app.TrackingResultMatrix.HeadTrackingfasion==2
                    % calculate the headposition
                    % the center of nose and mouse,centered in box middle
                    for i=1:1:app.TrackingResultMatrix.Session
                        app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headposition=(app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadAnteriorposition(:,1:2)+app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadPosteriorposition(:,1:2))/2;
                    end
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Head position was calculated!' };
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                    % calculate the headdirection
                    % the line from mouse to nose, east 0,north 90,
                    for i=1:1:app.TrackingResultMatrix.Session
                        for j=1:1:app.TrackingResultMatrix.FrameineachSession(i)
                            app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headdirection(j)=SpatialTuning_BNT.DirectionCalulate(app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadPosteriorposition(j,1),app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadPosteriorposition(j,2),app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadAnteriorposition(j,1),app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadAnteriorposition(j,2));
                        end
                    end
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Head direction was calculated!' };
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                elseif app.TrackingResultMatrix.HeadTrackingfasion==3                    
                    for i=1:1:app.TrackingResultMatrix.Session
                        app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headposition=app.TrackingResultMatrix.TrackingdataRaw{1,i}.HeadCenterposition(:,1:2);
                    end
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Head position was calculated!' };
                    scroll(app.ListBox, 'bottom')
                    drawnow;                    
                    % set headdirection to zero
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Can not calculate head direction!!!' };
                    scroll(app.ListBox, 'bottom')
                    drawnow;                     
                else
                    
                end

                % calculate the headspeed,
                for i=1:1:app.TrackingResultMatrix.Session
                    app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed=...
                    SpatialTuning_BNT.SpeedCalculate(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headposition,...
                    1./app.TrackingResultMatrix.Trackingframerate,...
                    round(app.TrackingResultMatrix.Speedsmooth*app.TrackingResultMatrix.Trackingframerate));
                end
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head speed was calculated!' };
                scroll(app.ListBox, 'bottom')
                drawnow;  


                if app.TrackBody.Value==1
                    if app.TrackingResultMatrix.BodyTrackingfasion==1            
                        % calculate the bodyposition                        
                        for i=1:1:app.TrackingResultMatrix.Session
                            app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyposition=(app.TrackingResultMatrix.TrackingdataRaw{1,i}.BodyAnteriorposition(:,1:2)+app.TrackingResultMatrix.TrackingdataRaw{1,i}.BodyPosteriorposition(:,1:2))/2;
                        end
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'Body position was calculated!' };
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                        % calculate the bodydirection
                        for i=1:1:app.TrackingResultMatrix.Session
                            for j=1:1:app.TrackingResultMatrix.FrameineachSession(i)
                                app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodydirection(j)=SpatialTuning_BNT.DirectionCalulate(app.TrackingResultMatrix.TrackingdataRaw{1,i}.BodyPosteriorposition(j,1),app.TrackingResultMatrix.TrackingdataRaw{1,i}.BodyPosteriorposition(j,2),app.TrackingResultMatrix.TrackingdataRaw{1,i}.BodyAnteriorposition(j,1),app.TrackingResultMatrix.TrackingdataRaw{1,i}.BodyAnteriorposition(j,2));
                            end
                        end
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'Body direction was calculated!' };
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                        
                    elseif app.TrackingResultMatrix.BodyTrackingfasion==2
                        % calculate the bodyposition 
                        for i=1:1:app.TrackingResultMatrix.Session
                            app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyposition=app.TrackingResultMatrix.TrackingdataRaw{1,i}.BodyCenterposition(:,1:2);
                        end
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'Body position was calculated!' };
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'Can not calculate body direction!!!' };
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                        
                    else
                    end
                else
                end
                
                % calculate the bodyspeed
                if app.TrackBody.Value==1
                    for i=1:1:app.TrackingResultMatrix.Session
                        app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed=...
                        SpatialTuning_BNT.SpeedCalculate(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyposition,...
                        1./app.TrackingResultMatrix.Trackingframerate,...
                        round(app.TrackingResultMatrix.Speedsmooth*app.TrackingResultMatrix.Trackingframerate));
                    end
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Body speed was calculated!' };
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                else
                end
                app.TrackingResultMatrix.Report=cell(1,app.TrackingResultMatrix.Session);
                
                % calculate the statistics
                for i=1:1:app.TrackingResultMatrix.Session
                    Distance_head=SpatialTuning_BNT.DistanceCalculate(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headposition(2:end,:),app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headposition(1:end-1,:));
                    app.TrackingResultMatrix.Report{1,i}.HeadTotalDistance=sum(Distance_head);
                    Distance_body=SpatialTuning_BNT.DistanceCalculate(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyposition(2:end,:),app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyposition(1:end-1,:));
                    app.TrackingResultMatrix.Report{1,i}.BodyTotalDistance=sum(Distance_body);
                    app.TrackingResultMatrix.Report{1,i}.HeadMeanSpeed=mean(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed);
                    app.TrackingResultMatrix.Report{1,i}.BodyMeanSpeed=mean(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed);
                    app.TrackingResultMatrix.Report{1,i}.Head90thSpeed=prctile(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed,90);
                    app.TrackingResultMatrix.Report{1,i}.Body90thSpeed=prctile(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed,90);
                    
                end
                

                %%%%%% output dataset %%%%%%
                TrackingResultMatrix=app.TrackingResultMatrix;
                save ([app.TrackingResultMatrix.File_folder_raw,'\TrackingResultMatrix.mat'],'TrackingResultMatrix');
                clear TrackingResultMatrix;
                app.TotalframesTracking.Value=length(app.TrackingResultMatrix.PositionDirectionExtract{1,1}.headspeed);
                drawnow;
                app.TotalTimesTracking.Value=length(app.TrackingResultMatrix.PositionDirectionExtract{1,1}.headspeed)./app.TrackingResultMatrix.Trackingframerate;
                drawnow;
                app.StartTimeTracking.Value=0;
                app.EndTimeTracking.Value=app.TotalTimesTracking.Value;
                
                if strcmp(app.ShowpartDropDown.Value,'Head')
                    app.Totaldistance.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.HeadTotalDistance;
                    app.Meanspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.HeadMeanSpeed;
                    app.Highspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.Head90thSpeed;
                else
                    app.Totaldistance.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.BodyTotalDistance;
                    app.Meanspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.BodyMeanSpeed;
                    app.Highspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.Body90thSpeed;
                end
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={'tracking  analysis was finished, tracking data was safed!'};
                scroll(app.ListBox, 'bottom')
                drawnow;
            else
            end
            
            
            if strcmp(app.Analysistype.Value,'Complete pipline')
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Now start to generate NAT....'};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                %% generate NAT
                NAT=struct;
                ExperimentInformation=struct;
                ExperimentInformation.Session=app.TrackingResultMatrix.Session; % how many session in one recording
                ExperimentInformation.TrackingResolution=app.TrackingResultMatrix.TrackingResolution;  % mm/pixel
                ExperimentInformation.BoxCenter=app.TrackingResultMatrix.BoxCenter;
                ExperimentInformation.RawDataAddress=app.NeuronInformation.File_folder_root;
                ExperimentInformation.Trackingframerate=app.TrackingResultMatrix.Trackingframerate;
                ExperimentInformation.FrameinEachSession=app.TrackingResultMatrix.FrameineachSession;
                ExperimentInformation.TimeStamp=app.TrackingResultMatrix.TimeStamp;
                ExperimentInformation.DLCfilename=app.TrackingResultMatrix.DLCfilename;
                ExperimentInformation.HeadTrackingfasion=app.TrackingResultMatrix.HeadTrackingfasion;
                ExperimentInformation.BodydTrackingfasion=app.TrackingResultMatrix.BodyTrackingfasion;
                ExperimentInformation.BodypartRaw=app.TrackingResultMatrix.BodypartRaw;
                ExperimentInformation.ImagingChannel=app.NeuronInformation.ImagingChannel;
                ExperimentInformation.ImagingPlane=double(app.NeuronInformation.ImagingPlane);
                ExperimentInformation.TotalCell=app.NeuronInformation.TotalCell;
                ExperimentInformation.IsCell=app.NeuronInformation.IsCell;
                ExperimentInformation.CellinPlane=app.NeuronInformation.CellinPlane;
                ExperimentInformation.CellinEachPlan=app.NeuronInformation.CellinEachPlane;
                ExperimentInformation.CellSNR=app.NeuronInformation.SNR;
                ExperimentInformation.EventCount_raw=app.NeuronInformation.EventCount_raw;
                ExperimentInformation.CellStat=app.NeuronInformation.ROIStat_Iscell;
                ExperimentInformation.FocalPlane=app.NeuronInformation.FocalPlane;
                ExperimentInformation.Framesize=app.NeuronInformation.Framesize;
                ExperimentInformation.ImagingOptions=app.NeuronInformation.Options;
                ExperimentInformation.RepeatCell=app.NeuronInformation.RepeatCell;
                ExperimentInformation.Headpositioncorrection=app.Headpositioncorrection.Value; % head position correction, >0 is the tracking position are ears
                ExperimentInformation.Sessioncombined=app.TrackingResultMatrix.Sessioncombined;
                
                NAT=cell(1,ExperimentInformation.Session);
                
                %% calculate the frames without tracking (head-fixed experiment)
                
                ExperimentInformation.Frames_DLC_max=sum(ExperimentInformation.FrameinEachSession(app.DLCAlignedFile.Value:end));
                ExperimentInformation.Frames_suite2p_max=sum(ExperimentInformation.ImagingOptions.frames_per_file(app.Suite2PAlignedFile.Value:end));
                
                
                ExperimentInformation.Suite2P_startframe=sum(ExperimentInformation.ImagingOptions.frames_per_file(1:(app.Suite2PAlignedFile.Value-1)))+1;
                ExperimentInformation.DLC_startframe=sum(ExperimentInformation.FrameinEachSession(1:(app.DLCAlignedFile.Value-1)))+1;
                
                
                ExperimentInformation.Frames_Suite2P=min(ExperimentInformation.Frames_DLC_max./double(ExperimentInformation.ImagingPlane),ExperimentInformation.Frames_suite2p_max);
                ExperimentInformation.Frames_DLC=min(ExperimentInformation.Frames_suite2p_max.*double(ExperimentInformation.ImagingPlane),ExperimentInformation.Frames_DLC_max);
                
                
                ExperimentInformation.Suite2P_filetaken=0;
                ExperimentInformation.Suite2P_frametaken=0;
                while ExperimentInformation.Suite2P_frametaken<ExperimentInformation.Frames_Suite2P
                    ExperimentInformation.Suite2P_frametaken=ExperimentInformation.Suite2P_frametaken+ExperimentInformation.ImagingOptions.frames_per_file(app.Suite2PAlignedFile.Value+ExperimentInformation.Suite2P_filetaken);
                    ExperimentInformation.Suite2P_filetaken=ExperimentInformation.Suite2P_filetaken+1;
                end


                ExperimentInformation.DLC_filetaken=0;
                ExperimentInformation.DLC_frametaken=0;
                while ExperimentInformation.DLC_frametaken<ExperimentInformation.Frames_DLC
                    ExperimentInformation.DLC_frametaken=ExperimentInformation.DLC_frametaken+ExperimentInformation.FrameinEachSession(app.DLCAlignedFile.Value+ExperimentInformation.DLC_filetaken);
                    ExperimentInformation.DLC_filetaken=ExperimentInformation.DLC_filetaken+1;
                end
                
                
                ExperimentInformation.Suite2PAlignedFile=app.options.Suite2PAlignedFile;
                ExperimentInformation.DLCAlignedFile=app.options.DLCAlignedFile;
                
                ExperimentInformation.Suite2P_Endframe=ExperimentInformation.Suite2P_startframe+ExperimentInformation.Frames_Suite2P-1;
                ExperimentInformation.DLC_Endframe=ExperimentInformation.DLC_startframe+ExperimentInformation.Frames_DLC-1;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Total Suite2p frames taken for generating NAT: ',num2str(ExperimentInformation.Frames_Suite2P)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Total Suite2p files taken for generating NAT: ',num2str(ExperimentInformation.Suite2P_filetaken)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Suite2p start frame: ',num2str(ExperimentInformation.Suite2P_startframe)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Suite2p end frame: ',num2str(ExperimentInformation.Suite2P_Endframe)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Total DLC frames taken for generating NAT: ',num2str(ExperimentInformation.Frames_DLC)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Total DLC files taken for generating NAT: ',num2str(ExperimentInformation.DLC_filetaken)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['DLC start frame: ',num2str(ExperimentInformation.DLC_startframe)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['DLC end frame: ',num2str(ExperimentInformation.DLC_Endframe)]};
                scroll(app.ListBox, 'bottom')
                drawnow
                
                
                app.Suite2pselectedListBox.Items={};
                app.DLCselectedListBox.Items={};
                
                for i=1:1:ExperimentInformation.Suite2P_filetaken
                    app.Suite2pselectedListBox.Items(length(app.Suite2pselectedListBox.Items)+1)=app.Suite2PfilesListBox.Items(app.Suite2PAlignedFile.Value+i-1);
                end
                
                for i=1:1:ExperimentInformation.DLC_filetaken
                    app.DLCselectedListBox.Items(length(app.DLCselectedListBox.Items)+1)=app.DLCfilesListBox.Items(app.DLCAlignedFile.Value+i-1);
                end
                %
                %colume 1:  timestamp
                %colume 2:3 headposition
                %colume 4:  headdirection
                %colume 5:  headspeed
                %colume 6:  headvilid
                %colume 7:8 bodyposition
                %colume 9:  bodydirection
                %colume 10:  bodyspeed
                %colume 11:  bodyvilid
                %colume 12: reserved
                %colume 13:4:13+N*4-1 neuron detaF/F_raw, detaF/F_filtered,
                %detaF_F_significant and deconvoled signal
                
                
                ConfidenceThreadhold=0.5;
                ExperimentInformation.Session_taken=ExperimentInformation.DLC_filetaken;
                
                for k=1:1:ExperimentInformation.Session_taken
                    i=app.DLCAlignedFile.Value+k-1;
                    NAT{1,i}=NaN(ExperimentInformation.FrameinEachSession(i),13+ExperimentInformation.TotalCell*4-1);
                    NAT{1,i}(:,1)=ExperimentInformation.TimeStamp{i};
                    NAT{1,i}(:,2:3)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headposition;
                    NAT{1,i}(:,4)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headdirection;
                    NAT{1,i}(:,5)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed;
                    NAT{1,i}(:,7:8)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyposition;
                    NAT{1,i}(:,9)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodydirection;
                    NAT{1,i}(:,10)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed;
                    
%                     if ExperimentInformation.Trackingfasion==1
                            for j=1:1:ExperimentInformation.FrameinEachSession(i)
                                NAT{1,i}(j,6)=1;
                                NAT{1,i}(j,11)=1;
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,4)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,7)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,6)=0;
%                             else
%                                 NAT{1,i}(j,6)=1;
%                             end
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,22)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,25)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,11)=0;
%                             else
%                                 NAT{1,i}(j,11)=1;
%                             end
                            end
%                     elseif ExperimentInformation.Trackingfasion==2
%                         for j=1:1:ExperimentInformation.FrameinEachSession(i)
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,7)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,10)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,6)=0;
%                             else
%                                 NAT{1,i}(j,6)=1;
%                             end
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,13)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,15)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,11)=0;
%                             else
%                                 NAT{1,i}(j,11)=1;
%                             end
%                         end
%                     elseif ExperimentInformation.Trackingfasion==3
%                         for j=1:1:ExperimentInformation.FrameinEachSession(i)
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,16)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,19)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,6)=0;
%                             else
%                                 NAT{1,i}(j,6)=1;
%                             end
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,22)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,25)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,11)=0;
%                             else
%                                 NAT{1,i}(j,11)=1;
%                             end
%                         end
%                     else
%                     end
                    
                    %Old
                    %NAT{1,i}(:,2)=circshift(NAT{1,i}(:,2),-1*ExperimentInformation.Headpositioncorrection);
                    %NAT{1,i}(:,3)=circshift(NAT{1,i}(:,3),-1*ExperimentInformation.Headpositioncorrection);
                    %NAT{1,i}(:,5)=circshift(NAT{1,i}(:,5),-1*ExperimentInformation.Headpositioncorrection);
                    
                    %Head position correction according to input of distance to true (animal experienced) position 
                    %new_x = x + (distanceInput * cos(degrees*pi/180))
                    %new_y = y + (distanceInput * sin(degrees*pi/180))
                    
                    NAT{1,i}(:,2)= NAT{1,i}(:,2) + (ExperimentInformation.Headpositioncorrection * cos(NAT{1,i}(:,4) * pi/180));
                    NAT{1,i}(:,3)= NAT{1,i}(:,3) + (ExperimentInformation.Headpositioncorrection * sin(NAT{1,i}(:,4) * pi/180));


                    g=app.Suite2PAlignedFile.Value+k-1;
                    Startframe_suite2p=sum(ExperimentInformation.ImagingOptions.frames_per_file(1:app.Suite2PAlignedFile.Value+k-2))+1;
                    
                    
                    for j=1:1:ExperimentInformation.TotalCell
                        NAT{1,i}(ExperimentInformation.CellinPlane(j)+1:ExperimentInformation.ImagingPlane:end,9+j*4)=app.NeuronActiveMatrix.detaF_F(Startframe_suite2p:...
                            Startframe_suite2p+ExperimentInformation.FrameinEachSession(i)./ExperimentInformation.ImagingPlane-1,j+2);
                        
                        NAT{1,i}(ExperimentInformation.CellinPlane(j)+1:ExperimentInformation.ImagingPlane:end,10+j*4)=app.NeuronActiveMatrix.detaF_F_filtered(Startframe_suite2p:...
                            Startframe_suite2p+ExperimentInformation.FrameinEachSession(i)./ExperimentInformation.ImagingPlane-1,j+2);
                        
                        NAT{1,i}(ExperimentInformation.CellinPlane(j)+1:ExperimentInformation.ImagingPlane:end,11+j*4)=app.NeuronActiveMatrix.Significanttransient(Startframe_suite2p:...
                            Startframe_suite2p+ExperimentInformation.FrameinEachSession(i)./ExperimentInformation.ImagingPlane-1,j+2);
                        
                        NAT{1,i}(ExperimentInformation.CellinPlane(j)+1:ExperimentInformation.ImagingPlane:end,12+j*4)=app.NeuronActiveMatrix.FilteredEvent(Startframe_suite2p:...
                            Startframe_suite2p+ExperimentInformation.FrameinEachSession(i)./ExperimentInformation.ImagingPlane-1,j+2);
                    end
                end
                app.ExperimentInformation=ExperimentInformation;
                app.NAT=NAT;
                %
                %%%%%% output NAT and experimentinformation %%%%%%
                app.ListBox.Items(length(app.ListBox.Items)+1)={'NAT was generated!'};
                scroll(app.ListBox, 'bottom')
                drawnow
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Now saving NAT, please wait....'};
                scroll(app.ListBox, 'bottom')
                drawnow
                save ([app.ExperimentInformation.RawDataAddress,'\NAT.mat'],'NAT','-v7.3');
                save ([app.ExperimentInformation.RawDataAddress,'\ExperimentInformation.mat'],'ExperimentInformation','-v7.3');
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={'NAT was saved!'};
                scroll(app.ListBox, 'bottom')
                drawnow          
            else
            end
            
        end

        % Value changed function: Sessioncombined
        function SessioncombinedValueChanged(app, event)

            if app.Sessioncombined.Value==1
                app.ListBox.Items(length(app.ListBox.Items)+1)={'sessions are combined for analysis.'};
            else
                app.ListBox.Items(length(app.ListBox.Items)+1)={'sessions are seperated for analysis.'};
            end
            scroll(app.ListBox, 'bottom') ;
        end

        % Value changed function: TrackingsychedwithMINI2P
        function TrackingsychedwithMINI2PValueChanged(app, event)

            if app.TrackingsychedwithMINI2P.Value==1
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Tracking was synchronized with MINI2P imaging!'};
                app.Trackingframerate.Editable=0;
                scroll(app.ListBox, 'bottom')
            else
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Tracking was not synchronized with MINI2P imaging!'};
                app.Trackingframerate.Editable=1;
                scroll(app.ListBox, 'bottom')
            end
        end

        % Value changed function: Analysistype
        function AnalysistypeValueChanged(app, event)
         
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Analysis trpe: ',app.Analysistype.Value]};
            scroll(app.ListBox, 'bottom')
        end

        % Value changed function: ShowsessionDropDown
        function ShowsessionDropDownValueChanged(app, event)
            app.TotalframesTracking.Value=length(app.TrackingResultMatrix.PositionDirectionExtract{1,str2double(app.ShowsessionDropDown.Value)}.headspeed);
            drawnow;
            app.TotalTimesTracking.Value=length(app.TrackingResultMatrix.PositionDirectionExtract{1,str2double(app.ShowsessionDropDown.Value)}.headspeed)./app.TrackingResultMatrix.Trackingframerate;
            drawnow;
            app.StartTimeTracking.Value=0;
            app.EndTimeTracking.Value=app.TotalTimesTracking.Value;
            
            if strcmp(app.ShowpartDropDown.Value,'Head')
                app.Totaldistance.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.HeadTotalDistance;
                app.Meanspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.HeadMeanSpeed;
                app.Highspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.Head90thSpeed;
            else
                app.Totaldistance.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.BodyTotalDistance;
                app.Meanspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.BodyMeanSpeed;
                app.Highspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.Body90thSpeed;
            end
        end

        % Value changed function: ShowpartDropDown
        function ShowpartDropDownValueChanged(app, event)
            if strcmp(app.ShowpartDropDown.Value,'Head')
                app.Totaldistance.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.HeadTotalDistance;
                app.Meanspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.HeadMeanSpeed;
                app.Highspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.Head90thSpeed;
            else
                app.Totaldistance.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.BodyTotalDistance;
                app.Meanspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.BodyMeanSpeed;
                app.Highspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.Body90thSpeed;
            end
        end

        % Button pushed function: ShowtrajectoryButton
        function ShowtrajectoryButtonPushed(app, event)
            
            app.FigTracking=figure('Name',['Trajectory, ', app.ShowpartDropDown.Value,', speed-min: ', num2str(app.Speedthresholdmin.Value),'cm/s, speed-max: ', num2str(app.Speedthresholdmax.Value),'cm/s',', start time: ', num2str(app.StartTimeTracking.Value),'s, end time: ', num2str(app.EndTimeTracking.Value),'s' ],'NumberTitle','off');
            Min_speed=app.Speedthresholdmin.Value;
            Max_speed=app.Speedthresholdmax.Value;
            StartTime=app.StartTimeTracking.Value;
            EndTime=app.EndTimeTracking.Value;
            
            i=str2double(app.ShowsessionDropDown.Value);
            Range_min=app.PlotRange_min.Value;
            Range_max=app.PlotRange_max.Value;
            if strcmp(app.ShowpartDropDown.Value,'Head')
                SelectFrame=find((app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed>Min_speed).*(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed<Max_speed).*...
                    (app.TrackingResultMatrix.TimeStamp{1,i}>=StartTime).*(app.TrackingResultMatrix.TimeStamp{1,i}<=EndTime)==1);
                PositionTrain= app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headposition(SelectFrame,:);
                SpeedTrain= app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed(SelectFrame);
                DirectionTrain= app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headdirection(SelectFrame);
            else
                SelectFrame=find((app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed>Min_speed).*(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed<Max_speed).*...
                    (app.TrackingResultMatrix.TimeStamp{1,i}>=StartTime).*(app.TrackingResultMatrix.TimeStamp{1,i}<=EndTime)==1);
                PositionTrain= app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyposition(SelectFrame,:);
                SpeedTrain= app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed(SelectFrame);
                DirectionTrain= app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodydirection(SelectFrame);
            end
            if strcmp(app.TrajectoryplottypeDropDown.Value,'Position')
                plot(PositionTrain(:,1),PositionTrain(:,2),'Color',[0.3 0.3 0.3],'LineWidth',1);
                app.TrackPlotHandle=gca;
            elseif strcmp(app.TrajectoryplottypeDropDown.Value,'Speed')
                surf([PositionTrain(:,1),PositionTrain(:,1)],[PositionTrain(:,2),PositionTrain(:,2)],[SpeedTrain(:), SpeedTrain(:)],'FaceColor', 'none','EdgeColor', 'interp','LineWidth', 1,'EdgeAlpha',0.6);
                if strcmp(app.TrackingandtuningcolormapDropDown.Value,'jet')
                    CMP=jet(256);
                elseif strcmp(app.TrackingandtuningcolormapDropDown.Value,'parula')
                    CMP=parula(256);
                elseif strcmp(app.TrackingandtuningcolormapDropDown.Value,'inferno')
                    CMP=CMP_WJ.inferno(256);
                elseif strcmp(app.TrackingandtuningcolormapDropDown.Value,'hot')
                    CMP=hot(256);
                else
                end
                app.TrackPlotHandle=gca;
                colormap(app.TrackPlotHandle,CMP);
                caxis([app.ColorRange_min.Value app.ColorRange_max.Value]);
                app.TrackColorbarHandle=colorbar;
                app.TrackColorbarHandle.Label.String = 'Speed (cm/s)';
                view(2);
            elseif strcmp(app.TrajectoryplottypeDropDown.Value,'Direction')
                surf([PositionTrain(:,1),PositionTrain(:,1)],[PositionTrain(:,2),PositionTrain(:,2)],[SpeedTrain(:), DirectionTrain(:)],'FaceColor', 'none','EdgeColor', 'interp','LineWidth', 1,'EdgeAlpha',0.6);
                CMP=hsv(256);
                app.TrackPlotHandle=gca;
                colormap(app.TrackPlotHandle,CMP);
                caxis([0 360]);
                app.TrackColorbarHandle=colorbar;
                app.TrackColorbarHandle.Label.String = 'Direction (degree)';
                view(2);
            else
            end
            if strcmp(app.BackgroundDropDown.Value,'White')
                set(app.TrackPlotHandle,'Color',[1 1 1]);
                set(app.TrackPlotHandle,'XColor',[0 0 0]);
                set(app.TrackPlotHandle,'YColor',[0 0 0]);
                if ~strcmp(app.TrajectoryplottypeDropDown.Value,'Position')
                    set(app.TrackColorbarHandle,'XColor',[0 0 0]);
                else
                end
                set(app.FigTracking,'Color',[1 1 1]);
                xlabel(app.TrackPlotHandle,'X (cm)');
                ylabel(app.TrackPlotHandle,'Y (cm)');
                if strcmp(app.ShowpartDropDown.Value,'Head')
                    title(app.TrackPlotHandle,'Animal trajectory (head)','Color',[0 0 0]);
                else
                    title(app.TrackPlotHandle,'Animal trajectory (body)','Color',[0 0 0]);
                end
            elseif strcmp(app.BackgroundDropDown.Value,'Black')
                set(app.TrackPlotHandle,'Color',[0 0 0]);
                set(app.TrackPlotHandle,'XColor',[1 1 1]);
                set(app.TrackPlotHandle,'YColor',[1 1 1]);
                if ~strcmp(app.TrajectoryplottypeDropDown.Value,'Position')
                    set(app.TrackColorbarHandle,'XColor',[1 1 1]);
                else
                end
                set(app.FigTracking,'Color',[0 0 0]);
                xlabel(app.TrackPlotHandle,'X (cm)');
                ylabel(app.TrackPlotHandle,'Y (cm)');
                if strcmp(app.ShowpartDropDown.Value,'Head')
                    title(app.TrackPlotHandle,'Animal trajectory (head)','Color',[1 1 1]);
                else
                    title(app.TrackPlotHandle,'Animal trajectory (body)','Color',[1 1 1]);
                end
            else
            end
            
            
            xlim(app.TrackPlotHandle,[Range_min Range_max]);
            ylim(app.TrackPlotHandle,[Range_min Range_max]);
            set(gca,'TickDir','out');
            daspect([1 1 1]);
            grid off
            box on


        end

        % Button pushed function: ShowdirectionplotButton
        function ShowdirectionplotButtonPushed(app, event)
            
            app.FigDirection=figure('Name',[app.ShowpartDropDown.Value,' direction, start time: ', num2str(app.StartTimeTracking.Value),'s, end time: ', num2str(app.EndTimeTracking.Value),'s',' A-bin: ',num2str(app.Directionbinsize.Value),', A-smooth: ',num2str(app.Directionsmoothing.Value), ],'NumberTitle','off');
            Min_speed=app.Speedthresholdmin.Value;
            Max_speed=app.Speedthresholdmax.Value;
            StartTime=app.StartTimeTracking.Value;
            EndTime=app.EndTimeTracking.Value;
            i=str2double(app.ShowsessionDropDown.Value);
            if strcmp(app.ShowpartDropDown.Value,'Head')
                SelectFrame=find((app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed>Min_speed).*(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed<Max_speed).*...
                    (app.TrackingResultMatrix.TimeStamp{1,i}>=StartTime).*(app.TrackingResultMatrix.TimeStamp{1,i}<=EndTime)==1);
                DirectionTrain= app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headdirection(SelectFrame);
            else
                SelectFrame=find((app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed>Min_speed).*(app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed<Max_speed).*...
                    (app.TrackingResultMatrix.TimeStamp{1,i}>=StartTime).*(app.TrackingResultMatrix.TimeStamp{1,i}<=EndTime)==1);
                DirectionTrain= app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodydirection(SelectFrame);
            end
            AngularTuning=SpatialTuning_BNT.turningCurve(DirectionTrain,DirectionTrain,1/app.Trackingframerate.Value,'binWidth',double(app.Directionbinsize.Value));
            Occupancy=preprocessing.circularSmooth(AngularTuning(:,3),double(app.Directionsmoothing.Value));
            MaxTime=max(Occupancy);
            Hj=polarplot([AngularTuning(:,1)/180*pi;AngularTuning(1,1)/180*pi],[Occupancy;Occupancy(1)],'color',[0 0 0],'LineWidth',1.5);
            Ax = gca;
            Ax.RTick = [];
            Ax.RTickLabel = [];
            Ax.ThetaTickLabel = [];
            Ax.ThetaTick  = [0 90 180 270];
            Ax.RLim=[0 MaxTime];
            AX.FontSize=0;
            Ax.ThetaAxis.Color = [1 1 1];
            AX.RColor = [1 1 1];
            set(gca,'GridColor',[0 0 0]);
            set(gca,'GridAlpha',1);
            set(gca,'RGrid','off');
            set(Hj,'LineWidth',1);
            AX.RAxis.Visible='off';
            title([app.ShowpartDropDown.Value,' direction occupancy, max: ',num2str(MaxTime,'%.2f'),' s'])
            box off


        end

        % Button pushed function: ShowactivitytracesButton
        function ShowactivitytracesButtonPushed(app, event)
            StartTime=app.Starttime.Value;
            EndTime=StartTime+app.Window.Value;
            Xrange(1)=StartTime;
            Xrange(2)=EndTime;
            
            
            if  strcmp(app.TabGroup.SelectedTab.Title,'Range')
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Select cell by a range'};
                scroll(app.ListBox, 'bottom')
                drawnow
                app.SelectCell=[app.Startcell.Value: app.Startcell.Value+app.Cellstocheck.Value-1];
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Selected cell ID: ',num2str(app.SelectCell(1)),' to ',num2str(app.SelectCell(end))]};
                scroll(app.ListBox, 'bottom')
                drawnow
            else
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Select cell by arbitrary user input'};
                scroll(app.ListBox, 'bottom')
                drawnow
                Comma=strfind(app.CellID.Value,'	');
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,',');
                else
                end
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,';');
                else
                end
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,' ');
                else
                end
                Num=length(Comma);
                app.SelectCell=zeros(1,Num+1);
                for i=2:1:Num
                    app.SelectCell(i)=str2double(app.CellID.Value(Comma(i-1)+1:Comma(i)-1));
                end
                app.SelectCell(1)=str2double(app.CellID.Value(1:Comma(1)-1));
                app.SelectCell(Num+1)=str2double(app.CellID.Value(Comma(Num)+1:end));
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Selected cell ID: ',num2str(app.SelectCell)]};
                scroll(app.ListBox, 'bottom')
                drawnow
            end
            
            
            figure ('Name',['Neuronal activity, start time: ',num2str(app.Starttime.Value),'s, time length: ',num2str(app.Window.Value),'s, offset: ',num2str(app.Offset.Value)],'NumberTitle','off')
            app.ListBox.Items(length(app.ListBox.Items)+1)={''};
            if strcmp(app.TracetypeDropDown.Value,'Only dF/F')
                m=1;
                for i=app.SelectCell
                    ID=i;
                    plot(app.NeuronActiveMatrix.F_raw_IsCell(:,1),smooth((m-1)*app.Offset.Value+app.NeuronActiveMatrix.detaF_F(:,ID+2),app.Smoothforshow.Value),'color',[0 0 0],'Linewidth',1.5);
                    hold on
                    text(Xrange(1)-20,(m-1)*app.Offset.Value,[num2str(ID),' (',num2str(app.NeuronInformation.IsCell(ID)),')'],'FontSize',7);
                    text(Xrange(2)+5,(m-1)*app.Offset.Value,num2str(app.NeuronInformation.SNR(ID),'%.1f'),'FontSize',7);
                    m=m+1;
                    app.ListBox.Items(length(app.ListBox.Items))={['Plot cell ',num2str(i)]};
                    pause (0.01)
                    drawnow;
                end
                hold off
                ylim([app.Cellstocheck.Value*app.Offset.Value -1*app.Offset.Value*3]);
                if strcmp(app.TabGroup.SelectedTab.Title,'Range')
                    title(['dF/F for cell ',num2str(app.SelectCell(1)),' to cell ',num2str(app.SelectCell(end))]);
                else
                    title(['dF/F for cells:  ',num2str(app.SelectCell)]);
                end
                
            elseif strcmp(app.TracetypeDropDown.Value,'dF/F with significant transient')
                CMP=[0 0 0; 1 0 0];
                m=1;
                for i=app.SelectCell
                    if app.Abort_flag==true
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'Analysis is aborted!'};
                        scroll(app.ListBox, 'bottom')
                        app.Abort_flag=false;
                        drawnow;
                        break
                    else
                    end
                    ID=i;
                    X=app.NeuronActiveMatrix.F_raw_IsCell(:,1);
                    Y=smooth((m-1)*app.Offset.Value+app.NeuronActiveMatrix.detaF_F(:,ID+2),app.Smoothforshow.Value);
                    C=app.NeuronActiveMatrix.Significanttransient(:,ID+2);
                    surf([X,X],[Y,Y],[C,C],'FaceColor', 'none','EdgeColor', 'interp','LineWidth', 1.5,'EdgeAlpha',0.9);
                    colormap(gca,CMP);
                    caxis([0 1]);
                    view(2);
                    text(Xrange(1)-20,(m-1)*app.Offset.Value,[num2str(ID),' (',num2str(app.NeuronInformation.IsCell(ID)),')'],'FontSize',7);
                    text(Xrange(2)+5,(m-1)*app.Offset.Value,num2str(app.NeuronInformation.SNR(ID),'%.1f'),'FontSize',7);
                    m=m+1;
                    app.ListBox.Items(length(app.ListBox.Items))={['Plot cell ',num2str(i)]};
                    pause (0.01)
                    drawnow;
                    hold on
                    
                end
                hold off
                ylim([app.Cellstocheck.Value*app.Offset.Value -1*app.Offset.Value*3]);
                if strcmp(app.TabGroup.SelectedTab.Title,'Range')
                    title(['dF/F with significant transient for cell ',num2str(app.SelectCell(1)),' to cell ',num2str(app.SelectCell(end))]);
                else
                    title(['dF/F with significant transient for cells:  ',num2str(app.SelectCell)]);
                end
            elseif strcmp(app.TracetypeDropDown.Value,'Only deconvolved signal')
                CMP=[0 0 0; 1 0 0];
                m=1;
                for i=app.SelectCell
                    if app.Abort_flag==true
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'Analysis is aborted!'};
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                        app.Abort_flag=false;
                        break
                    else
                    end
                    ID=i;
                    X=app.NeuronActiveMatrix.F_raw_IsCell(:,1);
                    Y=(m-1)*app.Offset.Value+app.NeuronActiveMatrix.FilteredEvent(:,ID+2);
                    plot(X,Y,'color',[0 0 0],'Linewidth',1.5);
                    text(Xrange(1)-20,(m-1)*app.Offset.Value,[num2str(ID),' (',num2st(app.NeuronInformation.IsCell(ID)),')'],'FontSize',7);
                    text(Xrange(2)+5,(m-1)*app.Offset.Value,num2str(app.NeuronInformation.SNR(ID),'%.1f'),'FontSize',7);
                    m=m+1;
                    app.ListBox.Items(length(app.ListBox.Items))={['Plot cell ',num2str(i)]};
                    pause (0.01)
                    drawnow;
                    hold on
                end
                hold off
                ylim([app.Cellstocheck.Value*app.Offset.Value -1*app.Offset.Value*3]);
                if strcmp(app.TabGroup.SelectedTab.Title,'Range')
                    title(['Deconvolved signal for cell ',num2str(app.SelectCell(1)),' to cell ',num2str(app.SelectCell(end))]);
                else
                    title(['Deconvolved signal for cells:  ',num2str(app.SelectCell)]);
                end
            elseif strcmp(app.TracetypeDropDown.Value,'dF/F with deconvolved signal')
                CMP=[0 0 0; 1 0 0];
                m=1;
                for i=app.SelectCell
                    if app.Abort_flag==true
                        app.ListBox.Items(length(app.ListBox.Items)+1)={'Analysis is aborted!'};
                        scroll(app.ListBox, 'bottom')
                        drawnow;
                        app.Abort_flag=false;
                        break
                    else
                    end
                    ID=i;
                    X=app.NeuronActiveMatrix.F_raw_IsCell(:,1);
                    Y=smooth((m-1)*app.Offset.Value+app.NeuronActiveMatrix.detaF_F(:,ID+2),app.Smoothforshow.Value);
                    Y2=(m-1)*app.Offset.Value+app.NeuronActiveMatrix.FilteredEvent(:,ID+2);
                    C=app.NeuronActiveMatrix.Significanttransient(:,ID+2);
                    surf([X,X],[Y,Y],[C,C],'FaceColor', 'none','EdgeColor', 'interp','LineWidth', 1.5,'EdgeAlpha',0.9);
                    colormap(gca,CMP);
                    caxis([0 1]);
                    view(2);
                    text(Xrange(1)-20,(m-1)*app.Offset.Value,[num2str(ID),' (',num2str(app.NeuronInformation.IsCell(ID)),')'],'FontSize',7);
                    text(Xrange(2)+5,(m-1)*app.Offset.Value,num2str(app.NeuronInformation.SNR(ID),'%.1f'),'FontSize',7);
                    m=m+1;
                    app.ListBox.Items(length(app.ListBox.Items))={['Plot cell ',num2str(i)]};
                    pause (0.01)
                    drawnow;
                    hold on
                    plot(X,Y2,'color',[0 0 1],'Linewidth',1.5);
                    
                end
                hold off
                ylim([app.Cellstocheck.Value*app.Offset.Value -1*app.Offset.Value*3]);
                if strcmp(app.TabGroup.SelectedTab.Title,'Range')
                    title(['dF/F with deconvolved signal signal for cell ',num2str(app.SelectCell(1)),' to cell ',num2str(app.SelectCell(end))]);
                else
                    title(['dF/F with deconvolved signal signal for cells:  ',num2str(app.SelectCell)]);
                end
            end
            
            
            xlim([Xrange]);
            box off
            axis off
            
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.Totalsessions.Value=app.TrackingResultMatrix.Session;
            app.TotalframesTracking.Value=length(app.TrackingResultMatrix.PositionDirectionExtract{1,str2double(app.ShowsessionDropDown.Value)}.headspeed);
            drawnow;
            app.TotalTimesTracking.Value=length(app.TrackingResultMatrix.PositionDirectionExtract{1,str2double(app.ShowsessionDropDown.Value)}.headspeed)./app.TrackingResultMatrix.Trackingframerate;
            drawnow;
            app.StartTimeTracking.Value=0;
            app.EndTimeTracking.Value=app.TotalTimesTracking.Value;
            if strcmp(app.ShowpartDropDown.Value,'Head')
                app.Totaldistance.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.HeadTotalDistance;
                app.Meanspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.HeadMeanSpeed;
                app.Highspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.Head90thSpeed;
            else
                app.Totaldistance.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.BodyTotalDistance;
                app.Meanspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.BodyMeanSpeed;
                app.Highspeed.Value=app.TrackingResultMatrix.Report{1,str2double(app.ShowsessionDropDown.Value)}.Body90thSpeed;
            end
        end

        % Button pushed function: ShowSNRdistributionButton
        function ShowSNRdistributionButtonPushed(app, event)
            figure ('Name','SNR distribution','NumberTitle','off')
            plot(app.NeuronInformation.SNR)
            title('SNR for each cell');
        end

        % Button pushed function: ShoweventcountdistributionButton
        function ShoweventcountdistributionButtonPushed(app, event)
            figure ('Name','Event count distribution','NumberTitle','off')
            plot(app.NeuronInformation.EventCount_raw)
            title('Event count for each cell');
        end

        % Button pushed function: ShowallcellmapButton
        function ShowallcellmapButtonPushed(app, event)
            
            app.ListBox.Items(length(app.ListBox.Items)+1)={'Cell map options:'};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Map colorcode: ',app.CellmapcolorcodeDropDown.Value]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Map colormap: ',app.CellmapcolormapDropDown.Value]};
            scroll(app.ListBox, 'bottom')
            drawnow;
            app.ListBox.Items(length(app.ListBox.Items)+1)={'Start to draw cell map...'};
            scroll(app.ListBox, 'bottom')
            drawnow;
            app.ListBox.Items(length(app.ListBox.Items)+1)={'Cell 1 was drawn.'};
            if strcmp(app.CellmapcolorcodeDropDown.Value,'Random')
                CMP=hsv(64);
            elseif strcmp(app.CellmapcolorcodeDropDown.Value,'Planes') || strcmp(app.CellmapcolorcodeDropDown.Value,'Single plane')
                CMP= [...
                    0.2314    0.2863    0.5725; ...
                    0.9333         0         0; ...
                    0    0.5451    0.2706; ...
                    0.3882    0.0941    0.4745; ...
                    0    0.5098    0.5020; ...
                    0.7333         0    0.1294; ...
                    0.3725    0.3333    0.6078; ...
                    0.6353         0    0.3373; ...
                    0.5020    0.5059    0.5020; ...
                    0.1059    0.0980    0.0980];
            else
                CMP=hot(256);
            end
            colormap(app.ColorbarAxes,CMP)
            c=colorbar(app.ColorbarAxes);
            c.Location='south';
            c.Position=[0 0.1 1 0.75];
            c.Ticks=[];
            drawnow;
            
            
            figure (figure ('Name',['Cell map, colored by ',app.CellmapcolorcodeDropDown.Value],'NumberTitle','off'))
            set(gcf,'color',[0 0 0]);
            if app.Imageoverlaid.Value==1
                StartPixel=(str2double(app.PlaneDropDown.Value)-1)*app.NeuronInformation.Framesize(1)+1;
                Image_show=app.NeuronInformation.Options.meanImg(:,StartPixel:StartPixel+app.NeuronInformation.Framesize(1)-1);
                Image_corrected=Image_show;
                h=imagesc((Image_corrected-min(min(Image_show)))/(1*prctile(Image_show(:),app.Max.Value)-min(min(Image_show))),[0 1]);
            else
                Image_show=zeros(app.NeuronInformation.Framesize);
                Image_corrected=Image_show;
                h=imagesc(Image_corrected,[0 1]);
            end
            colormap(gca,gray)
            daspect([1 1 1]);
            xlabel('x')
            ylabel('y')
            camroll(app.Rotationdegree.Value)
            if app.Fliphorizontally.Value==1
                set(gca,'XDir','reverse');
            else
            end
            set(gca, 'TickDir', 'out')
            xlim([1 app.NeuronInformation.Framesize(1)])
            ylim([1 app.NeuronInformation.Framesize(2)])
            set(gca,'XColor',[1 1 1]);
            set(gca,'YColor',[1 1 1]);
            hold on
            
            for i=1:1:app.NeuronInformation.TotalCell
                if app.Abort_flag==true
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Analysis is aborted!'};
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                    app.Abort_flag=false;
                    break
                else
                end
                if app.Showrepeatcell.Value==1
                    Repeat=0;
                else
                    Repeat=app.NeuronInformation.RepeatCell;
                end
                if ~ismember(i,Repeat)
                    Xpoint=mod(double(app.NeuronInformation.ROIStat_Iscell{1,i}.xpix)',app.NeuronInformation.Framesize(1));
                    Ypoint=mod(double(app.NeuronInformation.ROIStat_Iscell{1,i}.ypix)',app.NeuronInformation.Framesize(2));
                    Xpoint(Xpoint==0)=app.NeuronInformation.Framesize(1);
                    Ypoint(Ypoint==0)=app.NeuronInformation.Framesize(2);
                    ROI_pannel=zeros(app.NeuronInformation.Framesize);
                    for m=1:1:length(Xpoint)
                        ROI_pannel(Ypoint(m),Xpoint(m))=1;
                    end
                    %                     ROI_pannel_corrected=imwarp(ROI_pannel,TransformMatrix,'OutputView',imref2d(size(ROI_pannel)));
                    ROI_pannel_corrected=ROI_pannel;
                    
                    [Ypoint,Xpoint,~]=find(ROI_pannel_corrected>0);
                    Center_x=mean(Xpoint);
                    Center_y=mean(Ypoint);
                    P=convhull(Xpoint,Ypoint);
                    if strcmp(app.CellmapcolorcodeDropDown.Value,'Random')
                        CMP=hsv(64);
                        ColorID=round(1+rand(1,1)*(64-1));
                        fill(Xpoint(P),Ypoint(P),CMP(ColorID,:),'LineStyle','none','facealpha',.6);
                    elseif strcmp(app.CellmapcolorcodeDropDown.Value,'Planes')
                        CMP= [...
                            0.2314    0.2863    0.5725; ...
                            0.9333         0         0; ...
                            0    0.5451    0.2706; ...
                            0.3882    0.0941    0.4745; ...
                            0    0.5098    0.5020; ...
                            0.7333         0    0.1294; ...
                            0.3725    0.3333    0.6078; ...
                            0.6353         0    0.3373; ...
                            0.5020    0.5059    0.5020; ...
                            0.1059    0.0980    0.0980];
                        Plane=app.NeuronInformation.CellinPlane(i)+1;
                        fill(Xpoint(P),Ypoint(P),CMP(Plane,:),'LineStyle','none','facealpha',.6);
                    elseif strcmp(app.CellmapcolorcodeDropDown.Value,'Single plane')
                        CMP= [...
                            0.2314    0.2863    0.5725; ...
                            0.9333         0         0; ...
                            0    0.5451    0.2706; ...
                            0.3882    0.0941    0.4745; ...
                            0    0.5098    0.5020; ...
                            0.7333         0    0.1294; ...
                            0.3725    0.3333    0.6078; ...
                            0.6353         0    0.3373; ...
                            0.5020    0.5059    0.5020; ...
                            0.1059    0.0980    0.0980];
                        Plane=app.NeuronInformation.CellinPlane(i)+1;
                        if Plane==str2double(app.PlaneDropDown.Value)
                            fill(Xpoint(P),Ypoint(P),CMP(Plane,:),'LineStyle','none','facealpha',.6);
                        else
                        end
                    elseif strcmp(app.CellmapcolorcodeDropDown.Value,'SNR')
                        CMP=hot(256);
                        SNR_cell=app.NeuronInformation.SNR(i);
                        SNR_cell_norm=(SNR_cell-app.Range_min.Value)./(app.Range_max.Value-app.Range_min.Value);
                        if SNR_cell_norm<0
                            SNR_cell_norm=0;
                        elseif SNR_cell_norm>1
                            SNR_cell_norm=1;
                        else
                        end
                        fill(Xpoint(P),Ypoint(P),CMP(round(SNR_cell_norm*255)+1,:),'LineStyle','none','facealpha',.6);
                    elseif strcmp(app.CellmapcolorcodeDropDown.Value,'Events')
                        CMP=hot(256);
                        Event_cell=app.NeuronInformation.EventCount_raw(i);
                        Event_cell_norm=(Event_cell-app.Range_min.Value)./(app.Range_max.Value-app.Range_min.Value);
                        if Event_cell_norm<0
                            Event_cell_norm=0;
                        elseif Event_cell_norm>1
                            Event_cell_norm=1;
                        else
                        end
                        fill(Xpoint(P),Ypoint(P),CMP(round(Event_cell_norm*255)+1,:),'LineStyle','none','facealpha',.6);
                    elseif strcmp(app.CellmapcolorcodeDropDown.Value,'Ch2 likelyhood')
                        CMP=hot(256);
                        Ch2_cell=app.NeuronInformation.ROIStat_Iscell{1,i}.chan2_prob;
                        Ch2_cell_norm=(Ch2_cell-app.Range_min.Value)./(app.Range_max.Value-app.Range_min.Value);
                        if Ch2_cell_norm<0
                            Ch2_cell_norm=0;
                        elseif Ch2_cell_norm>1
                            Ch2_cell_norm=1;
                        else
                        end
                        fill(Xpoint(P),Ypoint(P),CMP(round(Ch2_cell_norm*255)+1,:),'LineStyle','none','facealpha',.6);
                    else
                    end
                    if app.ShowcellID.Value==1
                        text(Center_x,Center_y,num2str(i),'FontSize',8);
                    else
                    end
                    hold on;
                    app.ListBox.Items(length(app.ListBox.Items))={['Cell ',num2str(i),' was drawn.']};
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                else
                    app.ListBox.Items(length(app.ListBox.Items))={['Cell ',num2str(i),' was skipped.']};
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                end
            end
            hold off
            %         text(FOVposition(FOV_ID,1)+128,FOVposition(FOV_ID,2)+128,['FOV',num2str(FOV_ID)],'Color',CMP(FOV_ID,:),'FontSize',20);
            
            %     xlim([0 450]);
            %     xlabel('x')
            %     ylim([0 450]);
            %     ylabel('y')
            %     caxis([0 1] );
            %     camroll(-90)
            %     set(gca,'YDir','reverse');
            %     set(gcf,'color',[0 0 0]);
            %     set(gca,'color',[0 0 0]);
            %     set(a3, 'XAxisLocation', 'top')
            %     set(a3, 'TickDir', 'out')


        end

        % Button pushed function: ShowprojectedimageButton
        function ShowprojectedimageButtonPushed(app, event)
            StartPixel=(str2double(app.PlaneDropDown.Value)-1)*app.NeuronInformation.Framesize(1)+1;
            if strcmp(app.TypeDropDown.Value,'Mean')
                if strcmp(app.ChannelDropDown.Value,'1')
                    Image_show=app.NeuronInformation.Options.meanImg(:,StartPixel:StartPixel+app.NeuronInformation.Framesize(1)-1);
                elseif strcmp(app.ChannelDropDown.Value,'2')
                    Image_show=app.NeuronInformation.Options.meanImg_chan2(:,StartPixel:StartPixel+app.NeuronInformation.Framesize(1)-1);
                else
                end
            else
                if strcmp(app.ChannelDropDown.Value,'1')
                    Image_show=app.NeuronInformation.Options.max_proj(:,StartPixel:StartPixel+app.NeuronInformation.Framesize(1)-1);
                else
                end
            end
            Image_corrected=Image_show;
            figure ('Name',['Projected image, ',app.TypeDropDown.Value,', CH',app.ChannelDropDown.Value,', P',app.PlaneDropDown.Value],'NumberTitle','off')
            set(gcf,'color',[0 0 0]);
            h=imagesc((Image_corrected-min(min(Image_show)))/(1*prctile(Image_show(:),app.Max.Value)-min(min(Image_show))),[0 1]);
            colormap(gca,gray)
            daspect([1 1 1]);
            xlabel('x')
            ylabel('y')
            camroll(app.Rotationdegree.Value)
            if app.Fliphorizontally.Value==1
                set(gca,'XDir','reverse');
            else
            end
            set(gca, 'TickDir', 'out')
            xlim([1 app.NeuronInformation.Framesize(1)])
            ylim([1 app.NeuronInformation.Framesize(2)])
            set(gca,'XColor',[1 1 1]);
            set(gca,'YColor',[1 1 1]);
            
        end

        % Value changed function: Suite2PAlignedFile
        function Suite2PAlignedFileValueChanged(app, event)
            app.Suite2PfilesListBox.Value=app.Suite2PfilesListBox.Items(app.Suite2PAlignedFile.Value);
        end

        % Value changed function: Suite2PfilesListBox
        function Suite2PfilesListBoxValueChanged(app, event)
          Indicator=strfind(app.Suite2PfilesListBox.Value,':');
          app.Suite2PAlignedFile.Value=str2num(app.Suite2PfilesListBox.Value(1:Indicator-1));
            
        end

        % Value changed function: DLCAlignedFile
        function DLCAlignedFileValueChanged(app, event)
            app.DLCfilesListBox.Value=app.DLCfilesListBox.Items(app.DLCAlignedFile.Value);
        end

        % Value changed function: DLCfilesListBox
        function DLCfilesListBoxValueChanged(app, event)
          Indicator=strfind(app.DLCfilesListBox.Value,':');
          app.DLCAlignedFile.Value=str2num(app.DLCfilesListBox.Value(1:Indicator-1));
            
        end

        % Button pushed function: GenerateNATButton
        function GenerateNATButtonPushed(app, event)
                  app.ListBox.Items(length(app.ListBox.Items)+1)={'Now start to generate NAT....'};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                %% generate NAT
                NAT=struct;
                ExperimentInformation=struct;
                ExperimentInformation.Session=app.TrackingResultMatrix.Session; % how many session in one recording
                ExperimentInformation.TrackingResolution=app.TrackingResultMatrix.TrackingResolution;  % mm/pixel
                ExperimentInformation.BoxCenter=app.TrackingResultMatrix.BoxCenter;
                ExperimentInformation.RawDataAddress=app.NeuronInformation.File_folder_root;
                ExperimentInformation.Trackingframerate=app.TrackingResultMatrix.Trackingframerate;
                ExperimentInformation.FrameinEachSession=app.TrackingResultMatrix.FrameineachSession;
                ExperimentInformation.TimeStamp=app.TrackingResultMatrix.TimeStamp;
                ExperimentInformation.DLCfilename=app.TrackingResultMatrix.DLCfilename;
                ExperimentInformation.HeadTrackingfasion=app.TrackingResultMatrix.HeadTrackingfasion;
                ExperimentInformation.BodydTrackingfasion=app.TrackingResultMatrix.BodyTrackingfasion;
                ExperimentInformation.BodypartRaw=app.TrackingResultMatrix.BodypartRaw;
                ExperimentInformation.ImagingChannel=app.NeuronInformation.ImagingChannel;
                ExperimentInformation.ImagingPlane=double(app.NeuronInformation.ImagingPlane);
                ExperimentInformation.TotalCell=app.NeuronInformation.TotalCell;
                ExperimentInformation.IsCell=app.NeuronInformation.IsCell;
                ExperimentInformation.CellinPlane=app.NeuronInformation.CellinPlane;
                ExperimentInformation.CellinEachPlan=app.NeuronInformation.CellinEachPlane;
                ExperimentInformation.CellSNR=app.NeuronInformation.SNR;
                ExperimentInformation.EventCount_raw=app.NeuronInformation.EventCount_raw;
                ExperimentInformation.CellStat=app.NeuronInformation.ROIStat_Iscell;
                ExperimentInformation.FocalPlane=app.NeuronInformation.FocalPlane;
                ExperimentInformation.Framesize=app.NeuronInformation.Framesize;
                ExperimentInformation.ImagingOptions=app.NeuronInformation.Options;
                ExperimentInformation.RepeatCell=app.NeuronInformation.RepeatCell;
                ExperimentInformation.Headpositioncorrection=app.Headpositioncorrection.Value; % head position correction, >0 is the tracking position are ears
                ExperimentInformation.Sessioncombined=app.TrackingResultMatrix.Sessioncombined;
                
                NAT=cell(1,ExperimentInformation.Session);
                
                %% calculate the frames without tracking (head-fixed experiment)
                
                ExperimentInformation.Frames_DLC_max=sum(ExperimentInformation.FrameinEachSession(app.DLCAlignedFile.Value:end));
                ExperimentInformation.Frames_suite2p_max=sum(ExperimentInformation.ImagingOptions.frames_per_file(app.Suite2PAlignedFile.Value:end));
                
                
                ExperimentInformation.Suite2P_startframe=sum(ExperimentInformation.ImagingOptions.frames_per_file(1:(app.Suite2PAlignedFile.Value-1)))+1;
                ExperimentInformation.DLC_startframe=sum(ExperimentInformation.FrameinEachSession(1:(app.DLCAlignedFile.Value-1)))+1;
                
                
                ExperimentInformation.Frames_Suite2P=min(ExperimentInformation.Frames_DLC_max./double(ExperimentInformation.ImagingPlane),ExperimentInformation.Frames_suite2p_max);
                ExperimentInformation.Frames_DLC=min(ExperimentInformation.Frames_suite2p_max.*double(ExperimentInformation.ImagingPlane),ExperimentInformation.Frames_DLC_max);
                
                
                ExperimentInformation.Suite2P_filetaken=0;
                ExperimentInformation.Suite2P_frametaken=0;
                while ExperimentInformation.Suite2P_frametaken<ExperimentInformation.Frames_Suite2P
                    ExperimentInformation.Suite2P_frametaken=ExperimentInformation.Suite2P_frametaken+ExperimentInformation.ImagingOptions.frames_per_file(app.Suite2PAlignedFile.Value+ExperimentInformation.Suite2P_filetaken);
                    ExperimentInformation.Suite2P_filetaken=ExperimentInformation.Suite2P_filetaken+1;
                end


                ExperimentInformation.DLC_filetaken=0;
                ExperimentInformation.DLC_frametaken=0;
                while ExperimentInformation.DLC_frametaken<ExperimentInformation.Frames_DLC
                    ExperimentInformation.DLC_frametaken=ExperimentInformation.DLC_frametaken+ExperimentInformation.FrameinEachSession(app.DLCAlignedFile.Value+ExperimentInformation.DLC_filetaken);
                    ExperimentInformation.DLC_filetaken=ExperimentInformation.DLC_filetaken+1;
                end
                
                
                ExperimentInformation.Suite2PAlignedFile=app.options.Suite2PAlignedFile;
                ExperimentInformation.DLCAlignedFile=app.options.DLCAlignedFile;
                
                ExperimentInformation.Suite2P_Endframe=ExperimentInformation.Suite2P_startframe+ExperimentInformation.Frames_Suite2P-1;
                ExperimentInformation.DLC_Endframe=ExperimentInformation.DLC_startframe+ExperimentInformation.Frames_DLC-1;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Total Suite2p frames taken for generating NAT: ',num2str(ExperimentInformation.Frames_Suite2P)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Total Suite2p files taken for generating NAT: ',num2str(ExperimentInformation.Suite2P_filetaken)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Suite2p start frame: ',num2str(ExperimentInformation.Suite2P_startframe)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Suite2p end frame: ',num2str(ExperimentInformation.Suite2P_Endframe)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Total DLC frames taken for generating NAT: ',num2str(ExperimentInformation.Frames_DLC)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Total DLC files taken for generating NAT: ',num2str(ExperimentInformation.DLC_filetaken)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['DLC start frame: ',num2str(ExperimentInformation.DLC_startframe)]};
                scroll(app.ListBox, 'bottom')
                drawnow;
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={['DLC end frame: ',num2str(ExperimentInformation.DLC_Endframe)]};
                scroll(app.ListBox, 'bottom')
                drawnow
                
                
                app.Suite2pselectedListBox.Items={};
                app.DLCselectedListBox.Items={};
                
                for i=1:1:ExperimentInformation.Suite2P_filetaken
                    app.Suite2pselectedListBox.Items(length(app.Suite2pselectedListBox.Items)+1)=app.Suite2PfilesListBox.Items(app.Suite2PAlignedFile.Value+i-1);
                end
                
                for i=1:1:ExperimentInformation.DLC_filetaken
                    app.DLCselectedListBox.Items(length(app.DLCselectedListBox.Items)+1)=app.DLCfilesListBox.Items(app.DLCAlignedFile.Value+i-1);
                end
                %
                %colume 1:  timestamp
                %colume 2:3 headposition
                %colume 4:  headdirection
                %colume 5:  headspeed
                %colume 6:  headvilid
                %colume 7:8 bodyposition
                %colume 9:  bodydirection
                %colume 10:  bodyspeed
                %colume 11:  bodyvilid
                %colume 12: reserved
                %colume 13:4:13+N*4-1 neuron detaF/F_raw, detaF/F_filtered,
                %detaF_F_significant and deconvoled signal
                
                
                ConfidenceThreadhold=0.5;
                ExperimentInformation.Session_taken=ExperimentInformation.DLC_filetaken;
                
                for k=1:1:ExperimentInformation.Session_taken
                    i=app.DLCAlignedFile.Value+k-1;
                    NAT{1,i}=NaN(ExperimentInformation.FrameinEachSession(i),13+ExperimentInformation.TotalCell*4-1);
                    NAT{1,i}(:,1)=ExperimentInformation.TimeStamp{i};
                    NAT{1,i}(:,2:3)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headposition;
                    NAT{1,i}(:,4)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headdirection;
                    NAT{1,i}(:,5)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.headspeed;
                    NAT{1,i}(:,7:8)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyposition;
                    NAT{1,i}(:,9)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodydirection;
                    NAT{1,i}(:,10)=app.TrackingResultMatrix.PositionDirectionExtract{1,i}.bodyspeed;
                    
%                     if ExperimentInformation.Trackingfasion==1
                        for j=1:1:ExperimentInformation.FrameinEachSession(i)
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,4)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,7)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,6)=0;
%                             else
                                NAT{1,i}(j,6)=1;
                                NAT{1,i}(j,11)=1;
%                             end
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,22)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,25)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,11)=0;
%                             else
%                                 NAT{1,i}(j,11)=1;
%                             end
                        end
%                     elseif ExperimentInformation.Trackingfasion==2
%                         for j=1:1:ExperimentInformation.FrameinEachSession(i)
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,7)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,10)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,6)=0;
%                             else
%                                 NAT{1,i}(j,6)=1;
%                             end
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,13)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,15)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,11)=0;
%                             else
%                                 NAT{1,i}(j,11)=1;
%                             end
%                         end
%                     elseif ExperimentInformation.Trackingfasion==3
%                         for j=1:1:ExperimentInformation.FrameinEachSession(i)
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,16)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,19)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,6)=0;
%                             else
%                                 NAT{1,i}(j,6)=1;
%                             end
%                             if (app.TrackingResultMatrix.DLC{1,i}(j,22)<ConfidenceThreadhold)||(app.TrackingResultMatrix.DLC{1,i}(j,25)<ConfidenceThreadhold)
%                                 NAT{1,i}(j,11)=0;
%                             else
%                                 NAT{1,i}(j,11)=1;
%                             end
%                         end
%                     else
%                     end
                    
                    %Old
                    %NAT{1,i}(:,2)=circshift(NAT{1,i}(:,2),-1*ExperimentInformation.Headpositioncorrection);
                    %NAT{1,i}(:,3)=circshift(NAT{1,i}(:,3),-1*ExperimentInformation.Headpositioncorrection);
                    %NAT{1,i}(:,5)=circshift(NAT{1,i}(:,5),-1*ExperimentInformation.Headpositioncorrection);
                    
                    %Head position correction according to input of distance to true (animal experienced) position 
                    %new_x = x + (distanceInput * cos(degrees*pi/180))
                    %new_y = y + (distanceInput * sin(degrees*pi/180))
                    
                    NAT{1,i}(:,2)= NAT{1,i}(:,2) + (ExperimentInformation.Headpositioncorrection * cos(NAT{1,i}(:,4) * pi/180));
                    NAT{1,i}(:,3)= NAT{1,i}(:,3) + (ExperimentInformation.Headpositioncorrection * sin(NAT{1,i}(:,4) * pi/180));


                    g=app.Suite2PAlignedFile.Value+k-1;
                    Startframe_suite2p=sum(ExperimentInformation.ImagingOptions.frames_per_file(1:app.Suite2PAlignedFile.Value+k-2))+1;
                    
                    
                    for j=1:1:ExperimentInformation.TotalCell
                        NAT{1,i}(ExperimentInformation.CellinPlane(j)+1:ExperimentInformation.ImagingPlane:end,9+j*4)=app.NeuronActiveMatrix.detaF_F(Startframe_suite2p:...
                            Startframe_suite2p+ExperimentInformation.FrameinEachSession(i)./ExperimentInformation.ImagingPlane-1,j+2);
                        
                        NAT{1,i}(ExperimentInformation.CellinPlane(j)+1:ExperimentInformation.ImagingPlane:end,10+j*4)=app.NeuronActiveMatrix.detaF_F_filtered(Startframe_suite2p:...
                            Startframe_suite2p+ExperimentInformation.FrameinEachSession(i)./ExperimentInformation.ImagingPlane-1,j+2);
                        
                        NAT{1,i}(ExperimentInformation.CellinPlane(j)+1:ExperimentInformation.ImagingPlane:end,11+j*4)=app.NeuronActiveMatrix.Significanttransient(Startframe_suite2p:...
                            Startframe_suite2p+ExperimentInformation.FrameinEachSession(i)./ExperimentInformation.ImagingPlane-1,j+2);
                        
                        NAT{1,i}(ExperimentInformation.CellinPlane(j)+1:ExperimentInformation.ImagingPlane:end,12+j*4)=app.NeuronActiveMatrix.FilteredEvent(Startframe_suite2p:...
                            Startframe_suite2p+ExperimentInformation.FrameinEachSession(i)./ExperimentInformation.ImagingPlane-1,j+2);
                    end
                end
                app.ExperimentInformation=ExperimentInformation;
                app.NAT=NAT;
                %
                %%%%%% output NAT and experimentinformation %%%%%%
                app.ListBox.Items(length(app.ListBox.Items)+1)={'NAT was generated!'};
                scroll(app.ListBox, 'bottom')
                drawnow
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Now saving NAT, please wait....'};
                scroll(app.ListBox, 'bottom')
                drawnow
                save ([app.ExperimentInformation.RawDataAddress,'\NAT.mat'],'NAT','-v7.3');
                save ([app.ExperimentInformation.RawDataAddress,'\ExperimentInformation.mat'],'ExperimentInformation','-v7.3');
                
                app.ListBox.Items(length(app.ListBox.Items)+1)={'NAT was saved!'};
                scroll(app.ListBox, 'bottom')
                drawnow
            
        end

        % Button pushed function: CloseallfiguresButton
        function CloseallfiguresButtonPushed(app, event)
            close all
        end

        % Button pushed function: ShowresultsButton
        function ShowresultsButtonPushed(app, event)
            
            
            if  strcmp(app.TabGroup.SelectedTab.Title,'Range')
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Select cell by a range'};
                scroll(app.ListBox, 'bottom')
                drawnow
                app.SelectCell=[app.Startcell.Value: app.Startcell.Value+app.Cellstocheck.Value-1];
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Selected cell ID: ',num2str(app.SelectCell(1)),' to ',num2str(app.SelectCell(end))]};
                scroll(app.ListBox, 'bottom')
                drawnow
            else
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Select cell by arbitrary user input'};
                scroll(app.ListBox, 'bottom')
                drawnow
                Comma=strfind(app.CellID.Value,'	');
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,',');
                else
                end
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,';');
                else
                end
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,' ');
                else
                end
                Num=length(Comma);
                app.SelectCell=zeros(1,Num+1);
                for i=2:1:Num
                    app.SelectCell(i)=str2double(app.CellID.Value(Comma(i-1)+1:Comma(i)-1));
                end
                app.SelectCell(1)=str2double(app.CellID.Value(1:Comma(1)-1));
                app.SelectCell(Num+1)=str2double(app.CellID.Value(Comma(Num)+1:end));
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Selected cell ID: ',num2str(app.SelectCell)]};
                scroll(app.ListBox, 'bottom')
                drawnow
            end
            
            
            Cell_forshow=app.SelectCell;
            bin_space=app.Spatialbin.Value;
            bin_angle=app.Directionbinsize.Value;
            smooth_space=app.Spatialsmoothing.Value;
            smooth_angle=app.Directionsmoothing.Value;
            Mintime=app.Mintime.Value;
            MinSpeed=app.Speedthresholdmin.Value;
            MaxSpeed=app.Speedthresholdmax.Value;
            limit=[app.PlotRange_min.Value app.PlotRange_max.Value  app.PlotRange_min.Value app.PlotRange_max.Value];
            if strcmp(app.TrackingandtuningcolormapDropDown.Value,'jet')
                CMP=jet(256);
            elseif strcmp(app.TrackingandtuningcolormapDropDown.Value,'parula')
                CMP=parula(256);
            elseif strcmp(app.TrackingandtuningcolormapDropDown.Value,'inferno')
                CMP=CMP_WJ.inferno(256);
            elseif strcmp(app.TrackingandtuningcolormapDropDown.Value,'hot')
                CMP=hot(256);
            else
            end
            
            Session=[app.ExperimentInformation.DLCAlignedFile:app.ExperimentInformation.DLCAlignedFile+app.ExperimentInformation.DLC_filetaken-1];
            for ID=Cell_forshow
                if app.Abort_flag==true
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Analysis is aborted!'};
                    scroll(app.ListBox, 'bottom')
                    app.Abort_flag=false;
                    drawnow;
                    break
                else
                end
                i=ID;
                if i<=app.ExperimentInformation.TotalCell
                    figure('Name',['Cell ',num2str(i),', ',app.ShowpartDropDown.Value,', ',app.SignaltypeDropDown.Value,', S-bin: ',num2str(bin_space),', S-smooth: ',num2str(smooth_space), ', A-bin: ',num2str(bin_angle),', A-smooth: ',num2str(smooth_angle),', Speed-min: ',num2str(MinSpeed),', Speed-max: ',num2str(MaxSpeed)],'NumberTitle','off')
                    width=1024;
                    height=200*length(Session);
                    x0=100;
                    y0=100;
                    set(gcf,'position',[x0,y0,width,height])
                    for j=Session
                        if strcmp(app.ShowpartDropDown.Value,'Head')
                            SelectedFrame_filtered=intersect(find(~isnan(app.NAT{1,j}(:,4*i+10))),find(app.NAT{1,j}(:,6)==1));% filter out the frames with head tracking likelyhood valid
                            transient_filtered=intersect(find(app.NAT{1,j}(:,4*i+10)>0),find(app.NAT{1,j}(:,6)==1));% filter out the frames with speed valid
                            Event_filtered=intersect(find(app.NAT{1,j}(:,4*i+12)>0),find(app.NAT{1,j}(:,6)==1));% filter out the frames with speed valid
                            transient_filtered=intersect(transient_filtered,find(app.NAT{1,j}(:,5)>MinSpeed));% filter out the frames with speed threadhold
                            Event_filtered=intersect(Event_filtered,find(app.NAT{1,j}(:,5)>MinSpeed));% filter out the frames with speed threadhold
                            transient_filtered=intersect(transient_filtered,find(app.NAT{1,j}(:,5)<MaxSpeed));% filter out the frames with speed threadhold
                            Event_filtered=intersect(Event_filtered,find(app.NAT{1,j}(:,5)<MaxSpeed));% filter out
                            SelectedFrame_filtered=intersect(SelectedFrame_filtered,find(app.NAT{1,j}(:,5)>MinSpeed));% filter out the frames with speed threadhold
                            SelectedFrame_filtered=intersect(SelectedFrame_filtered,find(app.NAT{1,j}(:,5)<MaxSpeed));% filter out the frames with speed threadholdt the frames with speed threadhold
                            Positiontrain=app.NAT{1,j}(SelectedFrame_filtered,1:3);
                            DirectionTrain=app.NAT{1,j}(SelectedFrame_filtered,4);
                        else
                            SelectedFrame_filtered=intersect(find(~isnan(app.NAT{1,j}(:,4*i+10))),find(app.NAT{1,j}(:,11)==1));% filter out the frames with head tracking likelyhood valid
                            transient_filtered=intersect(find(app.NAT{1,j}(:,4*i+10)>0),find(app.NAT{1,j}(:,11)==1));% filter out the frames with speed valid
                            Event_filtered=intersect(find(app.NAT{1,j}(:,4*i+12)>0),find(app.NAT{1,j}(:,11)==1));% filter out the frames with speed valid
                            transient_filtered=intersect(transient_filtered,find(app.NAT{1,j}(:,10)>MinSpeed));% filter out the frames with speed threadhold
                            Event_filtered=intersect(Event_filtered,find(app.NAT{1,j}(:,10)>MinSpeed));% filter out the frames with speed threadhold
                            transient_filtered=intersect(transient_filtered,find(app.NAT{1,j}(:,10)<MaxSpeed));% filter out the frames with speed threadhold
                            Event_filtered=intersect(Event_filtered,find(app.NAT{1,j}(:,10)<MaxSpeed));% filter out
                            SelectedFrame_filtered=intersect(SelectedFrame_filtered,find(app.NAT{1,j}(:,10)>MinSpeed));% filter out the frames with speed threadhold
                            SelectedFrame_filtered=intersect(SelectedFrame_filtered,find(app.NAT{1,j}(:,10)<MaxSpeed));% filter out the frames with speed threadhold
                            Positiontrain=app.NAT{1,j}(SelectedFrame_filtered,[1 7 8]);
                            DirectionTrain=app.NAT{1,j}(SelectedFrame_filtered,4);
                        end
                        
                        if strcmp(app.SignaltypeDropDown.Value,'dF/F')
                            Signaltrain=app.NAT{1,j}(SelectedFrame_filtered,[1 4*i+10]);
                            Event_time=intersect(find(app.NAT{1,j}(:,4*i+10)>0),SelectedFrame_filtered);
                            if strcmp(app.ShowpartDropDown.Value,'Head')
                                EventTrain=app.NAT{1,j}(Event_time,[2,3,4*i+10]);
                            else
                                EventTrain=app.NAT{1,j}(Event_time,[7,8,4*i+10]);
                            end
                        else
                            Signaltrain=app.NAT{1,j}(SelectedFrame_filtered,[1 4*i+12]);
                            Event_time=intersect(find(app.NAT{1,j}(:,4*i+12)>0),SelectedFrame_filtered);
                            if strcmp(app.ShowpartDropDown.Value,'Head')
                                EventTrain=app.NAT{1,j}(Event_time,[2,3,4*i+12]);
                            else
                                EventTrain=app.NAT{1,j}(Event_time,[7,8,4*i+12]);
                            end
                        end
                        ActivityMap=SpatialTuning_BNT.map(Positiontrain,Signaltrain,'smooth',smooth_space,'binWidth',bin_space,'minTime',Mintime,'limits',limit);
                        AngularTuning=SpatialTuning_BNT.TurnningCurver_power(DirectionTrain,Signaltrain(:,2),bin_angle, 1/app.ExperimentInformation.ImagingOptions.fs,smooth_angle);


                        subplot(length(Session),4,(j-1)*4+1,'align')
                        plot(Positiontrain(:,2),Positiontrain(:,3),'color', [0.6 0.6 0.6],'LineWidth',0.5);
                        hold on
                        scatter(EventTrain(:,1),EventTrain(:,2),20*(EventTrain(:,3)./max(EventTrain(:,3))),[1 0 0],'filled','MarkerFaceAlpha',0.8)
                        colormap(gca,gray)
                        colorbar;
                        ylim([app.PlotRange_min.Value app.PlotRange_max.Value])
                        xlim([app.PlotRange_min.Value app.PlotRange_max.Value])
                        title(['Event plot, P: ',num2str(max(EventTrain(:,3)),'%.2f')]);
                        daspect([1 1 1]);
                        box on
                        axis on
                        xlabel('X (cm)');
                        ylabel('Y (cm)');
                        set(gca,'TickDir','out');
                        
                        
                        subplot(length(Session),4,(j-1)*4+2,'align')
                        plot(Positiontrain(:,2),Positiontrain(:,3),'color', [0.6 0.6 0.6],'LineWidth',0.5);
                        hold on
                        if strcmp(app.ShowpartDropDown.Value,'Head')
                            scatter(EventTrain(:,1),EventTrain(:,2),20*(EventTrain(:,3)./max(EventTrain(:,3))),app.NAT{1,j}(Event_time,4),'filled','MarkerFaceAlpha',0.8)
                        else
                            scatter(EventTrain(:,1),EventTrain(:,2),20*(EventTrain(:,3)./max(EventTrain(:,3))),app.NAT{1,j}(Event_time,4),'filled','MarkerFaceAlpha',0.8)
                        end
                        colormap(gca,hsv)
                        caxis([0 360]);
                        colorbar;
                        ylim([app.PlotRange_min.Value app.PlotRange_max.Value])
                        xlim([app.PlotRange_min.Value app.PlotRange_max.Value])
                        title('Event plot, direction coded');
                        daspect([1 1 1]);
                        box on
                        axis on
                        xlabel('X (cm)');
                        ylabel('Y (cm)');
                        set(gca,'TickDir','out');
                        
                        h=subplot(length(Session),4,(j-1)*4+3,'align') ;
                        MAP=ActivityMap.z;
                        MAX=max(MAP(:));
                        imagesc(1,1,flipud(MAP),'AlphaData',~isnan(flipud(MAP)));
                        colormap(h,CMP);
                        caxis([0 MAX]);
                        colorbar;
                        daspect([1 1 1]);
                        ylim([1 size(MAP,1)])
                        xlim([1 size(MAP,2)])
                        box on
                        axis on
                        xlabel('bins');
                        ylabel('bins');
                        set(gca,'TickDir','out');
                        title(['Activity map, P: ',num2str(max(MAP(:)),'%.2f')]);
                        
                        subplot(length(Session),4,(j-1)*4+4,'align')
                        
                        Occupancy=preprocessing.circularSmooth(AngularTuning(:,3),smooth_angle);
                        Tuning=AngularTuning(:,2);
                        MaxTime=max(Occupancy);
                        MaxTuning=max(Tuning);
                        Occupancy=0.5*Occupancy./MaxTime;
                        Tuning=Tuning./MaxTuning;
                        
                        H=polarplot([AngularTuning(:,1)/180*pi;AngularTuning(1,1)/180*pi],[Occupancy;Occupancy(1)],'color',[0.6 0.6 0.6],'LineWidth',1);
                        hold on
                        H=polarplot([AngularTuning(:,1)/180*pi;AngularTuning(1,1)/180*pi],[Tuning;Tuning(1)],'color',[0 0 0],'LineWidth',1.5);
                        Ax = gca;
                        Ax.RTick = [];
                        Ax.RTickLabel = [];
                        Ax.ThetaTickLabel = [];
                        Ax.ThetaTick  = [0 90 180 270];
                        Ax.RLim=[0 1];
                        AX.FontSize=0;
                        Ax.ThetaAxis.Color = [1 1 1];
                        AX.RColor = [1 1 1];
                        set(gca,'GridColor',[0 0 0]);
                        set(gca,'GridAlpha',1);
                        set(gca,'RGrid','off');
                        set(H,'LineWidth',1);
                        AX.RAxis.Visible='off';
                        title(['P-time: ',num2str(MaxTime,'%.2f'),' s',', P-tuning: ',num2str(MaxTuning,'%.2f')])
                        box off
                        drawnow;
                    end
                    
                    if app.SaveCheckBox.Value==1
                        mkdir cellplots
                        saveas(gcf,['cellplots/Cell',num2str(i),'_',...
                            app.ShowpartDropDown.Value,', ',app.SignaltypeDropDown.Value,...
                            '_S-bin-',num2str(bin_space),'_S-smooth-',num2str(smooth_space),...
                            '_A-bin-',num2str(bin_angle),'_A-smooth-',num2str(smooth_angle),...
                            '_Speed-min-',num2str(MinSpeed),'_Speed-max-',num2str(MaxSpeed),'.jpeg']);
                    else
                    end
                else
                end
                drawnow;
            end
            
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            if  strcmp(app.TabGroup.SelectedTab.Title,'Range')
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Select cell by a range'};
                scroll(app.ListBox, 'bottom')
                drawnow
                app.SelectCell=[app.Startcell.Value: app.Startcell.Value+app.Cellstocheck.Value-1];
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Selected cell ID: ',num2str(app.SelectCell(1)),' to ',num2str(app.SelectCell(end))]};
                scroll(app.ListBox, 'bottom')
                drawnow
            else
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Select cell by arbitrary user input'};
                scroll(app.ListBox, 'bottom')
                drawnow
                Comma=strfind(app.CellID.Value,'	');
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,',');
                else
                end
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,';');
                else
                end
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,' ');
                else
                end
                Num=length(Comma);
                app.SelectCell=zeros(1,Num+1);
                for i=2:1:Num
                    app.SelectCell(i)=str2double(app.CellID.Value(Comma(i-1)+1:Comma(i)-1));
                end
                app.SelectCell(1)=str2double(app.CellID.Value(1:Comma(1)-1));
                app.SelectCell(Num+1)=str2double(app.CellID.Value(Comma(Num)+1:end));
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Selected cell ID: ',num2str(app.SelectCell)]};
                scroll(app.ListBox, 'bottom')
                drawnow
            end
            
        end

        % Button pushed function: ShowselectcellmapButton
        function ShowselectcellmapButtonPushed(app, event)
            if  strcmp(app.TabGroup.SelectedTab.Title,'Range')
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Select cell by a range'};
                scroll(app.ListBox, 'bottom')
                drawnow
                app.SelectCell=[app.Startcell.Value: app.Startcell.Value+app.Cellstocheck.Value-1];
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Selected cell ID: ',num2str(app.SelectCell(1)),' to ',num2str(app.SelectCell(end))]};
                scroll(app.ListBox, 'bottom')
                drawnow
            else
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Select cell by arbitrary user input'};
                scroll(app.ListBox, 'bottom')
                drawnow
                Comma=strfind(app.CellID.Value,'	');
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,',');
                else
                end
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,';');
                else
                end
                if isempty(Comma)
                    Comma=strfind(app.CellID.Value,' ');
                else
                end
                Num=length(Comma);
                app.SelectCell=zeros(1,Num+1);
                for i=2:1:Num
                    app.SelectCell(i)=str2double(app.CellID.Value(Comma(i-1)+1:Comma(i)-1));
                end
                app.SelectCell(1)=str2double(app.CellID.Value(1:Comma(1)-1));
                app.SelectCell(Num+1)=str2double(app.CellID.Value(Comma(Num)+1:end));
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Selected cell ID: ',num2str(app.SelectCell)]};
                scroll(app.ListBox, 'bottom')
                drawnow
            end


            app.ListBox.Items(length(app.ListBox.Items)+1)={'Cell map options:'};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Map colorcode: ',app.CellmapcolorcodeDropDown.Value]};
            app.ListBox.Items(length(app.ListBox.Items)+1)={['Map colormap: ',app.CellmapcolormapDropDown.Value]};
            scroll(app.ListBox, 'bottom')
            drawnow;
            app.ListBox.Items(length(app.ListBox.Items)+1)={'Start to draw cell map...'};
            scroll(app.ListBox, 'bottom')
            drawnow;
            app.ListBox.Items(length(app.ListBox.Items)+1)={'Cell 1 was drawn.'};
            if strcmp(app.CellmapcolorcodeDropDown.Value,'Random')
                CMP=hsv(64);
            elseif strcmp(app.CellmapcolorcodeDropDown.Value,'Planes') || strcmp(app.CellmapcolorcodeDropDown.Value,'Single plane')
                CMP= [...
                    0.2314    0.2863    0.5725; ...
                    0.9333         0         0; ...
                    0    0.5451    0.2706; ...
                    0.3882    0.0941    0.4745; ...
                    0    0.5098    0.5020; ...
                    0.7333         0    0.1294; ...
                    0.3725    0.3333    0.6078; ...
                    0.6353         0    0.3373; ...
                    0.5020    0.5059    0.5020; ...
                    0.1059    0.0980    0.0980];
            else
                CMP=hot(256);
            end
            colormap(app.ColorbarAxes,CMP)
            c=colorbar(app.ColorbarAxes);
            c.Location='south';
            c.Position=[0 0.1 1 0.75];
            c.Ticks=[];
            drawnow;
            
            
            figure (figure ('Name',['Cell map, colored by ',app.CellmapcolorcodeDropDown.Value],'NumberTitle','off'))
            set(gcf,'color',[0 0 0]);
            if app.Imageoverlaid.Value==1
                StartPixel=(str2double(app.PlaneDropDown.Value)-1)*app.NeuronInformation.Framesize(1)+1;
                Image_show=app.NeuronInformation.Options.meanImg(:,StartPixel:StartPixel+app.NeuronInformation.Framesize(1)-1);
                Image_corrected=Image_show;
                h=imagesc((Image_corrected-min(min(Image_show)))/(1*prctile(Image_show(:),app.Max.Value)-min(min(Image_show))),[0 1]);
            else
                Image_show=zeros(app.NeuronInformation.Framesize);
                Image_corrected=Image_show;
                h=imagesc(Image_corrected,[0 1]);
            end
            colormap(gca,gray)
            daspect([1 1 1]);
            xlabel('x')
            ylabel('y')
            camroll(app.Rotationdegree.Value)
            if app.Fliphorizontally.Value==1
                set(gca,'XDir','reverse');
            else
            end
            set(gca, 'TickDir', 'out')
            xlim([1 app.NeuronInformation.Framesize(1)])
            ylim([1 app.NeuronInformation.Framesize(2)])
            set(gca,'XColor',[1 1 1]);
            set(gca,'YColor',[1 1 1]);
            hold on
            
            for i=app.SelectCell
                if app.Abort_flag==true
                    app.ListBox.Items(length(app.ListBox.Items)+1)={'Analysis is aborted!'};
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                    app.Abort_flag=false;
                    break
                else
                end
                if app.Showrepeatcell.Value==1
                    Repeat=0;
                else
                    Repeat=app.NeuronInformation.RepeatCell;
                end
                if ~ismember(i,Repeat)
                    Xpoint=mod(double(app.NeuronInformation.ROIStat_Iscell{1,i}.xpix)',app.NeuronInformation.Framesize(1));
                    Ypoint=mod(double(app.NeuronInformation.ROIStat_Iscell{1,i}.ypix)',app.NeuronInformation.Framesize(2));
                    Xpoint(Xpoint==0)=app.NeuronInformation.Framesize(1);
                    Ypoint(Ypoint==0)=app.NeuronInformation.Framesize(2);
                    ROI_pannel=zeros(app.NeuronInformation.Framesize);
                    for m=1:1:length(Xpoint)
                        ROI_pannel(Ypoint(m),Xpoint(m))=1;
                    end
                    %                     ROI_pannel_corrected=imwarp(ROI_pannel,TransformMatrix,'OutputView',imref2d(size(ROI_pannel)));
                    ROI_pannel_corrected=ROI_pannel;
                    
                    [Ypoint,Xpoint,~]=find(ROI_pannel_corrected>0);
                    Center_x=mean(Xpoint);
                    Center_y=mean(Ypoint);
                    P=convhull(Xpoint,Ypoint);
                    if strcmp(app.CellmapcolorcodeDropDown.Value,'Random')
                        CMP=hsv(64);
                        ColorID=round(1+rand(1,1)*(64-1));
                        fill(Xpoint(P),Ypoint(P),CMP(ColorID,:),'LineStyle','none','facealpha',.6);
                    elseif strcmp(app.CellmapcolorcodeDropDown.Value,'Planes')
                        CMP= [...
                            0.2314    0.2863    0.5725; ...
                            0.9333         0         0; ...
                            0    0.5451    0.2706; ...
                            0.3882    0.0941    0.4745; ...
                            0    0.5098    0.5020; ...
                            0.7333         0    0.1294; ...
                            0.3725    0.3333    0.6078; ...
                            0.6353         0    0.3373; ...
                            0.5020    0.5059    0.5020; ...
                            0.1059    0.0980    0.0980];
                        Plane=app.NeuronInformation.CellinPlane(i)+1;
                        fill(Xpoint(P),Ypoint(P),CMP(Plane,:),'LineStyle','none','facealpha',.6);
                    elseif strcmp(app.CellmapcolorcodeDropDown.Value,'Single plane')
                        CMP= [...
                            0.2314    0.2863    0.5725; ...
                            0.9333         0         0; ...
                            0    0.5451    0.2706; ...
                            0.3882    0.0941    0.4745; ...
                            0    0.5098    0.5020; ...
                            0.7333         0    0.1294; ...
                            0.3725    0.3333    0.6078; ...
                            0.6353         0    0.3373; ...
                            0.5020    0.5059    0.5020; ...
                            0.1059    0.0980    0.0980];
                        Plane=app.NeuronInformation.CellinPlane(i)+1;
                        if Plane==str2double(app.PlaneDropDown.Value)
                            fill(Xpoint(P),Ypoint(P),CMP(Plane,:),'LineStyle','none','facealpha',.6);
                        else
                        end
                    elseif strcmp(app.CellmapcolorcodeDropDown.Value,'SNR')
                        CMP=hot(256);
                        SNR_cell=app.NeuronInformation.SNR(i);
                        SNR_cell_norm=(SNR_cell-app.Range_min.Value)./(app.Range_max.Value-app.Range_min.Value);
                        if SNR_cell_norm<0
                            SNR_cell_norm=0;
                        elseif SNR_cell_norm>1
                            SNR_cell_norm=1;
                        else
                        end
                        fill(Xpoint(P),Ypoint(P),CMP(round(SNR_cell_norm*255)+1,:),'LineStyle','none','facealpha',.6);
                    elseif strcmp(app.CellmapcolorcodeDropDown.Value,'Events')
                        CMP=hot(256);
                        Event_cell=app.NeuronInformation.EventCount_raw(i);
                        Event_cell_norm=(Event_cell-app.Range_min.Value)./(app.Range_max.Value-app.Range_min.Value);
                        if Event_cell_norm<0
                            Event_cell_norm=0;
                        elseif Event_cell_norm>1
                            Event_cell_norm=1;
                        else
                        end
                        fill(Xpoint(P),Ypoint(P),CMP(round(Event_cell_norm*255)+1,:),'LineStyle','none','facealpha',.6);
                    elseif strcmp(app.CellmapcolorcodeDropDown.Value,'Ch2 likelyhood')
                        CMP=hot(256);
                        Ch2_cell=app.NeuronInformation.ROIStat_Iscell{1,i}.chan2_prob;
                        Ch2_cell_norm=(Ch2_cell-app.Range_min.Value)./(app.Range_max.Value-app.Range_min.Value);
                        if Ch2_cell_norm<0
                            Ch2_cell_norm=0;
                        elseif Ch2_cell_norm>1
                            Ch2_cell_norm=1;
                        else
                        end
                        fill(Xpoint(P),Ypoint(P),CMP(round(Ch2_cell_norm*255)+1,:),'LineStyle','none','facealpha',.6);
                    else
                    end
                    if app.ShowcellID.Value==1
                        text(Center_x,Center_y,num2str(i),'FontSize',8);
                    else
                    end
                    
                    hold on;
                    app.ListBox.Items(length(app.ListBox.Items))={['Cell ',num2str(i),' was drawn.']};
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                    
                else
                    app.ListBox.Items(length(app.ListBox.Items))={['Cell ',num2str(i),' was skipped.']};
                    scroll(app.ListBox, 'bottom')
                    drawnow;
                end
            end
            hold off
            %         text(FOVposition(FOV_ID,1)+128,FOVposition(FOV_ID,2)+128,['FOV',num2str(FOV_ID)],'Color',CMP(FOV_ID,:),'FontSize',20);
            
            %     xlim([0 450]);
            %     xlabel('x')
            %     ylim([0 450]);
            %     ylabel('y')
            %     caxis([0 1] );
            %     camroll(-90)
            %     set(gca,'YDir','reverse');
            %     set(gcf,'color',[0 0 0]);
            %     set(gca,'color',[0 0 0]);
            %     set(a3, 'XAxisLocation', 'top')
            %     set(a3, 'TickDir', 'out')


        end

        % Selection change function: TabHead
        function TabHeadSelectionChanged(app, event)
            
            if strcmp(app.TabHead.SelectedTab.Title,'M1')
                app.TabHead.SelectedTab
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking model 1 is selected'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking position: lest point, right point;'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Left point csv column: ',num2str(app.Left_Head_X.Value),', ',num2str(app.Left_Head_Y.Value)]};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Right point csv column: ',num2str(app.Right_Head_X.Value),', ',num2str(app.Right_Head_Y.Value)]};
                
            elseif strcmp(app.TabHead.SelectedTab.Title,'M2')
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking model  2 is selected'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking position: anterior point, posterior point;'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Anterior point csv column: ',num2str(app.Anterior_Head_X.Value),', ',num2str(app.Anterior_Head_Y.Value)]};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Posterior point csv column: ',num2str(app.Posterior_Head_X.Value),', ',num2str(app.Posterior_Head_Y.Value)]};
                
            elseif strcmp(app.TabHead.SelectedTab.Title,'M3')
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking model 3 is selected'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Tracking head direction is not possible!'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Head tracking position: center point'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Center point csv column: ',num2str(app.Center_Head_X.Value),', ',num2str(app.Center_Head_Y.Value)]};
            else
            end
            scroll(app.ListBox, 'bottom')
            drawnow
            
            
        end

        % Value changed function: TrackBody
        function TrackBodyValueChanged(app, event)
            if app.TrackBody.Value==0
                
                app.Anterior_Body_X.Enable=0;
                app.Anterior_Body_Y.Enable=0;
                app.Posterior_Body_X.Enable=0;
                app.Posterior_Body_Y.Enable=0;
                app.Center_Body_X.Enable=0;
                app.Center_Body_Y.Enable=0;
            else                
                app.Anterior_Body_X.Enable=1;
                app.Anterior_Body_Y.Enable=1;
                app.Posterior_Body_X.Enable=1;
                app.Posterior_Body_Y.Enable=1;
                app.Center_Body_X.Enable=1;
                app.Center_Body_Y.Enable=1;                
            end
        end

        % Selection change function: TabBody
        function TabBodySelectionChanged(app, event)

            if strcmp(app.TabBody.SelectedTab.Title,'M1')
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Bady tracking model 2 is selected'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Body tracking position: anterior point, posterior point;'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Anterior point csv column: ',num2str(app.Anterior_Body_X.Value),', ',num2str(app.Anterior_Body_Y.Value)]};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Posterior point csv column: ',num2str(app.Posterior_Body_X.Value),', ',num2str(app.Posterior_Body_Y.Value)]};
                
            elseif strcmp(app.TabBody.SelectedTab.Title,'M2')
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Body tracking model 2 is selected'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Tracking body direction is not possible!'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={'Body tracking position: center point'};
                app.ListBox.Items(length(app.ListBox.Items)+1)={['Center point csv column: ',num2str(app.Center_Body_X.Value),', ',num2str(app.Center_Body_Y.Value)]};
            else
            end
            scroll(app.ListBox, 'bottom')
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatainputPanel = uipanel(app.UIFigure);
            app.DatainputPanel.Title = 'Data input';
            app.Savetooptionfile = uibutton(app.DatainputPanel, 'push');
            app.Savetooptionfile.ButtonPushedFcn = createCallbackFcn(app, @SavetooptionfilePushed, true);
            app.Savetooptionfile.Text = 'Save to option file';
            app.AnalysistypeDropDownLabel = uilabel(app.DatainputPanel);
            app.AnalysistypeDropDownLabel.Text = 'Analysis type';
            app.Analysistype = uidropdown(app.DatainputPanel);
            app.Analysistype.Items = {'Load in processed data', 'Complete pipline', 'Only MINI2P', 'Only Tracking', 'MINI2P+Tracking'};
            app.Analysistype.ValueChangedFcn = createCallbackFcn(app, @AnalysistypeValueChanged, true);
            app.Analysistype.Value = 'Complete pipline';
            app.Selectoptionfile = uibutton(app.DatainputPanel, 'push');
            app.Selectoptionfile.ButtonPushedFcn = createCallbackFcn(app, @SelectoptionfilePushed, true);
            app.Selectoptionfile.Text = 'Select option file';
            app.SelectDatafolderandDLCoutput = uibutton(app.DatainputPanel, 'push');
            app.SelectDatafolderandDLCoutput.ButtonPushedFcn = createCallbackFcn(app, @SelectDatafolderandDLCoutputPushed, true);
            app.SelectDatafolderandDLCoutput.Text = 'Select data folder and DLC output';
            app.OptionsforneuronalactivityanalysisPanel = uipanel(app.UIFigure);
            app.OptionsforneuronalactivityanalysisPanel.Title = 'Options for neuronal activity analysis';
            app.MovingwindowsEditFieldLabel = uilabel(app.OptionsforneuronalactivityanalysisPanel);
            app.MovingwindowsEditFieldLabel.Text = 'Moving window (s)';
            app.Movingwindow = uieditfield(app.OptionsforneuronalactivityanalysisPanel, 'numeric');
            app.Movingwindow.Value = 30;
            app.TransientlastingsLabel = uilabel(app.OptionsforneuronalactivityanalysisPanel);
            app.TransientlastingsLabel.Text = 'Transient lasting (s)';
            app.Transientlasting = uieditfield(app.OptionsforneuronalactivityanalysisPanel, 'numeric');
            app.Transientlasting.Value = 0.75;
            app.LowpassfilterHzEditFieldLabel = uilabel(app.OptionsforneuronalactivityanalysisPanel);
            app.LowpassfilterHzEditFieldLabel.Text = 'Lowpass filter (Hz)';
            app.Lowpassfilter = uieditfield(app.OptionsforneuronalactivityanalysisPanel, 'numeric');
            app.Lowpassfilter.Value = 2;
            app.NeuropilcorrectionEditFieldLabel = uilabel(app.OptionsforneuronalactivityanalysisPanel);
            app.NeuropilcorrectionEditFieldLabel.Text = 'Neuropil correction';
            app.Neuropilcorrection = uieditfield(app.OptionsforneuronalactivityanalysisPanel, 'numeric');
            app.Neuropilcorrection.Value = 0.7;
            app.CelldistancethresholdpixelEditFieldLabel = uilabel(app.OptionsforneuronalactivityanalysisPanel);
            app.CelldistancethresholdpixelEditFieldLabel.Text = 'Cell distance threshold (pixel)';
            app.Celldistancethreshold = uieditfield(app.OptionsforneuronalactivityanalysisPanel, 'numeric');
            app.Celldistancethreshold.Value = 10;
            app.CellcrosscorrelationthresholdEditFieldLabel = uilabel(app.OptionsforneuronalactivityanalysisPanel);
            app.CellcrosscorrelationthresholdEditFieldLabel.Text = 'Cell cross-correlation threshold ';
            app.Cellcrosscorrelationthreshold = uieditfield(app.OptionsforneuronalactivityanalysisPanel, 'numeric');
            app.Cellcrosscorrelationthreshold.Value = 0.5;
            app.CelloverlapratiothresholdEditFieldLabel = uilabel(app.OptionsforneuronalactivityanalysisPanel);
            app.CelloverlapratiothresholdEditFieldLabel.Text = 'Cell overlap ratio threshold ';
            app.Celloverlapratiothreshold = uieditfield(app.OptionsforneuronalactivityanalysisPanel, 'numeric');
            app.Celloverlapratiothreshold.Value = 0.75;
            app.TransientextendsLabel = uilabel(app.OptionsforneuronalactivityanalysisPanel);
            app.TransientextendsLabel.Text = 'Transient extend (s) ';
            app.Transientextend = uieditfield(app.OptionsforneuronalactivityanalysisPanel, 'numeric');
            app.Transientextend.Value = 0.4;
            app.SaveSIInformation = uicheckbox(app.OptionsforneuronalactivityanalysisPanel);
            app.SaveSIInformation.Text = 'Save SI Information';
            app.OptionsfortrackinganalysisPanel = uipanel(app.UIFigure);
            app.OptionsfortrackinganalysisPanel.Title = 'Options for tracking analysis';
            app.ResolutioncmpixelEditFieldLabel = uilabel(app.OptionsfortrackinganalysisPanel);
            app.ResolutioncmpixelEditFieldLabel.Text = 'Resolution (cm/pixel)';
            app.TrackingResolution = uieditfield(app.OptionsfortrackinganalysisPanel, 'numeric');
            app.TrackingResolution.Value = 0.09;
            app.CenterxpixelEditFieldLabel = uilabel(app.OptionsfortrackinganalysisPanel);
            app.CenterxpixelEditFieldLabel.Text = 'Center x (pixel)';
            app.Centerx = uieditfield(app.OptionsfortrackinganalysisPanel, 'numeric');
            app.Centerx.Value = 512;
            app.CenterypixelEditFieldLabel = uilabel(app.OptionsfortrackinganalysisPanel);
            app.CenterypixelEditFieldLabel.Text = 'Center y (pixel)';
            app.Centery = uieditfield(app.OptionsfortrackinganalysisPanel, 'numeric');
            app.Centery.Value = 512;
            app.Sessioncombined = uicheckbox(app.OptionsfortrackinganalysisPanel);
            app.Sessioncombined.ValueChangedFcn = createCallbackFcn(app, @SessioncombinedValueChanged, true);
            app.Sessioncombined.Text = 'Session combined?';
            app.Sessioncombined.Value = true;
            app.TrackingframerateHzEditFieldLabel = uilabel(app.OptionsfortrackinganalysisPanel);
            app.TrackingframerateHzEditFieldLabel.Text = 'Tracking frame rate (Hz)';
            app.Trackingframerate = uieditfield(app.OptionsfortrackinganalysisPanel, 'numeric');
            app.Trackingframerate.Editable = 'off';
            app.Trackingframerate.Value = 14.5;
            app.TrackingsychedwithMINI2P = uicheckbox(app.OptionsfortrackinganalysisPanel);
            app.TrackingsychedwithMINI2P.ValueChangedFcn = createCallbackFcn(app, @TrackingsychedwithMINI2PValueChanged, true);
            app.TrackingsychedwithMINI2P.Text = 'Tracking syched with MINI2P?';
            app.TrackingsychedwithMINI2P.Value = true;
            app.TabHead = uitabgroup(app.OptionsfortrackinganalysisPanel);
            app.TabHead.SelectionChangedFcn = createCallbackFcn(app, @TabHeadSelectionChanged, true);
            app.M1Tab = uitab(app.TabHead);
            app.M1Tab.Title = 'M1';
            app.Image2 = uiimage(app.M1Tab);
            app.Image2.ImageSource = 'DLC2_head.png';
            app.HeadPositionLabel_2 = uilabel(app.M1Tab);
            app.HeadPositionLabel_2.Text = 'Head Position';
            app.HeadDirectionLabel_2 = uilabel(app.M1Tab);
            app.HeadDirectionLabel_2.Text = 'Head Direction';
            app.LeftpointLabel = uilabel(app.M1Tab);
            app.LeftpointLabel.Text = 'Left point';
            app.RightpointLabel = uilabel(app.M1Tab);
            app.RightpointLabel.Text = 'Right point';
            app.CSVcolumnLabel_2 = uilabel(app.M1Tab);
            app.CSVcolumnLabel_2.Text = 'CSV column';
            app.XLabel_3 = uilabel(app.M1Tab);
            app.XLabel_3.Text = 'X';
            app.Right_Head_X = uieditfield(app.M1Tab, 'numeric');
            app.Right_Head_X.Value = 8;
            app.YLabel_3 = uilabel(app.M1Tab);
            app.YLabel_3.Text = 'Y';
            app.Right_Head_Y = uieditfield(app.M1Tab, 'numeric');
            app.Right_Head_Y.Value = 9;
            app.CSVcolumnLabel = uilabel(app.M1Tab);
            app.CSVcolumnLabel.Text = 'CSV column';
            app.XLabel_4 = uilabel(app.M1Tab);
            app.XLabel_4.Text = 'X';
            app.Left_Head_X = uieditfield(app.M1Tab, 'numeric');
            app.Left_Head_X.Value = 5;
            app.YLabel_4 = uilabel(app.M1Tab);
            app.YLabel_4.Text = 'Y';
            app.Left_Head_Y = uieditfield(app.M1Tab, 'numeric');
            app.Left_Head_Y.Value = 6;
            app.M2Tab = uitab(app.TabHead);
            app.M2Tab.Title = 'M2';
            app.Image = uiimage(app.M2Tab);
            app.Image.ImageSource = 'DLC1_head.png';
            app.HeadPositionLabel = uilabel(app.M2Tab);
            app.HeadPositionLabel.Text = 'Head Position';
            app.HeadDirectionLabel = uilabel(app.M2Tab);
            app.HeadDirectionLabel.Text = 'Head Direction';
            app.AnteriorpointLabel = uilabel(app.M2Tab);
            app.AnteriorpointLabel.Text = 'Anterior point';
            app.PosteriorpointLabel = uilabel(app.M2Tab);
            app.PosteriorpointLabel.Text = 'Posterior point';
            app.XLabel = uilabel(app.M2Tab);
            app.XLabel.Text = 'X';
            app.Anterior_Head_X = uieditfield(app.M2Tab, 'numeric');
            app.Anterior_Head_X.Value = 2;
            app.YLabel = uilabel(app.M2Tab);
            app.YLabel.Text = 'Y';
            app.Anterior_Head_Y = uieditfield(app.M2Tab, 'numeric');
            app.Anterior_Head_Y.Value = 3;
            app.XLabel_2 = uilabel(app.M2Tab);
            app.XLabel_2.Text = 'X';
            app.Posterior_Head_X = uieditfield(app.M2Tab, 'numeric');
            app.Posterior_Head_X.Value = 5;
            app.YLabel_2 = uilabel(app.M2Tab);
            app.YLabel_2.Text = 'Y';
            app.Posterior_Head_Y = uieditfield(app.M2Tab, 'numeric');
            app.Posterior_Head_Y.Value = 6;
            app.CSVcolumnLabel_3 = uilabel(app.M2Tab);
            app.CSVcolumnLabel_3.Text = 'CSV column';
            app.CSVcolumnLabel_4 = uilabel(app.M2Tab);
            app.CSVcolumnLabel_4.Text = 'CSV column';
            app.M3Tab = uitab(app.TabHead);
            app.M3Tab.Title = 'M3';
            app.HeadPositionLabel_3 = uilabel(app.M3Tab);
            app.HeadPositionLabel_3.Text = 'Head Position';
            app.HeadDirectionNALabel = uilabel(app.M3Tab);
            app.HeadDirectionNALabel.Text = 'Head Direction (NA)';
            app.CSVcolumnLabel_9 = uilabel(app.M3Tab);
            app.CSVcolumnLabel_9.Text = 'CSV column';
            app.XLabel_9 = uilabel(app.M3Tab);
            app.XLabel_9.Text = 'X';
            app.Center_Head_X = uieditfield(app.M3Tab, 'numeric');
            app.Center_Head_X.Value = 2;
            app.YLabel_9 = uilabel(app.M3Tab);
            app.YLabel_9.Text = 'Y';
            app.Center_Head_Y = uieditfield(app.M3Tab, 'numeric');
            app.Center_Head_Y.Value = 3;
            app.Image4 = uiimage(app.M3Tab);
            app.Image4.ImageSource = 'DLC3_head.png';
            app.TrackBody = uicheckbox(app.OptionsfortrackinganalysisPanel);
            app.TrackBody.ValueChangedFcn = createCallbackFcn(app, @TrackBodyValueChanged, true);
            app.TrackBody.Text = 'Track body?';
            app.TrackBody.Value = true;
            app.TabBody = uitabgroup(app.OptionsfortrackinganalysisPanel);
            app.TabBody.SelectionChangedFcn = createCallbackFcn(app, @TabBodySelectionChanged, true);
            app.M1Tab_2 = uitab(app.TabBody);
            app.M1Tab_2.Title = 'M1';
            app.Image3_3 = uiimage(app.M1Tab_2);
            app.Image3_3.ImageSource = 'DLC1_body.png';
            app.BodyPositionLabel_2 = uilabel(app.M1Tab_2);
            app.BodyPositionLabel_2.Text = 'Body Position';
            app.BodyDirectionLabel_2 = uilabel(app.M1Tab_2);
            app.BodyDirectionLabel_2.Text = 'Body Direction';
            app.AnteriorpointLabel_3 = uilabel(app.M1Tab_2);
            app.AnteriorpointLabel_3.Text = 'Anterior point';
            app.PosteriorpointLabel_3 = uilabel(app.M1Tab_2);
            app.PosteriorpointLabel_3.Text = 'Posterior point';
            app.CSVcolumnLabel_7 = uilabel(app.M1Tab_2);
            app.CSVcolumnLabel_7.Text = 'CSV column';
            app.CSVcolumnLabel_8 = uilabel(app.M1Tab_2);
            app.CSVcolumnLabel_8.Text = 'CSV column';
            app.XLabel_7 = uilabel(app.M1Tab_2);
            app.XLabel_7.Text = 'X';
            app.Anterior_Body_X = uieditfield(app.M1Tab_2, 'numeric');
            app.Anterior_Body_X.Value = 11;
            app.YLabel_7 = uilabel(app.M1Tab_2);
            app.YLabel_7.Text = 'Y';
            app.Anterior_Body_Y = uieditfield(app.M1Tab_2, 'numeric');
            app.Anterior_Body_Y.Value = 12;
            app.XLabel_8 = uilabel(app.M1Tab_2);
            app.XLabel_8.Text = 'X';
            app.Posterior_Body_X = uieditfield(app.M1Tab_2, 'numeric');
            app.Posterior_Body_X.Value = 14;
            app.YLabel_8 = uilabel(app.M1Tab_2);
            app.YLabel_8.Text = 'Y';
            app.Posterior_Body_Y = uieditfield(app.M1Tab_2, 'numeric');
            app.Posterior_Body_Y.Value = 15;
            app.M2Tab_2 = uitab(app.TabBody);
            app.M2Tab_2.Title = 'M2';
            app.BodyPositionLabel_3 = uilabel(app.M2Tab_2);
            app.BodyPositionLabel_3.Text = 'Body Position';
            app.BodyDirectionNALabel = uilabel(app.M2Tab_2);
            app.BodyDirectionNALabel.Text = 'Body Direction (NA)';
            app.CSVcolumnLabel_10 = uilabel(app.M2Tab_2);
            app.CSVcolumnLabel_10.Text = 'CSV column';
            app.Center_Body_X = uieditfield(app.M2Tab_2, 'numeric');
            app.Center_Body_X.Value = 11;
            app.Center_Body_Y = uieditfield(app.M2Tab_2, 'numeric');
            app.Center_Body_Y.Value = 12;
            app.Image5 = uiimage(app.M2Tab_2);
            app.Image5.ImageSource = 'DLC3_body.png';
            app.SpeedsmoothsLabel = uilabel(app.OptionsfortrackinganalysisPanel);
            app.SpeedsmoothsLabel.Text = 'Speed smooth (s)';
            app.SpeedSmooth = uieditfield(app.OptionsfortrackinganalysisPanel, 'numeric');
            app.SpeedSmooth.Value = 1;
            app.HeadtrackingfassionLabel = uilabel(app.OptionsfortrackinganalysisPanel);
            app.HeadtrackingfassionLabel.Text = 'Head tracking fassion';
            app.BodytrackingfassionLabel = uilabel(app.OptionsfortrackinganalysisPanel);
            app.BodytrackingfassionLabel.Text = 'Body tracking fassion';
            app.NeuronalactivityanalysisresultpreviewPanel = uipanel(app.UIFigure);
            app.NeuronalactivityanalysisresultpreviewPanel.Title = 'Neuronal activity analysis result preview';
            app.ColorbarAxes = uiaxes(app.NeuronalactivityanalysisresultpreviewPanel);
            title(app.ColorbarAxes, '')
            xlabel(app.ColorbarAxes, '')
            ylabel(app.ColorbarAxes, '')
            app.ColorbarAxes.XTick = [];
            app.ColorbarAxes.YTick = [];
            app.ShowactivitytracesButton = uibutton(app.NeuronalactivityanalysisresultpreviewPanel, 'push');
            app.ShowactivitytracesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowactivitytracesButtonPushed, true);
            app.ShowactivitytracesButton.Text = 'Show activity traces';
            app.WindowsEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.WindowsEditFieldLabel.Text = 'Window (s)';
            app.Window = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Window.Value = 500;
            app.StartsEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.StartsEditFieldLabel.Text = 'Start(s)';
            app.Starttime = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.OffsetdFFEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.OffsetdFFEditFieldLabel.Text = 'Offset (dF/F)';
            app.Offset = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Offset.Value = -3;
            app.TotalCellsEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.TotalCellsEditFieldLabel.Text = ' Total Cells';
            app.TotalCells = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.TotalCells.Editable = 'off';
            app.PlanesEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.PlanesEditFieldLabel.Text = 'Planes';
            app.Planes = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Planes.Editable = 'off';
            app.ChannelsEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.Channels = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Channels.Editable = 'off';
            app.RepeatedcellsEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.RepeatedcellsEditFieldLabel.Text = ' Repeated cells';
            app.Repeatedcells = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Repeatedcells.Editable = 'off';
            app.XpixelEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.XpixelEditFieldLabel.Text = 'X pixel';
            app.Xpixel = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Xpixel.Editable = 'off';
            app.YpixelEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.YpixelEditFieldLabel.Text = 'Y pixel';
            app.Ypixel = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Ypixel.Editable = 'off';
            app.TotalvolumesLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.TotalvolumesLabel.Text = 'Total volumes';
            app.TotalVolumes = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.TotalVolumes.ValueDisplayFormat = '%.0f';
            app.TotalVolumes.Editable = 'off';
            app.TotalTimesEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.TotalTimesEditFieldLabel.Text = 'Total Time (s)';
            app.TotalTimes = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.TotalTimes.ValueDisplayFormat = '%.0f';
            app.TotalTimes.Editable = 'off';
            app.FramerateEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.Framerate = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Framerate.Editable = 'off';
            app.VolumerateLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.VolumerateLabel.Text = 'Volume rate';
            app.Volumerate = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Volumerate.Editable = 'off';
            app.ShowSNRdistributionButton = uibutton(app.NeuronalactivityanalysisresultpreviewPanel, 'push');
            app.ShowSNRdistributionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSNRdistributionButtonPushed, true);
            app.ShowSNRdistributionButton.Text = 'Show SNR distribution';
            app.ShoweventcountdistributionButton = uibutton(app.NeuronalactivityanalysisresultpreviewPanel, 'push');
            app.ShoweventcountdistributionButton.ButtonPushedFcn = createCallbackFcn(app, @ShoweventcountdistributionButtonPushed, true);
            app.ShoweventcountdistributionButton.Text = 'Show event count distribution';
            app.SmoothforshowEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.SmoothforshowEditFieldLabel.Text = 'Smooth for show';
            app.Smoothforshow = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Smoothforshow.Value = 3;
            app.ShowprojectedimageButton = uibutton(app.NeuronalactivityanalysisresultpreviewPanel, 'push');
            app.ShowprojectedimageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowprojectedimageButtonPushed, true);
            app.ShowprojectedimageButton.Text = 'Show projected image';
            app.ChannelDropDownLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.NeuronalactivityanalysisresultpreviewPanel);
            app.ChannelDropDown.Items = {'1'};
            app.ChannelDropDown.Value = '1';
            app.PlaneDropDownLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.PlaneDropDownLabel.Text = 'Plane';
            app.PlaneDropDown = uidropdown(app.NeuronalactivityanalysisresultpreviewPanel);
            app.PlaneDropDown.Items = {'1'};
            app.PlaneDropDown.Value = '1';
            app.MaxLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.MaxLabel.Text = 'Max (%)';
            app.Max = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Max.Value = 99;
            app.RotationdegreeEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.RotationdegreeEditFieldLabel.Text = 'Rotation (degree) ';
            app.Rotationdegree = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Fliphorizontally = uicheckbox(app.NeuronalactivityanalysisresultpreviewPanel);
            app.Fliphorizontally.Text = 'Flip horizontally';
            app.TypeDropDownLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.NeuronalactivityanalysisresultpreviewPanel);
            app.TypeDropDown.Items = {'Mean', 'Max'};
            app.TypeDropDown.Value = 'Mean';
            app.ShowallcellmapButton = uibutton(app.NeuronalactivityanalysisresultpreviewPanel, 'push');
            app.ShowallcellmapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallcellmapButtonPushed, true);
            app.ShowallcellmapButton.Text = 'Show all-cell map';
            app.CellmapcolorcodeDropDownLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.CellmapcolorcodeDropDownLabel.Text = 'Cell map colorcode';
            app.CellmapcolorcodeDropDown = uidropdown(app.NeuronalactivityanalysisresultpreviewPanel);
            app.CellmapcolorcodeDropDown.Items = {'Random', 'Planes', 'Single plane', 'SNR', 'Events', 'Ch2 likelyhood'};
            app.CellmapcolorcodeDropDown.Value = 'Random';
            app.CellmapcolormapDropDownLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.CellmapcolormapDropDownLabel.Text = 'Cell map colormap';
            app.CellmapcolormapDropDown = uidropdown(app.NeuronalactivityanalysisresultpreviewPanel);
            app.CellmapcolormapDropDown.Items = {'HSV', 'AAAS', 'Nature', 'Weijian'};
            app.CellmapcolormapDropDown.Value = 'HSV';
            app.Imageoverlaid = uicheckbox(app.NeuronalactivityanalysisresultpreviewPanel);
            app.Imageoverlaid.Text = 'Image overlaid';
            app.Imageoverlaid.Value = true;
            app.Range_minEditFieldLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.Range_minEditFieldLabel.Text = 'Range_min';
            app.Range_min = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Range_maxEditField_2Label = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.Range_maxEditField_2Label.Text = 'Range_max';
            app.Range_max = uieditfield(app.NeuronalactivityanalysisresultpreviewPanel, 'numeric');
            app.Range_max.Value = 10;
            app.ShowcellID = uicheckbox(app.NeuronalactivityanalysisresultpreviewPanel);
            app.ShowcellID.Text = 'Show cell ID';
            app.ShowselectcellmapButton = uibutton(app.NeuronalactivityanalysisresultpreviewPanel, 'push');
            app.ShowselectcellmapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowselectcellmapButtonPushed, true);
            app.ShowselectcellmapButton.Text = 'Show select-cell map';
            app.TracetypeDropDownLabel = uilabel(app.NeuronalactivityanalysisresultpreviewPanel);
            app.TracetypeDropDownLabel.Text = 'Trace type';
            app.TracetypeDropDown = uidropdown(app.NeuronalactivityanalysisresultpreviewPanel);
            app.TracetypeDropDown.Items = {'Only dF/F', 'dF/F with significant transient', 'Only deconvolved signal', 'dF/F with deconvolved signal'};
            app.TracetypeDropDown.Value = 'Only dF/F';
            app.Showrepeatcell = uicheckbox(app.NeuronalactivityanalysisresultpreviewPanel);
            app.Showrepeatcell.Text = 'Show repeated cells';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {' '};
            app.ListBox.Value = ' ';
            app.TrackinganalysisresultpreviewPanel = uipanel(app.UIFigure);
            app.TrackinganalysisresultpreviewPanel.Title = 'Tracking analysis result preview';
            app.TotaldistancecmEditFieldLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.TotaldistancecmEditFieldLabel.Text = 'Total distance (cm)';
            app.Totaldistance = uieditfield(app.TrackinganalysisresultpreviewPanel, 'numeric');
            app.Totaldistance.ValueDisplayFormat = '%.0f';
            app.Totaldistance.Editable = 'off';
            app.MeanspeedcmsEditFieldLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.MeanspeedcmsEditFieldLabel.Text = 'Mean speed (cm/s)';
            app.Meanspeed = uieditfield(app.TrackinganalysisresultpreviewPanel, 'numeric');
            app.Meanspeed.ValueDisplayFormat = '%.2f';
            app.Meanspeed.Editable = 'off';
            app.thspeedcmsLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.thspeedcmsLabel.Text = '90th speed (cm/s)';
            app.Highspeed = uieditfield(app.TrackinganalysisresultpreviewPanel, 'numeric');
            app.Highspeed.ValueDisplayFormat = '%.2f';
            app.Highspeed.Editable = 'off';
            app.TotalsessionsEditFieldLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.TotalsessionsEditFieldLabel.Text = 'Total sessions';
            app.Totalsessions = uieditfield(app.TrackinganalysisresultpreviewPanel, 'numeric');
            app.Totalsessions.Editable = 'off';
            app.Totalsessions.Value = 1;
            app.ShowsessionDropDownLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.ShowsessionDropDownLabel.Text = 'Show session';
            app.ShowsessionDropDown = uidropdown(app.TrackinganalysisresultpreviewPanel);
            app.ShowsessionDropDown.Items = {'1'};
            app.ShowsessionDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowsessionDropDownValueChanged, true);
            app.ShowsessionDropDown.Value = '1';
            app.TrajectoryplottypeLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.TrajectoryplottypeLabel.Text = 'Trajectory plot type';
            app.TrajectoryplottypeDropDown = uidropdown(app.TrackinganalysisresultpreviewPanel);
            app.TrajectoryplottypeDropDown.Items = {'Position', 'Speed', 'Direction'};
            app.TrajectoryplottypeDropDown.Value = 'Position';
            app.ShowtrajectoryButton = uibutton(app.TrackinganalysisresultpreviewPanel, 'push');
            app.ShowtrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ShowtrajectoryButtonPushed, true);
            app.ShowtrajectoryButton.Text = 'Show trajectory';
            app.Colorcode_mincmsLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.Colorcode_mincmsLabel.Text = 'Colorcode_min (cm/s)';
            app.ColorRange_min = uieditfield(app.TrackinganalysisresultpreviewPanel, 'numeric');
            app.Colormap_maxcmsLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.Colormap_maxcmsLabel.Text = 'Colormap_max (cm/s)';
            app.ColorRange_max = uieditfield(app.TrackinganalysisresultpreviewPanel, 'numeric');
            app.ColorRange_max.Value = 20;
            app.TotalframesEditFieldLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.TotalframesEditFieldLabel.Text = 'Total frames';
            app.TotalframesTracking = uieditfield(app.TrackinganalysisresultpreviewPanel, 'numeric');
            app.TotalframesTracking.ValueDisplayFormat = '%.0f';
            app.TotalframesTracking.Editable = 'off';
            app.TotalTimesEditField_2Label = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.TotalTimesEditField_2Label.Text = 'Total Time (s)';
            app.TotalTimesTracking = uieditfield(app.TrackinganalysisresultpreviewPanel, 'numeric');
            app.TotalTimesTracking.ValueDisplayFormat = '%.0f';
            app.TotalTimesTracking.Editable = 'off';
            app.StartTimesLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.StartTimesLabel.Text = 'Start Time (s)';
            app.StartTimeTracking = uieditfield(app.TrackinganalysisresultpreviewPanel, 'numeric');
            app.EndTImesLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.EndTImesLabel.Text = 'End TIme (s)';
            app.EndTimeTracking = uieditfield(app.TrackinganalysisresultpreviewPanel, 'numeric');
            app.EndTimeTracking.ValueDisplayFormat = '%.2f';
            app.EndTimeTracking.Value = 1;
            app.BackgroundDropDownLabel = uilabel(app.TrackinganalysisresultpreviewPanel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.TrackinganalysisresultpreviewPanel);
            app.BackgroundDropDown.Items = {'White', 'Black'};
            app.BackgroundDropDown.Value = 'White';
            app.ShowdirectionplotButton = uibutton(app.TrackinganalysisresultpreviewPanel, 'push');
            app.ShowdirectionplotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowdirectionplotButtonPushed, true);
            app.ShowdirectionplotButton.Text = 'Show direction plot';
            app.RefreshButton = uibutton(app.TrackinganalysisresultpreviewPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.NATgenerationPanel = uipanel(app.UIFigure);
            app.NATgenerationPanel.Title = 'NAT generation';
            app.Suite2PfilesLabel = uilabel(app.NATgenerationPanel);
            app.Suite2PfilesLabel.Text = 'Suite2P files';
            app.Suite2PfilesListBox = uilistbox(app.NATgenerationPanel);
            app.Suite2PfilesListBox.Items = {};
            app.Suite2PfilesListBox.ValueChangedFcn = createCallbackFcn(app, @Suite2PfilesListBoxValueChanged, true);
            app.Suite2PfilesListBox.Value = {};
            app.DLCfilesListBoxLabel = uilabel(app.NATgenerationPanel);
            app.DLCfilesListBoxLabel.Text = 'DLC files';
            app.DLCfilesListBox = uilistbox(app.NATgenerationPanel);
            app.DLCfilesListBox.Items = {};
            app.DLCfilesListBox.ValueChangedFcn = createCallbackFcn(app, @DLCfilesListBoxValueChanged, true);
            app.DLCfilesListBox.Value = {};
            app.GenerateNATButton = uibutton(app.NATgenerationPanel, 'push');
            app.GenerateNATButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNATButtonPushed, true);
            app.GenerateNATButton.Text = 'Generate NAT!';
            app.AlignwithEditFieldLabel = uilabel(app.NATgenerationPanel);
            app.AlignwithEditFieldLabel.Text = 'Align with';
            app.Suite2PAlignedFile = uieditfield(app.NATgenerationPanel, 'numeric');
            app.Suite2PAlignedFile.ValueChangedFcn = createCallbackFcn(app, @Suite2PAlignedFileValueChanged, true);
            app.Suite2PAlignedFile.Value = 1;
            app.AlignwithEditField_2Label = uilabel(app.NATgenerationPanel);
            app.AlignwithEditField_2Label.Text = 'Align with';
            app.DLCAlignedFile = uieditfield(app.NATgenerationPanel, 'numeric');
            app.DLCAlignedFile.ValueChangedFcn = createCallbackFcn(app, @DLCAlignedFileValueChanged, true);
            app.DLCAlignedFile.Value = 1;
            app.Suite2pselectedListBoxLabel = uilabel(app.NATgenerationPanel);
            app.Suite2pselectedListBoxLabel.Text = 'Suite2p selected';
            app.Suite2pselectedListBox = uilistbox(app.NATgenerationPanel);
            app.Suite2pselectedListBox.Items = {};
            app.Suite2pselectedListBox.Value = {};
            app.DLCselectedListBoxLabel = uilabel(app.NATgenerationPanel);
            app.DLCselectedListBoxLabel.Text = 'DLC selected';
            app.DLCselectedListBox = uilistbox(app.NATgenerationPanel);
            app.DLCselectedListBox.Items = {};
            app.DLCselectedListBox.Value = {};
            app.HeadpositioncorrectioncmLabel = uilabel(app.NATgenerationPanel);
            app.HeadpositioncorrectioncmLabel.Text = 'Head position correction (cm)';
            app.Headpositioncorrection = uieditfield(app.NATgenerationPanel, 'numeric');
            app.Headpositioncorrection.Value = 8;
            app.NATresultpreviewPanel = uipanel(app.UIFigure);
            app.NATresultpreviewPanel.Title = 'NAT result preview';
            app.ShowresultsButton = uibutton(app.NATresultpreviewPanel, 'push');
            app.ShowresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowresultsButtonPushed, true);
            app.ShowresultsButton.Text = 'Show results';
            app.SaveCheckBox = uicheckbox(app.NATresultpreviewPanel);
            app.SaveCheckBox.Text = 'Save?';
            app.SignaltypeDropDownLabel = uilabel(app.NATresultpreviewPanel);
            app.SignaltypeDropDownLabel.Text = 'Signal type';
            app.SignaltypeDropDown = uidropdown(app.NATresultpreviewPanel);
            app.SignaltypeDropDown.Items = {'dF/F', 'Deconvolution'};
            app.SignaltypeDropDown.Value = 'Deconvolution';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RangeTab = uitab(app.TabGroup);
            app.RangeTab.Title = 'Range';
            app.CellstocheckEditFieldLabel = uilabel(app.RangeTab);
            app.CellstocheckEditFieldLabel.Text = 'Cells to check';
            app.Cellstocheck = uieditfield(app.RangeTab, 'numeric');
            app.Cellstocheck.Value = 50;
            app.StartcellEditFieldLabel = uilabel(app.RangeTab);
            app.StartcellEditFieldLabel.Text = 'Start cell';
            app.Startcell = uieditfield(app.RangeTab, 'numeric');
            app.Startcell.Value = 1;
            app.CellselectionLabel = uilabel(app.RangeTab);
            app.CellselectionLabel.Text = 'Cell selection';
            app.ArbitraryTab = uitab(app.TabGroup);
            app.ArbitraryTab.Title = 'Arbitrary';
            app.CellIDLabel = uilabel(app.ArbitraryTab);
            app.CellIDLabel.Text = 'Cell ID ';
            app.CellID = uieditfield(app.ArbitraryTab, 'text');
            app.CellID.Value = '1,2,3';
            app.CellselectionLabel_2 = uilabel(app.ArbitraryTab);
            app.CellselectionLabel_2.Text = 'Cell selection';
            app.TrackingandcombinedplotsoptionsPanel = uipanel(app.UIFigure);
            app.TrackingandcombinedplotsoptionsPanel.Title = 'Tracking and combined plots options';
            app.AnglesmoothingdegreeLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.AnglesmoothingdegreeLabel.Text = 'Angle smoothing (degree)';
            app.Directionsmoothing = uieditfield(app.TrackingandcombinedplotsoptionsPanel, 'numeric');
            app.Directionsmoothing.Value = 2;
            app.AnglebindegreeLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.AnglebindegreeLabel.Text = 'Angle bin (degree)';
            app.Directionbinsize = uieditfield(app.TrackingandcombinedplotsoptionsPanel, 'numeric');
            app.Directionbinsize.Value = 3;
            app.SpatialbincmEditFieldLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.SpatialbincmEditFieldLabel.Text = 'Spatial bin (cm)';
            app.Spatialbin = uieditfield(app.TrackingandcombinedplotsoptionsPanel, 'numeric');
            app.Spatialbin.Value = 2.5;
            app.SpatialsmoothingcmEditFieldLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.SpatialsmoothingcmEditFieldLabel.Text = 'Spatial smoothing(cm)';
            app.Spatialsmoothing = uieditfield(app.TrackingandcombinedplotsoptionsPanel, 'numeric');
            app.Spatialsmoothing.Value = 3;
            app.SpeedthresholdmincmsEditFieldLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.SpeedthresholdmincmsEditFieldLabel.Text = 'Speed threshold min (cm/s)';
            app.Speedthresholdmin = uieditfield(app.TrackingandcombinedplotsoptionsPanel, 'numeric');
            app.Speedthresholdmin.Value = 2.5;
            app.SpeedthresholdmaxcmsEditFieldLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.SpeedthresholdmaxcmsEditFieldLabel.Text = 'Speed threshold max (cm/s)';
            app.Speedthresholdmax = uieditfield(app.TrackingandcombinedplotsoptionsPanel, 'numeric');
            app.Speedthresholdmax.Value = 100;
            app.PlotrangemincmLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.PlotrangemincmLabel.Text = 'Plot range min (cm)';
            app.PlotRange_min = uieditfield(app.TrackingandcombinedplotsoptionsPanel, 'numeric');
            app.PlotRange_min.Value = -42;
            app.PlotrangemaxcmLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.PlotrangemaxcmLabel.Text = 'Plot range max (cm)';
            app.PlotRange_max = uieditfield(app.TrackingandcombinedplotsoptionsPanel, 'numeric');
            app.PlotRange_max.Value = 42;
            app.MintimesEditFieldLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.MintimesEditFieldLabel.Text = 'Min time (s)';
            app.Mintime = uieditfield(app.TrackingandcombinedplotsoptionsPanel, 'numeric');
            app.Mintime.Value = 0.1;
            app.TrackingandtuningcolormapDropDownLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.TrackingandtuningcolormapDropDownLabel.Text = 'Tracking and tuning colormap';
            app.TrackingandtuningcolormapDropDown = uidropdown(app.TrackingandcombinedplotsoptionsPanel);
            app.TrackingandtuningcolormapDropDown.Items = {'jet', 'parula', 'inferno', 'hot'};
            app.TrackingandtuningcolormapDropDown.Value = 'hot';
            app.ShowpartDropDownLabel = uilabel(app.TrackingandcombinedplotsoptionsPanel);
            app.ShowpartDropDownLabel.Text = 'Show part';
            app.ShowpartDropDown = uidropdown(app.TrackingandcombinedplotsoptionsPanel);
            app.ShowpartDropDown.Items = {'Head', 'Body'};
            app.ShowpartDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowpartDropDownValueChanged, true);
            app.ShowpartDropDown.Value = 'Head';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.RunanalysisButton = uibutton(app.ControlPanel, 'push');
            app.RunanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButtonPushed, true);
            app.RunanalysisButton.Text = 'Run analysis !';
            app.AbortanalysisButton = uibutton(app.ControlPanel, 'push');
            app.AbortanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AbortanalysisButtonPushed, true);
            app.AbortanalysisButton.Text = 'Abort analysis';
            app.CloseallfiguresButton = uibutton(app.ControlPanel, 'push');
            app.CloseallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfiguresButtonPushed, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
            app.CleanupcommendtextButton = uibutton(app.UIFigure, 'push');
            app.CleanupcommendtextButton.ButtonPushedFcn = createCallbackFcn(app, @CleanupcommendtextButtonPushed, true);
            app.CleanupcommendtextButton.Text = 'Clean up commend text';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NATEXplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end