classdef StitchingChecker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WidefieldImagePanel             matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        WF_SelectFile                   matlab.ui.control.Button
        DeleteWF                        matlab.ui.control.Button
        FilepathEditFieldLabel          matlab.ui.control.Label
        WF_FilePathPanel                matlab.ui.control.EditField
        AlphaSliderLabel                matlab.ui.control.Label
        WF_Alpha                        matlab.ui.control.Slider
        MINI2PImageNeuronInformationmatPanel  matlab.ui.container.Panel
        AddFOV                          matlab.ui.control.Button
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        PlaneDropDownLabel              matlab.ui.control.Label
        PlaneDropDown                   matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        UpdateButton                    matlab.ui.control.Button
        TotalcellsEditFieldLabel        matlab.ui.control.Label
        Totalcells                      matlab.ui.control.NumericEditField
        TotalrepeatedcellsLabel         matlab.ui.control.Label
        TotalRepeatCell                 matlab.ui.control.NumericEditField
        AbortButton                     matlab.ui.control.Button
        CellVisible                     matlab.ui.control.CheckBox
        ColorbyDropDownLabel            matlab.ui.control.Label
        ColorbyDropDown                 matlab.ui.control.DropDown
        CellColormap                    matlab.ui.control.DropDown
        ColormapDropDownLabel_2         matlab.ui.control.Label
        CellFacealphaLabel              matlab.ui.control.Label
        CellFaceAlpha                   matlab.ui.control.Slider
        CellLinealphaLabel              matlab.ui.control.Label
        CellLineAlpha                   matlab.ui.control.Slider
        FOVAlphaglobal                  matlab.ui.control.Button
        AlphaEditFieldLabel             matlab.ui.control.Label
        AlphaGlobal                     matlab.ui.control.NumericEditField
        CellAlphaglobal                 matlab.ui.control.Button
        FOVVisible                      matlab.ui.control.CheckBox
        ShowBox                         matlab.ui.control.CheckBox
        ExtractAllCell                  matlab.ui.control.Button
        ShiftXSpinner_2Label            matlab.ui.control.Label
        FOVShiftXControl                matlab.ui.control.Spinner
        ShiftYSpinner_2Label            matlab.ui.control.Label
        FOVShiftYControl                matlab.ui.control.Spinner
        SizeSpinner_2Label              matlab.ui.control.Label
        FOV_resizeControl               matlab.ui.control.Spinner
        RotateSpinner_2Label            matlab.ui.control.Label
        FOV_rotateControl               matlab.ui.control.Spinner
        FlipXControl                    matlab.ui.control.CheckBox
        FlipYControl                    matlab.ui.control.CheckBox
        ShowFOVID                       matlab.ui.control.CheckBox
        DeleteButton                    matlab.ui.control.Button
        StitchibgPoorListBoxLabel       matlab.ui.control.Label
        StitchingPoorListBox            matlab.ui.control.ListBox
        CellextractedLabel              matlab.ui.control.Label
        CellextractedList               matlab.ui.control.ListBox
        CurrentFOVEditFieldLabel        matlab.ui.control.Label
        CurrentFOV                      matlab.ui.control.NumericEditField
        FOVAlphaLabel                   matlab.ui.control.Label
        AlphasingleFOVControl           matlab.ui.control.Slider
        FaceAlpha_globalLabel           matlab.ui.control.Label
        Cell_FaceAlphaGlobal            matlab.ui.control.NumericEditField
        LineAlpha_globalLabel           matlab.ui.control.Label
        Cell_lineAlphaGlobal            matlab.ui.control.NumericEditField
        FontsizeLabel                   matlab.ui.control.Label
        Fontsize                        matlab.ui.control.Spinner
        ColorLabel                      matlab.ui.control.Label
        Colornumber                     matlab.ui.control.Spinner
        HideRepeatedCell_plane          matlab.ui.control.CheckBox
        DetectOverlapCell               matlab.ui.control.Button
        OverlaprationLabel              matlab.ui.control.Label
        OverlapThreshold                matlab.ui.control.NumericEditField
        ExtractAllCell_3                matlab.ui.control.Button
        MisalignySpinnerLabel           matlab.ui.control.Label
        MisalignySpinner                matlab.ui.control.Spinner
        MisalignxSpinnerLabel           matlab.ui.control.Label
        MisalignxSpinner                matlab.ui.control.Spinner
        DistancepixLabel                matlab.ui.control.Label
        PixelThreshold                  matlab.ui.control.NumericEditField
        HideRepeatedCell_FOV            matlab.ui.control.CheckBox
        DataInputandOutputPanel         matlab.ui.container.Panel
        SelectDataFolder                matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        FilepathEditField_2Label        matlab.ui.control.Label
        DataFilepathEditField           matlab.ui.control.EditField
        InputButton                     matlab.ui.control.Button
        StitchingViewerPanel            matlab.ui.container.Panel
        WFAxes                          matlab.ui.control.UIAxes
        ColorbarAxes                    matlab.ui.control.UIAxes
        RetinotopicMappingPanel         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SelectRMButton                  matlab.ui.control.Button
        SelectDFButton                  matlab.ui.control.Button
        DeleteRM                        matlab.ui.control.Button
        RetMapEditFieldLabel            matlab.ui.control.Label
        RetMapEditField                 matlab.ui.control.EditField
        AlphaSliderLabel_2              matlab.ui.control.Label
        RM_alpha                        matlab.ui.control.Slider
        MeandFFEditFieldLabel           matlab.ui.control.Label
        MeandFFEditField                matlab.ui.control.EditField
        MINI2PCorrectionTransfomrMatrixPanel  matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        FilepathEditField_4Label        matlab.ui.control.Label
        TransMatrix_filepath            matlab.ui.control.EditField
        CroesscorrelationanalysisPanel  matlab.ui.container.Panel
        CheckCorrelationButton          matlab.ui.control.Button
        WidefieldCheckBox               matlab.ui.control.CheckBox
        RangepixelLabel                 matlab.ui.control.Label
        RangepixelEditField             matlab.ui.control.NumericEditField
        DrawcommentareaButton           matlab.ui.control.Button
        ClearROIButton                  matlab.ui.control.Button
        ReferenceFOVEditFieldLabel      matlab.ui.control.Label
        ReferenceFOVEditField           matlab.ui.control.NumericEditField
        CurrentFOVAxes                  matlab.ui.control.UIAxes
        ReferenceFOVAxes                matlab.ui.control.UIAxes
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        XcorrelationAxes                matlab.ui.control.UIAxes
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        PeakEditFieldLabel              matlab.ui.control.Label
        PeakEditField                   matlab.ui.control.NumericEditField
        WidefieldtransformationPanel    matlab.ui.container.Panel
        WF_Reset                        matlab.ui.control.Button
        ShiftXSpinnerLabel              matlab.ui.control.Label
        WF_ShiftX                       matlab.ui.control.Spinner
        ShiftYSpinnerLabel              matlab.ui.control.Label
        WF_ShiftY                       matlab.ui.control.Spinner
        SizeSpinnerLabel                matlab.ui.control.Label
        WF_Resize                       matlab.ui.control.Spinner
        RotateSpinnerLabel              matlab.ui.control.Label
        WF_Rotate                       matlab.ui.control.Spinner
        RegionseperationPanel           matlab.ui.container.Panel
        AddRegion                       matlab.ui.control.Button
        Deleteallregion                 matlab.ui.control.Button
        RegionPoorListBoxLabel          matlab.ui.control.Label
        RegionPoorListBox               matlab.ui.control.ListBox
        ShownameCheckBox                matlab.ui.control.CheckBox
        TotalRegionEditFieldLabel       matlab.ui.control.Label
        TotalRegionEditField            matlab.ui.control.NumericEditField
        CurrentRegionEditFieldLabel     matlab.ui.control.Label
        CurrentRegionEditField          matlab.ui.control.NumericEditField
        RegionnameEditFieldLabel        matlab.ui.control.Label
        RegionnameEditField             matlab.ui.control.EditField
        VisibleCheckBox                 matlab.ui.control.CheckBox
        Deleteallregion_2               matlab.ui.control.Button
        AlphaSliderLabel_3              matlab.ui.control.Label
        Region_Alpha                    matlab.ui.control.Slider
        LineWidthLabel                  matlab.ui.control.Label
        LineWidth                       matlab.ui.control.Spinner
        AssignCell                      matlab.ui.control.Button
        RegionAlphaglobal               matlab.ui.control.Button
        LineAlpha_globalLabel_2         matlab.ui.control.Label
        Region_AlphaGlobal              matlab.ui.control.NumericEditField
        TextboxListBoxLabel             matlab.ui.control.Label
        Textbox                         matlab.ui.control.ListBox
        CleanTextboxButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        WF_file ;% file name for wide field image
        WF_path; % file path for wide field image
        WF_Image_raw; % raw wide-field image
        WF_Image_normalized ;% raw wide-field image
        WF_Image_shift; % raw wide-field image
        RM_file; % file name for wide field image
        RM_path; % file path for wide field image
        RM_Image_raw ;% raw wide-field image
        RM_Image_normalized ;% raw wide-field image
        RM_Image_shift; % raw wide-field image
        H_WF={}; % Handle for wide-field image
        H_RM={} ;% Handle for wide-field image
        StitchingPoor={};
        H_FOV={};
        H_Cells={};
        H_FOVID={};
        Regions={};  
        ROI_X={};
        ROI_Y={};        
        HideCell={}
        CellinRegion={};        
        
        
        DF_Image;
        DF_Image_shift;

        TransformMatrix=[];
        FOV_planeshow=[];
        FOV_Channelshow=[];   
        FOV_Projectshow=[];   
        
        FOV_X;
        FOV_Y;
        FOV_rotate;
        FOV_flipX;
        FOV_flipY;
        FOV_alpha;
        FOV_size;
        
        Image_corrected={};
        Image_normalized={};
        Image_shift={};
        
        CommonArea;
        CurrentFOVbox;

        Abort_flag=false;

        CellFace;
        CellEdge;

        OverlapMatrix=[]
        OverlappedCell={}
        
        RepeatedCell=[]
        
        processeddatafolder=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WF_SelectFile
        function WF_SelectFilePushed(app, event)
            [app.WF_file,app.WF_path] = uigetfile('*.tif','Please select the wide-field image');
            cd (app.WF_path);
            app.WF_Image_raw=imread([app.WF_path,'\',app.WF_file]);
            app.WF_FilePathPanel.Value=[app.WF_path,'\',app.WF_file];
            app.Textbox.Items(length(app.Textbox.Items)+1)={[app.WF_path,'\',app.WF_file,' is loaded']};
            app.WF_Image_raw=double(app.WF_Image_raw);
            app.WF_Image_normalized=(app.WF_Image_raw-min(app.WF_Image_raw(:)))./(max(app.WF_Image_raw(:))-min(app.WF_Image_raw(:)));
            app.WF_Image_shift=imresize(app.WF_Image_normalized,app.WF_Resize.Value,'bilinear');
            app.WF_Image_shift=imrotate(app.WF_Image_shift,app.WF_Rotate.Value,'bilinear');
            if ~isempty(app.H_WF)
                app.H_WF.CData=uint8(255*app.WF_Image_shift);
            else
                app.H_WF=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,uint8(255*app.WF_Image_shift));
                app.H_WF.AlphaData=app.WF_Alpha.Value;
                colormap(app.WFAxes,gray)
                app.WFAxes.DataAspectRatio=[1 1 1]; 
                if ~isempty(app.H_RM)
                    preprocessing.ZOrderSet(app.H_RM, 0);
                else
                end
                preprocessing.ZOrderSet(app.H_WF, 0);                             
            end
        end

        % Value changed function: WF_Rotate
        function WF_RotateValueChanged(app, event)
            if ~isempty(app.WF_Image_normalized)
                app.WF_Image_shift=imresize(app.WF_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.WF_Image_shift=imrotate(app.WF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.RM_Image_normalized)
                app.RM_Image_shift=imresize(app.RM_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.RM_Image_shift=imrotate(app.RM_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.DF_Image)
                app.DF_Image_shift=imresize(app.DF_Image,app.WF_Resize.Value,'bilinear');
                app.DF_Image_shift=imrotate(app.DF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            app.H_WF.CData=uint8(255*app.WF_Image_shift);
            app.H_WF.AlphaData=app.WF_Alpha.Value;
            RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));
            app.H_RM.CData=RM_Image_shift_rgb;
            if isempty(app.DF_Image_shift)
                app.H_RM.AlphaData=app.RM_alpha.Value;
            else
                app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
            end            
        end

        % Value changed function: AlphasingleFOVControl
        function AlphasingleFOVControlValueChanged(app, event)
            app.H_FOV{app.CurrentFOV.Value,1}.AlphaData= app.AlphasingleFOVControl.Value.*(app.Image_shift{app.CurrentFOV.Value,1}>0);
            app.FOV_alpha(app.CurrentFOV.Value,1)=app.AlphasingleFOVControl.Value;          
        end

        % Value changed function: WF_Alpha
        function WF_AlphaValueChanged(app, event)
            app.H_WF.AlphaData=app.WF_Alpha.Value;
            if ~isempty(app.H_RM)
                preprocessing.ZOrderSet(app.H_RM, 0);
            else
            end
            preprocessing.ZOrderSet(app.H_WF, 0);
        end

        % Value changed function: WF_Resize
        function WF_ResizeValueChanged(app, event)
            if ~isempty(app.WF_Image_normalized)
                app.WF_Image_shift=imresize(app.WF_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.WF_Image_shift=imrotate(app.WF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.RM_Image_normalized)
                app.RM_Image_shift=imresize(app.RM_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.RM_Image_shift=imrotate(app.RM_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.DF_Image)
                app.DF_Image_shift=imresize(app.DF_Image,app.WF_Resize.Value,'bilinear');
                app.DF_Image_shift=imrotate(app.DF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            app.H_WF.CData=uint8(255*app.WF_Image_shift);
            app.H_WF.AlphaData=app.WF_Alpha.Value;
            RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));
            app.H_RM.CData=RM_Image_shift_rgb;
            if isempty(app.DF_Image_shift)
                app.H_RM.AlphaData=app.RM_alpha.Value;
            else
                app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
            end   
        end

        % Value changed function: WF_ShiftY
        function WF_ShiftYValueChanged(app, event)
            app.H_WF.YData=app.WF_ShiftY.Value;
            app.H_RM.YData=app.WF_ShiftY.Value;
        end

        % Value changed function: WF_ShiftX
        function WF_ShiftXValueChanged(app, event)
            app.H_WF.XData=app.WF_ShiftX.Value;
            app.H_RM.XData=app.WF_ShiftX.Value;
        end

        % Button pushed function: SelectRMButton
        function SelectRMButtonPushed(app, event)
            [app.RM_file,app.RM_path] = uigetfile('*.tif','Please select the retinotopic mapping image');
            cd (app.RM_path);
            app.RM_Image_raw=imread([app.RM_path,'\',app.RM_file]);
            app.RetMapEditField.Value=[app.RM_path,'\',app.RM_file];
            app.Textbox.Items(length(app.Textbox.Items)+1)={[app.RM_path,'\',app.RM_file,' is loaded']};
            app.RM_Image_raw=double(app.RM_Image_raw);
            app.RM_Image_normalized=(app.RM_Image_raw-min(app.RM_Image_raw(:)))./(max(app.RM_Image_raw(:))-min(app.RM_Image_raw(:)));
            app.RM_Image_shift=imresize(app.RM_Image_normalized,app.WF_Resize.Value,'bilinear');
            app.RM_Image_shift=imrotate(app.RM_Image_shift,app.WF_Rotate.Value,'bilinear');
            RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));            
            if ~isempty(app.H_RM)
                app.H_RM.CData=RM_Image_shift_rgb;
            else
                hold (app.WFAxes,'on');
                app.H_RM=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,RM_Image_shift_rgb);
                if isempty(app.DF_Image_shift)
                    app.H_RM.AlphaData=app.RM_alpha.Value;
                else
                    app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
                end
                preprocessing.ZOrderSet(app.H_RM, 0);
                preprocessing.ZOrderSet(app.H_WF, 0);
            end
        end

        % Value changed function: RM_alpha
        function RM_alphaValueChanged(app, event)
            
            if isempty(app.DF_Image_shift)
                app.H_RM.AlphaData=app.RM_alpha.Value;
            else
                app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
            end            
        end

        % Button pushed function: SelectDFButton
        function SelectDFButtonPushed(app, event)
            [DF_file,DF_path] = uigetfile('*.tif','Please select the averaged dF/F image');
            cd (DF_path);
            app.DF_Image=imread([DF_path,'\',DF_file]);
            app.MeandFFEditField.Value=[DF_path,'\',DF_file];
            app.Textbox.Items(length(app.Textbox.Items)+1)={[DF_path,'\',DF_file,' is loaded']};
            app.DF_Image=double(app.DF_Image);
            app.DF_Image=(app.DF_Image-min(app.DF_Image(:)))./(max(app.DF_Image(:))-min(app.DF_Image(:)));
            app.DF_Image_shift=imresize(app.DF_Image,app.WF_Resize.Value,'bilinear');
            app.DF_Image_shift=imrotate(app.DF_Image_shift,app.WF_Rotate.Value,'bilinear');
            app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
            preprocessing.ZOrderSet(app.H_RM, 0);
            preprocessing.ZOrderSet(app.H_WF, 0);
        end

        % Button pushed function: WF_Reset
        function WF_ResetButtonPushed(app, event)
            app.WF_Resize.Value=1;
            app.WF_Rotate.Value=0;
            app.WF_ShiftX.Value=0;
            app.WF_ShiftY.Value=0;
            
            if ~isempty(app.WF_Image_normalized)
                app.WF_Image_shift=imresize(app.WF_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.WF_Image_shift=imrotate(app.WF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.RM_Image_normalized)
                app.RM_Image_shift=imresize(app.RM_Image_normalized,app.WF_Resize.Value,'bilinear');
                app.RM_Image_shift=imrotate(app.RM_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            if ~isempty(app.DF_Image)
                app.DF_Image_shift=imresize(app.DF_Image,app.WF_Resize.Value,'bilinear');
                app.DF_Image_shift=imrotate(app.DF_Image_shift,app.WF_Rotate.Value,'bilinear');
            else
            end
            app.H_WF.XData=app.WF_ShiftX.Value;
            app.H_WF.YData=app.WF_ShiftY.Value;
            app.H_WF.CData=uint8(255*app.WF_Image_shift);
            app.H_WF.AlphaData=app.WF_Alpha.Value;
            
            RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));

            app.H_RM.XData=app.WF_ShiftX.Value;
            app.H_RM.YData=app.WF_ShiftY.Value;
            app.H_RM.CData=RM_Image_shift_rgb;
            if isempty(app.DF_Image_shift)
                app.H_RM.AlphaData=app.RM_alpha.Value;
            else
                app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
            end

        end

        % Button pushed function: DeleteWF
        function DeleteWFButtonPushed(app, event)
            if ~isempty(app.H_WF)
                delete(app.H_WF)
                app.H_WF={};
                app.Textbox.Items(length(app.Textbox.Items)+1)={'Wide-field image is deleted'};
                scroll(app.Textbox, 'bottom') ;
            else
            end
        end

        % Button pushed function: DeleteRM
        function DeleteRMButtonPushed(app, event)
            if ~isempty(app.H_RM)
                delete(app.H_RM)
                app.H_RM={};
                app.Textbox.Items(length(app.Textbox.Items)+1)={'TRetinotopic map is deleted'};
                scroll(app.Textbox, 'bottom') ;
            else
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            tem=load(app.TransMatrix_filepath.Value);
            app.TransformMatrix=tem.TransformMatrix;
            app.Textbox.Items(length(app.Textbox.Items)+1)={'Transformantion matrix is loaded'};
            scroll(app.Textbox, 'bottom') ;
            
        end

        % Button pushed function: AddFOV
        function AddFOVButtonPushed(app, event)
            app.CurrentFOV.Value=length(app.StitchingPoor)+1;
            [file,path] = uigetfile('*.mat','Please select the NeuronInformation.mat file');
            cd (path);
            app.StitchingPoor{length(app.StitchingPoor)+1,1}=load([path,'\',file]);
            
            app.Textbox.Items(length(app.Textbox.Items)+1)={[path,'\',file,' is loaded as FOV ', num2str(app.CurrentFOV.Value)]};
            scroll(app.Textbox, 'bottom') ;
            
            app.H_Cells{app.CurrentFOV.Value,1}={};
            app.ROI_X{app.CurrentFOV.Value,1}=[];
            app.ROI_Y{app.CurrentFOV.Value,1}=[];
            
            Marker=strfind(app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.File_folder_root,'\');
            Filename=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.File_folder_root(Marker(end)+1:end);
            
            app.StitchingPoorListBox.Items(length(app.StitchingPoor))={['FOV-',num2str(app.CurrentFOV.Value),'-',Filename]};
            app.StitchingPoorListBox.Value=app.StitchingPoorListBox.Items(app.CurrentFOV.Value);
            app.CellextractedList.Items(length(app.StitchingPoor))={'NA'};
            app.CellextractedList.Value=app.CellextractedList.Items(app.CurrentFOV.Value);


            StartPixel=(str2double(app.PlaneDropDown.Value)-1)*app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)+1;
            app.FOV_planeshow(app.CurrentFOV.Value,1)=str2double(app.PlaneDropDown.Value); 
            if strcmp(app.TypeDropDown.Value,'Mean')
                app.FOV_Projectshow(app.CurrentFOV.Value,1)=1; 
                if strcmp(app.ChannelDropDown.Value,'1')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=1; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.meanImg(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                elseif strcmp(app.ChannelDropDown.Value,'2')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=2; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.meanImg_chan2(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                else
                end
            else
                app.FOV_Projectshow(app.CurrentFOV.Value,1)=2; 
                if strcmp(app.ChannelDropDown.Value,'1')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=1; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.max_proj(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                else
                end
            end
 

            if isempty(app.TransformMatrix)
                app.Image_corrected{app.CurrentFOV.Value,1}=Image_show;
                app.Textbox.Items(length(app.Textbox.Items)+1)={'No transformmation matrxi, raw image is used'};
                scroll(app.Textbox, 'bottom') ;
            else
                app.Image_corrected{app.CurrentFOV.Value,1}=imwarp(Image_show,app.TransformMatrix,'OutputView',imref2d(size(Image_show)));
                app.Textbox.Items(length(app.Textbox.Items)+1)={'Corrected image is used'};
                scroll(app.Textbox, 'bottom') ;
            end
                  
            app.Image_corrected{app.CurrentFOV.Value,1}=double(app.Image_corrected{app.CurrentFOV.Value,1});
            if app.FlipXControl.Value==1
                Image_flip=flip(app.Image_corrected{app.CurrentFOV.Value,1},1);
            else
                Image_flip=app.Image_corrected{app.CurrentFOV.Value,1};
            end
            if app.FlipYControl.Value==1
                Image_flip=flip(Image_flip,2);
            else
                Image_flip=Image_flip;
            end
            app.Image_normalized{app.CurrentFOV.Value,1}=(Image_flip-min(Image_flip(:)))./(max(Image_flip(:))-min(Image_flip(:)));
            
            app.Image_shift{app.CurrentFOV.Value,1}=imresize(app.Image_normalized{app.CurrentFOV.Value,1},app.FOV_resizeControl.Value,'bilinear');
            app.Image_shift{app.CurrentFOV.Value,1}=imrotate(app.Image_shift{app.CurrentFOV.Value,1},app.FOV_rotateControl.Value,'bilinear');
            
            hold(app.WFAxes,'on')
            
            app.H_FOV{app.CurrentFOV.Value,1}=image(app.WFAxes,app.FOVShiftXControl.Value,app.FOVShiftYControl.Value,uint8(255*app.Image_shift{app.CurrentFOV.Value,1}));
            app.H_FOV{app.CurrentFOV.Value,1}.AlphaData=app.AlphasingleFOVControl.Value.*(app.Image_shift{app.CurrentFOV.Value,1}>0);
            colormap(app.WFAxes,gray)
            app.WFAxes.DataAspectRatio=[1 1 1];
            preprocessing.ZOrderSet(app.H_FOV{app.CurrentFOV.Value,1}, 1);
            if app.FOVVisible.Value==1
                set(app.H_FOV{app.CurrentFOV.Value,1},'Visible','on');
                
            else
                set(app.H_FOV{app.CurrentFOV.Value,1},'Visible','off');
            end
            hold(app.WFAxes,'off')
            
            app.H_FOVID{app.CurrentFOV.Value,1}=text(app.WFAxes,app.FOVShiftXControl.Value+...
                                            size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1)/2,...
                                            app.FOVShiftYControl.Value+...
                                            size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)/2,...
                                            num2str(app.CurrentFOV.Value),'Color',[0.2 0.2 0.2],...
                                            'BackgroundColor',[0.7 0.7 0.7],'FontSize',app.Fontsize.Value);
            preprocessing.ZOrderSet(app.H_FOVID{app.CurrentFOV.Value,1}, 1);
            if app.ShowFOVID.Value==1
                set(app.H_FOVID{app.CurrentFOV.Value,1},'Visible','on');
                
            else
                set(app.H_FOVID{app.CurrentFOV.Value,1},'Visible','off');
            end
          
            app.FOV_X(app.CurrentFOV.Value,1)=app.FOVShiftXControl.Value;
            app.FOV_Y(app.CurrentFOV.Value,1)=app.FOVShiftYControl.Value;
            app.FOV_rotate(app.CurrentFOV.Value,1)=app.FOV_rotateControl.Value;
            app.FOV_size(app.CurrentFOV.Value,1)=app.FOV_resizeControl.Value;
            app.FOV_alpha(app.CurrentFOV.Value,1)=app.AlphasingleFOVControl.Value;
            app.FOV_flipX(app.CurrentFOV.Value,1)=app.FlipXControl.Value;
            app.FOV_flipY(app.CurrentFOV.Value,1)=app.FlipYControl.Value;
            app.CellFace(app.CurrentFOV.Value,1)=app.CellFaceAlpha.Value;
            app.CellEdge(app.CurrentFOV.Value,1)=app.CellLineAlpha.Value;
            CurrentFOVposition=[app.H_FOV{app.CurrentFOV.Value,1}.XData app.H_FOV{app.CurrentFOV.Value,1}.YData size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)];
            
            if isempty(app.CurrentFOVbox)
                app.CurrentFOVbox=rectangle(app.WFAxes,'Position',CurrentFOVposition,'EdgeColor','r','LineWidth',1);
            else
                app.CurrentFOVbox.Position=CurrentFOVposition;
            end
            assignin('base','H_FOVID',app.H_FOVID{app.CurrentFOV.Value,1})
            if app.ShowBox.Value==1
                set(app.CurrentFOVbox,'visible','on');
            else
                set(app.CurrentFOVbox,'visible','off');
            end
            
            app.Totalcells.Value=0;
            for    i=1:1:length(app.StitchingPoor)
                app.Totalcells.Value=app.Totalcells.Value+app.StitchingPoor{i,1}.NeuronInformation.TotalCell;
            end
            app.TotalRepeatCell.Value=0;
            app.RepeatedCell(app.CurrentFOV.Value)=size(app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.RepeatCell,2);
            for    i=1:1:length(app.StitchingPoor)                
                app.TotalRepeatCell.Value=app.TotalRepeatCell.Value+app.RepeatedCell(i);
            end
            
%             assignin('base','H_FOV',app.H_FOV{app.CurrentFOV.Value,1})
        end

        % Value changed function: StitchingPoorListBox
        function StitchingPoorListBoxValueChanged(app, event)
            value = app.StitchingPoorListBox.Value;
            Marker=strfind(value,'-');
            SelectedFOV=str2num(value(Marker(1)+1:Marker(2)-1));
            app.CurrentFOV.Value=SelectedFOV;
            app.CellextractedList.Value=app.CellextractedList.Items(app.CurrentFOV.Value);
            Marker=strfind(app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.File_folder_root,'\');
            preprocessing.ZOrderSet(app.H_FOV{app.CurrentFOV.Value,1}, 1);
            class(app.H_Cells{app.CurrentFOV.Value,1})
            if isa(app.H_Cells{app.CurrentFOV.Value,1},'matlab.graphics.primitive.Patch')
                preprocessing.ZOrderSet(app.H_Cells{app.CurrentFOV.Value,1}, 1);
            else
            end
            preprocessing.ZOrderSet(app.H_FOVID{app.CurrentFOV.Value,1}, 1);
            
            
            app.PlaneDropDown.Value=num2str(app.FOV_planeshow(app.CurrentFOV.Value,1));             
            app.ChannelDropDown.Value=num2str(app.FOV_Channelshow(app.CurrentFOV.Value,1));  
            app.TypeDropDown.Value=app.TypeDropDown.Items(app.FOV_Projectshow(app.CurrentFOV.Value,1));
            
            app.FOVShiftXControl.Value=app.FOV_X(app.CurrentFOV.Value,1);
            app.FOVShiftYControl.Value=app.FOV_Y(app.CurrentFOV.Value,1);
            app.FOV_rotateControl.Value=app.FOV_rotate(app.CurrentFOV.Value,1);
            app.FOV_resizeControl.Value=app.FOV_size(app.CurrentFOV.Value,1);
            app.AlphasingleFOVControl.Value=app.FOV_alpha(app.CurrentFOV.Value,1);
            app.FlipXControl.Value=app.FOV_flipX(app.CurrentFOV.Value,1);
            app.FlipYControl.Value=app.FOV_flipY(app.CurrentFOV.Value,1);
            app.CellextractedList.Value=app.CellextractedList.Items(app.CurrentFOV.Value);
            app.CellFaceAlpha.Value=app.CellFace(app.CurrentFOV.Value,1);
            app.CellLineAlpha.Value=app.CellEdge(app.CurrentFOV.Value,1);            
            
            CurrentFOVposition=[app.H_FOV{app.CurrentFOV.Value,1}.XData app.H_FOV{app.CurrentFOV.Value,1}.YData size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)];
            app.CurrentFOVbox.Position=CurrentFOVposition;
            app.Textbox.Items(length(app.Textbox.Items)+1)={['Selet FOV', num2str(app.CurrentFOV.Value),', raw file:',app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.File_folder_root(Marker(end)+1:end)]};
            scroll(app.Textbox, 'bottom') ;
            
        end

        % Button pushed function: CleanTextboxButton
        function CleanTextboxButtonPushed(app, event)
           app.Textbox.Items={};
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            [file,path] = uigetfile('*.mat','Please select the NeuronInformation.mat file');
            cd (path);
            app.StitchingPoor{app.CurrentFOV.Value,1}=load([path,'\',file]);
            app.Textbox.Items(length(app.Textbox.Items)+1)={[path,'\',file,' is loaded as FOV ', num2str(app.CurrentFOV.Value)]};
            scroll(app.Textbox, 'bottom') ;
            Marker=strfind(app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.File_folder_root,'\');
            Filename=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.File_folder_root(Marker(end)+1:end);
            
            if ~isempty(app.H_Cells{app.CurrentFOV.Value,1})
                delete (app.H_Cells{app.CurrentFOV.Value,1});
                app.H_Cells{app.CurrentFOV.Value,1}={};
                app.ROI_X{app.CurrentFOV.Value,1}=[];
                app.ROI_Y{app.CurrentFOV.Value,1}=[];
                app.CellextractedList.Items(app.CurrentFOV.Value)={'NA'};
                app.CellextractedList.Value=app.CellextractedList.Items(app.CurrentFOV.Value);
            else
            end
            app.CellinRegion{app.CurrentFOV.Value,1}=[];
            app.StitchingPoorListBox.Items(app.CurrentFOV.Value)={['FOV-',num2str(app.CurrentFOV.Value),'-',Filename]};


            app.StitchingPoorListBox.Value=app.StitchingPoorListBox.Items(app.CurrentFOV.Value);
 
            StartPixel=(str2double(app.PlaneDropDown.Value)-1)*app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)+1;
            app.FOV_planeshow(app.CurrentFOV.Value,1)=str2double(app.PlaneDropDown.Value); 
            if strcmp(app.TypeDropDown.Value,'Mean')
                app.FOV_Projectshow(app.CurrentFOV.Value,1)=1; 
                if strcmp(app.ChannelDropDown.Value,'1')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=1; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.meanImg(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                elseif strcmp(app.ChannelDropDown.Value,'2')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=2; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.meanImg_chan2(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                else
                end
            else
                app.FOV_Projectshow(app.CurrentFOV.Value,1)=2; 
                if strcmp(app.ChannelDropDown.Value,'1')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=1; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.max_proj(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                else
                end
            end 

            
            if isempty(app.TransformMatrix)
                app.Image_corrected{app.CurrentFOV.Value,1}=Image_show;
                app.Textbox.Items(length(app.Textbox.Items)+1)={'No transformmation matrxi, raw image is used'};
                scroll(app.Textbox, 'bottom') ;
            else
                app.Image_corrected{app.CurrentFOV.Value,1}=imwarp(Image_show,app.TransformMatrix,'OutputView',imref2d(size(Image_show)));
                app.Textbox.Items(length(app.Textbox.Items)+1)={'Corrected image is used'};
                scroll(app.Textbox, 'bottom') ;
            end
            
            
            app.Image_corrected{app.CurrentFOV.Value,1}=double(app.Image_corrected{app.CurrentFOV.Value,1});
            if app.FlipXControl.Value==1
                Image_flip=flip(app.Image_corrected{app.CurrentFOV.Value,1},1);
            else
                Image_flip=app.Image_corrected{app.CurrentFOV.Value,1};
            end
            if app.FlipYControl.Value==1
                Image_flip=flip(Image_flip,2);
            else
                Image_flip=Image_flip;
            end
            app.Image_normalized{app.CurrentFOV.Value,1}=(Image_flip-min(Image_flip(:)))./(max(Image_flip(:))-min(Image_flip(:)));
            
            app.Image_shift{app.CurrentFOV.Value,1}=imresize(app.Image_normalized{app.CurrentFOV.Value,1},app.FOV_resizeControl.Value,'bilinear');
            app.Image_shift{app.CurrentFOV.Value,1}=imrotate(app.Image_shift{app.CurrentFOV.Value,1},app.FOV_rotateControl.Value,'bilinear');
            
            
            app.H_FOV{app.CurrentFOV.Value,1}.CData=uint8(255*app.Image_shift{app.CurrentFOV.Value,1});
            app.H_FOV{app.CurrentFOV.Value,1}.AlphaData=app.AlphasingleFOVControl.Value.*(app.Image_shift{app.CurrentFOV.Value,1}>0);


            app.FOV_X(app.CurrentFOV.Value,1)=app.FOVShiftXControl.Value;
            app.FOV_Y(app.CurrentFOV.Value,1)=app.FOVShiftYControl.Value;
            app.FOV_rotate(app.CurrentFOV.Value,1)=app.FOV_rotateControl.Value;
            app.FOV_size(app.CurrentFOV.Value,1)=app.FOV_resizeControl.Value;
            app.FOV_alpha(app.CurrentFOV.Value,1)=app.AlphasingleFOVControl.Value;
            app.FOV_flipX(app.CurrentFOV.Value,1)=app.FlipXControl.Value;
            app.FOV_flipY(app.CurrentFOV.Value,1)=app.FlipYControl.Value;
            CurrentFOVposition=[app.H_FOV{app.CurrentFOV.Value,1}.XData app.H_FOV{app.CurrentFOV.Value,1}.YData size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)];
            app.CurrentFOVbox.Position=CurrentFOVposition;
            
            app.Totalcells.Value=0;
            for    i=1:1:length(app.StitchingPoor)
                app.Totalcells.Value=app.Totalcells.Value+app.StitchingPoor{i,1}.NeuronInformation.TotalCell;
            end
            app.TotalRepeatCell.Value=0;
            app.RepeatedCell(app.CurrentFOV.Value)=size(app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.RepeatCell,2);
            for    i=1:1:length(app.StitchingPoor)                
                app.TotalRepeatCell.Value=app.TotalRepeatCell.Value+app.RepeatedCell(i);
            end
            
        end

        % Value changed function: FOVShiftXControl
        function FOVShiftXControlValueChanged(app, event)
            app.H_FOV{app.CurrentFOV.Value,1}.XData=app.FOVShiftXControl.Value;
            app.FOV_X(app.CurrentFOV.Value,1)=app.FOVShiftXControl.Value;
            if ~isempty(app.H_Cells{app.CurrentFOV.Value,1})
                app.H_Cells{app.CurrentFOV.Value,1}.XData=app.ROI_X{app.CurrentFOV.Value,1}+app.FOV_X(app.CurrentFOV.Value,1)+app.MisalignxSpinner.Value;
            else
            end
            CurrentFOVposition=[app.H_FOV{app.CurrentFOV.Value,1}.XData app.H_FOV{app.CurrentFOV.Value,1}.YData size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)];
            app.CurrentFOVbox.Position=CurrentFOVposition;
            app.H_FOVID{app.CurrentFOV.Value,1}.Position(1)=app.FOVShiftXControl.Value+size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1)/2;
            preprocessing.ZOrderSet(app.H_FOVID{app.CurrentFOV.Value,1}, 1);
            app.CellinRegion{app.CurrentFOV.Value,1}=[];
        end

        % Value changed function: FOVShiftYControl
        function FOVShiftYControlValueChanged(app, event)
            app.H_FOV{app.CurrentFOV.Value,1}.YData=app.FOVShiftYControl.Value;
            app.FOV_Y(app.CurrentFOV.Value,1)=app.FOVShiftYControl.Value;
            if ~isempty(app.H_Cells{app.CurrentFOV.Value,1})
                app.H_Cells{app.CurrentFOV.Value,1}.YData=app.ROI_Y{app.CurrentFOV.Value,1}+app.FOV_Y(app.CurrentFOV.Value,1)+app.MisalignySpinner.Value;
            else
            end
            CurrentFOVposition=[app.H_FOV{app.CurrentFOV.Value,1}.XData app.H_FOV{app.CurrentFOV.Value,1}.YData size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)];
            app.CurrentFOVbox.Position=CurrentFOVposition;
            app.H_FOVID{app.CurrentFOV.Value,1}.Position(2)=app.FOVShiftYControl.Value+size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)/2;
            preprocessing.ZOrderSet(app.H_FOVID{app.CurrentFOV.Value,1}, 1);
            app.CellinRegion{app.CurrentFOV.Value,1}=[];
        end

        % Value changed function: FOV_resizeControl
        function FOV_resizeControlValueChanged(app, event)
            if isa(app.H_Cells{app.CurrentFOV.Value,1},'matlab.graphics.primitive.Patch')
                delete (app.H_Cells{app.CurrentFOV.Value,1});
                app.H_Cells{app.CurrentFOV.Value,1}={};
                app.ROI_X{app.CurrentFOV.Value,1}=[];
                app.ROI_Y{app.CurrentFOV.Value,1}=[];
                app.CellextractedList.Items(app.CurrentFOV.Value)={'NA'};
                app.CellextractedList.Value=app.CellextractedList.Items(app.CurrentFOV.Value);
            else
            end
            app.CellinRegion{app.CurrentFOV.Value,1}=[];
            app.Textbox.Items(length(app.Textbox.Items)+1)={'FOV size is changed, you need to re-extract the cells!'};
            scroll(app.Textbox, 'bottom') ;
            app.Image_shift{app.CurrentFOV.Value,1}=imresize(app.Image_normalized{app.CurrentFOV.Value,1},app.FOV_resizeControl.Value,'bilinear');
            app.Image_shift{app.CurrentFOV.Value,1}=imrotate(app.Image_shift{app.CurrentFOV.Value,1},app.FOV_rotateControl.Value,'bilinear');
            app.H_FOV{app.CurrentFOV.Value,1}.CData=uint8(255*app.Image_shift{app.CurrentFOV.Value,1});
            app.H_FOV{app.CurrentFOV.Value,1}.AlphaData=app.AlphasingleFOVControl.Value.*(app.Image_shift{app.CurrentFOV.Value,1}>0);
            app.FOV_size(app.CurrentFOV.Value,1)=app.FOV_resizeControl.Value;
            CurrentFOVposition=[app.H_FOV{app.CurrentFOV.Value,1}.XData app.H_FOV{app.CurrentFOV.Value,1}.YData size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)];
            app.CurrentFOVbox.Position=CurrentFOVposition;
            app.H_FOVID{app.CurrentFOV.Value,1}.Position(1)=app.FOVShiftXControl.Value+size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1)/2;
            app.H_FOVID{app.CurrentFOV.Value,1}.Position(2)=app.FOVShiftYControl.Value+size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)/2;
            preprocessing.ZOrderSet(app.H_FOVID{app.CurrentFOV.Value,1}, 1);               
            
        end

        % Value changed function: FOV_rotateControl
        function FOV_rotateControlValueChanged(app, event)
            if isa(app.H_Cells{app.CurrentFOV.Value,1},'matlab.graphics.primitive.Patch')
                delete (app.H_Cells{app.CurrentFOV.Value,1});
                app.H_Cells{app.CurrentFOV.Value,1}={};
                app.ROI_X{app.CurrentFOV.Value,1}=[];
                app.ROI_Y{app.CurrentFOV.Value,1}=[];
                app.CellextractedList.Items(app.CurrentFOV.Value)={'NA'};
                app.CellextractedList.Value=app.CellextractedList.Items(app.CurrentFOV.Value);
            else
            end
            app.CellinRegion{app.CurrentFOV.Value,1}=[];
            app.Textbox.Items(length(app.Textbox.Items)+1)={'FOV rotation is changed, you need to re-extract the cells!'};
            scroll(app.Textbox, 'bottom') ;


            app.Image_shift{app.CurrentFOV.Value,1}=imresize(app.Image_normalized{app.CurrentFOV.Value,1},app.FOV_resizeControl.Value,'bilinear');
            app.Image_shift{app.CurrentFOV.Value,1}=imrotate(app.Image_shift{app.CurrentFOV.Value,1},app.FOV_rotateControl.Value,'bilinear');
            app.H_FOV{app.CurrentFOV.Value,1}.CData=uint8(255*app.Image_shift{app.CurrentFOV.Value,1});
            app.H_FOV{app.CurrentFOV.Value,1}.AlphaData=app.AlphasingleFOVControl.Value.*(app.Image_shift{app.CurrentFOV.Value,1}>0);
            app.FOV_rotate(app.CurrentFOV.Value,1)=app.FOV_rotateControl.Value;
            CurrentFOVposition=[app.H_FOV{app.CurrentFOV.Value,1}.XData app.H_FOV{app.CurrentFOV.Value,1}.YData size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)];
            app.CurrentFOVbox.Position=CurrentFOVposition;
            app.H_FOVID{app.CurrentFOV.Value,1}.Position(1)=app.FOVShiftXControl.Value+size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1)/2;
            app.H_FOVID{app.CurrentFOV.Value,1}.Position(2)=app.FOVShiftYControl.Value+size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)/2;
            preprocessing.ZOrderSet(app.H_FOVID{app.CurrentFOV.Value,1}, 1);   


        end

        % Value changed function: FlipXControl
        function FlipXControlValueChanged(app, event)
            if isa(app.H_Cells{app.CurrentFOV.Value,1},'matlab.graphics.primitive.Patch')
                delete (app.H_Cells{app.CurrentFOV.Value,1});
                app.H_Cells{app.CurrentFOV.Value,1}={};
                app.ROI_X{app.CurrentFOV.Value,1}=[];
                app.ROI_Y{app.CurrentFOV.Value,1}=[];
                app.CellextractedList.Items(app.CurrentFOV.Value)={'NA'};
                app.CellextractedList.Value=app.CellextractedList.Items(app.CurrentFOV.Value);
            else
            end
            app.CellinRegion{app.CurrentFOV.Value,1}=[];
            app.Textbox.Items(length(app.Textbox.Items)+1)={'FOV direction is changed, you need to re-extract the cells!'};
            scroll(app.Textbox, 'bottom') ;
            if app.FlipXControl.Value==1
                Image_flip=flip(app.Image_corrected{app.CurrentFOV.Value,1},1);
            else
                Image_flip=app.Image_corrected{app.CurrentFOV.Value,1};
            end
            if app.FlipYControl.Value==1
                Image_flip=flip(Image_flip,2);
            else
                Image_flip=Image_flip;
            end
            app.Image_normalized{app.CurrentFOV.Value,1}=(Image_flip-min(Image_flip(:)))./(max(Image_flip(:))-min(Image_flip(:)));
            app.Image_shift{app.CurrentFOV.Value,1}=imresize(app.Image_normalized{app.CurrentFOV.Value,1},app.FOV_resizeControl.Value,'bilinear');
            app.Image_shift{app.CurrentFOV.Value,1}=imrotate(app.Image_shift{app.CurrentFOV.Value,1},app.FOV_rotateControl.Value,'bilinear');
            app.H_FOV{app.CurrentFOV.Value,1}.CData=uint8(255*app.Image_shift{app.CurrentFOV.Value,1});
            app.H_FOV{app.CurrentFOV.Value,1}.AlphaData=app.AlphasingleFOVControl.Value.*(app.Image_shift{app.CurrentFOV.Value,1}>0);
            app.FOV_flipX(app.CurrentFOV.Value,1)=app.FlipXControl.Value;
            CurrentFOVposition=[app.H_FOV{app.CurrentFOV.Value,1}.XData app.H_FOV{app.CurrentFOV.Value,1}.YData size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)];
            app.CurrentFOVbox.Position=CurrentFOVposition;
            app.H_FOVID{app.CurrentFOV.Value,1}.Position(1)=app.FOVShiftXControl.Value+size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1)/2;
            app.H_FOVID{app.CurrentFOV.Value,1}.Position(2)=app.FOVShiftYControl.Value+size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)/2;
            preprocessing.ZOrderSet(app.H_FOVID{app.CurrentFOV.Value,1}, 1);             
        end

        % Value changed function: FlipYControl
        function FlipYControlValueChanged(app, event)
            if isa(app.H_Cells{app.CurrentFOV.Value,1},'matlab.graphics.primitive.Patch')
                delete (app.H_Cells{app.CurrentFOV.Value,1});
                app.H_Cells{app.CurrentFOV.Value,1}={};
                app.ROI_X{app.CurrentFOV.Value,1}=[];
                app.ROI_Y{app.CurrentFOV.Value,1}=[];
                app.CellextractedList.Items(app.CurrentFOV.Value)={'NA'};
                app.CellextractedList.Value=app.CellextractedList.Items(app.CurrentFOV.Value);
            else
            end
            app.CellinRegion{app.CurrentFOV.Value,1}=[];
            app.Textbox.Items(length(app.Textbox.Items)+1)={'FOV direction is changed, you need to re-extract the cells!'};
            scroll(app.Textbox, 'bottom') ;
            if app.FlipXControl.Value==1
                Image_flip=flip(app.Image_corrected{app.CurrentFOV.Value,1},1);
            else
                Image_flip=app.Image_corrected{app.CurrentFOV.Value,1};
            end
            if app.FlipYControl.Value==1
                Image_flip=flip(Image_flip,2);
            else
                Image_flip=Image_flip;
            end
            app.Image_normalized{app.CurrentFOV.Value,1}=(Image_flip-min(Image_flip(:)))./(max(Image_flip(:))-min(Image_flip(:)));
            app.Image_shift{app.CurrentFOV.Value,1}=imresize(app.Image_normalized{app.CurrentFOV.Value,1},app.FOV_resizeControl.Value,'bilinear');
            app.Image_shift{app.CurrentFOV.Value,1}=imrotate(app.Image_shift{app.CurrentFOV.Value,1},app.FOV_rotateControl.Value,'bilinear');
            app.H_FOV{app.CurrentFOV.Value,1}.CData=uint8(255*app.Image_shift{app.CurrentFOV.Value,1});
            app.H_FOV{app.CurrentFOV.Value,1}.AlphaData=app.AlphasingleFOVControl.Value.*(app.Image_shift{app.CurrentFOV.Value,1}>0);
            app.FOV_flipY(app.CurrentFOV.Value,1)=app.FlipYControl.Value;
            CurrentFOVposition=[app.H_FOV{app.CurrentFOV.Value,1}.XData app.H_FOV{app.CurrentFOV.Value,1}.YData size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)];
            app.CurrentFOVbox.Position=CurrentFOVposition;
            app.H_FOVID{app.CurrentFOV.Value,1}.Position(1)=app.FOVShiftXControl.Value+size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1)/2;
            app.H_FOVID{app.CurrentFOV.Value,1}.Position(2)=app.FOVShiftYControl.Value+size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)/2;
            preprocessing.ZOrderSet(app.H_FOVID{app.CurrentFOV.Value,1}, 1);            
        end

        % Button pushed function: CheckCorrelationButton
        function CheckCorrelationButtonPushed(app, event)


            app.Textbox.Items(length(app.Textbox.Items)+1)={['Common area position: ',num2str(round(app.CommonArea.Position(1))),', ',num2str(round(app.CommonArea.Position(2)))]};
            scroll(app.Textbox, 'bottom') ;
            app.Textbox.Items(length(app.Textbox.Items)+1)={['Common area size: ',num2str(round(app.CommonArea.Position(3))),', ',num2str(round(app.CommonArea.Position(4)))]};
            scroll(app.Textbox, 'bottom') ;
            
            
            FOV_inROI=zeros(round(app.CommonArea.Position(4)),round(app.CommonArea.Position(3)));
            FOV_StartX=round(app.CommonArea.Position(1))-app.H_FOV{app.CurrentFOV.Value,1}.XData+1;
            FOV_StartY=round(app.CommonArea.Position(2))-app.H_FOV{app.CurrentFOV.Value,1}.YData+1;
            FOV_EndX=size(FOV_inROI,2)+FOV_StartX-1;
            FOV_EndY=size(FOV_inROI,1)+FOV_StartY-1;
            app.Textbox.Items(length(app.Textbox.Items)+1)={'Extracting pixels in common area for current FOV....'};
            scroll(app.Textbox, 'bottom') ;
            for i=FOV_StartY:FOV_EndY
                for j=FOV_StartX:FOV_EndX
                    if i>0 && j>0 && i<size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) && j<size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)
                        FOV_inROI(i-FOV_StartY+1,j-FOV_StartX+1)=app.H_FOV{app.CurrentFOV.Value,1}.CData(i,j);
                    else
                    end
                end
            end
            image(app.CurrentFOVAxes,FOV_inROI);
            %                 assignin('base','FOV_inROI',FOV_inROI);
            colormap(app.CurrentFOVAxes,gray);
            app.CurrentFOVAxes.DataAspectRatio=[1 1 1];
            app.CurrentFOVAxes.XLim=[1 size(FOV_inROI,2)];
            app.CurrentFOVAxes.YLim=[1 size(FOV_inROI,1)];
            
            if app.WidefieldCheckBox.Value==1
                H_reference=app.H_WF;
                app.Textbox.Items(length(app.Textbox.Items)+1)={'Reference FOV is wide-field image'};
                scroll(app.Textbox, 'bottom');
            else
                H_reference=app.H_FOV{app.ReferenceFOVEditField.Value,1};
                app.Textbox.Items(length(app.Textbox.Items)+1)={['Reference FOV is: FOV',num2str(app.ReferenceFOVEditField.Value)']};
                scroll(app.Textbox, 'bottom');
            end
            
            Reference_inROI=zeros(round(app.CommonArea.Position(4)),round(app.CommonArea.Position(3)));
            Reference_StartX=round(app.CommonArea.Position(1))-H_reference.XData+1;
            Reference_StartY=round(app.CommonArea.Position(2))-H_reference.YData+1;
            Reference_EndX=size(Reference_inROI,2)+Reference_StartX-1;
            Reference_EndY=size(Reference_inROI,1)+Reference_StartY-1;
            app.Textbox.Items(length(app.Textbox.Items)+1)={'Extracting pixels in common area for referent FOV....'};
            scroll(app.Textbox, 'bottom');
            for i=Reference_StartY:Reference_EndY
                for j=Reference_StartX:Reference_EndX
                    if i>0 && j>0 && i<size(H_reference.CData,1) && j<size(H_reference.CData,2)
                        Reference_inROI(i-Reference_StartY+1,j-Reference_StartX+1)=H_reference.CData(i,j);
                    else
                    end
                end
            end
            image(app.ReferenceFOVAxes,Reference_inROI);
            %                 assignin('base','FOV_inROI',Reference_inROI);
            colormap(app.ReferenceFOVAxes,gray);
            app.ReferenceFOVAxes.DataAspectRatio=[1 1 1];
            app.ReferenceFOVAxes.XLim=[1 size(Reference_inROI,2)];
            app.ReferenceFOVAxes.YLim=[1 size(Reference_inROI,1)];
            
            app.Textbox.Items(length(app.Textbox.Items)+1)={'Now calculating the cross-correlation....'};
            scroll(app.Textbox, 'bottom');
            FOV_inROI_norm=(FOV_inROI-min(FOV_inROI(:)))./(max(FOV_inROI(:))-min(FOV_inROI(:)));
            Reference_inROI_norm=(Reference_inROI-min(Reference_inROI(:)))./(max(Reference_inROI(:))-min(Reference_inROI(:)));
            
            Xcorrelation=normxcorr2(Reference_inROI_norm,FOV_inROI_norm);
            Center=round(size(Xcorrelation)./2);
            Xcorrelation_crop=Xcorrelation(Center(1)-app.RangepixelEditField.Value:Center(1)+app.RangepixelEditField.Value,Center(2)-app.RangepixelEditField.Value:Center(2)+app.RangepixelEditField.Value);
            H3=imagesc(app.XcorrelationAxes,-1*round(size(Xcorrelation_crop,2)/2),-1*round(size(Xcorrelation_crop,1)/2),Xcorrelation_crop,[min(Xcorrelation_crop(:)) max(Xcorrelation_crop(:))]);
            C=colorbar(app.XcorrelationAxes);
            app.XcorrelationAxes.DataAspectRatio=[1 1 1];
            app.XcorrelationAxes.XLim=[-0.5*size(Xcorrelation_crop,2),0.5*size(Xcorrelation_crop,2)];
            app.XcorrelationAxes.YLim=[-0.5*size(Xcorrelation_crop,1),0.5*size(Xcorrelation_crop,2)];
            
            app.PeakEditField.Value=max(max(Xcorrelation_crop));
            
            [ypeak, xpeak]=find(Xcorrelation_crop==app.PeakEditField.Value);
            app.YEditField.Value=ypeak-round(size(Xcorrelation_crop,2)/2);
            app.XEditField.Value=xpeak-round(size(Xcorrelation_crop,2)/2);
            
            
        end

        % Button pushed function: DrawcommentareaButton
        function DrawcommentareaButtonPushed(app, event)
            if ~isempty(app.CommonArea)
                delete(app.CommonArea);
            else
            end
            app.Textbox.Items(length(app.Textbox.Items)+1)={'please draw a rectangle ROI in the common area between the current FOV to the reference FOV'};
            scroll(app.Textbox, 'bottom') ;
            app.CommonArea=drawrectangle(app.WFAxes,'Color',[0.9 0 0]);
            app.Textbox.Items(length(app.Textbox.Items)+1)={'Rectangle ROI was drawn!'};
            scroll(app.Textbox, 'bottom') ;
        end

        % Button pushed function: ClearROIButton
        function ClearROIButtonPushed(app, event)
            if ~isempty(app.CommonArea)
                delete(app.CommonArea);
            else
            end
        end

        % Button pushed function: AddRegion
        function AddRegionButtonPushed(app, event)
    
                if strcmp(app.CellColormap.Value,'hsv')
                   CMP=hsv(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'cool')
                   CMP=cool(app.Colornumber.Value);
                elseif strcmp(app.CellColormap.Value,'spring')
                   CMP=spring(app.Colornumber.Value);                   
                elseif strcmp(app.CellColormap.Value,'summer')
                   CMP=summer(app.Colornumber.Value);                    
                elseif strcmp(app.CellColormap.Value,'autumn')
                   CMP=autumn(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'winter')
                   CMP=winter(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'AAAS')
                   CMP_raw= [...
                    0.2314    0.2863    0.5725; ...
                    0.9333         0         0; ...
                    0    0.5451    0.2706; ...
                    0.3882    0.0941    0.4745; ...
                    0    0.5098    0.5020; ...
                    0.7333         0    0.1294; ...
                    0.3725    0.3333    0.6078; ...
                    0.6353         0    0.3373; ...
                    0.5020    0.5059    0.5020; ...
                    0.1059    0.0980    0.0980];                
                    CMP(:,1)=interp1(CMP_raw(:,1),1:10/app.Colornumber.Value:10);
                    CMP(:,2)=interp1(CMP_raw(:,2),1:10/app.Colornumber.Value:10);
                    CMP(:,3)=interp1(CMP_raw(:,3),1:10/app.Colornumber.Value:10);
                else
                end
            

            app.TotalRegionEditField.Value=app.TotalRegionEditField.Value+1;
            app.CurrentRegionEditField.Value=app.TotalRegionEditField.Value;
            
            app.RegionPoorListBox.Items(app.CurrentRegionEditField.Value)={num2str(app.CurrentRegionEditField.Value)};
            app.RegionPoorListBox.Value=app.RegionPoorListBox.Items(app.CurrentRegionEditField.Value);
            
            CMPsize=size(CMP,1);
            CMP_ID=mod(app.CurrentRegionEditField.Value,CMPsize);
            if CMP_ID==0
                CMP_ID=CMPsize;
            else
            end
            
            app.Textbox.Items(length(app.Textbox.Items)+1)={['Drawing region ',num2str(app.CurrentRegionEditField.Value),'...']};
            scroll(app.Textbox, 'bottom') ;
            app.Regions{app.CurrentRegionEditField.Value,1}=drawfreehand(app.WFAxes,'Color',CMP(CMP_ID,:));
            app.RegionnameEditField.Value=num2str(app.CurrentRegionEditField.Value);
            app.Regions{app.CurrentRegionEditField.Value,1}.Label=app.RegionnameEditField.Value;
            app.Regions{app.CurrentRegionEditField.Value,1}.FaceAlpha =app.Region_Alpha.Value;
            app.Regions{app.CurrentRegionEditField.Value,1}.LineWidth=app.LineWidth.Value;
            if app.ShownameCheckBox.Value==1
                app.Regions{app.CurrentRegionEditField.Value,1}.LabelVisible='on';
            else
                app.Regions{app.CurrentRegionEditField.Value,1}.LabelVisible='off';
            end
            app.Textbox.Items(length(app.Textbox.Items)+1)={['Region ',num2str(app.CurrentRegionEditField.Value),' is drawn!']};
            scroll(app.Textbox, 'bottom') ;
            if app.VisibleCheckBox.Value==1
                set(app.Regions{app.CurrentRegionEditField.Value,1},'visible','on');
            else
                set(app.Regions{app.CurrentRegionEditField.Value,1},'visible','off');
            end
       
        end

        % Button pushed function: Deleteallregion
        function DeleteallregionButtonPushed(app, event)
            
            for i=1:1:size(app.Regions,1)
                delete (app.Regions{i,1});
            end
            app.Regions={};
            app.TotalRegionEditField.Value=0;
            app.CurrentRegionEditField.Value=0;
            app.RegionPoorListBox.Value={};
            app.RegionPoorListBox.Items={};
            app.Textbox.Items(length(app.Textbox.Items)+1)={['All regions were deleted!']};
            scroll(app.Textbox, 'bottom') ;
        end

        % Value changed function: VisibleCheckBox
        function VisibleCheckBoxValueChanged(app, event)
            if app.VisibleCheckBox.Value==1
                for i=1:1:size(app.Regions,1)
                    set(app.Regions{i,1},'visible','on');
                end
            else
                for i=1:1:size(app.Regions,1)
                    set(app.Regions{i,1},'visible','off');
                end
            end
        end

        % Value changed function: RegionnameEditField
        function RegionnameEditFieldValueChanged(app, event)
            app.Regions{app.CurrentRegionEditField.Value,1}.Label=app.RegionnameEditField.Value;
            
        end

        % Value changed function: RegionPoorListBox
        function RegionPoorListBoxValueChanged(app, event)
            SelectRegion=str2num(app.RegionPoorListBox.Value);
            app.CurrentRegionEditField.Value=SelectRegion;
            app.RegionnameEditField.Value=app.Regions{app.CurrentRegionEditField.Value,1}.Label;
            app.Region_Alpha.Value=app.Regions{app.CurrentRegionEditField.Value,1}.FaceAlpha;
            app.Textbox.Items(length(app.Textbox.Items)+1)={['Selet Region ', num2str(app.CurrentRegionEditField.Value),', name:',app.RegionnameEditField.Value]};
            scroll(app.Textbox, 'bottom') ;
            
        end

        % Value changed function: ShownameCheckBox
        function ShownameCheckBoxValueChanged(app, event)

            if app.ShownameCheckBox.Value==1
                for i=1:1:size(app.Regions,1)
                    app.Regions{i,1}.LabelVisible ='on';
                end
            else
                for i=1:1:size(app.Regions,1)
                    app.Regions{i,1}.LabelVisible ='off';
                end
            end
        end

        % Value changed function: Region_Alpha
        function Region_AlphaValueChanged(app, event)
            app.Regions{app.CurrentRegionEditField.Value,1}.FaceAlpha =app.Region_Alpha.Value;
        end

        % Value changed function: LineWidth
        function LineWidthValueChanged(app, event)
            
            for i=1:1:size(app.Regions,1)
                app.Regions{i,1}.LineWidth=app.LineWidth.Value;
            end
        end

        % Button pushed function: AssignCell
        function AssignCellButtonPushed(app, event)
            app.Textbox.Items(length(app.Textbox.Items)+1)={''};
            scroll(app.Textbox, 'bottom') ;
            for i=1:1:size(app.H_Cells,1)
                if isa(app.H_Cells{i,1},'matlab.graphics.primitive.Patch')
                    app.CellinRegion{i,1}=zeros(app.StitchingPoor{i,1}.NeuronInformation.TotalCell,1);                
                    CenterX=mean(app.H_Cells{i,1}.XData,1);
                    CenterY=mean(app.H_Cells{i,1}.YData,1);
                    for j=1:1:app.StitchingPoor{i,1}.NeuronInformation.TotalCell
                        for k=1:1:size(app.Regions,1)
                            if inROI(app.Regions{k,1},CenterX(j),CenterY(j))
                                app.CellinRegion{i,1}(j)=k;
                            else
                            end                            
                            app.Textbox.Items(length(app.Textbox.Items))={['Now chcek if Cell ',num2str(j),' in FOV ', num2str(i), ' is in Region ',num2str(k)]};
                            scroll(app.Textbox, 'bottom') ;                            
                        end                        
                    end
                else
                end
            end

                if strcmp(app.CellColormap.Value,'hsv')
                   CMP=hsv(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'cool')
                   CMP=cool(app.Colornumber.Value);
                elseif strcmp(app.CellColormap.Value,'spring')
                   CMP=spring(app.Colornumber.Value);                   
                elseif strcmp(app.CellColormap.Value,'summer')
                   CMP=summer(app.Colornumber.Value);                    
                elseif strcmp(app.CellColormap.Value,'autumn')
                   CMP=autumn(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'winter')
                   CMP=winter(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'AAAS')
                   CMP_raw= [...
                    0.2314    0.2863    0.5725; ...
                    0.9333         0         0; ...
                    0    0.5451    0.2706; ...
                    0.3882    0.0941    0.4745; ...
                    0    0.5098    0.5020; ...
                    0.7333         0    0.1294; ...
                    0.3725    0.3333    0.6078; ...
                    0.6353         0    0.3373; ...
                    0.5020    0.5059    0.5020; ...
                    0.1059    0.0980    0.0980];
                
                    CMP(:,1)=interp1(CMP_raw(:,1),1:10/app.Colornumber.Value:10);
                    CMP(:,2)=interp1(CMP_raw(:,2),1:10/app.Colornumber.Value:10);
                    CMP(:,3)=interp1(CMP_raw(:,3),1:10/app.Colornumber.Value:10);                   
                else
                end
                for i=1:1:size(app.H_Cells,1)
                    CellColor=zeros(1,size(app.ROI_X{i,1},2),3);                   
                    if strcmp(app.ColorbyDropDown.Value,'FOV')
                        ColorID=mod(i,size(CMP,1));
                        if ColorID==0
                            ColorID=size(CMP,1);
                        else
                        end
                        CellColor(1,:,1)=CMP(ColorID,1);
                        CellColor(1,:,2)=CMP(ColorID,2);
                        CellColor(1,:,3)=CMP(ColorID,3);
                    elseif strcmp(app.ColorbyDropDown.Value,'Plane')
                        for k=1:1:size(app.ROI_X{i,1},2)
                            ColorID=mod(app.StitchingPoor{i,1}.NeuronInformation.CellinPlane(k)+1,size(CMP,1));
                            if ColorID==0
                                ColorID=size(CMP,1);
                            else
                            end
                            CellColor(1,k,1)=CMP(ColorID,1);
                            CellColor(1,k,2)=CMP(ColorID,2);
                            CellColor(1,k,3)=CMP(ColorID,3);
                        end   
                    elseif strcmp(app.ColorbyDropDown.Value,'Region') 
                        if isempty(app.Regions)||isempty(app.CellinRegion{i,1})
                            CellColor(1,:,1)=0.5;
                            CellColor(1,:,2)=0.5;
                            CellColor(1,:,3)=0.5;
                        else
                            for k=1:1:size(app.ROI_X{i,1},2)
                                if app.CellinRegion{i,1}(k,1)==0
                                    CellColor(1,k,1)=0.5;
                                    CellColor(1,k,2)=0.5;
                                    CellColor(1,k,3)=0.5;
                                else
                                    ColorID=mod(app.CellinRegion{i,1}(k,1),size(CMP,1));
                                    if ColorID==0
                                        ColorID=size(CMP,1);
                                    else
                                    end
                                    CellColor(1,k,1)=CMP(ColorID,1);
                                    CellColor(1,k,2)=CMP(ColorID,2);
                                    CellColor(1,k,3)=CMP(ColorID,3);
                                end  
                            end          
                        end
                    else
                    end
                    if isa(app.H_Cells{i,1},'matlab.graphics.primitive.Patch')
                        app.H_Cells{i,1}.CData=CellColor;
                    else
                    end
                end
                colormap(app.ColorbarAxes,CMP)
                c=colorbar(app.ColorbarAxes);
                c.Location='south';
                c.Position=[0 0.1 1 0.75];
                for i=1:1:size(app.Regions,1)
                    ColorID=mod(i,size(CMP,1));
                    if ColorID==0
                        ColorID=size(CMP,1);
                    else
                    end
                    app.Regions{i,1}.Color=CMP(ColorID,:);
                end  
            
        end

        % Button pushed function: ExtractAllCell
        function ExtractAllCellButtonPushed(app, event)
            
            %          if strcmp(app.PlanetoshowDropDown.Value,'All')
            j=app.CurrentFOV.Value;
            if isa(app.H_Cells{app.CurrentFOV.Value,1},'matlab.graphics.primitive.Patch')
                delete(app.H_Cells{app.CurrentFOV.Value,1})
            else
            end
            app.ROI_X{app.CurrentFOV.Value,1}=[];
            app.ROI_Y{app.CurrentFOV.Value,1}=[];
            app.CellextractedList.Items(app.CurrentFOV.Value)={'NA'};
            app.CellextractedList.Value=app.CellextractedList.Items(app.CurrentFOV.Value);
            app.CellinRegion{app.CurrentFOV.Value,1}=[];
            
            for i=1:1:app.StitchingPoor{j,1}.NeuronInformation.TotalCell
                if app.Abort_flag==true
                    app.Textbox.Items(length(app.Textbox.Items)+1)={'Drawing is aborted!'};
                    scroll(app.Textbox, 'bottom')
                    app.Abort_flag=false;
                    drawnow;
                    break
                else
                end
                ROI_pannel=zeros(app.StitchingPoor{j,1}.NeuronInformation.Framesize);
                Xpoint=mod(double(app.StitchingPoor{j,1}.NeuronInformation.ROIStat_Iscell{1,i}.xpix)',app.StitchingPoor{j,1}.NeuronInformation.Framesize(1));
                Ypoint=mod(double(app.StitchingPoor{j,1}.NeuronInformation.ROIStat_Iscell{1,i}.ypix)',app.StitchingPoor{j,1}.NeuronInformation.Framesize(2));
                Xpoint(Xpoint==0)=app.StitchingPoor{j,1}.NeuronInformation.Framesize(1);
                Ypoint(Ypoint==0)=app.StitchingPoor{j,1}.NeuronInformation.Framesize(2);
                for m=1:1:length(Xpoint)
                    ROI_pannel(Ypoint(m),Xpoint(m))=1;
                end
                if isempty(app.TransformMatrix)
                    ROI_pannel_corrected=ROI_pannel;
                else
                    ROI_pannel_corrected=imwarp(ROI_pannel,app.TransformMatrix,'OutputView',imref2d(size(ROI_pannel)));
                end
                
                if app.FOV_flipX(j,1)==1
                    ROI_pannel_corrected=flip(ROI_pannel_corrected,1);
                else
                end
                if app.FOV_flipY(j,1)
                    ROI_pannel_corrected=flip(ROI_pannel_corrected,2);
                else
                end
                ROI_pannel_corrected=imresize(ROI_pannel_corrected,app.FOV_size(j,1),'bilinear');
                ROI_pannel_corrected=imrotate(ROI_pannel_corrected,app.FOV_rotate(j,1),'bilinear');
                [Ypoint,Xpoint,~]=find(ROI_pannel_corrected>0);
                P=convhull(Xpoint,Ypoint);
                app.ROI_X{j,1}(1:length(P),i)=Xpoint(P);
                app.ROI_Y{j,1}(1:length(P),i)=Ypoint(P);
                if length(app.Textbox.Items)==0
                    app.Textbox.Items(1)={['Now extracting cell ', num2str(i),' in FOV ',num2str(j)]};
                else
                    app.Textbox.Items(length(app.Textbox.Items))={['Now extracting cell ', num2str(i),' in FOV ',num2str(j)]};
                end
                scroll(app.Textbox, 'bottom') ;
                drawnow;
            end
            for i=1:1:app.StitchingPoor{j,1}.NeuronInformation.TotalCell
                app.ROI_X{j,1}(app.ROI_X{j,1}(:,i)==0,i)=app.ROI_X{j,1}(find(app.ROI_X{j,1}(:,i),1,'last'),i);
                app.ROI_Y{j,1}(app.ROI_Y{j,1}(:,i)==0,i)=app.ROI_Y{j,1}(find(app.ROI_Y{j,1}(:,i),1,'last'),i);
            end
            app.ROI_X{j,1}=app.ROI_X{j,1};
            app.ROI_Y{j,1}=app.ROI_Y{j,1};
            app.CellextractedList.Items(j)={num2str(app.StitchingPoor{j,1}.NeuronInformation.TotalCell)};
            app.CellextractedList.Value=app.CellextractedList.Items(j);


                if strcmp(app.CellColormap.Value,'hsv')
                   CMP=hsv(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'cool')
                   CMP=cool(app.Colornumber.Value);
                elseif strcmp(app.CellColormap.Value,'spring')
                   CMP=spring(app.Colornumber.Value);                   
                elseif strcmp(app.CellColormap.Value,'summer')
                   CMP=summer(app.Colornumber.Value);                    
                elseif strcmp(app.CellColormap.Value,'autumn')
                   CMP=autumn(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'winter')
                   CMP=winter(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'AAAS')
                   CMP_raw= [...
                    0.2314    0.2863    0.5725; ...
                    0.9333         0         0; ...
                    0    0.5451    0.2706; ...
                    0.3882    0.0941    0.4745; ...
                    0    0.5098    0.5020; ...
                    0.7333         0    0.1294; ...
                    0.3725    0.3333    0.6078; ...
                    0.6353         0    0.3373; ...
                    0.5020    0.5059    0.5020; ...
                    0.1059    0.0980    0.0980];
                
                    CMP(:,1)=interp1(CMP_raw(:,1),1:10/app.Colornumber.Value:10);
                    CMP(:,2)=interp1(CMP_raw(:,2),1:10/app.Colornumber.Value:10);
                    CMP(:,3)=interp1(CMP_raw(:,3),1:10/app.Colornumber.Value:10);                   
                else
                end
                    CellColor=zeros(1,size(app.ROI_X{j,1},2),3);
                    if strcmp(app.ColorbyDropDown.Value,'FOV')
                        ColorID=mod(j,size(CMP,1));
                        if ColorID==0
                            ColorID=size(CMP,1);
                        else
                        end
                        CellColor(1,:,1)=CMP(ColorID,1);
                        CellColor(1,:,2)=CMP(ColorID,2);
                        CellColor(1,:,3)=CMP(ColorID,3);
                    elseif strcmp(app.ColorbyDropDown.Value,'Plane')
                        for k=1:1:size(app.ROI_X{j,1},2)
                            ColorID=mod(app.StitchingPoor{j,1}.NeuronInformation.CellinPlane(k)+1,size(CMP,1));
                            if ColorID==0
                                ColorID=size(CMP,1);
                            else
                            end
                            CellColor(1,k,1)=CMP(ColorID,1);
                            CellColor(1,k,2)=CMP(ColorID,2);
                            CellColor(1,k,3)=CMP(ColorID,3);
                        end
                    elseif strcmp(app.ColorbyDropDown.Value,'Region') 
                        if isempty(app.Regions)||isempty(app.CellinRegion{i,1})
                            CellColor(1,:,1)=0.5;
                            CellColor(1,:,2)=0.5;
                            CellColor(1,:,3)=0.5;
                        else
                            for k=1:1:size(app.ROI_X{j,1},2)
                                if app.CellinRegion{j,1}(k,1)==0
                                    CellColor(1,k,1)=0.5;
                                    CellColor(1,k,2)=0.5;
                                    CellColor(1,k,3)=0.5;
                                else
                                    ColorID=mod(app.CellinRegion{j,1}(k,1),size(CMP,1));
                                    if ColorID==0
                                        ColorID=size(CMP,1);
                                    else
                                    end
                                    CellColor(1,k,1)=CMP(ColorID,1);
                                    CellColor(1,k,2)=CMP(ColorID,2);
                                    CellColor(1,k,3)=CMP(ColorID,3);
                                end  
                            end          
                        end
                    else
                    end
                
                app.H_Cells{j,1}=patch(app.WFAxes,app.ROI_X{j,1}+app.FOV_X(j,1)+app.MisalignxSpinner.Value,app.ROI_Y{j,1}+app.FOV_Y(j,1)+app.MisalignySpinner.Value,CellColor,'FaceAlpha',app.CellFaceAlpha.Value,'EdgeAlpha',app.CellLineAlpha.Value,'Visible',app.CellVisible.Value);
                app.CellFace(app.CurrentFOV.Value,1)=app.CellFaceAlpha.Value;
                app.CellEdge(app.CurrentFOV.Value,1)=app.CellLineAlpha.Value;
                
                app.HideCell{j,1}=ones(size(app.ROI_X{j,1},2),1);
                app.OverlappedCell{j,1}=ones(size(app.ROI_X{j,1},2),1);         
                app.HideCell{j,1}(squeeze(app.StitchingPoor{j,1}.NeuronInformation.RepeatCell),1)=0;
                
                if app.HideRepeatedCell_plane.Value==1 && app.HideRepeatedCell_FOV.Value==1
                    app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{j,1}.*app.OverlappedCell{j,1}; 
                    app.H_Cells{j,1}.AlphaDataMapping= 'none';  
                    app.H_Cells{j,1}.FaceAlpha='flat';
                elseif app.HideRepeatedCell_plane.Value==1 && app.HideRepeatedCell_FOV.Value==0
                    app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{j,1};
                    app.H_Cells{j,1}.AlphaDataMapping= 'none';  
                    app.H_Cells{j,1}.FaceAlpha='flat';                  
                elseif  app.HideRepeatedCell_plane.Value==0 && app.HideRepeatedCell_FOV.Value==1
                    app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.OverlappedCell{j,1}; 
                    app.H_Cells{j,1}.AlphaDataMapping= 'none';  
                    app.H_Cells{j,1}.FaceAlpha='flat';                    
                else                       
                    app.H_Cells{j,1}.FaceAlpha=app.CellFaceAlpha.Value;
                end              
            preprocessing.ZOrderSet(app.H_Cells{app.CurrentFOV.Value,1}, 1);
            preprocessing.ZOrderSet(app.H_FOVID{app.CurrentFOV.Value,1}, 1);
            app.Textbox.Items(length(app.Textbox.Items)+1)={['Cells in FOV ',num2str(j),' were extracted!']};
            scroll(app.Textbox, 'bottom') ;
            drawnow;
            colormap(app.ColorbarAxes,CMP)
            c=colorbar(app.ColorbarAxes);
            c.Location='south';
            c.Position=[0 0.1 1 0.75];
            assignin('base','H_Cells',app.H_Cells{j,1});
            drawnow;


        end

        % Value changed function: CellVisible
        function CellVisibleValueChanged(app, event)
            if app.CellVisible.Value==1
                for i=1:1:size(app.H_Cells,1)
                    set(app.H_Cells{i,1},'visible','on');
                end
            else
                for i=1:1:size(app.H_Cells,1)
                    set(app.H_Cells{i,1},'visible','off');
                end
            end
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.Abort_flag=true;
            app.Textbox.Items(length(app.Textbox.Items)+1)={'Used decided to abort the analysis!'};
            drawnow;
        end

        % Callback function
        function DragontopButtonPushed(app, event)
            for i=1:1:size(app.H_Cells,1)
                if ~isempty(app.H_Cells{i,1})
                    preprocessing.ZOrderSet(app.H_Cells{i,1}, 1);
                    i
                else
                end
            end
            preprocessing.ZOrderSet(app.H_Cells{app.CurrentFOV.Value,1}, 1);
        end

        % Callback function
        function FOVVisibleValueChanged(app, event)

            if app.FOVVisible.Value==1
                for i=1:1:size(app.H_FOV,1)
                    app.H_FOV{i,1}.Visible='on';
                end
            else
                for i=1:1:size(app.H_FOV,1)
                    app.H_FOV{i,1}.Visible='off';
                end
            end
        end

        % Value changed function: FOVVisible
        function FOVVisibleValueChanged2(app, event)
            
            if app.FOVVisible.Value==1
                for i=1:1:size(app.H_FOV,1)
                    set(app.H_FOV{i,1},'visible','on');
                end
            else
                for i=1:1:size(app.H_FOV,1)
                    set(app.H_FOV{i,1},'visible','off');
                end
            end
        end

        % Value changed function: ShowBox
        function ShowBoxValueChanged(app, event)
            if app.ShowBox.Value==1
                set(app.CurrentFOVbox,'visible','on');
            else
                set(app.CurrentFOVbox,'visible','off');
            end
        end

        % Callback function
        function ShowCellinCurrentFOVButtonPushed(app, event)
            if strcmp(app.CellDrawType.Value,'Replace')
                for i=1:1:size(app.H_Cells,1)
                    delete (app.H_Cells{i,1});
                end
            else
            end
            k=size(app.H_Cells,1)+1;
            hold (app.WFAxes,'on');
            j=app.CurrentFOV.Value;
            for i=1:1:app.StitchingPoor{j,1}.NeuronInformation.TotalCell
                if app.Abort_flag==true
                    app.Textbox.Items(length(app.Textbox.Items)+1)={'Drawing is aborted!'};
                    scroll(app.Textbox, 'bottom')
                    app.Abort_flag=false;
                    drawnow;
                    break
                else
                end
                if strcmp(app.PlanetoshowDropDown.Value,'All')
                    Xpoint=mod(double(app.StitchingPoor{j,1}.NeuronInformation.ROIStat_Iscell{1,i}.xpix)',app.StitchingPoor{j,1}.NeuronInformation.Framesize(1));
                    Ypoint=mod(double(app.StitchingPoor{j,1}.NeuronInformation.ROIStat_Iscell{1,i}.ypix)',app.StitchingPoor{j,1}.NeuronInformation.Framesize(2));
                    Xpoint(Xpoint==0)=app.StitchingPoor{j,1}.NeuronInformation.Framesize(1);
                    Ypoint(Ypoint==0)=app.StitchingPoor{j,1}.NeuronInformation.Framesize(2);
                    ROI_pannel=zeros(app.StitchingPoor{j,1}.NeuronInformation.Framesize);
                    for m=1:1:length(Xpoint)
                        ROI_pannel(Ypoint(m),Xpoint(m))=1;
                    end
                    
                    if isempty(app.TransformMatrix)
                        ROI_pannel_corrected=ROI_pannel;
                    else
                        ROI_pannel_corrected=imwarp(ROI_pannel,app.TransformMatrix,'OutputView',imref2d(size(ROI_pannel)));
                    end
                    
                    if app.FOV_flipX(j,1)==1
                        ROI_pannel_corrected=flip(ROI_pannel_corrected,1);
                    else
                    end
                    if app.FOV_flipY(j,1)
                        ROI_pannel_corrected=flip(ROI_pannel_corrected,2);
                    else
                    end
                    
                    ROI_pannel_corrected=imresize(ROI_pannel_corrected,app.FOV_size(j,1),'bilinear');
                    ROI_pannel_corrected=imrotate(ROI_pannel_corrected,app.FOV_rotate(j,1),'bilinear');
                    ROI_pannel_filled=zeros(size(ROI_pannel_corrected));
                    [Ypoint,Xpoint,~]=find(ROI_pannel_corrected>0);
                    if strcmp(app.ColorbyDropDown.Value,'FOV')
                        CMP=hsv(length(app.StitchingPoor));
                        P=convhull(Xpoint,Ypoint);
                        app.H_Cells{k,1}=fill(app.WFAxes,app.FOV_X(j,1)+Xpoint(P),app.FOV_Y(j,1)+Ypoint(P),CMP(j,:),'LineStyle','none','facealpha',.6);
                        if app.CellVisible.Value==1
                            set(app.H_Cells{k,1},'visible','on');
                        else
                            set(app.H_Cells{k,1},'visible','off');
                        end
                        hold (app.WFAxes,'on');
                        app.Textbox.Items(length(app.Textbox.Items)+1)={['Now draw cell ',num2str(k),', FOV ',num2str(j),', ID ', num2str(i)]};
                        scroll(app.Textbox, 'bottom') ;
                        drawnow;
                    else
                    end
                    k=k+1;
                    hold (app.WFAxes,'on');
                else
                    Plane_toshow=str2double(app.PlanetoshowDropDown.Value);
                    if app.StitchingPoor{j,1}.NeuronInformation.CellinPlane(i)==Plane_toshow-1
                        Xpoint=mod(double(app.StitchingPoor{j,1}.NeuronInformation.ROIStat_Iscell{1,i}.xpix)',app.StitchingPoor{j,1}.NeuronInformation.Framesize(1));
                        Ypoint=mod(double(app.StitchingPoor{j,1}.NeuronInformation.ROIStat_Iscell{1,i}.ypix)',app.StitchingPoor{j,1}.NeuronInformation.Framesize(2));
                        Xpoint(Xpoint==0)=app.StitchingPoor{j,1}.NeuronInformation.Framesize(1);
                        Ypoint(Ypoint==0)=app.StitchingPoor{j,1}.NeuronInformation.Framesize(2);
                        ROI_pannel=zeros(app.StitchingPoor{j,1}.NeuronInformation.Framesize);
                        for m=1:1:length(Xpoint)
                            ROI_pannel(Ypoint(m),Xpoint(m))=1;
                        end
                        
                        if isempty(app.TransformMatrix)
                            ROI_pannel_corrected=ROI_pannel;
                        else
                            ROI_pannel_corrected=imwarp(ROI_pannel,app.TransformMatrix,'OutputView',imref2d(size(ROI_pannel)));
                        end
                        
                        if app.FOV_flipX(j,1)==1
                            ROI_pannel_corrected=flip(ROI_pannel_corrected,1);
                        else
                        end
                        if app.FOV_flipY(j,1)
                            ROI_pannel_corrected=flip(ROI_pannel_corrected,2);
                        else
                        end
                        
                        ROI_pannel_corrected=imresize(ROI_pannel_corrected,app.FOV_size(j,1),'bilinear');
                        ROI_pannel_corrected=imrotate(ROI_pannel_corrected,app.FOV_rotate(j,1),'bilinear');
                        
                        [Ypoint,Xpoint,~]=find(ROI_pannel_corrected>0);
                        if strcmp(app.ColorbyDropDown.Value,'FOV')
                            CMP=hsv(length(app.StitchingPoor));
                            P=convhull(Xpoint,Ypoint);
                            app.H_Cells{k,1}=fill(app.WFAxes,app.FOV_X(j,1)+Xpoint(P),app.FOV_Y(j,1)+Ypoint(P),CMP(j,:),'LineStyle','none','facealpha',.6);
                            if app.CellVisible.Value==1
                                set(app.H_Cells{k,1},'visible','on');
                            else
                                set(app.H_Cells{k,1},'visible','off');
                            end
                            hold (app.WFAxes,'on');
                            app.Textbox.Items(length(app.Textbox.Items)+1)={['Now draw cell ',num2str(k),', FOV ',num2str(j),', ID ', num2str(i)]};
                            scroll(app.Textbox, 'bottom') ;
                            drawnow;
                        else
                        end
                        k=k+1;
                        hold (app.WFAxes,'on');
                    else
                    end
                end
            end
            hold (app.WFAxes,'off');
        end

        % Callback function
        function Deleteallregion_3ButtonPushed(app, event)
            for i=1:1:size(app.H_Cells,1)
                delete (app.H_Cells{i,1});
            end
            app.H_Cells={};
        end

        % Value changed function: CellFaceAlpha
        function CellFaceAlphaValueChanged(app, event)
            
            app.CellFace(app.CurrentFOV.Value,1)=app.CellFaceAlpha.Value;
            app.H_Cells{app.CurrentFOV.Value,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{app.CurrentFOV.Value,1};
%             app.H_Cells{app.CurrentFOV.Value,1}.FaceVertexAlphaData=0.1*ones(644,1);
            if app.HideRepeatedCell_plane.Value==1
                app.H_Cells{app.CurrentFOV.Value,1}.FaceAlpha='flat';
            else
                app.H_Cells{app.CurrentFOV.Value,1}.FaceAlpha=app.CellFaceAlpha.Value;
            end
            drawnow;
            
        end

        % Value changed function: CellLineAlpha
        function CellLineAlphaValueChanged(app, event)
            set(app.H_Cells{app.CurrentFOV.Value,1},'EdgeAlpha',app.CellLineAlpha.Value);
            app.CellEdge(app.CurrentFOV.Value,1)=app.CellLineAlpha.Value;
            drawnow;
        end

        % Button pushed function: FOVAlphaglobal
        function FOVAlphaglobalPushed(app, event)
            app.FOV_alpha(:)=app.AlphaGlobal.Value;
            app.AlphasingleFOVControl.Value=app.AlphaGlobal.Value;
            for i=1:1:size(app.H_FOV,1)
                app.H_FOV{i,1}.AlphaData= app.AlphasingleFOVControl.Value.*(app.Image_shift{i,1}>0);
            end
        end

        % Button pushed function: CellAlphaglobal
        function CellAlphaglobalButtonPushed(app, event)
            app.CellFace(:)=app.Cell_FaceAlphaGlobal.Value;
            app.CellFaceAlpha.Value=app.Cell_FaceAlphaGlobal.Value;      
            app.CellEdge(:)=app.Cell_lineAlphaGlobal.Value;
            app.CellLineAlpha.Value=app.Cell_lineAlphaGlobal.Value;
            for i=1:1:size(app.H_Cells,1)   
                set(app.H_Cells{i,1},'EdgeAlpha',app.CellLineAlpha.Value);
                set(app.H_Cells{i,1},'FaceAlpha',app.CellFaceAlpha.Value);
                app.H_Cells{i,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{i,1};
            if app.HideRepeatedCell_plane.Value==1
                app.H_Cells{i,1}.FaceAlpha='flat';
            else
                app.H_Cells{i,1}.FaceAlpha=app.CellFaceAlpha.Value;
            end                
            end
            
        end

        % Value changed function: ShowFOVID
        function ShowFOVIDValueChanged(app, event)
            if app.ShowFOVID.Value==1
               for i=1:1:size(app.H_FOVID,1)   
                   set(app.H_FOVID{i,1},'visible','on');
               end
            else
               for i=1:1:size(app.H_FOVID,1)   
                   set(app.H_FOVID{i,1},'visible','off');
               end   
            end                   
        end

        % Value changed function: Fontsize
        function FontsizeValueChanged(app, event)
               for i=1:1:size(app.H_FOVID,1)   
                   set(app.H_FOVID{i,1},'FontSize',app.Fontsize.Value);
               end
        end

        % Value changed function: CellColormap
        function CellColormapValueChanged(app, event)
                if strcmp(app.CellColormap.Value,'hsv')
                   CMP=hsv(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'cool')
                   CMP=cool(app.Colornumber.Value);
                elseif strcmp(app.CellColormap.Value,'spring')
                   CMP=spring(app.Colornumber.Value);                   
                elseif strcmp(app.CellColormap.Value,'summer')
                   CMP=summer(app.Colornumber.Value);                    
                elseif strcmp(app.CellColormap.Value,'autumn')
                   CMP=autumn(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'winter')
                   CMP=winter(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'AAAS')
                   CMP_raw= [...
                    0.2314    0.2863    0.5725; ...
                    0.9333         0         0; ...
                    0    0.5451    0.2706; ...
                    0.3882    0.0941    0.4745; ...
                    0    0.5098    0.5020; ...
                    0.7333         0    0.1294; ...
                    0.3725    0.3333    0.6078; ...
                    0.6353         0    0.3373; ...
                    0.5020    0.5059    0.5020; ...
                    0.1059    0.0980    0.0980];
                
                
                    CMP(:,1)=interp1(CMP_raw(:,1),1:10/app.Colornumber.Value:10);
                    CMP(:,2)=interp1(CMP_raw(:,2),1:10/app.Colornumber.Value:10);
                    CMP(:,3)=interp1(CMP_raw(:,3),1:10/app.Colornumber.Value:10);                   
                else
                end
                for i=1:1:size(app.H_Cells,1)
                    CellColor=zeros(1,size(app.ROI_X{i,1},2),3);                   
                    if strcmp(app.ColorbyDropDown.Value,'FOV')
                        ColorID=mod(i,size(CMP,1));
                        if ColorID==0
                            ColorID=size(CMP,1);
                        else
                        end
                        CellColor(1,:,1)=CMP(ColorID,1);
                        CellColor(1,:,2)=CMP(ColorID,2);
                        CellColor(1,:,3)=CMP(ColorID,3);
                    elseif strcmp(app.ColorbyDropDown.Value,'Plane')
                        for k=1:1:size(app.ROI_X{i,1},2)
                            ColorID=mod(app.StitchingPoor{i,1}.NeuronInformation.CellinPlane(k)+1,size(CMP,1));
                            if ColorID==0
                                ColorID=size(CMP,1);
                            else
                            end
                            CellColor(1,k,1)=CMP(ColorID,1);
                            CellColor(1,k,2)=CMP(ColorID,2);
                            CellColor(1,k,3)=CMP(ColorID,3);
                        end   
                    elseif strcmp(app.ColorbyDropDown.Value,'Region') 
                        if isempty(app.Regions)||isempty(app.CellinRegion{i,1})
                            CellColor(1,:,1)=0.5;
                            CellColor(1,:,2)=0.5;
                            CellColor(1,:,3)=0.5;
                        else
                            for k=1:1:size(app.ROI_X{i,1},2)
                                if app.CellinRegion{i,1}(k,1)==0
                                    CellColor(1,k,1)=0.5;
                                    CellColor(1,k,2)=0.5;
                                    CellColor(1,k,3)=0.5;
                                else
                                    ColorID=mod(app.CellinRegion{i,1}(k,1),size(CMP,1));
                                    if ColorID==0
                                        ColorID=size(CMP,1);
                                    else
                                    end
                                    CellColor(1,k,1)=CMP(ColorID,1);
                                    CellColor(1,k,2)=CMP(ColorID,2);
                                    CellColor(1,k,3)=CMP(ColorID,3);
                                end
                            end          
                        end                        
                    else
                    end
                    if isa(app.H_Cells{i,1},'matlab.graphics.primitive.Patch')
                        app.H_Cells{i,1}.CData=CellColor;
                    else
                    end
                end
                colormap(app.ColorbarAxes,CMP)
                c=colorbar(app.ColorbarAxes);
                c.Location='south';
                c.Position=[0 0.1 1 0.75];
                
                for i=1:1:size(app.Regions,1)
                    ColorID=mod(i,size(CMP,1));
                    if ColorID==0
                        ColorID=size(CMP,1);
                    else
                    end
                    app.Regions{i,1}.Color=CMP(ColorID,:);
                end
   
%                 c.Ticks=[];
                drawnow;
        end

        % Value changed function: Colornumber
        function ColornumberValueChanged(app, event)

                if strcmp(app.CellColormap.Value,'hsv')
                   CMP=hsv(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'cool')
                   CMP=cool(app.Colornumber.Value);
                elseif strcmp(app.CellColormap.Value,'spring')
                   CMP=spring(app.Colornumber.Value);                   
                elseif strcmp(app.CellColormap.Value,'summer')
                   CMP=summer(app.Colornumber.Value);                    
                elseif strcmp(app.CellColormap.Value,'autumn')
                   CMP=autumn(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'winter')
                   CMP=winter(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'AAAS')
                   CMP_raw= [...
                    0.2314    0.2863    0.5725; ...
                    0.9333         0         0; ...
                    0    0.5451    0.2706; ...
                    0.3882    0.0941    0.4745; ...
                    0    0.5098    0.5020; ...
                    0.7333         0    0.1294; ...
                    0.3725    0.3333    0.6078; ...
                    0.6353         0    0.3373; ...
                    0.5020    0.5059    0.5020; ...
                    0.1059    0.0980    0.0980];
                
                    CMP(:,1)=interp1(CMP_raw(:,1),1:10/app.Colornumber.Value:10);
                    CMP(:,2)=interp1(CMP_raw(:,2),1:10/app.Colornumber.Value:10);
                    CMP(:,3)=interp1(CMP_raw(:,3),1:10/app.Colornumber.Value:10);                   
                else
                end
                for i=1:1:size(app.H_Cells,1)
                    CellColor=zeros(1,size(app.ROI_X{i,1},2),3);                   
                    if strcmp(app.ColorbyDropDown.Value,'FOV')
                        ColorID=mod(i,size(CMP,1));
                        if ColorID==0
                            ColorID=size(CMP,1);
                        else
                        end
                        CellColor(1,:,1)=CMP(ColorID,1);
                        CellColor(1,:,2)=CMP(ColorID,2);
                        CellColor(1,:,3)=CMP(ColorID,3);
                    elseif strcmp(app.ColorbyDropDown.Value,'Plane')
                        for k=1:1:size(app.ROI_X{i,1},2)
                            ColorID=mod(app.StitchingPoor{i,1}.NeuronInformation.CellinPlane(k)+1,size(CMP,1));
                            if ColorID==0
                                ColorID=size(CMP,1);
                            else
                            end
                            CellColor(1,k,1)=CMP(ColorID,1);
                            CellColor(1,k,2)=CMP(ColorID,2);
                            CellColor(1,k,3)=CMP(ColorID,3);
                        end   
                    elseif strcmp(app.ColorbyDropDown.Value,'Region') 
                        if isempty(app.Regions)||isempty(app.CellinRegion{i,1})
                            CellColor(1,:,1)=0.5;
                            CellColor(1,:,2)=0.5;
                            CellColor(1,:,3)=0.5;
                        else
                            for k=1:1:size(app.ROI_X{i,1},2)
                                if app.CellinRegion{i,1}(k,1)==0
                                    CellColor(1,k,1)=0.5;
                                    CellColor(1,k,2)=0.5;
                                    CellColor(1,k,3)=0.5;
                                else
                                    ColorID=mod(app.CellinRegion{i,1}(k,1),size(CMP,1));
                                    if ColorID==0
                                        ColorID=size(CMP,1);
                                    else
                                    end
                                    CellColor(1,k,1)=CMP(ColorID,1);
                                    CellColor(1,k,2)=CMP(ColorID,2);
                                    CellColor(1,k,3)=CMP(ColorID,3);
                                end  
                            end          
                        end
                    else
                    end
                    if isa(app.H_Cells{i,1},'matlab.graphics.primitive.Patch')
                        app.H_Cells{i,1}.CData=CellColor;
                    else
                    end
                end
                colormap(app.ColorbarAxes,CMP)
                c=colorbar(app.ColorbarAxes);
                c.Location='south';
                c.Position=[0 0.1 1 0.75];
                for i=1:1:size(app.Regions,1)
                    ColorID=mod(i,size(CMP,1));
                    if ColorID==0
                        ColorID=size(CMP,1);
                    else
                    end
                    app.Regions{i,1}.Color=CMP(ColorID,:);
                end                

        end

        % Value changed function: ColorbyDropDown
        function ColorbyDropDownValueChanged(app, event)
                if strcmp(app.CellColormap.Value,'hsv')
                   CMP=hsv(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'cool')
                   CMP=cool(app.Colornumber.Value);
                elseif strcmp(app.CellColormap.Value,'spring')
                   CMP=spring(app.Colornumber.Value);                   
                elseif strcmp(app.CellColormap.Value,'summer')
                   CMP=summer(app.Colornumber.Value);                    
                elseif strcmp(app.CellColormap.Value,'autumn')
                   CMP=autumn(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'winter')
                   CMP=winter(app.Colornumber.Value); 
                elseif strcmp(app.CellColormap.Value,'AAAS')
                   CMP_raw= [...
                    0.2314    0.2863    0.5725; ...
                    0.9333         0         0; ...
                    0    0.5451    0.2706; ...
                    0.3882    0.0941    0.4745; ...
                    0    0.5098    0.5020; ...
                    0.7333         0    0.1294; ...
                    0.3725    0.3333    0.6078; ...
                    0.6353         0    0.3373; ...
                    0.5020    0.5059    0.5020; ...
                    0.1059    0.0980    0.0980];
                
                    CMP(:,1)=interp1(CMP_raw(:,1),1:10/app.Colornumber.Value:10);
                    CMP(:,2)=interp1(CMP_raw(:,2),1:10/app.Colornumber.Value:10);
                    CMP(:,3)=interp1(CMP_raw(:,3),1:10/app.Colornumber.Value:10);                   
                else
                end
                for i=1:1:size(app.H_Cells,1)
                    CellColor=zeros(1,size(app.ROI_X{i,1},2),3);                   
                    if strcmp(app.ColorbyDropDown.Value,'FOV')
                        ColorID=mod(i,size(CMP,1));
                        if ColorID==0
                            ColorID=size(CMP,1);
                        else
                        end
                        CellColor(1,:,1)=CMP(ColorID,1);
                        CellColor(1,:,2)=CMP(ColorID,2);
                        CellColor(1,:,3)=CMP(ColorID,3);
                    elseif strcmp(app.ColorbyDropDown.Value,'Plane')
                        for k=1:1:size(app.ROI_X{i,1},2)
                            ColorID=mod(app.StitchingPoor{i,1}.NeuronInformation.CellinPlane(k)+1,size(CMP,1));
                            if ColorID==0
                                ColorID=size(CMP,1);
                            else
                            end
                            CellColor(1,k,1)=CMP(ColorID,1);
                            CellColor(1,k,2)=CMP(ColorID,2);
                            CellColor(1,k,3)=CMP(ColorID,3);
                        end 
                    elseif strcmp(app.ColorbyDropDown.Value,'Region') 
                        if isempty(app.Regions)||isempty(app.CellinRegion{i,1})
                            CellColor(1,:,1)=0.5;
                            CellColor(1,:,2)=0.5;
                            CellColor(1,:,3)=0.5;
                        else                            
                            for k=1:1:size(app.ROI_X{i,1},2)
                                if app.CellinRegion{i,1}(k,1)==0
                                    CellColor(1,k,1)=0.5;
                                    CellColor(1,k,2)=0.5;
                                    CellColor(1,k,3)=0.5;
                                else
                                    ColorID=mod(app.CellinRegion{i,1}(k,1),size(CMP,1));
                                    if ColorID==0
                                        ColorID=size(CMP,1);
                                    else
                                    end
                                    CellColor(1,k,1)=CMP(ColorID,1);
                                    CellColor(1,k,2)=CMP(ColorID,2);
                                    CellColor(1,k,3)=CMP(ColorID,3);
                                end  
                            end          
                        end                        
                    else
                    end
                    if isa(app.H_Cells{i,1},'matlab.graphics.primitive.Patch')
                        app.H_Cells{i,1}.CData=CellColor;
                    else
                    end
                end
  
                
        end

        % Value changed function: HideRepeatedCell_plane
        function HideRepeatedCell_planeValueChanged(app, event)

            for j=1:1:size(app.H_Cells,1)
                if isa(app.H_Cells{j,1},'matlab.graphics.primitive.Patch')
                    if app.HideRepeatedCell_plane.Value==1 && app.HideRepeatedCell_FOV.Value==1
                        app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{j,1}.*app.OverlappedCell{j,1};
                        app.H_Cells{j,1}.AlphaDataMapping= 'none';
                        app.H_Cells{j,1}.FaceAlpha='flat';
                    elseif app.HideRepeatedCell_plane.Value==1 && app.HideRepeatedCell_FOV.Value==0
                        app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{j,1};
                        app.H_Cells{j,1}.AlphaDataMapping= 'none';
                        app.H_Cells{j,1}.FaceAlpha='flat';
                    elseif  app.HideRepeatedCell_plane.Value==0 && app.HideRepeatedCell_FOV.Value==1
                        app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.OverlappedCell{j,1};
                        app.H_Cells{j,1}.AlphaDataMapping= 'none';
                        app.H_Cells{j,1}.FaceAlpha='flat';
                    else
                        app.H_Cells{j,1}.FaceAlpha=app.CellFaceAlpha.Value;
                    end
                else
                end
            end


        end

        % Value changed function: MisalignxSpinner
        function MisalignxSpinnerValueChanged(app, event)
            for i=1:1:size(app.H_Cells,1)
                if ~isempty(app.H_Cells{i,1})
                    app.H_Cells{i,1}.XData=app.ROI_X{i,1}+app.FOV_X(i,1)+app.MisalignxSpinner.Value;
                else
                end
            end
        end

        % Value changed function: MisalignySpinner
        function MisalignySpinnerValueChanged(app, event)
            for i=1:1:size(app.H_Cells,1)
                if ~isempty(app.H_Cells{i,1})
                    app.H_Cells{i,1}.YData=app.ROI_Y{i,1}+app.FOV_Y(i,1)+app.MisalignySpinner.Value;
                else
                end
            end
        end

        % Value changed function: PlaneDropDown
        function PlaneDropDownValueChanged(app, event)
            StartPixel=(str2double(app.PlaneDropDown.Value)-1)*app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)+1;
            app.FOV_planeshow(app.CurrentFOV.Value,1)=str2double(app.PlaneDropDown.Value); 
            if strcmp(app.TypeDropDown.Value,'Mean')
                app.FOV_Projectshow(app.CurrentFOV.Value,1)=1; 
                if strcmp(app.ChannelDropDown.Value,'1')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=1; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.meanImg(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                elseif strcmp(app.ChannelDropDown.Value,'2')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=2; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.meanImg_chan2(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                else
                end
            else
                app.FOV_Projectshow(app.CurrentFOV.Value,1)=2; 
                if strcmp(app.ChannelDropDown.Value,'1')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=1; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.max_proj(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                else
                end
            end 

            
            if isempty(app.TransformMatrix)
                app.Image_corrected{app.CurrentFOV.Value,1}=Image_show;
                app.Textbox.Items(length(app.Textbox.Items)+1)={'No transformmation matrxi, raw image is used'};
                scroll(app.Textbox, 'bottom') ;
            else
                app.Image_corrected{app.CurrentFOV.Value,1}=imwarp(Image_show,app.TransformMatrix,'OutputView',imref2d(size(Image_show)));
                app.Textbox.Items(length(app.Textbox.Items)+1)={'Corrected image is used'};
                scroll(app.Textbox, 'bottom') ;
            end
            
            
            app.Image_corrected{app.CurrentFOV.Value,1}=double(app.Image_corrected{app.CurrentFOV.Value,1});
            if app.FlipXControl.Value==1
                Image_flip=flip(app.Image_corrected{app.CurrentFOV.Value,1},1);
            else
                Image_flip=app.Image_corrected{app.CurrentFOV.Value,1};
            end
            if app.FlipYControl.Value==1
                Image_flip=flip(Image_flip,2);
            else
                Image_flip=Image_flip;
            end
            app.Image_normalized{app.CurrentFOV.Value,1}=(Image_flip-min(Image_flip(:)))./(max(Image_flip(:))-min(Image_flip(:)));            
            app.Image_shift{app.CurrentFOV.Value,1}=imresize(app.Image_normalized{app.CurrentFOV.Value,1},app.FOV_resizeControl.Value,'bilinear');
            app.Image_shift{app.CurrentFOV.Value,1}=imrotate(app.Image_shift{app.CurrentFOV.Value,1},app.FOV_rotateControl.Value,'bilinear');         
            app.H_FOV{app.CurrentFOV.Value,1}.CData=uint8(255*app.Image_shift{app.CurrentFOV.Value,1});
            
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            StartPixel=(str2double(app.PlaneDropDown.Value)-1)*app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)+1;
            app.FOV_planeshow(app.CurrentFOV.Value,1)=str2double(app.PlaneDropDown.Value); 
            if strcmp(app.TypeDropDown.Value,'Mean')
                app.FOV_Projectshow(app.CurrentFOV.Value,1)=1; 
                if strcmp(app.ChannelDropDown.Value,'1')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=1; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.meanImg(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                elseif strcmp(app.ChannelDropDown.Value,'2')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=2; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.meanImg_chan2(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                else
                end
            else
                app.FOV_Projectshow(app.CurrentFOV.Value,1)=2; 
                if strcmp(app.ChannelDropDown.Value,'1')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=1; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.max_proj(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                else
                end
            end 

            
            if isempty(app.TransformMatrix)
                app.Image_corrected{app.CurrentFOV.Value,1}=Image_show;
                app.Textbox.Items(length(app.Textbox.Items)+1)={'No transformmation matrxi, raw image is used'};
                scroll(app.Textbox, 'bottom') ;
            else
                app.Image_corrected{app.CurrentFOV.Value,1}=imwarp(Image_show,app.TransformMatrix,'OutputView',imref2d(size(Image_show)));
                app.Textbox.Items(length(app.Textbox.Items)+1)={'Corrected image is used'};
                scroll(app.Textbox, 'bottom') ;
            end
            
            
            app.Image_corrected{app.CurrentFOV.Value,1}=double(app.Image_corrected{app.CurrentFOV.Value,1});
            if app.FlipXControl.Value==1
                Image_flip=flip(app.Image_corrected{app.CurrentFOV.Value,1},1);
            else
                Image_flip=app.Image_corrected{app.CurrentFOV.Value,1};
            end
            if app.FlipYControl.Value==1
                Image_flip=flip(Image_flip,2);
            else
                Image_flip=Image_flip;
            end
            app.Image_normalized{app.CurrentFOV.Value,1}=(Image_flip-min(Image_flip(:)))./(max(Image_flip(:))-min(Image_flip(:)));            
            app.Image_shift{app.CurrentFOV.Value,1}=imresize(app.Image_normalized{app.CurrentFOV.Value,1},app.FOV_resizeControl.Value,'bilinear');
            app.Image_shift{app.CurrentFOV.Value,1}=imrotate(app.Image_shift{app.CurrentFOV.Value,1},app.FOV_rotateControl.Value,'bilinear');         
            app.H_FOV{app.CurrentFOV.Value,1}.CData=uint8(255*app.Image_shift{app.CurrentFOV.Value,1});
            
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            StartPixel=(str2double(app.PlaneDropDown.Value)-1)*app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)+1;
            app.FOV_planeshow(app.CurrentFOV.Value,1)=str2double(app.PlaneDropDown.Value); 
            if strcmp(app.TypeDropDown.Value,'Mean')
                app.FOV_Projectshow(app.CurrentFOV.Value,1)=1; 
                if strcmp(app.ChannelDropDown.Value,'1')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=1; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.meanImg(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                elseif strcmp(app.ChannelDropDown.Value,'2')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=2; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.meanImg_chan2(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                else
                end
            else
                app.FOV_Projectshow(app.CurrentFOV.Value,1)=2; 
                if strcmp(app.ChannelDropDown.Value,'1')
                    app.FOV_Channelshow(app.CurrentFOV.Value,1)=1; 
                    Image_show=app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Options.max_proj(:,StartPixel:StartPixel+app.StitchingPoor{app.CurrentFOV.Value,1}.NeuronInformation.Framesize(1)-1);
                else
                end
            end 

            
            if isempty(app.TransformMatrix)
                app.Image_corrected{app.CurrentFOV.Value,1}=Image_show;
                app.Textbox.Items(length(app.Textbox.Items)+1)={'No transformmation matrxi, raw image is used'};
                scroll(app.Textbox, 'bottom') ;
            else
                app.Image_corrected{app.CurrentFOV.Value,1}=imwarp(Image_show,app.TransformMatrix,'OutputView',imref2d(size(Image_show)));
                app.Textbox.Items(length(app.Textbox.Items)+1)={'Corrected image is used'};
                scroll(app.Textbox, 'bottom') ;
            end
            
            
            app.Image_corrected{app.CurrentFOV.Value,1}=double(app.Image_corrected{app.CurrentFOV.Value,1});
            if app.FlipXControl.Value==1
                Image_flip=flip(app.Image_corrected{app.CurrentFOV.Value,1},1);
            else
                Image_flip=app.Image_corrected{app.CurrentFOV.Value,1};
            end
            if app.FlipYControl.Value==1
                Image_flip=flip(Image_flip,2);
            else
                Image_flip=Image_flip;
            end
            app.Image_normalized{app.CurrentFOV.Value,1}=(Image_flip-min(Image_flip(:)))./(max(Image_flip(:))-min(Image_flip(:)));            
            app.Image_shift{app.CurrentFOV.Value,1}=imresize(app.Image_normalized{app.CurrentFOV.Value,1},app.FOV_resizeControl.Value,'bilinear');
            app.Image_shift{app.CurrentFOV.Value,1}=imrotate(app.Image_shift{app.CurrentFOV.Value,1},app.FOV_rotateControl.Value,'bilinear');         
            app.H_FOV{app.CurrentFOV.Value,1}.CData=uint8(255*app.Image_shift{app.CurrentFOV.Value,1});
            
        end

        % Button pushed function: RegionAlphaglobal
        function RegionAlphaglobalButtonPushed(app, event)
            for i=1:1:size(app.Regions,1)              
                app.Regions{i,1}.FaceAlpha =app.Region_Alpha.Value;
            end
        end

        % Button pushed function: DetectOverlapCell
        function DetectOverlapCellButtonPushed(app, event)
            app.OverlapMatrix=zeros(app.Totalcells.Value,app.Totalcells.Value);
            k=1;
            app.Textbox.Items(length(app.Textbox.Items)+1)={'Now start to analyze the cell overlap'};
            app.Textbox.Items(length(app.Textbox.Items)+1)={''};            
            scroll(app.Textbox, 'bottom') ;
            donecell=0;
            for    j=1:1:length(app.StitchingPoor)
                 app.OverlappedCell{j,1}(:)=1;
            end
            for    j=1:1:length(app.StitchingPoor)-1
                donecell=donecell+app.StitchingPoor{j,1}.NeuronInformation.TotalCell;
                for i=1:1:app.StitchingPoor{j,1}.NeuronInformation.TotalCell
                    RefCell_X=app.H_Cells{j,1}.XData(:,i);
%                     RefCell_X=unique(RefCell_X,'stable')
                    RefCell_Y=app.H_Cells{j,1}.YData(:,i);
%                     RefCell_Y=unique(RefCell_Y,'stable')
                    Center1_X=mean(RefCell_X);
                    Center1_Y=mean(RefCell_Y);                    
                    Poly1=polyshape(RefCell_X,RefCell_Y,'KeepCollinearPoints',false);
                    Area1=polyarea(RefCell_X,RefCell_Y);
                    
                    p=donecell+1;
                    for m=j+1:1:length(app.StitchingPoor)

                        for n=1:1:app.StitchingPoor{m,1}.NeuronInformation.TotalCell
                            if app.Abort_flag==true
                                app.Textbox.Items(length(app.Textbox.Items)+1)={'Drawing is aborted!'};
                                scroll(app.Textbox, 'bottom')
                                app.Abort_flag=false;
                                drawnow;
                                break
                            else
                            end
                            TagCell_X=app.H_Cells{m,1}.XData(:,n);
%                             TagCell_X=unique(TagCell_X,'stable');
                            TagCell_Y=app.H_Cells{m,1}.YData(:,n);
%                             TagCell_Y=unique(TagCell_Y,'stable');
                            Center2_X=mean(TagCell_X);
                            Center2_Y=mean(TagCell_Y);
                            if (Center1_X-Center2_X)^2+(Center1_Y-Center2_Y)^2<app.PixelThreshold.Value
                                Poly2=polyshape(TagCell_X,TagCell_Y,'KeepCollinearPoints',false);
                                Area2=polyarea(TagCell_X,TagCell_Y);
                                Overlap=intersect(Poly1,Poly2);
                                Area_com=polyarea(Overlap.Vertices(:,1),Overlap.Vertices(:,2));
                                app.OverlapMatrix(k,p)=Area_com./min(Area1,Area2);
                                if app.OverlapMatrix(k,p)>app.OverlapThreshold.Value
                                    SNR1=app.StitchingPoor{j,1}.NeuronInformation.SNR(i);
                                    SNR2=app.StitchingPoor{m,1}.NeuronInformation.SNR(n);
                                    if SNR1<SNR2
                                        app.OverlappedCell{j,1}(i,1)=0;
                                    else
                                        app.OverlappedCell{m,1}(n,1)=0;
                                    end
                                else
                                end
                              
                                app.Textbox.Items(length(app.Textbox.Items))={['Overlap ration of Cell ',num2str(k),' vs Cell ',num2str(p),' is ',num2str(app.OverlapMatrix(k,p))]};
                                scroll(app.Textbox, 'bottom') ;
                            else
%                                 app.Textbox.Items(length(app.Textbox.Items))={['Overlap ration of Cell ',num2str(k),' vs Cell ',num2str(p),' is ',num2str(app.OverlapMatrix(k,p))]};
%                                 scroll(app.Textbox, 'bottom') ;
                            end
                            p=p+1;
                        end
                    end
                    k=k+1;
                end
            end   

 
            for j=1:1:size(app.H_Cells,1)
                if isa(app.H_Cells{j,1},'matlab.graphics.primitive.Patch')
                    if app.HideRepeatedCell_plane.Value==1 && app.HideRepeatedCell_FOV.Value==1   
                        assignin('base','OverlappedCell',app.OverlappedCell{j,1});
                        assignin('base','HideCell',app.HideCell{j,1});
                        app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{j,1}.*app.OverlappedCell{j,1};
                        app.H_Cells{j,1}.AlphaDataMapping= 'none';
                        app.H_Cells{j,1}.FaceAlpha='flat';
                    elseif app.HideRepeatedCell_plane.Value==1 && app.HideRepeatedCell_FOV.Value==0
                        app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{j,1};
                        app.H_Cells{j,1}.AlphaDataMapping= 'none';
                        app.H_Cells{j,1}.FaceAlpha='flat';
                    elseif  app.HideRepeatedCell_plane.Value==0 && app.HideRepeatedCell_FOV.Value==1
                        app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.OverlappedCell{j,1};
                        app.H_Cells{j,1}.AlphaDataMapping= 'none';
                        app.H_Cells{j,1}.FaceAlpha='flat';
                    else
                        app.H_Cells{j,1}.FaceAlpha=app.CellFaceAlpha.Value;
                    end
                else
                end
            end
            
               assignin('base','OverlappedCell',app.OverlappedCell);

            app.TotalRepeatCell.Value=0;
            for    i=1:1:length(app.StitchingPoor)
                Repeat1=app.StitchingPoor{i,1}.NeuronInformation.RepeatCell;
                Repeat2=find(app.OverlappedCell{i,1}==0);            
                app.RepeatedCell(i)=length(unique([Repeat1(:); Repeat2(:)]));
                app.TotalRepeatCell.Value=app.TotalRepeatCell.Value+app.RepeatedCell(i);
            end                                   
            app.Textbox.Items(length(app.Textbox.Items)+1)={'Overlap detection is finished!'};            
            scroll(app.Textbox, 'bottom') ;
        end

        % Button pushed function: ExtractAllCell_3
        function ExtractAllCell_3ButtonPushed(app, event)
            figure ('Name','Overlapping ratio');
            A=app.OverlapMatrix(app.OverlapMatrix(:)>0);
            if max(app.OverlapMatrix(:))>0
                imagesc(app.OverlapMatrix,[0 max(app.OverlapMatrix(:))]);
            else
                imagesc(app.OverlapMatrix,[0 0.1]);
            end
                colorbar
        end

        % Value changed function: HideRepeatedCell_FOV
        function HideRepeatedCell_FOVValueChanged(app, event)
            for j=1:1:size(app.H_Cells,1)
                if isa(app.H_Cells{j,1},'matlab.graphics.primitive.Patch')
                    if app.HideRepeatedCell_plane.Value==1 && app.HideRepeatedCell_FOV.Value==1
                        app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{j,1}.*app.OverlappedCell{j,1};
                        app.H_Cells{j,1}.AlphaDataMapping= 'none';
                        app.H_Cells{j,1}.FaceAlpha='flat';
                    elseif app.HideRepeatedCell_plane.Value==1 && app.HideRepeatedCell_FOV.Value==0
                        app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{j,1};
                        app.H_Cells{j,1}.AlphaDataMapping= 'none';
                        app.H_Cells{j,1}.FaceAlpha='flat';
                    elseif  app.HideRepeatedCell_plane.Value==0 && app.HideRepeatedCell_FOV.Value==1
                        app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.OverlappedCell{j,1};
                        app.H_Cells{j,1}.AlphaDataMapping= 'none';
                        app.H_Cells{j,1}.FaceAlpha='flat';
                    else
                        app.H_Cells{j,1}.FaceAlpha=app.CellFaceAlpha.Value;
                    end
                else
                end
            end
            
        end

        % Button pushed function: SelectDataFolder
        function SelectDataFolderPushed(app, event)
                        
            app.processeddatafolder=uigetdir; % munually select the raw file folder;
            app.Textbox.Items={};
            app.Textbox.Items(length(app.Textbox.Items)+1)={'Processed data folder selected:'};
            app.Textbox.Items(length(app.Textbox.Items)+1)={app.processeddatafolder};
            app.DataFilepathEditField.Value=app.processeddatafolder;
            scroll(app.Textbox, 'bottom')
            cd (app.processeddatafolder); % the working folder will be changed to the data folder
            
        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            app.Textbox.Items={};
            [SavedData_file,SavedData_path] = uigetfile('*.mat','Please select the processed data to load');
            cd (SavedData_path);
            app.DataFilepathEditField.Value=SavedData_path;

            app.Textbox.Items(length(app.Textbox.Items)+1)={'Now start to load processed data.....'};
            scroll(app.Textbox, 'bottom')           
            tem=load([SavedData_path,'\',SavedData_file]);
%             assignin('base','tem',tem);

           app.Textbox.Items(length(app.Textbox.Items)+1)={['Data folder: ',SavedData_path]};
           app.Textbox.Items(length(app.Textbox.Items)+1)={['Data name: ',SavedData_file]};            
           scroll(app.Textbox, 'bottom')               
            
           app.processeddatafolder=SavedData_path;
           
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Loading package 1: wide-field imaging data...'};
           scroll(app.Textbox, 'bottom') 
           app.WF_file=tem.Stitchingdata.WF.WF_file;
           app.WF_path=tem.Stitchingdata.WF.WF_path;
           app.WF_FilePathPanel.Value=app.WF_path;
           app.WF_Image_raw=tem.Stitchingdata.WF.WF_Image_raw;
           app.WF_Image_normalized=tem.Stitchingdata.WF.WF_Image_normalized;
           app.WF_Image_shift=tem.Stitchingdata.WF.WF_Image_shift;
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Wide-field imaging is loaded!'};
           scroll(app.Textbox, 'bottom') 
           app.WF_Alpha.Value=tem.Stitchingdata.WF.WF_Alpha;          
           app.WF_ShiftX.Value=tem.Stitchingdata.WF.WF_ShiftX;
           app.WF_ShiftY.Value=tem.Stitchingdata.WF.WF_ShiftY;          
           app.WF_Resize.Value=tem.Stitchingdata.WF.WF_Resize;
           app.WF_Rotate.Value=tem.Stitchingdata.WF.WF_Rotate;           

                
           if isa(app.H_WF,'matlab.graphics.primitive.Image')
               delete(app.H_WF)
           else
               app.H_WF={};
           end
           if isa(app.H_RM,'matlab.graphics.primitive.Image')
               delete(app.H_RM);
           else
               app.H_RM={};
           end           
           
           app.H_WF=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,uint8(255*app.WF_Image_shift));
           app.H_WF.AlphaData=app.WF_Alpha.Value;
           colormap(app.WFAxes,gray)
           app.WFAxes.DataAspectRatio=[1 1 1];
           preprocessing.ZOrderSet(app.H_WF, 0);   
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Wide-field imaging is drawn!'};
           scroll(app.Textbox, 'bottom')


           app.Textbox.Items(length(app.Textbox.Items)+1)={'Loading package 2: retinopotic map data...'};
           scroll(app.Textbox, 'bottom')           
           app.RM_file=tem.Stitchingdata.RM.RM_file;           
           app.RM_path=tem.Stitchingdata.RM.RM_path;             
           app.RM_Image_raw=tem.Stitchingdata.RM.RM_Image_raw;           
           app.RM_Image_normalized=tem.Stitchingdata.RM.RM_Image_normalized;  
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Retinopotic map is loaded!'};
           scroll(app.Textbox, 'bottom')            
           app.RetMapEditField.Value=app.RM_path;
           
           app.RM_Image_shift=tem.Stitchingdata.RM.RM_Image_shift;               
           app.RM_alpha.Value=tem.Stitchingdata.RM.RM_alpha;             

           RM_Image_shift_rgb = ind2rgb(uint8(255*app.RM_Image_shift), jet(256));
           hold (app.WFAxes,'on');
           app.H_RM=image(app.WFAxes,app.WF_ShiftX.Value,app.WF_ShiftY.Value,RM_Image_shift_rgb);           
           app.H_RM.AlphaData=app.RM_alpha.Value;
           preprocessing.ZOrderSet(app.H_RM, 0);
           preprocessing.ZOrderSet(app.H_WF, 0);             
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Retinopotic map is loaded!'};
           scroll(app.Textbox, 'bottom')             
         
           %            
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Loading package 3: averaged df/f data...'};
           app.DF_Image=tem.Stitchingdata.DF.DF_Image;
           app.DF_Image_shift=tem.Stitchingdata.DF.DF_Image_shift;
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Averaged df/f is loaded!'};
           scroll(app.Textbox, 'bottom')           
           if isempty(app.DF_Image_shift)             
           else
               app.H_RM.AlphaData=app.RM_alpha.Value.*app.DF_Image_shift;
           end

      
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Loading package 4: transformation matrix...'};
           scroll(app.Textbox, 'bottom')           
           app.TransMatrix_filepath.Value=tem.Stitchingdata.TF.TFfilepath;
           app.TransformMatrix=tem.Stitchingdata.TF.TransformMatrix;
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Transformation matrix is loaded!'};
           scroll(app.Textbox, 'bottom')  
           
            
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Loading package 5: stitched FOV...'};
           scroll(app.Textbox, 'bottom')           

           
           app.StitchingPoor=tem.Stitchingdata.FOV.StitchingPoor;          

           app.FOV_X=tem.Stitchingdata.FOV.FOV_X;
           app.FOV_Y=tem.Stitchingdata.FOV.FOV_Y;
           app.FOV_size=tem.Stitchingdata.FOV.FOV_size;
           app.FOV_rotate=tem.Stitchingdata.FOV.FOV_rotate;
           app.FOV_flipX=tem.Stitchingdata.FOV.FOV_flipX;
           app.FOV_flipY=tem.Stitchingdata.FOV.FOV_flipY;
           app.FOV_alpha=tem.Stitchingdata.FOV.FOV_alpha;
           app.Image_corrected=tem.Stitchingdata.FOV.Image_corrected;
           app.Image_normalized=tem.Stitchingdata.FOV.Image_normalized;
           app.Image_shift=tem.Stitchingdata.FOV.Image_shift;
           app.CellinRegion=tem.Stitchingdata.FOV.CellinRegion;
           
           app.FOV_planeshow=tem.Stitchingdata.FOV.FOV_planeshow;
           app.FOV_Channelshow=tem.Stitchingdata.FOV.FOV_Channelshow;
           app.FOV_Projectshow=tem.Stitchingdata.FOV.FOV_Projectshow;


           app.StitchingPoorListBox.Items={};
           app.CellextractedList.Items={};
           for j=1:1:size(app.StitchingPoor,1)
               Marker=strfind(app.StitchingPoor{j,1}.NeuronInformation.File_folder_root,'\');
               Filename=app.StitchingPoor{j,1}.NeuronInformation.File_folder_root(Marker(end)+1:end);
               app.StitchingPoorListBox.Items(j)={['FOV-',num2str(j),'-',Filename]};
               app.StitchingPoorListBox.Value=app.StitchingPoorListBox.Items(j);
               app.CellextractedList.Items(j)={'NA'};
               app.CellextractedList.Value=app.CellextractedList.Items(j);
           end
            app.Totalcells.Value=0;
            for    i=1:1:length(app.StitchingPoor)
                app.Totalcells.Value=app.Totalcells.Value+app.StitchingPoor{i,1}.NeuronInformation.TotalCell;
            end
            app.TotalRepeatCell.Value=0;

           app.Textbox.Items(length(app.Textbox.Items)+1)={'Stitched FOVs are loaded!'};
           scroll(app.Textbox, 'bottom')      
           app.CurrentFOV.Value= size(app.StitchingPoor,1);

           
           for i=1:1:size(app.H_FOV,1)
               if isa(app.H_FOV{i,1},'matlab.graphics.primitive.Image')
                   delete(app.H_FOV{i,1});
               else
                   app.H_FOV{j,1}={};
               end
           end
           app.H_FOV={};
           
           for i=1:1:size(app.H_Cells,1)
               if isa(app.H_Cells{i,1},'matlab.graphics.primitive.Patch')
                   delete(app.H_Cells{i,1})
               else
                   app.H_Cells{j,1}={};
               end
           end
           app.H_Cells={};
           
           for i=1:1:size(app.H_FOVID,1)
               if isa(app.H_FOVID{i,1},'matlab.graphics.primitive.text')
                   delete(app.H_FOVID{i,1});
               else
                   app.H_FOVID{i,1}={};
               end
           end
           app.H_FOVID={};
           
           
           for j=1:1:size(app.StitchingPoor,1)
               app.H_FOV{j,1}=tem.Stitchingdata.FOV.H_FOV{j,1};
               set(app.H_FOV{j,1},'Parent',app.WFAxes);
               preprocessing.ZOrderSet(app.H_FOV{j,1}, 1);
               if app.FOVVisible.Value==1
                   set(app.H_FOV{j,1},'Visible','on');
                   
               else
                   set(app.H_FOV{j,1},'Visible','off');
               end
               app.ROI_X{j,1}=[];
               app.ROI_Y{j,1}=[];  
               app.H_FOVID{j,1}=tem.Stitchingdata.FOV.H_FOVID{j,1};
               set(app.H_FOVID{j,1},'Parent',app.WFAxes);
               preprocessing.ZOrderSet(app.H_FOVID{j,1}, 1);
               if app.ShowFOVID.Value==1
                   set(app.H_FOVID{j,1},'Visible','on');                   
               else
                   set(app.H_FOVID{j,1},'Visible','off');
               end
           end
            app.PlaneDropDown.Value=num2str(app.FOV_planeshow(app.CurrentFOV.Value,1));             
            app.ChannelDropDown.Value=num2str(app.FOV_Channelshow(app.CurrentFOV.Value,1));  
            app.TypeDropDown.Value=app.TypeDropDown.Items(app.FOV_Projectshow(app.CurrentFOV.Value,1));          
            app.FOVShiftXControl.Value=app.FOV_X(app.CurrentFOV.Value,1);
            app.FOVShiftYControl.Value=app.FOV_Y(app.CurrentFOV.Value,1);
            app.FOV_rotateControl.Value=app.FOV_rotate(app.CurrentFOV.Value,1);
            app.FOV_resizeControl.Value=app.FOV_size(app.CurrentFOV.Value,1);
            app.AlphasingleFOVControl.Value=app.FOV_alpha(app.CurrentFOV.Value,1);
            app.FlipXControl.Value=app.FOV_flipX(app.CurrentFOV.Value,1);
            app.FlipYControl.Value=app.FOV_flipY(app.CurrentFOV.Value,1);

            CurrentFOVposition=[app.H_FOV{app.CurrentFOV.Value,1}.XData app.H_FOV{app.CurrentFOV.Value,1}.YData size(app.H_FOV{app.CurrentFOV.Value,1}.CData,1) size(app.H_FOV{app.CurrentFOV.Value,1}.CData,2)];           
            if isempty(app.CurrentFOVbox)
                app.CurrentFOVbox=rectangle(app.WFAxes,'Position',CurrentFOVposition,'EdgeColor','r','LineWidth',1);
            else
                delete (app.CurrentFOVbox);
                app.CurrentFOVbox=rectangle(app.WFAxes,'Position',CurrentFOVposition,'EdgeColor','r','LineWidth',1);

            end

            if app.ShowBox.Value==1
                set(app.CurrentFOVbox,'visible','on');
            else
                set(app.CurrentFOVbox,'visible','off');
            end
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Stitched FOVs are drawn!'};
           scroll(app.Textbox, 'bottom')         


           app.Textbox.Items(length(app.Textbox.Items)+1)={'Loading package 6: extraced cells...'};
           scroll(app.Textbox, 'bottom')
           
            app.CellFace=tem.Stitchingdata.Cells.CellFace;
            app.CellEdge=tem.Stitchingdata.Cells.CellEdge;            
            app.OverlapMatrix=tem.Stitchingdata.Cells.OverlapMatrix;
            app.HideCell=tem.Stitchingdata.Cells.HideCell;
            app.OverlappedCell=tem.Stitchingdata.Cells.OverlappedCell;
            app.RepeatedCell=tem.Stitchingdata.Cells.RepeatedCell;    
        
            for    i=1:1:length(app.StitchingPoor)                
                app.TotalRepeatCell.Value=app.TotalRepeatCell.Value+app.RepeatedCell(i);
            end            
            for j=1:1:size(app.StitchingPoor,1)
               app.H_Cells{j,1}=tem.Stitchingdata.Cells.H_Cells{j,1};
               set(app.H_Cells{j,1},'Parent',app.WFAxes);
               preprocessing.ZOrderSet(app.H_Cells{j,1}, 1); 
               preprocessing.ZOrderSet(app.H_FOVID{j,1}, 1);
            end
         
            app.CellFaceAlpha.Value=app.CellFace(app.CurrentFOV.Value,1);
            app.CellLineAlpha.Value=app.CellEdge(app.CurrentFOV.Value,1);

            for j=1:1:size(app.H_Cells,1)   
                if app.HideRepeatedCell_plane.Value==1 && app.HideRepeatedCell_FOV.Value==1
                    app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{j,1}.*app.OverlappedCell{j,1}; 
                    app.H_Cells{j,1}.AlphaDataMapping= 'none';  
                    app.H_Cells{j,1}.FaceAlpha='flat';
                elseif app.HideRepeatedCell_plane.Value==1 && app.HideRepeatedCell_FOV.Value==0
                    app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.HideCell{j,1};
                    app.H_Cells{j,1}.AlphaDataMapping= 'none';  
                    app.H_Cells{j,1}.FaceAlpha='flat';                  
                elseif  app.HideRepeatedCell_plane.Value==0 && app.HideRepeatedCell_FOV.Value==1
                    app.H_Cells{j,1}.FaceVertexAlphaData=app.CellFaceAlpha.Value.*app.OverlappedCell{j,1}; 
                    app.H_Cells{j,1}.AlphaDataMapping= 'none';  
                    app.H_Cells{j,1}.FaceAlpha='flat';                    
                else                       
                    app.H_Cells{j,1}.FaceAlpha=app.CellFaceAlpha.Value;
                end 
            end
            
           for j=1:1:size(app.H_Cells,1)
               app.CellextractedList.Items(j)={num2str(length(tem.Stitchingdata.Cells.HideCell{j,1}))};
               app.CellextractedList.Value=app.CellextractedList.Items(j);
           end            


            app.Textbox.Items(length(app.Textbox.Items)+1)={'Extracted cells are loaded!'};
            scroll(app.Textbox, 'bottom')     
            
            
            app.Textbox.Items(length(app.Textbox.Items)+1)={'Loading package 7: used-defined regions...'};
            scroll(app.Textbox, 'bottom')
            app.ROI_X=tem.Stitchingdata.RegionInformation.ROI_X;
            app.ROI_Y=tem.Stitchingdata.RegionInformation.ROI_Y;
            for i=1:1:size(app.Regions,1)
               if isa(app.Regions{i,1},'images.roi.Freehand')
                   delete(app.Regions{i,1})
               else
               end
           end
           app.Regions={};
           app.RegionPoorListBox.Items={};
           
           for i=1:1:size(tem.Stitchingdata.RegionInformation.Regions,1)
               app.Regions{i,1}=tem.Stitchingdata.RegionInformation.Regions{i,1};
               set(app.Regions{i,1},'Parent',app.WFAxes);
               app.RegionPoorListBox.Items(i)={num2str(i)};
               
           end
            app.TotalRegionEditField.Value=size(app.Regions,1);
            app.CurrentRegionEditField.Value=app.TotalRegionEditField.Value;
            app.RegionPoorListBox.Value=app.RegionPoorListBox.Items(app.CurrentRegionEditField.Value);         
            if app.ShownameCheckBox.Value==1
                app.Regions{app.CurrentRegionEditField.Value,1}.LabelVisible='on';
            else
                app.Regions{app.CurrentRegionEditField.Value,1}.LabelVisible='off';
            end

            if app.VisibleCheckBox.Value==1
                set(app.Regions{app.CurrentRegionEditField.Value,1},'visible','on');
            else
                set(app.Regions{app.CurrentRegionEditField.Value,1},'visible','off');
            end           
            app.RegionnameEditField.Value=app.Regions{app.CurrentRegionEditField.Value,1}.Label;
            app.Region_Alpha.Value=app.Regions{app.CurrentRegionEditField.Value,1}.FaceAlpha;

                     
            app.Textbox.Items(length(app.Textbox.Items)+1)={['Used-defined regions are loaded']};
            scroll(app.Textbox, 'bottom') ;           
           

            if strcmp(app.CellColormap.Value,'hsv')
                CMP=hsv(app.Colornumber.Value);
            elseif strcmp(app.CellColormap.Value,'cool')
                CMP=cool(app.Colornumber.Value);
            elseif strcmp(app.CellColormap.Value,'spring')
                CMP=spring(app.Colornumber.Value);
            elseif strcmp(app.CellColormap.Value,'summer')
                CMP=summer(app.Colornumber.Value);
            elseif strcmp(app.CellColormap.Value,'autumn')
                CMP=autumn(app.Colornumber.Value);
            elseif strcmp(app.CellColormap.Value,'winter')
                CMP=winter(app.Colornumber.Value);
            elseif strcmp(app.CellColormap.Value,'AAAS')
                CMP_raw= [...
                    0.2314    0.2863    0.5725; ...
                    0.9333         0         0; ...
                    0    0.5451    0.2706; ...
                    0.3882    0.0941    0.4745; ...
                    0    0.5098    0.5020; ...
                    0.7333         0    0.1294; ...
                    0.3725    0.3333    0.6078; ...
                    0.6353         0    0.3373; ...
                    0.5020    0.5059    0.5020; ...
                    0.1059    0.0980    0.0980];
                
                
                CMP(:,1)=interp1(CMP_raw(:,1),1:10/app.Colornumber.Value:10);
                CMP(:,2)=interp1(CMP_raw(:,2),1:10/app.Colornumber.Value:10);
                CMP(:,3)=interp1(CMP_raw(:,3),1:10/app.Colornumber.Value:10);
            else
            end
            for i=1:1:size(app.H_Cells,1)
                CellColor=zeros(1,size(app.ROI_X{i,1},2),3);
                if strcmp(app.ColorbyDropDown.Value,'FOV')
                    ColorID=mod(i,size(CMP,1));
                    if ColorID==0
                        ColorID=size(CMP,1);
                    else
                    end
                    CellColor(1,:,1)=CMP(ColorID,1);
                    CellColor(1,:,2)=CMP(ColorID,2);
                    CellColor(1,:,3)=CMP(ColorID,3);
                elseif strcmp(app.ColorbyDropDown.Value,'Plane')
                    for k=1:1:size(app.ROI_X{i,1},2)
                        ColorID=mod(app.StitchingPoor{i,1}.NeuronInformation.CellinPlane(k)+1,size(CMP,1));
                        if ColorID==0
                            ColorID=size(CMP,1);
                        else
                        end
                        CellColor(1,k,1)=CMP(ColorID,1);
                        CellColor(1,k,2)=CMP(ColorID,2);
                        CellColor(1,k,3)=CMP(ColorID,3);
                    end
                elseif strcmp(app.ColorbyDropDown.Value,'Region')
                    if isempty(app.Regions)||isempty(app.CellinRegion{i,1})
                        CellColor(1,:,1)=0.5;
                        CellColor(1,:,2)=0.5;
                        CellColor(1,:,3)=0.5;
                    else
                        for k=1:1:size(app.ROI_X{i,1},2)
                            if app.CellinRegion{i,1}(k,1)==0
                                CellColor(1,k,1)=0.5;
                                CellColor(1,k,2)=0.5;
                                CellColor(1,k,3)=0.5;
                            else
                                ColorID=mod(app.CellinRegion{i,1}(k,1),size(CMP,1));
                                if ColorID==0
                                    ColorID=size(CMP,1);
                                else
                                end
                                CellColor(1,k,1)=CMP(ColorID,1);
                                CellColor(1,k,2)=CMP(ColorID,2);
                                CellColor(1,k,3)=CMP(ColorID,3);
                            end
                        end
                    end
                else
                end
                if isa(app.H_Cells{i,1},'matlab.graphics.primitive.Patch')
                    app.H_Cells{i,1}.CData=CellColor;
                else
                end
            end
            colormap(app.ColorbarAxes,CMP)
            c=colorbar(app.ColorbarAxes);
            c.Location='south';
            c.Position=[0 0.1 1 0.75];
            
            for i=1:1:size(app.Regions,1)
                ColorID=mod(i,size(CMP,1));
                if ColorID==0
                    ColorID=size(CMP,1);
                else
                end
                app.Regions{i,1}.Color=CMP(ColorID,:);
            end

            app.Textbox.Items(length(app.Textbox.Items)+1)={'Loading is finished! Countinue your project!'};
            drawnow;            


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Now start to packaging data.....'};
           scroll(app.Textbox, 'bottom')
           Stitchingdata=struct;
           Stitchingdata.processeddatafolder=app.processeddatafolder;
           app.Textbox.Items(length(app.Textbox.Items)+1)={['Data folder: ',app.processeddatafolder]};
           scroll(app.Textbox, 'bottom')
           
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Package 1: wide-field imaging data...'};
           scroll(app.Textbox, 'bottom')           
           Stitchingdata.WF=struct;
           Stitchingdata.WF.WF_file=app.WF_file;
           Stitchingdata.WF.WF_path=app.WF_path;
           Stitchingdata.WF.WF_Image_raw=app.WF_Image_raw;           
           Stitchingdata.WF.WF_Image_normalized=app.WF_Image_normalized;
           Stitchingdata.WF.WF_Image_shift=app.WF_Image_shift;  
           Stitchingdata.WF.WF_Alpha=app.WF_Alpha.Value;
           Stitchingdata.WF.WF_ShiftX=app.WF_ShiftX.Value;
           Stitchingdata.WF.WF_ShiftY=app.WF_ShiftY.Value;          
           Stitchingdata.WF.WF_Resize=app.WF_Resize.Value;
           Stitchingdata.WF.WF_Rotate=app.WF_Rotate.Value; 
%            Stitchingdata.WF.H_WF=app.H_WF;       

           app.Textbox.Items(length(app.Textbox.Items)+1)={'Package 2: retinopotic map data...'};
           scroll(app.Textbox, 'bottom')           
           Stitchingdata.RM=struct;
           Stitchingdata.RM.RM_file=app.RM_file;
           Stitchingdata.RM.RM_path=app.RM_path;
           Stitchingdata.RM.RM_Image_raw=app.RM_Image_raw;           
           Stitchingdata.RM.RM_Image_normalized=app.RM_Image_normalized;
           Stitchingdata.RM.RM_Image_shift=app.RM_Image_shift;         
           Stitchingdata.RM.RM_alpha=app.RM_alpha.Value;             
           
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Package 3: averaged df/f data...'};
           scroll(app.Textbox, 'bottom')           
           Stitchingdata.DF=struct;
           Stitchingdata.DF.DF_Image=app.DF_Image;
           Stitchingdata.DF.DF_Image_shift=app.DF_Image_shift;
           
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Package 4: transformation matrix...'};
           scroll(app.Textbox, 'bottom')           
           Stitchingdata.TF=struct;
           Stitchingdata.TF.TFfilepath=app.TransMatrix_filepath.Value;
           Stitchingdata.TF.TransformMatrix=app.TransformMatrix;

           
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Package 5: stitched FOV...'};
           scroll(app.Textbox, 'bottom')           
           Stitchingdata.FOV=struct;
           Stitchingdata.FOV.StitchingPoor=app.StitchingPoor;          
           Stitchingdata.FOV.H_FOVID=app.H_FOVID;
           Stitchingdata.FOV.FOV_X=app.FOV_X;
           Stitchingdata.FOV.FOV_Y=app.FOV_Y;
           Stitchingdata.FOV.FOV_size=app.FOV_size;
           Stitchingdata.FOV.FOV_rotate=app.FOV_rotate;
           Stitchingdata.FOV.FOV_flipX=app.FOV_flipX;
           Stitchingdata.FOV.FOV_flipY=app.FOV_flipY;
           Stitchingdata.FOV.FOV_alpha=app.FOV_alpha;           
           Stitchingdata.FOV.Image_corrected=app.Image_corrected;
           Stitchingdata.FOV.Image_normalized=app.Image_normalized;
           Stitchingdata.FOV.Image_shift=app.Image_shift;
           Stitchingdata.FOV.CellinRegion=app.CellinRegion;
           Stitchingdata.FOV.FOV_planeshow=app.FOV_planeshow;
           Stitchingdata.FOV.FOV_Channelshow=app.FOV_Channelshow;
           Stitchingdata.FOV.FOV_Projectshow=app.FOV_Projectshow;
           Stitchingdata.FOV.H_FOV=app.H_FOV;
           
           
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Package 6: extraced cells...'};
           scroll(app.Textbox, 'bottom')
           Stitchingdata.Cells=struct;
           
           Stitchingdata.Cells.CellFace=app.CellFace;
           Stitchingdata.Cells.CellEdge=app.CellEdge;
           Stitchingdata.Cells.OverlapMatrix=app.OverlapMatrix;
           Stitchingdata.Cells.HideCell=app.HideCell;          
           Stitchingdata.Cells.OverlappedCell=app.OverlappedCell;   
           Stitchingdata.Cells.RepeatedCell=app.RepeatedCell;                         
           Stitchingdata.Cells.H_Cells=app.H_Cells;
           
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Package 7: used-defined regions...'};
           scroll(app.Textbox, 'bottom')
           Stitchingdata.RegionInformation=struct;
           
           Stitchingdata.RegionInformation.Regions=app.Regions;
           Stitchingdata.RegionInformation.ROI_X=app.ROI_X;
           Stitchingdata.RegionInformation.ROI_Y=app.ROI_Y;


           save('Stitchingdata.mat','Stitchingdata');
           app.Textbox.Items(length(app.Textbox.Items)+1)={'Data is saved!'};
           scroll(app.Textbox, 'bottom')                      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WidefieldImagePanel = uipanel(app.UIFigure);
            app.WidefieldImagePanel.Title = 'Wide-field Image';
            app.GridLayout = uigridlayout(app.WidefieldImagePanel);
            app.WF_SelectFile = uibutton(app.GridLayout, 'push');
            app.WF_SelectFile.ButtonPushedFcn = createCallbackFcn(app, @WF_SelectFilePushed, true);
            app.WF_SelectFile.Text = 'Select';
            app.DeleteWF = uibutton(app.GridLayout, 'push');
            app.DeleteWF.ButtonPushedFcn = createCallbackFcn(app, @DeleteWFButtonPushed, true);
            app.DeleteWF.Text = 'Delete';
            app.FilepathEditFieldLabel = uilabel(app.GridLayout);
            app.FilepathEditFieldLabel.Text = 'File path';
            app.WF_FilePathPanel = uieditfield(app.GridLayout, 'text');
            app.AlphaSliderLabel = uilabel(app.GridLayout);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.WF_Alpha = uislider(app.GridLayout);
            app.WF_Alpha.Limits = [0 1];
            app.WF_Alpha.ValueChangedFcn = createCallbackFcn(app, @WF_AlphaValueChanged, true);
            app.WF_Alpha.Value = 1;
            app.MINI2PImageNeuronInformationmatPanel = uipanel(app.UIFigure);
            app.MINI2PImageNeuronInformationmatPanel.Title = 'MINI2P Image (NeuronInformation.mat)';
            app.AddFOV = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.AddFOV.ButtonPushedFcn = createCallbackFcn(app, @AddFOVButtonPushed, true);
            app.AddFOV.Text = 'Add';
            app.ChannelDropDownLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.MINI2PImageNeuronInformationmatPanel);
            app.ChannelDropDown.Items = {'1', '2'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = '1';
            app.PlaneDropDownLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.PlaneDropDownLabel.Text = 'Plane';
            app.PlaneDropDown = uidropdown(app.MINI2PImageNeuronInformationmatPanel);
            app.PlaneDropDown.Items = {'1', '2', '3', '4'};
            app.PlaneDropDown.ValueChangedFcn = createCallbackFcn(app, @PlaneDropDownValueChanged, true);
            app.PlaneDropDown.Value = '2';
            app.TypeDropDownLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.MINI2PImageNeuronInformationmatPanel);
            app.TypeDropDown.Items = {'Mean', 'Max'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Mean';
            app.UpdateButton = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TotalcellsEditFieldLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.TotalcellsEditFieldLabel.Text = 'Total cells';
            app.Totalcells = uieditfield(app.MINI2PImageNeuronInformationmatPanel, 'numeric');
            app.Totalcells.Editable = 'off';
            app.TotalrepeatedcellsLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.TotalrepeatedcellsLabel.Text = 'Total repeated cells';
            app.TotalRepeatCell = uieditfield(app.MINI2PImageNeuronInformationmatPanel, 'numeric');
            app.TotalRepeatCell.Editable = 'off';
            app.AbortButton = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort!';
            app.CellVisible = uicheckbox(app.MINI2PImageNeuronInformationmatPanel);
            app.CellVisible.ValueChangedFcn = createCallbackFcn(app, @CellVisibleValueChanged, true);
            app.CellVisible.Text = 'Cell Visible?';
            app.CellVisible.Value = true;
            app.ColorbyDropDownLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ColorbyDropDownLabel.Text = 'Color by';
            app.ColorbyDropDown = uidropdown(app.MINI2PImageNeuronInformationmatPanel);
            app.ColorbyDropDown.Items = {'FOV', 'Plane', 'Region', 'Custom'};
            app.ColorbyDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorbyDropDownValueChanged, true);
            app.ColorbyDropDown.Value = 'FOV';
            app.CellColormap = uidropdown(app.MINI2PImageNeuronInformationmatPanel);
            app.CellColormap.Items = {'hsv', 'cool', 'spring', 'summer', 'autumn', 'winter', 'AAAS'};
            app.CellColormap.ValueChangedFcn = createCallbackFcn(app, @CellColormapValueChanged, true);
            app.CellColormap.Value = 'hsv';
            app.ColormapDropDownLabel_2 = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ColormapDropDownLabel_2.Text = 'Colormap';
            app.CellFacealphaLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.CellFacealphaLabel.Text = 'Cell Face alpha';
            app.CellFaceAlpha = uislider(app.MINI2PImageNeuronInformationmatPanel);
            app.CellFaceAlpha.Limits = [0 1];
            app.CellFaceAlpha.ValueChangedFcn = createCallbackFcn(app, @CellFaceAlphaValueChanged, true);
            app.CellFaceAlpha.Value = 0.7;
            app.CellLinealphaLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.CellLinealphaLabel.Text = 'Cell Line alpha';
            app.CellLineAlpha = uislider(app.MINI2PImageNeuronInformationmatPanel);
            app.CellLineAlpha.Limits = [0 1];
            app.CellLineAlpha.ValueChangedFcn = createCallbackFcn(app, @CellLineAlphaValueChanged, true);
            app.CellLineAlpha.Value = 0.5;
            app.FOVAlphaglobal = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.FOVAlphaglobal.ButtonPushedFcn = createCallbackFcn(app, @FOVAlphaglobalPushed, true);
            app.FOVAlphaglobal.Text = 'Alpha global set';
            app.AlphaEditFieldLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaGlobal = uieditfield(app.MINI2PImageNeuronInformationmatPanel, 'numeric');
            app.AlphaGlobal.Value = 0.5;
            app.CellAlphaglobal = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.CellAlphaglobal.ButtonPushedFcn = createCallbackFcn(app, @CellAlphaglobalButtonPushed, true);
            app.CellAlphaglobal.Text = 'Alpha global set';
            app.FOVVisible = uicheckbox(app.MINI2PImageNeuronInformationmatPanel);
            app.FOVVisible.ValueChangedFcn = createCallbackFcn(app, @FOVVisibleValueChanged2, true);
            app.FOVVisible.Text = 'Image Visible?';
            app.FOVVisible.Value = true;
            app.ShowBox = uicheckbox(app.MINI2PImageNeuronInformationmatPanel);
            app.ShowBox.ValueChangedFcn = createCallbackFcn(app, @ShowBoxValueChanged, true);
            app.ShowBox.Text = 'Show box?';
            app.ShowBox.Value = true;
            app.ExtractAllCell = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.ExtractAllCell.ButtonPushedFcn = createCallbackFcn(app, @ExtractAllCellButtonPushed, true);
            app.ExtractAllCell.Text = 'Extract cells!';
            app.ShiftXSpinner_2Label = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ShiftXSpinner_2Label.Text = 'Shift-X';
            app.FOVShiftXControl = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.FOVShiftXControl.ValueChangedFcn = createCallbackFcn(app, @FOVShiftXControlValueChanged, true);
            app.ShiftYSpinner_2Label = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ShiftYSpinner_2Label.Text = 'Shift-Y';
            app.FOVShiftYControl = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.FOVShiftYControl.ValueChangedFcn = createCallbackFcn(app, @FOVShiftYControlValueChanged, true);
            app.SizeSpinner_2Label = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.SizeSpinner_2Label.Text = 'Size';
            app.FOV_resizeControl = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.FOV_resizeControl.Step = 0.02;
            app.FOV_resizeControl.ValueChangedFcn = createCallbackFcn(app, @FOV_resizeControlValueChanged, true);
            app.FOV_resizeControl.Value = 1;
            app.RotateSpinner_2Label = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.RotateSpinner_2Label.Text = 'Rotate';
            app.FOV_rotateControl = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.FOV_rotateControl.ValueChangedFcn = createCallbackFcn(app, @FOV_rotateControlValueChanged, true);
            app.FOV_rotateControl.Value = 90;
            app.FlipXControl = uicheckbox(app.MINI2PImageNeuronInformationmatPanel);
            app.FlipXControl.ValueChangedFcn = createCallbackFcn(app, @FlipXControlValueChanged, true);
            app.FlipXControl.Text = 'Flip X';
            app.FlipXControl.Value = true;
            app.FlipYControl = uicheckbox(app.MINI2PImageNeuronInformationmatPanel);
            app.FlipYControl.ValueChangedFcn = createCallbackFcn(app, @FlipYControlValueChanged, true);
            app.FlipYControl.Text = 'Flip Y';
            app.ShowFOVID = uicheckbox(app.MINI2PImageNeuronInformationmatPanel);
            app.ShowFOVID.ValueChangedFcn = createCallbackFcn(app, @ShowFOVIDValueChanged, true);
            app.ShowFOVID.Text = 'Show FOV ID';
            app.ShowFOVID.Value = true;
            app.DeleteButton = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.StitchibgPoorListBoxLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.StitchibgPoorListBoxLabel.Text = 'Stitchibg Poor';
            app.StitchingPoorListBox = uilistbox(app.MINI2PImageNeuronInformationmatPanel);
            app.StitchingPoorListBox.Items = {};
            app.StitchingPoorListBox.ValueChangedFcn = createCallbackFcn(app, @StitchingPoorListBoxValueChanged, true);
            app.StitchingPoorListBox.Value = {};
            app.CellextractedLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.CellextractedLabel.Text = 'Cell extracted';
            app.CellextractedList = uilistbox(app.MINI2PImageNeuronInformationmatPanel);
            app.CellextractedList.Items = {};
            app.CellextractedList.Enable = 'off';
            app.CellextractedList.Value = {};
            app.CurrentFOVEditFieldLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.CurrentFOVEditFieldLabel.Text = 'Current FOV';
            app.CurrentFOV = uieditfield(app.MINI2PImageNeuronInformationmatPanel, 'numeric');
            app.CurrentFOV.Editable = 'off';
            app.FOVAlphaLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.FOVAlphaLabel.Text = 'FOV Alpha';
            app.AlphasingleFOVControl = uislider(app.MINI2PImageNeuronInformationmatPanel);
            app.AlphasingleFOVControl.Limits = [0 1];
            app.AlphasingleFOVControl.ValueChangedFcn = createCallbackFcn(app, @AlphasingleFOVControlValueChanged, true);
            app.AlphasingleFOVControl.Value = 0.8;
            app.FaceAlpha_globalLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.FaceAlpha_globalLabel.Text = 'FaceAlpha_global';
            app.Cell_FaceAlphaGlobal = uieditfield(app.MINI2PImageNeuronInformationmatPanel, 'numeric');
            app.Cell_FaceAlphaGlobal.Value = 0.8;
            app.LineAlpha_globalLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.LineAlpha_globalLabel.Text = 'LineAlpha_global';
            app.Cell_lineAlphaGlobal = uieditfield(app.MINI2PImageNeuronInformationmatPanel, 'numeric');
            app.Cell_lineAlphaGlobal.Value = 0.2;
            app.FontsizeLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.FontsizeLabel.Text = 'Fontsize';
            app.Fontsize = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.Fontsize.ValueChangedFcn = createCallbackFcn(app, @FontsizeValueChanged, true);
            app.Fontsize.Value = 10;
            app.ColorLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.ColorLabel.Text = '#Color';
            app.Colornumber = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.Colornumber.Limits = [0 256];
            app.Colornumber.ValueChangedFcn = createCallbackFcn(app, @ColornumberValueChanged, true);
            app.Colornumber.Value = 10;
            app.HideRepeatedCell_plane = uicheckbox(app.MINI2PImageNeuronInformationmatPanel);
            app.HideRepeatedCell_plane.ValueChangedFcn = createCallbackFcn(app, @HideRepeatedCell_planeValueChanged, true);
            app.HideRepeatedCell_plane.Text = 'Hide repeated cells (plane)';
            app.HideRepeatedCell_plane.Value = true;
            app.DetectOverlapCell = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.DetectOverlapCell.ButtonPushedFcn = createCallbackFcn(app, @DetectOverlapCellButtonPushed, true);
            app.DetectOverlapCell.Text = 'Detect overlapped cells!';
            app.OverlaprationLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.OverlaprationLabel.Text = 'Overlap ration';
            app.OverlapThreshold = uieditfield(app.MINI2PImageNeuronInformationmatPanel, 'numeric');
            app.OverlapThreshold.Value = 0.75;
            app.ExtractAllCell_3 = uibutton(app.MINI2PImageNeuronInformationmatPanel, 'push');
            app.ExtractAllCell_3.ButtonPushedFcn = createCallbackFcn(app, @ExtractAllCell_3ButtonPushed, true);
            app.ExtractAllCell_3.Text = 'Show overlap map';
            app.MisalignySpinnerLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.MisalignySpinnerLabel.Enable = 'off';
            app.MisalignySpinnerLabel.Text = 'Misalign y';
            app.MisalignySpinner = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.MisalignySpinner.ValueChangedFcn = createCallbackFcn(app, @MisalignySpinnerValueChanged, true);
            app.MisalignySpinner.Editable = 'off';
            app.MisalignySpinner.Enable = 'off';
            app.MisalignySpinner.Value = -1;
            app.MisalignxSpinnerLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.MisalignxSpinnerLabel.Enable = 'off';
            app.MisalignxSpinnerLabel.Text = 'Misalign x';
            app.MisalignxSpinner = uispinner(app.MINI2PImageNeuronInformationmatPanel);
            app.MisalignxSpinner.ValueChangedFcn = createCallbackFcn(app, @MisalignxSpinnerValueChanged, true);
            app.MisalignxSpinner.Editable = 'off';
            app.MisalignxSpinner.Enable = 'off';
            app.MisalignxSpinner.Value = -1;
            app.DistancepixLabel = uilabel(app.MINI2PImageNeuronInformationmatPanel);
            app.DistancepixLabel.Text = 'Distance (pix)';
            app.PixelThreshold = uieditfield(app.MINI2PImageNeuronInformationmatPanel, 'numeric');
            app.PixelThreshold.Value = 10;
            app.HideRepeatedCell_FOV = uicheckbox(app.MINI2PImageNeuronInformationmatPanel);
            app.HideRepeatedCell_FOV.ValueChangedFcn = createCallbackFcn(app, @HideRepeatedCell_FOVValueChanged, true);
            app.HideRepeatedCell_FOV.Text = 'Hide repeated cells (FOV)';
            app.HideRepeatedCell_FOV.Value = true;
            app.DataInputandOutputPanel = uipanel(app.UIFigure);
            app.DataInputandOutputPanel.Title = 'Data Input and Output';
            app.SelectDataFolder = uibutton(app.DataInputandOutputPanel, 'push');
            app.SelectDataFolder.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderPushed, true);
            app.SelectDataFolder.Text = 'Select';
            app.SaveButton = uibutton(app.DataInputandOutputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save!';
            app.FilepathEditField_2Label = uilabel(app.DataInputandOutputPanel);
            app.FilepathEditField_2Label.Text = 'File path';
            app.DataFilepathEditField = uieditfield(app.DataInputandOutputPanel, 'text');
            app.InputButton = uibutton(app.DataInputandOutputPanel, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.StitchingViewerPanel = uipanel(app.UIFigure);
            app.StitchingViewerPanel.Title = 'Stitching Viewer';
            app.WFAxes = uiaxes(app.StitchingViewerPanel);
            title(app.WFAxes, 'Stitching pannel')
            xlabel(app.WFAxes, 'X')
            ylabel(app.WFAxes, 'Y')
            app.ColorbarAxes = uiaxes(app.StitchingViewerPanel);
            title(app.ColorbarAxes, '')
            xlabel(app.ColorbarAxes, '')
            ylabel(app.ColorbarAxes, '')
            app.ColorbarAxes.XTick = [];
            app.ColorbarAxes.YTick = [];
            app.RetinotopicMappingPanel = uipanel(app.UIFigure);
            app.RetinotopicMappingPanel.Title = 'Retinotopic Mapping';
            app.GridLayout2 = uigridlayout(app.RetinotopicMappingPanel);
            app.SelectRMButton = uibutton(app.GridLayout2, 'push');
            app.SelectRMButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRMButtonPushed, true);
            app.SelectRMButton.Text = 'Select';
            app.SelectDFButton = uibutton(app.GridLayout2, 'push');
            app.SelectDFButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDFButtonPushed, true);
            app.SelectDFButton.Text = 'Select';
            app.DeleteRM = uibutton(app.GridLayout2, 'push');
            app.DeleteRM.ButtonPushedFcn = createCallbackFcn(app, @DeleteRMButtonPushed, true);
            app.DeleteRM.Text = 'Delete';
            app.RetMapEditFieldLabel = uilabel(app.GridLayout2);
            app.RetMapEditFieldLabel.Text = 'Ret.Map';
            app.RetMapEditField = uieditfield(app.GridLayout2, 'text');
            app.AlphaSliderLabel_2 = uilabel(app.GridLayout2);
            app.AlphaSliderLabel_2.Text = 'Alpha';
            app.RM_alpha = uislider(app.GridLayout2);
            app.RM_alpha.Limits = [0 1];
            app.RM_alpha.ValueChangedFcn = createCallbackFcn(app, @RM_alphaValueChanged, true);
            app.RM_alpha.Value = 0.8;
            app.MeandFFEditFieldLabel = uilabel(app.GridLayout2);
            app.MeandFFEditFieldLabel.Text = 'Mean dF/F';
            app.MeandFFEditField = uieditfield(app.GridLayout2, 'text');
            app.MINI2PCorrectionTransfomrMatrixPanel = uipanel(app.UIFigure);
            app.MINI2PCorrectionTransfomrMatrixPanel.Title = 'MINI2P Correction Transformation Matrix ';
            app.LoadButton = uibutton(app.MINI2PCorrectionTransfomrMatrixPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FilepathEditField_4Label = uilabel(app.MINI2PCorrectionTransfomrMatrixPanel);
            app.FilepathEditField_4Label.Text = 'File path';
            app.TransMatrix_filepath = uieditfield(app.MINI2PCorrectionTransfomrMatrixPanel, 'text');
            app.TransMatrix_filepath.Value = '\\forskning.it.ntnu.no\ntnu\mh-kin\moser\open2pmini\FOV callibration\OPENMINI2P2_Workshop\LFOV001\AVG_OpenMINI2P_LFOV001_50umGrind_256_00001_2021-03-10-16-16-17.mat';
            app.CroesscorrelationanalysisPanel = uipanel(app.UIFigure);
            app.CroesscorrelationanalysisPanel.Title = 'Croess-correlation analysis';
            app.CheckCorrelationButton = uibutton(app.CroesscorrelationanalysisPanel, 'push');
            app.CheckCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @CheckCorrelationButtonPushed, true);
            app.CheckCorrelationButton.Text = 'Check Correlation';
            app.WidefieldCheckBox = uicheckbox(app.CroesscorrelationanalysisPanel);
            app.WidefieldCheckBox.Text = 'Wide-field';
            app.WidefieldCheckBox.Value = true;
            app.RangepixelLabel = uilabel(app.CroesscorrelationanalysisPanel);
            app.RangepixelLabel.Text = 'Range (pixel)';
            app.RangepixelEditField = uieditfield(app.CroesscorrelationanalysisPanel, 'numeric');
            app.RangepixelEditField.Value = 5;
            app.DrawcommentareaButton = uibutton(app.CroesscorrelationanalysisPanel, 'push');
            app.DrawcommentareaButton.ButtonPushedFcn = createCallbackFcn(app, @DrawcommentareaButtonPushed, true);
            app.DrawcommentareaButton.Text = 'Draw comment area';
            app.ClearROIButton = uibutton(app.CroesscorrelationanalysisPanel, 'push');
            app.ClearROIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearROIButtonPushed, true);
            app.ClearROIButton.Text = 'Clear ROI';
            app.ReferenceFOVEditFieldLabel = uilabel(app.CroesscorrelationanalysisPanel);
            app.ReferenceFOVEditFieldLabel.Text = 'Reference FOV';
            app.ReferenceFOVEditField = uieditfield(app.CroesscorrelationanalysisPanel, 'numeric');
            app.ReferenceFOVEditField.Value = 1;
            app.CurrentFOVAxes = uiaxes(app.CroesscorrelationanalysisPanel);
            title(app.CurrentFOVAxes, 'Current FOV in ROI')
            xlabel(app.CurrentFOVAxes, '')
            ylabel(app.CurrentFOVAxes, '')
            app.ReferenceFOVAxes = uiaxes(app.CroesscorrelationanalysisPanel);
            title(app.ReferenceFOVAxes, 'Reference FOV in ROI')
            xlabel(app.ReferenceFOVAxes, '')
            ylabel(app.ReferenceFOVAxes, '')
            app.YEditFieldLabel = uilabel(app.CroesscorrelationanalysisPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CroesscorrelationanalysisPanel, 'numeric');
            app.YEditField.Editable = 'off';
            app.XcorrelationAxes = uiaxes(app.CroesscorrelationanalysisPanel);
            title(app.XcorrelationAxes, 'Cross-correlation')
            xlabel(app.XcorrelationAxes, 'X')
            ylabel(app.XcorrelationAxes, 'Y')
            app.XEditFieldLabel = uilabel(app.CroesscorrelationanalysisPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CroesscorrelationanalysisPanel, 'numeric');
            app.XEditField.Editable = 'off';
            app.PeakEditFieldLabel = uilabel(app.CroesscorrelationanalysisPanel);
            app.PeakEditFieldLabel.Text = 'Peak ';
            app.PeakEditField = uieditfield(app.CroesscorrelationanalysisPanel, 'numeric');
            app.PeakEditField.Editable = 'off';
            app.WidefieldtransformationPanel = uipanel(app.UIFigure);
            app.WidefieldtransformationPanel.Title = 'Wide-field transformation';
            app.WF_Reset = uibutton(app.WidefieldtransformationPanel, 'push');
            app.WF_Reset.ButtonPushedFcn = createCallbackFcn(app, @WF_ResetButtonPushed, true);
            app.WF_Reset.Text = 'Reset';
            app.ShiftXSpinnerLabel = uilabel(app.WidefieldtransformationPanel);
            app.ShiftXSpinnerLabel.Text = 'Shift-X';
            app.WF_ShiftX = uispinner(app.WidefieldtransformationPanel);
            app.WF_ShiftX.ValueChangedFcn = createCallbackFcn(app, @WF_ShiftXValueChanged, true);
            app.ShiftYSpinnerLabel = uilabel(app.WidefieldtransformationPanel);
            app.ShiftYSpinnerLabel.Text = 'Shift-Y';
            app.WF_ShiftY = uispinner(app.WidefieldtransformationPanel);
            app.WF_ShiftY.ValueChangedFcn = createCallbackFcn(app, @WF_ShiftYValueChanged, true);
            app.SizeSpinnerLabel = uilabel(app.WidefieldtransformationPanel);
            app.SizeSpinnerLabel.Text = 'Size';
            app.WF_Resize = uispinner(app.WidefieldtransformationPanel);
            app.WF_Resize.Step = 0.02;
            app.WF_Resize.ValueChangedFcn = createCallbackFcn(app, @WF_ResizeValueChanged, true);
            app.WF_Resize.Value = 5.17;
            app.RotateSpinnerLabel = uilabel(app.WidefieldtransformationPanel);
            app.RotateSpinnerLabel.Text = 'Rotate';
            app.WF_Rotate = uispinner(app.WidefieldtransformationPanel);
            app.WF_Rotate.Step = 0.1;
            app.WF_Rotate.Limits = [-360 360];
            app.WF_Rotate.ValueChangedFcn = createCallbackFcn(app, @WF_RotateValueChanged, true);
            app.RegionseperationPanel = uipanel(app.UIFigure);
            app.RegionseperationPanel.Title = 'Region seperation';
            app.AddRegion = uibutton(app.RegionseperationPanel, 'push');
            app.AddRegion.ButtonPushedFcn = createCallbackFcn(app, @AddRegionButtonPushed, true);
            app.AddRegion.Text = 'Add';
            app.Deleteallregion = uibutton(app.RegionseperationPanel, 'push');
            app.Deleteallregion.ButtonPushedFcn = createCallbackFcn(app, @DeleteallregionButtonPushed, true);
            app.Deleteallregion.Text = 'Delete all';
            app.RegionPoorListBoxLabel = uilabel(app.RegionseperationPanel);
            app.RegionPoorListBoxLabel.Text = 'Region Poor';
            app.RegionPoorListBox = uilistbox(app.RegionseperationPanel);
            app.RegionPoorListBox.Items = {};
            app.RegionPoorListBox.ValueChangedFcn = createCallbackFcn(app, @RegionPoorListBoxValueChanged, true);
            app.RegionPoorListBox.Value = {};
            app.ShownameCheckBox = uicheckbox(app.RegionseperationPanel);
            app.ShownameCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShownameCheckBoxValueChanged, true);
            app.ShownameCheckBox.Text = 'Show name';
            app.ShownameCheckBox.Value = true;
            app.TotalRegionEditFieldLabel = uilabel(app.RegionseperationPanel);
            app.TotalRegionEditFieldLabel.Text = 'Total Region';
            app.TotalRegionEditField = uieditfield(app.RegionseperationPanel, 'numeric');
            app.TotalRegionEditField.Editable = 'off';
            app.CurrentRegionEditFieldLabel = uilabel(app.RegionseperationPanel);
            app.CurrentRegionEditFieldLabel.Text = 'Current Region';
            app.CurrentRegionEditField = uieditfield(app.RegionseperationPanel, 'numeric');
            app.CurrentRegionEditField.Editable = 'off';
            app.RegionnameEditFieldLabel = uilabel(app.RegionseperationPanel);
            app.RegionnameEditFieldLabel.Text = 'Region name';
            app.RegionnameEditField = uieditfield(app.RegionseperationPanel, 'text');
            app.RegionnameEditField.ValueChangedFcn = createCallbackFcn(app, @RegionnameEditFieldValueChanged, true);
            app.VisibleCheckBox = uicheckbox(app.RegionseperationPanel);
            app.VisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisibleCheckBoxValueChanged, true);
            app.VisibleCheckBox.Text = 'Visible?';
            app.VisibleCheckBox.Value = true;
            app.Deleteallregion_2 = uibutton(app.RegionseperationPanel, 'push');
            app.Deleteallregion_2.Text = 'Burn-in!';
            app.AlphaSliderLabel_3 = uilabel(app.RegionseperationPanel);
            app.AlphaSliderLabel_3.Text = 'Alpha';
            app.Region_Alpha = uislider(app.RegionseperationPanel);
            app.Region_Alpha.Limits = [0 1];
            app.Region_Alpha.ValueChangedFcn = createCallbackFcn(app, @Region_AlphaValueChanged, true);
            app.Region_Alpha.Value = 0.2;
            app.LineWidthLabel = uilabel(app.RegionseperationPanel);
            app.LineWidthLabel.Text = 'LineWidth';
            app.LineWidth = uispinner(app.RegionseperationPanel);
            app.LineWidth.Step = 0.1;
            app.LineWidth.Limits = [-360 360];
            app.LineWidth.ValueChangedFcn = createCallbackFcn(app, @LineWidthValueChanged, true);
            app.LineWidth.Value = 1;
            app.AssignCell = uibutton(app.RegionseperationPanel, 'push');
            app.AssignCell.ButtonPushedFcn = createCallbackFcn(app, @AssignCellButtonPushed, true);
            app.AssignCell.Text = 'Assign cells to regions!';
            app.RegionAlphaglobal = uibutton(app.RegionseperationPanel, 'push');
            app.RegionAlphaglobal.ButtonPushedFcn = createCallbackFcn(app, @RegionAlphaglobalButtonPushed, true);
            app.RegionAlphaglobal.Text = 'Alpha global set';
            app.LineAlpha_globalLabel_2 = uilabel(app.RegionseperationPanel);
            app.LineAlpha_globalLabel_2.Text = 'LineAlpha_global';
            app.Region_AlphaGlobal = uieditfield(app.RegionseperationPanel, 'numeric');
            app.Region_AlphaGlobal.Value = 0.2;
            app.TextboxListBoxLabel = uilabel(app.UIFigure);
            app.TextboxListBoxLabel.Text = 'Textbox';
            app.Textbox = uilistbox(app.UIFigure);
            app.Textbox.Items = {};
            app.Textbox.Value = {};
            app.CleanTextboxButton = uibutton(app.UIFigure, 'push');
            app.CleanTextboxButton.ButtonPushedFcn = createCallbackFcn(app, @CleanTextboxButtonPushed, true);
            app.CleanTextboxButton.Text = 'Clean Textbox';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StitchingChecker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end